classdef TMSAnalysisToolBox_v2_1_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenNewFileMenu                 matlab.ui.container.Menu
        LabChartmatMenu                 matlab.ui.container.Menu
        BrainsighttxtMenu               matlab.ui.container.Menu
        SignalmatcfsMenu                matlab.ui.container.Menu
        AcqknowledgematacqMenu          matlab.ui.container.Menu
        BrainVisioneegMenu              matlab.ui.container.Menu
        SpikesmrMenu                    matlab.ui.container.Menu
        CustomMenu                      matlab.ui.container.Menu
        OpenConditionFileMenu           matlab.ui.container.Menu
        OpenVersion1ConditionFilesMenu  matlab.ui.container.Menu
        UsewhenanerroroccursLabel       matlab.ui.control.Label
        ClearLoadingScreenButton        matlab.ui.control.Button
        App_Panel                       matlab.ui.container.Panel
        SButton                         matlab.ui.control.Button
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        LeftArrowAnalyzeButton          matlab.ui.control.Button
        RightArrowAnalyzeButton         matlab.ui.control.Button
        Citation                        matlab.ui.control.Label
        TutorialLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ChangeSaveFolderButton          matlab.ui.control.Button
        MissingfromanalyzeLabel         matlab.ui.control.Label
        AverageCheckBox                 matlab.ui.control.CheckBox
        SavematEditField                matlab.ui.control.EditField
        SaveProcessedDatamatButton      matlab.ui.control.Button
        SavecsvEditField                matlab.ui.control.EditField
        SavecsvButton                   matlab.ui.control.Button
        AutoScaleButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataOrganizationTab             matlab.ui.container.Tab
        FileOrderListBoxLabel           matlab.ui.control.Label
        FileOrderListBox                matlab.ui.control.ListBox
        SetDataWindowPanel              matlab.ui.container.Panel
        PostDurationsEditField          matlab.ui.control.NumericEditField
        PostDurationsEditFieldLabel     matlab.ui.control.Label
        PreDurationsEditField           matlab.ui.control.NumericEditField
        PreDurationsEditFieldLabel      matlab.ui.control.Label
        PrePostTriggerTimemsPanel       matlab.ui.container.Panel
        PostTriggermsEditField          matlab.ui.control.NumericEditField
        PostTriggermsEditFieldLabel     matlab.ui.control.Label
        PreTriggermsEditField           matlab.ui.control.NumericEditField
        PreTriggermsEditFieldLabel      matlab.ui.control.Label
        TrialSpinner                    matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
        FindEventsButton                matlab.ui.control.Button
        EventParametersPanel            matlab.ui.container.Panel
        DetectionSettingsDropDown       matlab.ui.control.DropDown
        DetectionSettingsDropDownLabel  matlab.ui.control.Label
        SearchISIsEditField             matlab.ui.control.NumericEditField
        SearchISIsEditFieldLabel        matlab.ui.control.Label
        ThresholdmVEditField            matlab.ui.control.NumericEditField
        ThresholdmVEditFieldLabel       matlab.ui.control.Label
        EventChannelEditField           matlab.ui.control.NumericEditField
        EventChannelEditFieldLabel      matlab.ui.control.Label
        EventNameListBox                matlab.ui.control.ListBox
        EventNameListBoxLabel           matlab.ui.control.Label
        SelectAllButton                 matlab.ui.control.Button
        TrialSelectListBox              matlab.ui.control.ListBox
        TrialSelectListBoxLabel         matlab.ui.control.Label
        EpochTypesButtonGroup           matlab.ui.container.ButtonGroup
        EventsButton                    matlab.ui.control.RadioButton
        CommentsButton                  matlab.ui.control.RadioButton
        BlocksButton                    matlab.ui.control.RadioButton
        ProcessTab                      matlab.ui.container.Tab
        CustomProcessesLabel            matlab.ui.control.Label
        CustomProcessTree               matlab.ui.container.CheckBoxTree
        ProcessOrderListBoxLabel        matlab.ui.control.Label
        ProcessOrderListBox             matlab.ui.control.ListBox
        OtherNotchHzLabel               matlab.ui.control.Label
        OtherNotchEditField             matlab.ui.control.NumericEditField
        HighPassFilterRs                matlab.ui.control.NumericEditField
        LowPassFilterRs                 matlab.ui.control.NumericEditField
        BandPassFilterRs                matlab.ui.control.NumericEditField
        HighPassFilterRsLabel           matlab.ui.control.Label
        BandPassFilterRsLabel           matlab.ui.control.Label
        LowPassFilterRsLabel            matlab.ui.control.Label
        HighPassFilterRp                matlab.ui.control.NumericEditField
        LowPassFilterRp                 matlab.ui.control.NumericEditField
        BandPassFilterRp                matlab.ui.control.NumericEditField
        HighPassFilterOrder             matlab.ui.control.NumericEditField
        LowPassFilterOrder              matlab.ui.control.NumericEditField
        BandPassFilterOrder             matlab.ui.control.NumericEditField
        HighPassEditField               matlab.ui.control.NumericEditField
        LowPassEditField                matlab.ui.control.NumericEditField
        BandPassEndEditField            matlab.ui.control.NumericEditField
        HighPassFilterRpLabel           matlab.ui.control.Label
        BandPassFilterRpLabel           matlab.ui.control.Label
        LowPassFilterRpLabel            matlab.ui.control.Label
        OtherNotchButton                matlab.ui.control.StateButton
        NotchEuropeanButton             matlab.ui.control.StateButton
        NotchUSButton                   matlab.ui.control.StateButton
        HalfWaveButton                  matlab.ui.control.StateButton
        FullWaveButton                  matlab.ui.control.StateButton
        BandPassFilterOrderLabel        matlab.ui.control.Label
        HighPassFilterOrderLabel        matlab.ui.control.Label
        LowPassFilterOrderLabel         matlab.ui.control.Label
        HighPassFilterType              matlab.ui.control.DropDown
        LowPassFilterType               matlab.ui.control.DropDown
        BandPassFilterType              matlab.ui.control.DropDown
        ResetDataButton                 matlab.ui.control.Button
        BandPassHzLabel                 matlab.ui.control.Label
        BandPassDashLabel               matlab.ui.control.Label
        BandPassStartEditField          matlab.ui.control.NumericEditField
        HighPassHzLabel                 matlab.ui.control.Label
        DerivativeOrderLabel            matlab.ui.control.Label
        DerivativeEditField             matlab.ui.control.NumericEditField
        DelayEditField                  matlab.ui.control.NumericEditField
        DelaymsLabel                    matlab.ui.control.Label
        LowPassHzLabel                  matlab.ui.control.Label
        FactorEditField                 matlab.ui.control.NumericEditField
        FactorLabel                     matlab.ui.control.Label
        MoveUpButton                    matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        ResetOrderButton                matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        ProcessTree                     matlab.ui.container.CheckBoxTree
        DownSampleNode                  matlab.ui.container.TreeNode
        NotchNode                       matlab.ui.container.TreeNode
        BandPassNode                    matlab.ui.container.TreeNode
        LowPassNode                     matlab.ui.container.TreeNode
        HighPassNode                    matlab.ui.container.TreeNode
        DerivativeNode                  matlab.ui.container.TreeNode
        DelayNode                       matlab.ui.container.TreeNode
        RectifyNode                     matlab.ui.container.TreeNode
        AnalysisTab                     matlab.ui.container.Tab
        CustomOutputsPanel              matlab.ui.container.Panel
        CustomOutputsGridLayout         matlab.ui.container.GridLayout
        NDButton                        matlab.ui.control.Button
        PlotOnsetOffsetSwitchLabel      matlab.ui.control.Label
        PlotOnsetOffsetSwitch           matlab.ui.control.Switch
        OverrideLamp                    matlab.ui.control.Lamp
        OverrideButton                  matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        PreStimLabel                    matlab.ui.control.Label
        PreStimStdEditField             matlab.ui.control.NumericEditField
        PreStimStdEditFieldLabel        matlab.ui.control.Label
        PreStimMeanEditField            matlab.ui.control.NumericEditField
        PreStimMeanEditFieldLabel       matlab.ui.control.Label
        PreStimPeriodmsEndEditField     matlab.ui.control.NumericEditField
        PreStimPeriodDashLabel          matlab.ui.control.Label
        PreStimPeriodmsStartEditField   matlab.ui.control.NumericEditField
        PreStimPeriodLabel              matlab.ui.control.Label
        PreStimStdLabel                 matlab.ui.control.Label
        PreStimMeanLabel                matlab.ui.control.Label
        PreStimPeriodUnitLabel          matlab.ui.control.Label
        AnalysisOptionLabel             matlab.ui.control.Label
        AnalysisOptionDropDown          matlab.ui.control.DropDown
        MotorEvokedPotentialPanel       matlab.ui.container.Panel
        OnsetRiseorFallCheckBox         matlab.ui.control.CheckBox
        FiftyPercentOffsetCheckBox      matlab.ui.control.CheckBox
        MCDConstantEditField            matlab.ui.control.NumericEditField
        MCDConstantEditFieldLabel       matlab.ui.control.Label
        OffsetMinDurmsEditField         matlab.ui.control.NumericEditField
        MinDurmsEditFieldLabel_2        matlab.ui.control.Label
        OffsetSDEditField               matlab.ui.control.NumericEditField
        SDEditFieldLabel_2              matlab.ui.control.Label
        OnsetMinDurmsEditField          matlab.ui.control.NumericEditField
        MinDurmsEditFieldLabel          matlab.ui.control.Label
        OnsetSDEditField                matlab.ui.control.NumericEditField
        SDEditFieldLabel                matlab.ui.control.Label
        AreaValueLabel                  matlab.ui.control.Label
        DecreaseValueLabel              matlab.ui.control.Label
        DurationmsValueLabel            matlab.ui.control.Label
        OnsetmsValueLabel               matlab.ui.control.Label
        AreamVsLabel                    matlab.ui.control.Label
        AmplitudemVLabel                matlab.ui.control.Label
        DurationmsLabel                 matlab.ui.control.Label
        OnsetmsLabel                    matlab.ui.control.Label
        OffsetLabel                     matlab.ui.control.Label
        OnsetLabel                      matlab.ui.control.Label
        StartTimemsEditField            matlab.ui.control.NumericEditField
        StartTimemsEditFieldLabel       matlab.ui.control.Label
        FiftyPercentOnsetCheckBox       matlab.ui.control.CheckBox
        OnsetOffsetDetectionLabel       matlab.ui.control.Label
        OnsetOffsetDetectionDropDown    matlab.ui.control.DropDown
        YScaleLabel                     matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2                  matlab.ui.control.NumericEditField
        XScaleLabel                     matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        UnitsDropDownLabel              matlab.ui.control.Label
        SampleRateLabel                 matlab.ui.control.Label
        DefineConditionsPanel           matlab.ui.container.Panel
        OverrideGroupNameEditField      matlab.ui.control.EditField
        OverrideGroupNameButton         matlab.ui.control.Button
        SheetNameEditField              matlab.ui.control.EditField
        RecoverButton                   matlab.ui.control.Button
        KeeporDeleteRowsButton          matlab.ui.control.Button
        UploadExcelFileButton           matlab.ui.control.Button
        LoadConditionsButton            matlab.ui.control.Button
        AutoFillEditField               matlab.ui.control.NumericEditField
        AutoFillButton                  matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        AddSelectedTrialsButton         matlab.ui.control.Button
        ImportEventsButton              matlab.ui.control.Button
        AddBlankRowButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ConditionsListBox               matlab.ui.control.ListBox
        ConditionsListBoxLabel          matlab.ui.control.Label
        SelectAllCondButton             matlab.ui.control.Button
        ConditionTrialsListBox          matlab.ui.control.ListBox
        ConditionTrialsLabel            matlab.ui.control.Label
        ChangeDataFolderButton          matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        DataFileEditField               matlab.ui.control.EditField
        DataFileEditFieldLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    properties (Access = private)
        AcqFile;
        Acqknowledge_Data;                       %Data loaded from Acqknowledge
        Acqknowledge_DataAll;                    %All data files for Acqknowledge
        AllInputsCell;                           %All inputs for uploading single excel
        AllPlotted_Data;                         %All plotted data (for trial select)
        BrainVision_Data;                        %Data loaded from BrainVision export
        BrainVision_DataAll;                     %All data files for BrainVision
        BrainSight_Data;                         %Data loaded from Brain Sight export
        ChangeCurrentRow                         %for when a row is changed in the conditions table (boolean)
        ComList;                                 %List of comments
        ConditionGroupNames;                     %Condition Group names from excel file
        Conditions_Data;                         %Data attached to Conditions table
        Conditions_DataAll;                      %All data from all conditions
        CurrChTitle;                             %Channel title of current data
        CurrentRow;                              %Current row in Conditions table that is being changed
        CustomUploadFile;                        %Custom upload file selected
        DeletedRows;                             %Conditions Table rows that were most recently deleted
        DisableLI_FindEvents;                    %used to disable loading indicator for find events button
        labChart_Data;                           %Data loaded from labChart export
        LoadedConditions;                        %Loaded Conditions file data
        LoadedConditionsAll;                     %All loaded Condition files data
        numStates;                               %Number of States (for Signal Data)
        numTrialsState;                          %Number of trials for each state
        Old;                                     %Old condition file uploaded (Boolean)
        Plotted_Data;                            %Current data that is plotted and will be analyzed
        Plugins;                                 %Plugins list
        SelectedCondition;                       %Selected condition in Conditions list box
        SelectedSheet;                           %selected excel sheet
        SelectedTableRows;                       %Rows that are selected in the Conditions table
        Signal_Data;                             %Data loaded from Signal export
        SignalFile;                              %Signal import file type (exported .mat, exported .cfs/converted .cfs)
        Spike_Data;                              %Data loaded from Spike export

        %analyze
        OverrideRange;                           %first and second click location for override
        PreStimStart=-110;                       %Start of pre-stim period (ms)
        PreStimEnd=-10;                          %End of pre-stim period (ms)
        PreStimMean;                             %Calculated mean of the rectified pre stim data
        PreStimRMS;                              %Calculated RMS for all analyzed trials during pre-stim period
        PreStimSTDV;                             %Calculated STDV for all analyzed trials during pre-stim period
        Analyze_TrialsUsed;                      %Which trials were used in the most recent Analyze
        Analyze_ConditionsUsed;                  %Which conditions were used in the most recent Analyze
        Analyze_OverrideUsed;                    %Boolean, if override was used in the most recent Analyze
        Analyze_TrialsUsedConditionName;         %Condition name for the trials used in most recetn Analyze
        Analyze_TrialsUsedChannel;               %Data channels that were analyzed in the most recent Analyze
        PrevToolBarSelect;                       %Previous item selected in axes toolbar
        CurrToolBarSelect;                       %Current item selected in axes toolbar
        POriginalAllPlottedData;                 %Original data before processing
        POriginalAvgPlottedData;                 %Original average data before processing
        POriginalTime;                           %Original time before processing
        Processed;                               %If data has been processed
        AvgConditionData;                        %Average of each condition data
        Toolbar;                                 %Axes toolbar
        PrevSelectedTab;                         %Previously selected tab
        ManualMark;                              %If a trial was manually marked as having no onset

        %Plotting lines
        OnsetLine;                               %Xline plots for all onsets found
        OffsetLine;                              %Xline plots for all offsets found
        PreStimArea;                             %Area plot for pre-stim area
        StartLine;                               %Xline plot for start time
        meanPreStimLine;                         %Yline plot for mean of pre stim
        PlotOnsetOffsetSwitchFig;                %Figure for the "plot onset/offset" switch


    end

    properties (Access = public)
        AllSampleRate;                           %Sample rate of data
        autoImportEvents;                        %For when Import Events button is pressed for filling in the Condtions table (boolean)
        Block;                                   %Data epoched based on blocks
        Ch;                                      %Channel
        Comments;                                %Data epoched based on comments and pre/post duration
        Convert2PlottedUnits;                    %Conversion for volts to selected unit, since updateSpinners and FindEvents pulls data in volts
        Custom_Data;                             %Data from custom file upload
        Custom_DataAll;                          %All data from all files for Custom upload
        CustomOutputs;                           %Outputs from custom analysis plugin
        DataType;                                %Which data file type is imported
        DataExtracted;                           %Data has been epoched (boolean)
        DetectionSetting;                        %Detection setting for finding events, currently only threshold crossing
        Directory;                               %File directory
        DownSampledRate;                         %sample rate if the data is downsampled
        EventChannel;                            %Channel to search for events in
        Events;                                  %Data epoched based on events
        EventTicks;                              %Ticks with events for Spike Data
        EpochType;                               %Epoch type to organize data to (Blocks, Comments, Events)
        EventThresh;                             %Threshold for events
        Filename;                                %File name of file loaded
        ImportEventsNameL                        %For Import Events button, track which Event Name
        MEP_Amp;                                 %Calculated MEP amplitude for all analyzed trials
        MEP_Area;                                %Calculated MEP area for all analyzed trials
        AllOnOffsetTime;                         %All onset and offset times for all analyzed trials
        SP_PercDecrease;                         %Calculated SP percent decrease for all analyzed trials
        SP_Area;                                 %Calculated SP area for all analyzed trials
        numBlocks;                               %Number of blocks
        numChannels;                             %Number of channels
        numTrials;                               %Number of trials - based on epoch type
        numTrialsC;                              %Number of trials for each comment
        numTrialsCondition;                      %number of trials per condition
        OverrideUsed;                            %Boolean, override was used = 1
        Path;                                    %Path of file loaded
        PlottedUnits;                            %Units of data plotted
        PostTriggerTime;                         %Time after the software set trigger to plot, not all software types save this data
        PostDur;                                 %Time after event to include
        PreDur;                                  %Time before event to include
        PreTriggerTime;                          %Time before the software set trigger to plot
        Processed_Conditions_DataAll;            %All data from all conditions processed
        ProcessOrder;                            %Order of processes last done
        SearchISI;                               %Search ISI for events
        SelectedComtxt;                          %Selected comment text
        StateV;                                  %State, contains blocks
        Time;                                    %Time that data is plotted on
        Trial;                                   %Trial: block#, comment# in for specified comment text, which event

        %Plotting lines
        OnsetLimitLines;                         %Onset limit lines
        OffsetLimitLines;                        %Offset limit lines

        %figures
        CustomOnOffDetectOpts                 %figure for custom detection methods (onset/offset detection plugins)
        CustomProcessingOpts;                    %figure for custom processing methods (processing plugins)
        CustomAnalysisOpts;                      %figure for custom analysis methods (analysis plugins)

        ToolboxDocFolderName='TMSToolBoxApp';    %Name of the folder created in Documents/MATLAB
    end

    methods (Access = public)
        function refreshApp(app)
            %==============================================================
            %Resets figure and data variables to default
            %==============================================================

            %Analysis Variables--------------------------------------------
            %Pre stim
            app.PreStimMeanEditField.Value=0; app.PreStimRMS=0;
            app.PreStimStdEditField.Value=0; app.PreStimSTDV=0;

            %Onset/Offset Detection
            app.AllOnOffsetTime=[];


            %Override
            app.OverrideUsed=0;
            app.OverrideRange=[];
            app.UIFigure.Pointer = 'arrow';
            app.OverrideLamp.Color=[0.8 0.8 0.8];

            %Analyze
            app.MEP_Amp=[];
            app.MEP_Area=[];
            app.SP_PercDecrease=[];
            app.SP_Area=[];
            app.Analyze_TrialsUsed=[];
            app.Analyze_TrialsUsedConditionName=cell(1,1);
            app.Analyze_ConditionsUsed=[];
            app.Analyze_OverrideUsed=0;
            app.Analyze_TrialsUsedChannel=cell(1,1);
            app.MissingfromanalyzeLabel.Visible=0;
            app.PrevSelectedTab='';
            app.CustomOutputs=[];
            app.ManualMark=0;

            %Save Analyze Results
            app.SavecsvButton.BackgroundColor=[0.39,0.83,0.07];
            app.SavecsvEditField.Value="";

            %Plotting
            if ishghandle(app.OnsetLine) == 1
                delete(app.OnsetLine);
            end
            if ishghandle(app.OffsetLine) == 1
                delete(app.OffsetLine);
            end
            if ishghandle(app.PreStimArea) == 1
                delete(app.PreStimArea);
            end
            if ishghandle(app.StartLine) == 1
                delete(app.StartLine);
            end
            if ishghandle(app.OnsetLimitLines) == 1
                delete(app.OnsetLimitLines);
            end
            if ishghandle(app.OffsetLimitLines) == 1
                delete(app.OffsetLimitLines);
            end
            if ishghandle(app.meanPreStimLine) == 1
                delete(app.meanPreStimLine);
            end
            app.PrevToolBarSelect="";

            %Average
            app.AverageCheckBox.Value=0;
            app.AverageCheckBox.Enable=1;
            app.ConditionTrialsListBox.Enable=1;
            app.SelectAllCondButton.Enable=1;

            %Process
            app.Processed=0;
            app.ResetDataButton.Enable=0;
            app.POriginalAllPlottedData=cell(1,1);
            app.POriginalAvgPlottedData=[];
            app.DownSampledRate=[];
            app.ProcessOrder=[];
            app.Processed_Conditions_DataAll=cell(1,4);

            app.ProcessButton.Enable=0;
            app.AnalyzeButton.Enable=0;
            app.OverrideButton.Enable=0;
            app.NDButton.Enable=0;

            app.SaveProcessedDatamatButton.Enable=0;
            app.SavematEditField.Enable=0;
            app.OnsetRiseorFallCheckBox.Value=0;

            %Reset figure look---------------------------------------------
            app.UIFigure.AutoResizeChildren = 'off';
            %Enable all Epoch Types
            app.BlocksButton.Enable=1;
            app.CommentsButton.Enable=1;
            app.EventsButton.Enable=1;
            app.UnitsDropDown.Value='mV';
            app.FindEventsButton.Visible=1;
            app.DataType=[];

            %Set current evet type to Block
            app.BlocksButton.Value=1;
            app.EpochTypesButtonGroup.Visible=1;
            app.PrePostTriggerTimemsPanel.Visible=1;

            %Enable Spinners
            app.ChannelSpinner.Enable=0;
            app.TrialSpinner.Enable=0;
            app.ChannelSpinner.Visible=1; app.ChannelSpinnerLabel.Visible=1;
            app.TrialSpinner.Visible=1; app.TrialSpinnerLabel.Visible=1;

            %Hide epoch type specific panels
            app.SetDataWindowPanel.Visible=0;
            app.EventNameListBox.Visible=0; app.EventNameListBoxLabel.Visible=0; app.EventNameListBox.Items=""; app.EventNameListBox.ItemsData=[];
            app.TrialSelectListBox.Visible=1; app.TrialSelectListBoxLabel.Visible=1; app.TrialSelectListBox.Items="";
            app.SelectAllButton.Visible=1;
            app.EventParametersPanel.Visible=0;
            app.PrePostTriggerTimemsPanel.Visible=1;

            %Enable Data Organization tab items
            app.EpochTypesButtonGroup.Enable=1;
            app.TrialSpinnerLabel.Enable=1;
            app.ChannelSpinnerLabel.Enable=1;
            app.FindEventsButton.Enable=1;
            app.PrePostTriggerTimemsPanel.Enable=1;
            app.TrialSelectListBox.Enable=1;
            app.TrialSelectListBoxLabel.Enable=1;

            %Clear graph
            cla(app.UIAxes);
            title(app.UIAxes,'Title');
            xlabel(app.UIAxes,'X');
            ylabel(app.UIAxes,'Y');
            app.PlottedUnits='mV';
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.MinEditField.Value=0;
            app.MaxEditField.Value=0;
            app.MinEditField_2.Value=0;
            app.MaxEditField_2.Value=0;
            app.SampleRateLabel.Text='Sample Rate: ';

            %Clear Filename and Event list box
            app.DataFileEditField.Value="";
            app.ComList="";
            app.FileOrderListBox.Visible=0; app.FileOrderListBoxLabel.Visible=0; app.FileOrderListBox.Items="";

            %Clear Conditions
            app.UITable.Data=[];
            app.SelectedTableRows=[];
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s);
            app.autoImportEvents=0;
            app.ImportEventsNameL=1;
            app.Conditions_Data=cell(1,3);
            app.LoadedConditionsAll=struct();
            app.ConditionsListBox.Visible=0; app.ConditionsListBoxLabel.Visible=0;
            app.ConditionsListBox.Items=""; app.ConditionsListBox.ItemsData=[];
            app.ConditionsListBox.Value='';
            app.ChangeCurrentRow=0;
            app.UITable.ColumnEditable=true;
            app.SelectAllButton.Enable=1;
            app.AutoFillEditField.Value=2;
            app.DeletedRows=struct();
            app.RecoverButton.Enable=0;
            app.ConditionTrialsListBox.Visible=0; app.ConditionTrialsLabel.Visible=0;
            app.SelectAllCondButton.Visible=0;
            app.RightArrowAnalyzeButton.Visible=0; app.RightArrowAnalyzeButton.Enable=0;
            app.LeftArrowAnalyzeButton.Visible=0; app.LeftArrowAnalyzeButton.Enable=0;
            app.SButton.Visible=0; app.SButton.Enable=0;
            app.DefineConditionsPanel.Enable=1;
            app.numTrialsCondition=zeros(1,3);
            app.Conditions_DataAll=cell(1,2);

            %Enable Conditions Table buttons
            app.AddSelectedTrialsButton.Enable=1;
            app.AddBlankRowButton.Enable=1;
            app.ImportEventsButton.Enable=1;
            app.AutoFillButton.Enable=1;
            app.AutoFillEditField.Enable=1;
            app.KeeporDeleteRowsButton.Enable=1;
            app.SaveButton.Enable=1;
            app.ClearButton.Enable=1;
            app.ApplyButton.Enable=1;
            app.LoadConditionsButton.Enable=1;
            app.UploadExcelFileButton.Enable=1;
            app.Old=0;
            app.SheetNameEditField.Value='';
            app.OverrideGroupNameEditField.Value='';
            app.ConditionGroupNames=cell(1,1);
            app.SheetNameEditField.Enable=1;
            app.DisableLI_FindEvents=0;

            %Reset key variables-------------------------------------------
            %Data
            app.labChart_Data=[];
            app.BrainSight_Data=[];
            app.Signal_Data=[];
            app.Acqknowledge_Data=[];
            app.BrainVision_Data=[];
            app.Spike_Data=[];
            app.Custom_Data=[];
            app.Custom_DataAll=struct();
            app.DataExtracted=0;
            app.Block=struct();
            app.Comments=struct();
            app.Events=[];
            app.Convert2PlottedUnits=1000;               %default plotting unit mV, data saved in volts
            app.EpochType=1;
            app.numTrialsC=[];
            app.CustomUploadFile='';
            app.numBlocks=0;
            app.Plotted_Data=[];
            app.AllPlotted_Data=cell(1,1);

            %Pre/post duration and Trigger Time
            app.PostDurationsEditField.Value=0.15;  app.PostDur=0.15;
            app.PreDurationsEditField.Value=0.15;   app.PreDur=0.15;
            app.PreTriggermsEditField.Value=0;      app.PreTriggerTime=0;
            app.PreTriggermsEditField.Enable=1;
            app.PostTriggermsEditField.Value=0;     app.PostTriggerTime=0;

            %Event Parameters
            app.EventChannelEditField.Value=1;      app.EventChannel=1;
            app.ThresholdmVEditField.Value=0;       app.EventThresh=0;
            app.SearchISIsEditField.Value=0;        app.SearchISI=0;
            app.DetectionSetting=1;

            %Trial and channel selected
            app.ChannelSpinner.Value=1;             app.Ch=1;
            app.TrialSpinner.Value=1;               app.Trial=1;

            %File upload values
            app.StateV=1;
            app.SignalFile=2;
            app.AcqFile=1;
            %unload CEDS64ML library if loaded
            if libisloaded('ceds64int')
                unloadlibrary ceds64int;
            end

            
            %Plugins-------------------------------------------------------
            AppFolder = fileparts(mfilename('fullpath'));   % app's own folder; correct in MATLAB and when compiled
            %look for plugin folder
            %Find plugins for data import "Custom" option =================
            if ~isfolder(fullfile(userpath,'TMSToolBoxApp', 'Plugins','Custom_File_Import'))
                mkdir(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Custom_File_Import'));
            end
            PluginsFileFolder=fullfile(AppFolder,'Fcns', 'Plugins','Custom_File_Import');
            Files=dir(PluginsFileFolder);
            e=1; Filenames=strings(1,1);
            for i=3:length(Files)
                if  Files(i).name(end-1:end) == '.m'
                    Filenames(e)=string(Files(i).name);
                    e=e+1;
                end
            end

            %Remove CustomFileTemplate.m from list
            Filenames(Filenames == 'CustomFileTemplate.m') =[];

            %Look for plugin files
            delete(app.CustomMenu.Children)
            if ~(Filenames == "") %if there are files in the filenames
                %Create menus for each custom file
                for i=1:length(Filenames)
                    MenuName=char(Filenames(i));
                    MenuName=MenuName(1:end-2);  %remove '.m'.
                    app.Plugins.CustomFile.(MenuName)=uimenu(app.CustomMenu);
                    app.Plugins.CustomFile.(MenuName).MenuSelectedFcn = createCallbackFcn(app, @CustomFileMenuSelected, true);
                    app.Plugins.CustomFile.(MenuName).Text=MenuName;
                end
            end

            %Find Data processing plugins =================================
            if ~isfolder(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Processing'))
                mkdir(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Processing'));
            end
            PluginsFileFolder=fullfile(AppFolder,'Fcns', 'Plugins','Processing');
            Files=dir(PluginsFileFolder);
            e=1; Filenames=strings(1,1);
            for i=3:length(Files)
                if  Files(i).name(end-1:end) == '.m'
                    Filenames(e)=string(Files(i).name);
                    e=e+1;
                end
            end
            Filenames(Filenames == 'ProcessingPluginTemplate.m') =[]; %Ignore template

            %Look for plugin files
            delete(app.CustomProcessTree.Children);
            if ~(Filenames == "") %if there are files in the filenames
                %Create menus for each custom file
                for i=1:length(Filenames)
                    MenuName=char(Filenames(i));
                    MenuName=MenuName(1:end-2);  %remove '.m'.

                    %update Custom Processes Tree
                    uitreenode(app.CustomProcessTree,'Text',MenuName,'NodeData',i);
                end

                %Add plugins to Process Tab
                app.CustomProcessTree.Visible=1;
                app.CustomProcessesLabel.Visible=1;
            else
                app.CustomProcessTree.Visible=0;
                app.CustomProcessesLabel.Visible=0;
            end


            %Find Onset/Offset Detection plugins ========================================
            if ~isfolder(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection'))
                mkdir(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection'));
            end
            PluginsFileFolder=fullfile(AppFolder,'Fcns','Plugins','OnsetOffsetDetection');
            Files=dir(PluginsFileFolder);
            e=1; Filenames=strings(1,1);
            for i=3:length(Files)
                if  Files(i).name(end-1:end) == '.m'
                    Filenames(e)=string(Files(i).name);
                    e=e+1;
                end
            end
            Filenames(Filenames == 'OnOffsetDetectionPluginTemplate.m') =[]; %Ignore template

            %Look for plugin files
            if ~(Filenames == "") %if there are files in the filenames
                %Create menus for each custom file
                app.OnsetOffsetDetectionDropDown.Items={'SD Bounds', 'Garvey et al'};
                for i=1:length(Filenames)
                    MenuName=char(Filenames(i));
                    MenuName=MenuName(1:end-2);  %remove '.m'.

                    %update onset/offset detection dropdown
                    app.OnsetOffsetDetectionDropDown.Items(end+1)={MenuName};
                end
            end

            %Find Analysis plugins ========================================
            if ~isfolder(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Analysis'))
                mkdir(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Analysis'));
            end
            PluginsFileFolder=fullfile(AppFolder,'Fcns', 'Plugins','Analysis');
            Files=dir(PluginsFileFolder);
            e=1; Filenames=strings(1,1);
            for i=3:length(Files)
                if  Files(i).name(end-1:end) == '.m'
                    Filenames(e)=string(Files(i).name);
                    e=e+1;
                end
            end
            Filenames(Filenames == 'AnalysisPluginTemplate.m') =[]; %Ignore template

            %Look for plugin files
            app.AnalysisOptionDropDown.ItemsData = {'_MEP','_SP','_EMGA'}; %Analysis options
            if ~(Filenames == "") %if there are files in the filenames
                %Create menus for each custom file
                app.AnalysisOptionDropDown.Items={'Motor Evoked Potential', 'Silent Period','EMG Analysis (WIP)'};
                
                for i=1:length(Filenames)
                    MenuName=char(Filenames(i));
                    MenuName=MenuName(1:end-2);  %remove '.m'.

                    %update onset/offset detection dropdown
                    app.AnalysisOptionDropDown.Items(end+1)={MenuName};
                    app.AnalysisOptionDropDown.ItemsData(end+1)={['_' MenuName]};
                end
            end


            %add plugins folder to path===
            if ~isdeployed
                addpath(genpath(fullfile(userpath, app.ToolboxDocFolderName, 'Plugins')));
            end
            %End of Plugins Section----------------------------------------

        end %end resfreshApp

        function [Plot_Data,Time,ChTitle] = ExtractLabChart(app)
            %==============================================================
            %Extracts data from LabChart export files (.mat)
            %Extracts block data, then epochs based on comments or events
            %Comment text options are listed in the EventNameListBox
            %==============================================================

            %Extract Block data
            if app.DataExtracted == 0 %Block data is used for comments and events, only extract once
                [app.numChannels,app.numBlocks,app.Block,app.AllSampleRate]=ExtractLB_BlockData(app.labChart_Data);
                app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
                app.DataExtracted=1;
            end

            %Epoch type: Blocks, Comments, Events
            switch app.EpochType
                case 1
                    app.numTrials=app.numBlocks;
                    updateTrialSpinnerLimits(app);
                case 2 %Comments
                    numComments=length(app.labChart_Data.com(:,1));
                    [app.Comments,app.numTrialsC] = ExtractLB_CommentsData(app.labChart_Data,app.Block,app.numChannels,numComments,app.PreDur,app.PostDur,app.AllSampleRate);
                    app.SelectedComtxt = app.EventNameListBox.Value;
                    %update trial spinner limits and trial number based on specified comment text
                    Com=string(app.SelectedComtxt);
                    comCol=app.EventNameListBox.ItemsData == Com;
                    app.numTrials=app.numTrialsC(comCol);           %redefine numTrials
                    updateTrialSpinnerLimits(app);
                    app.Trial = app.TrialSpinner.Value;             %update trial number, if current trial number selected is above the number of trials in the newly
                    %selected comment text the spinner will set to the max trial number, this will set the trial number to this value

                case 3 %Events
                    [app.numTrials, app.Events] = GetEvents(app);
                    if app.numTrials == 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        return
                    end

                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);

            end %end epochType switch

            %Plotting
            switch app.EpochType
                case 1 %Block
                    Plot_Data=app.Block.Data{app.Ch,app.Trial};                                           %Define data based on current selected channel and trial
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate); %Time defined by sample rate
                    if app.PreTriggerTime ~= 0
                        Time=Time-app.PreTriggerTime/1000;                                                %Adjust for Pre trigger setting
                    end
                    if app.PostTriggerTime ~=0                                                            %Adjust for Post trigger setting
                        FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                        %check for if post trigger time is beyond the dataset
                        if FramesAfter > length(Plot_Data)
                            FramesAfter=length(Plot_Data);
                            app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                        end
                        Time=Time(1:FramesAfter);
                        Plot_Data=Plot_Data(1:FramesAfter);
                    end
                case 2 %Comments
                    Com=string(app.SelectedComtxt);                                                       %Determine current specified comment text
                    comCol=app.EventNameListBox.ItemsData == Com;                                         %Use text to define the column the data is in
                    Plot_Data=app.Comments.Data{app.Trial,comCol}(app.Ch,:);                              %Define data based on current selected channel and trial
                    app.numTrials=app.numTrialsC(comCol);                                                 %Determine number of trials based on comment text
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;                                     %Time defined by sample rate and pre/post duration settings
                case 3 %Events
                    Plot_Data=app.Events.Data{app.Trial}(app.Ch,:);
                    ChTitle=app.Block.Titles(app.Ch);
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;
            end

        end %end ExtractLabChart

        function [Plot_Data,Time,ChTitle] = ExtractBrainSight(app)
            %==============================================================
            %Extracts data from BrainSight export files (.txt)
            %Extracts block data only
            %Only 2 channels of data, assumed to be named EMG Data 1 and EMG Data 2
            %The number of "samples" is the number of blocks
            %XYZ coordinates are added on to the Block variable
            %==============================================================

            HeaderRow=find(app.BrainSight_Data(:,1) == "# Sample Name");                              %find row with headers
            Samples=cell2mat(strfind(app.BrainSight_Data(HeaderRow+1:end,1),'Sample'));               %determine rows with sample data
            numSamples=sum(Samples);                                                                  %number of samples, each sample is a block
            Data1_Col=find(app.BrainSight_Data(HeaderRow,:) == "EMG Data 1");                         %find EMG data 1
            Data2_Col=find(app.BrainSight_Data(HeaderRow,:) == "EMG Data 2");                         %find EMG data 2
            DataCols=[Data1_Col Data2_Col];                                                           %columns with EMG data

            %Determine time and EMG units
            UnitsLoc=find(contains(app.BrainSight_Data(1:HeaderRow,1),'Units')==1);
            Units=app.BrainSight_Data(UnitsLoc,1);
            findSec=strfind(Units,'seconds');
            findVolts=strfind(Units,'volts');
            Units=char(Units);
            TimeUnit=Units(findSec-5:findSec-1);
            EMGUnit=Units(findVolts-5:findVolts-1);
            %Determine if time unit needs to be converted to seconds
            switch TimeUnit
                case 'milli'  %milliseconds
                    ConvertSec=0.001;
                otherwise  %units are in seconds
                    ConvertSec=1;
            end
            %Determine if EMG unit needs to be converted to volts
            switch EMGUnit
                case 'milli'  %millivolts
                    ConvertVolts=0.001;
                case 'micro' %microvolts
                    ConvertVolts=1e-6;
                otherwise  %units are in volts
                    ConvertVolts=1;
            end

            %XYZ coordinates
            LocX_Col=find(app.BrainSight_Data(HeaderRow,:) == "Loc. X");
            LocY_Col=find(app.BrainSight_Data(HeaderRow,:) == "Loc. Y");
            LocZ_Col=find(app.BrainSight_Data(HeaderRow,:) == "Loc. Z");

            LocX=app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,LocX_Col);
            LocY=app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,LocY_Col);
            LocZ=app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,LocZ_Col);
            XYZCoordinates=double([LocX LocY LocZ]);

            %number of emg channels used
            EMGChannels_Col=find(app.BrainSight_Data(HeaderRow,:) == "EMG Channels");
            numEMGChannelsAll=app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,EMGChannels_Col);     %number of EMG channels used for each sample
            %check for null
            if any(numEMGChannelsAll == "(null)")
                nullLoc=find(numEMGChannelsAll == "(null)");
            else
                nullLoc=[];
            end
            %check if the same number of channels is used for all samples
            comparenumEMGCh=numEMGChannelsAll == numEMGChannelsAll(1);
            numEMGCh_Same=find(comparenumEMGCh == 0);
            numEMGCh_Same(ismember(numEMGCh_Same,nullLoc))=[]; %remove nulls from this check
            numEMGCh_Same=find(comparenumEMGCh == 0, 1);
            numEMGChannelsAll=double(numEMGChannelsAll);                   %number of EMG channels for each sample

            %Extract EMG Data
            if isempty(numEMGCh_Same) == 1 %all blocks have the same number of channels
                numEMGChannels=numEMGChannelsAll(1);
                EMGData=cell(numEMGChannels,1);
                for i=1:numEMGChannels  %for each EMG channel extract data
                    DataCol=DataCols(i);
                    EMG=app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,DataCol);              %extract semicolon delimited data
                    %check for null
                    if ~isempty(nullLoc)
                        %create data of nans
                        NanStrVec=repmat("nan;",1,length(split(EMG(1),';'))); %they should all be the same length
                        NanStrS=join(NanStrVec);
                        NanChar=char(NanStrS);
                        NanChar(NanChar == ' ')=[];
                        NanChar(end)=[]; %remove last ';' symbol
                        NanStr=string(NanChar);

                        %fill null data with Nan string
                        EMG(nullLoc)=NanStr;

                    end
                    EMG=split(EMG,';');                                                             %parse out data
                    EMG=double(EMG);                                                                %convert from string to numeric matrix
                    for e=1:numSamples
                        EMGData{i,e}=EMG(e,:)*ConvertVolts; %convert to volts
                    end
                end
            else
                %there are some blocks with different number of emg channels
                EMGData=cell(max(numEMGChannelsAll) ,1);
                for i=1:max(numEMGChannelsAll)  %for the max number of EMG channels
                    DataCol=DataCols(i);
                    EMGA=app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,DataCol);             %extract semicolon delimited data
                    [MissingDataLoc,~]=find(EMGA=="");
                    EMGI=[(1:length(num2str(numEMGChannelsAll)))' EMGA];
                    EMGI(MissingDataLoc,:)=[];
                    %check for null
                    if ~isempty(nullLoc)
                        %create data of nans
                        NanStrVec=repmat("nan;",1,length(split(EMGI(1,2),';'))); %they should all be the same length
                        NanStrS=join(NanStrVec);
                        NanChar=char(NanStrS);
                        NanChar(NanChar == ' ')=[];
                        NanChar(end)=[]; %remove last ';' symbol
                        NanStr=string(NanChar);

                        %fill null data with Nan string
                        %if there are samples missing data from this emg channel
                        nullLocN=nullLoc-length(MissingDataLoc);
                        nullLocN(nullLocN <= 0)=[];
                        EMGI(nullLocN,2)=NanStr;

                    end

                    EMG=split(EMGI(:,2),';');                                                       %parse out data
                    EMG=[double(EMGI(:,1)) double(EMG)];                                            %convert from string to numeric matrix
                    EMG2=zeros(size(EMG));
                    %refill indices with missing data
                    for e=1:length(EMG(:,1))+length(MissingDataLoc)
                        [ILoc,~]=find(EMG(:,1)==e);
                        if isempty(ILoc) %if there is no index (cleared due to no data)
                            EMG2(e,:)=[e nan(1,length(EMG(1,:))-1)]; %add index with Nan data
                        else %fill with existing data
                            EMG2(e,:)=EMG(ILoc,:);
                        end
                    end

                    for e2=1:numSamples
                        EMGData{i,e2}=EMG2(e2,2:end)*ConvertVolts; %convert to volts
                    end
                end
            end


            %Time
            StartTime=find(app.BrainSight_Data(HeaderRow,:) == "EMG Start");
            app.PreTriggerTime=abs(double(app.BrainSight_Data(HeaderRow+1,StartTime))); %Assumed the same for all samples
            app.PreTriggermsEditField.Value=app.PreTriggerTime;                         %update edit field
            EndTime=find(app.BrainSight_Data(HeaderRow,:) == "EMG End");
            FrameTime=[double(app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,StartTime)) double(app.BrainSight_Data(HeaderRow+1:HeaderRow+numSamples,EndTime))];
            DurationAll=FrameTime(:,2)-FrameTime(:,1);
            compareDuration=DurationAll == DurationAll(1);
            Duration_Same=find(compareDuration == 0,1);
            Duration_Same(ismember(Duration_Same,nullLoc))=[]; %remove nulls from this check
            if isempty(Duration_Same) ==1
                %same duration for all samples
                Duration=DurationAll(1);
            else
                Duration=DurationAll;
            end

            %Calculate sample rate
            numFrames=length(EMGData{1,1}(1,:));
            app.AllSampleRate=round((numFrames-1)/(Duration*ConvertSec));
            app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];

            %Define app variables
            app.Block.Data=EMGData; %numChannels x
            app.Block.Coordinates=XYZCoordinates;
            app.Block.Titles=["EMG Data 1";"EMG Data 2"]; %!!! add check for if there is only on column of data
            app.numBlocks=numSamples;
            app.numTrials=app.numBlocks;
            app.numChannels=max(numEMGChannelsAll);


            %Plotting
            Plot_Data=EMGData{app.Ch,app.Trial};                                                   %Define data based on current selected channel and trial
            ChTitle=app.Block.Titles(app.Ch);                                                      %Channel title of current selected channel
            Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate);  %Time defined by sample rate and pre/post duration settings
            if app.PreTriggerTime ~= 0
                Time=Time-app.PreTriggerTime/1000;                                                 %Adjust for Pre trigger setting
            end
            if app.PostTriggerTime ~=0                                                             %Adjust for Post trigger setting
                FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                %check for if post trigger time is beyond the dataset
                if FramesAfter > length(Plot_Data)
                    FramesAfter=length(Plot_Data);
                    app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                end
                Time=Time(1:FramesAfter);
                Plot_Data=Plot_Data(1:FramesAfter);
            end
            app.PreTriggermsEditField.Enable=0;

        end %end ExtractBrainSight

        function [Plot_Data,Time,ChTitle] = ExtractSignal(app)
            %==============================================================
            %Extracts data from Signal export files (.mat and .cfs)
            %Signal data has states each with multiple blocks
            %Extracts block data for each State
            %States are listed in the EventNameListBox
            %File Type is determined in SignalmatcfsMenuSelected callback
            %==============================================================

            switch app.SignalFile
                case 1 %exported .mat
                    app.AllSampleRate=round(1/app.Signal_Data.interval); %samplerate
                    app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];

                    %channel info
                    app.numChannels=double(app.Signal_Data.chans);
                    ChTitles={app.Signal_Data.chaninfo.title}';
                    DataUnits={app.Signal_Data.chaninfo.units}';

                    StateA=[app.Signal_Data.frameinfo.state]';
                    app.numStates=StateA(end); %assumes largest state value to be at the end
                    app.numBlocks=app.Signal_Data.frames;
                    labelsAll={app.Signal_Data.frameinfo.label}';

                    %Convert Data units to volts
                    switch DataUnits{1} %convert to V
                        case 'V'
                            ConvertV=1;
                        case 'mV'
                            ConvertV=0.001;
                    end

                    %dividing into blocks
                    Block1=cell(app.numChannels,1); StateB=cell(app.numStates,1); e=1;
                    for i=1:app.numBlocks %separate Blocks into their "States"
                        DataValues=app.Signal_Data.values(:,:,i); %numPointsxnumChannels
                        for i2=1:app.numChannels
                            Block1{i2,e}=DataValues(:,i2)'*ConvertV;
                        end
                        label=labelsAll{i};
                        State=StateA(i);

                        StateB{State,1}=Block1;
                        StateB{State,2}=label;

                        %reset for when state changes, assumes states occur in chronological order
                        if (i~=app.numBlocks) && (State ~= StateA(i+1))
                            e=1;
                            Block1=cell(app.numChannels,1);
                        else
                            e=e+1;
                        end


                    end

                    app.PreTriggerTime=abs(round(app.Signal_Data.start));
                    PreTriggerTime_Units=app.Signal_Data.xunits; %check PreTriggerTime unit

                case 2 %convert .cfs or converted .cfs

                    %extract from loaded data
                    app.AllSampleRate=round(1/app.Signal_Data.D.param.xScale(1));
                    app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
                    app.PreTriggerTime=abs(round(app.Signal_Data.D.param.xOffset(1)));
                    PreTriggerTime_Units=app.Signal_Data.D.param.xUnits{1};        %check PreTriggerTime units

                    app.Signal_Data.values=app.Signal_Data.D.data;
                    app.numChannels=size(app.Signal_Data.values,2);
                    IsWave=[app.Signal_Data.Chans.IsWaveData];
                    ChTitles1={app.Signal_Data.Chans.Name}';
                    ChTitles=ChTitles1(IsWave);
                    DataUnits1={app.Signal_Data.Chans.yUnits}';
                    DataUnits=DataUnits1(IsWave);

                    app.numBlocks=size(app.Signal_Data.values,3);
                    StateInfoLoc=find(string({app.Signal_Data.DSVars.Desc})=="State");
                    StateA=app.Signal_Data.DSVars(StateInfoLoc).Values';
                    if StateA{1} == 0 %zero index
                        app.numStates=StateA{end}+1;                                   %assumes largest state value to be at the end
                    elseif StateA{1} == 1 %one index
                        app.numStates=StateA{end};
                    end
                    LabelsLoc=find(string({app.Signal_Data.DSVars.Desc})=="StateL");
                    labelsAll=app.Signal_Data.DSVars(LabelsLoc).Values;

                    %Convert Data units to volts
                    switch DataUnits{1} %convert to V
                        case 'V'
                            ConvertV=1;
                        case 'mV'
                            ConvertV=0.001;
                    end

                    %dividing into blocks
                    Block1=cell(app.numChannels,1); StateB=cell(app.numStates,1); e=1;
                    for i=1:app.numBlocks
                        DataValues=app.Signal_Data.values(:,:,i); %numPointsxnumChannels
                        for i2=1:app.numChannels
                            Block1{i2,e}=DataValues(:,i2)'*ConvertV;
                        end
                        label=labelsAll{i};

                        %check for zero vs one indexed
                        if StateA{1}==0
                            State=StateA{i}+1;
                            if (i~=1) && (State ~= StateA{i-1}+1)  %reset for when state changes, assumes states occur in chronological order
                                e=1;
                                Block1=cell(app.numChannels,1);
                            end
                        else
                            State=StateA{i};
                            if (i~=1) && (State ~= StateA{i-1})    %reset for when state changes, assumes states occur in chronological order
                                e=1;
                                Block1=cell(app.numChannels,1);
                            end
                        end

                        StateB{State,1}=Block1; %organize by state, StateB contains cells with block data for each state, convert to volts
                        StateB{State,2}=label;
                        e=e+1;
                    end

            end %end SignalFile (import type)

            %Convert PreTriggerTime units to seconds
            switch PreTriggerTime_Units %convert to s
                case 's'
                    ConvertS=1;
                case 'ms'
                    ConvertS=1000;
            end
            app.PreTriggerTime=app.PreTriggerTime/ConvertS;                %convert PreTriggerTime to units s
            app.PreTriggermsEditField.Value=app.PreTriggerTime;            %update edit field
            app.PreTriggermsEditField.Enable=0;

            app.Block.Data=StateB; %block data
            app.Block.Titles=string(ChTitles);

            %Remove any empty rows
            EmptyRows=all(cellfun(@isempty,app.Block.Data),2);
            if any(EmptyRows) %there are empty rows
                app.Block.Data(EmptyRows,:)=[];
                app.numStates=app.numStates-sum(EmptyRows);
            end

            %fill Event Name List box
            app.EventNameListBox.Items=string(app.Block.Data(:,2));
            app.EventNameListBox.ItemsData=1:length(app.EventNameListBox.Items);
            app.numTrialsState=cellfun(@(x) size(x,2) ,app.Block.Data(:,1));
            app.numTrials=app.numTrialsState(app.StateV);

            %check spinner values
            updateTrialSpinnerLimits(app);

            %output
            Plot_Data=app.Block.Data{app.StateV,1}{app.Ch, app.Trial};
            Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate); %Time defined by sample rate and pre/post duration settings
            if app.PreTriggerTime ~= 0
                Time=Time-app.PreTriggerTime/1000;                                                 %Adjust for Pre trigger setting
            end
            if app.PostTriggerTime ~=0                                                             %Adjust for Post trigger setting
                FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                %check for if post trigger time is beyond the dataset
                if FramesAfter > length(Plot_Data)
                    FramesAfter=length(Plot_Data);
                    app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                end
                Time=Time(1:FramesAfter);
                Plot_Data=Plot_Data(1:FramesAfter);
            end
            ChTitle=app.Block.Titles(app.Ch);
        end %end ExtractSignal

        function [Plot_Data,Time,ChTitle]= ExtractAcqknowledge(app)
            %==============================================================
            %Extracts data from Acqknowledge export files (.mat and .acq)
            %Extracts block data, then epochs based on events
            %File Type is determined in AcqknowledgematacqMenuSelected callback
            %Multiple file upload is possible
            %Continuous blocks, each file is a block
            %==============================================================

            switch app.AcqFile
                case 1 %Exported .mat
                    %Extract Block data
                    if app.DataExtracted == 0 %Block data is used for events, only extract once
                        for e=1:length(app.Filename)
                            app.Acqknowledge_Data=app.Acqknowledge_DataAll.(['File' num2str(e)]); %load each file's data

                            if e==1 %assumed same number of channels for each file
                                app.numBlocks=0; %just continuous data so only one block, new block for each file
                                app.numChannels=length(app.Acqknowledge_Data.data(1,:));

                                %Sample Rate
                                ISI=app.Acqknowledge_Data.isi;
                                ISI_Units=app.Acqknowledge_Data.isi_units;
                                %check units, covnert to seconds
                                if ISI_Units == 'ms'
                                    ISI=ISI*0.001;
                                end
                                app.AllSampleRate=1/ISI;
                                app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
                            end
                            FilenumChannels=length(app.Acqknowledge_Data.data(1,:));

                            Data=app.Acqknowledge_Data.data;

                            Units=cell(FilenumChannels,1);
                            FileTitles=string(app.Acqknowledge_Data.labels); %assumed to be channel titles
                            for i=1:FilenumChannels
                                Units{i,1}=app.Acqknowledge_Data.units;
                                switch Units{i,1}
                                    case 'mV' %convert to volts
                                        ConvertV=0.001;
                                    case 'V'
                                        ConvertV=1;
                                    otherwise
                                        ConvertV=1;
                                end

                                app.Block.Titles(i,e)= FileTitles(i);
                                app.Block.Data{i,e}=Data(:,i)'*ConvertV;                                        %Blocks
                            end

                            app.numBlocks=app.numBlocks+1; %add new block for each file, since each file is continuous each file is one block
                        end %end file data extract loop


                        %if there is more than one file
                        CheckMultiFileChan(app);

                        app.DataExtracted=1;
                    end %End if DataExtracted

                case 2 %.acq/converted .mat
                    if app.DataExtracted == 0 %Block data is used for  events, only extract once
                        %for each file, extract data
                        for e=1:length(app.Filename)
                            app.Acqknowledge_Data=app.Acqknowledge_DataAll.(['File' num2str(e)]); %load each file's data
                            if e==1 %assumed same number of channels for each file
                                app.numBlocks=0; %just continuous data so only one block, new block for each file
                                app.numChannels=app.Acqknowledge_Data.hdr.graph.num_channels;
                                app.AllSampleRate=1/(app.Acqknowledge_Data.hdr.graph.sample_time/1000); %1/convert sample time to seconds
                                app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
                            end
                            FilenumChannels=app.Acqknowledge_Data.hdr.graph.num_channels;

                            Data=app.Acqknowledge_Data.data;
                            Units=cell(FilenumChannels,1);
                            for i=1:FilenumChannels
                                Units{i,1}=app.Acqknowledge_Data.hdr.per_chan_data(i).units_text;
                                switch Units{i,1}
                                    case 'mV' %convert to volts
                                        ConvertV=0.001;
                                    case 'V'
                                        ConvertV=1;
                                    otherwise
                                        ConvertV=1;
                                end

                                app.Block.Titles(i,e)= string(app.Acqknowledge_Data.hdr.per_chan_data(i).comment_text); %assumed to be channel titles
                                app.Block.Data{i,e}=Data(:,i)'*ConvertV;                                        %Blocks
                            end
                            app.numBlocks=app.numBlocks+1; %add new block for each file, since each file is continuous each file is one block

                        end %end file data extract loop


                        %if there is more than one file
                        CheckMultiFileChan(app);

                        app.DataExtracted=1;
                    end %End if DataExtracted


            end % end AcqFile switch


            %Epoch Type
            switch app.EpochType
                case 1
                    app.numTrials=app.numBlocks;
                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);
                case 3
                    [app.numTrials, app.Events] = GetEvents(app);
                    if app.numTrials == 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        return
                    end

                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);
            end

            %Plotting
            switch app.EpochType
                case 1 %Blocks
                    Plot_Data=app.Block.Data{app.Ch,app.Trial};                                           %Define data based on current selected channel and trial
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate); %Time defined by sample rate and pre/post duration settings
                    if app.PreTriggerTime ~= 0
                        Time=Time-app.PreTriggerTime/1000;                                                %Adjust for Pre trigger setting
                    end
                    if app.PostTriggerTime ~=0                                                            %Adjust for Post trigger setting
                        FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                        %check for if post trigger time is beyond the dataset
                        if FramesAfter > length(Plot_Data)
                            FramesAfter=length(Plot_Data);
                            app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                        end
                        Time=Time(1:FramesAfter);
                        Plot_Data=Plot_Data(1:FramesAfter);
                    end
                case 3 %Events
                    Plot_Data=app.Events.Data{app.Trial}(app.Ch,:);
                    ChTitle=app.Block.Titles(app.Ch);
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;

            end
        end %end ExtractAcqknowledge

        function [Plot_Data,Time,ChTitle] = ExtractBrainVision(app)
            %==============================================================
            %Extracts data from BrainVision export files (.eeg)
            %Extracts block data, then epochs based on comments or events
            %Comment text options are listed in the EventNameListBox
            %Multiple file upload is possible
            %Continuous blocks, each file is a block
            %==============================================================

            if app.DataExtracted == 0
                for e=1:length(app.Filename)
                    app.BrainVision_Data=app.BrainVision_DataAll.(['File' num2str(e)]);
                    %Extract Blocks once
                    if e==1
                        app.AllSampleRate=app.BrainVision_Data.FileHeader.Fs;   %assumed same sample reate for all channels and blocks
                        app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
                        FnumBlocks=1;
                        app.numBlocks=0;
                    end
                    FileNumChannels=app.BrainVision_Data.FileHeader.nChans; %number of channels

                    %check units
                    Units=app.BrainVision_Data.FileHeader.chanunit(1); %assumed same units for all channels
                    switch Units{1}
                        case 'uV'
                            ConvertV=1e6;
                        case 'mV'
                            ConvertV=1000;
                        case 'V'
                            ConvertV=1;
                    end

                    %Blocks
                    DataCell=cell(FileNumChannels,FnumBlocks);
                    for i=1:FileNumChannels
                        DataCell{i,FnumBlocks}=app.BrainVision_Data.Data(i,:)/ConvertV;%convert units to volts
                    end
                    app.Block.Data(1:length(DataCell(:,1)),e)=DataCell;
                    app.Block.Titles(1:length(DataCell(:,1)),e)=string(app.BrainVision_Data.FileHeader.label);
                    app.numBlocks=app.numBlocks+1; %Continuous data, each file is a block
                end %end for each file

                %if there are multiple files
                CheckMultiFileChan(app)

                app.DataExtracted=1;
            end %end DataExtracted

            %EpochType
            switch app.EpochType
                case 1
                    app.numTrials=app.numBlocks;
                    updateTrialSpinnerLimits(app);
                case 2 %comments

                    %Create list of frames with comments
                    CommentTxtOptions=app.Comments.CommentTxtOptions;
                    CommentsList=cell(1,length(CommentTxtOptions));
                    for L=1:length(app.Filename) %for each file
                        app.BrainVision_Data.Comments=app.BrainVision_DataAll.(['Comments' num2str(L)]);
                        w=1;
                        for e=1:length(CommentTxtOptions) %for each comment text option
                            for i=1:length(app.BrainVision_Data.Comments) %search through all comments
                                CurrComText=app.BrainVision_Data.Comments(i).CommentText;
                                if CurrComText == CommentTxtOptions(e)
                                    CommentsList{L}(w,e)=app.BrainVision_Data.Comments(i).CommentLoc; %each cell is a block
                                    w=w+1;
                                end
                            end
                            w=1;

                        end
                    end
                    PreDurValue=app.PreDur*app.AllSampleRate;
                    PostDurValue=app.PostDur*app.AllSampleRate;

                    %create comments
                    app.numTrialsC=zeros(1,length(CommentTxtOptions)); CData=zeros(app.numChannels,length(-PreDurValue:PostDurValue));
                    for e=1:length(CommentTxtOptions) %for each comment text option
                        i2=1;
                        for L=1:length(app.Filename)
                            Frames=CommentsList{L}(:,e);
                            for i=Frames'
                                StartDiff=0; EndDiff=0;
                                Start=i-PreDurValue; End=i+PostDurValue;
                                if Start <= 0 %if start is less than zero, fill in with NaNs
                                    StartDiff=-Start+1;
                                    Start=1;
                                end
                                DataLength=length(app.Block.Data{L});
                                if End > DataLength %if end is greater than the length of the data, fill with NaNs
                                    EndDiff=End-DataLength;
                                    End=DataLength;
                                end

                                %reformat and cut data
                                for w=1:app.numChannels
                                    if isempty(app.Block.Data{w,L})
                                        app.Block.Data{w,L}= nan(1,DataLength);
                                    end
                                    CData(w,:)=[nan(1,StartDiff) app.Block.Data{w,L}(Start:End) nan(1,EndDiff)]; %fill beginning and end with NaNs if needed
                                end
                                app.Comments.Data{i2,e}=CData; %each column is for a comment text, order of comment texts is the same
                                i2=i2+1;
                            end
                            numEmpty=cellfun(@isempty,app.Comments.Data);          %Find number of empty vectors to determine
                            app.numTrialsC(e)=length(find(numEmpty(:,e) == 0));    %number of comments for each comment text
                        end
                    end

                    %Update spinner limits
                    Com=string(app.SelectedComtxt);
                    comCol=app.EventNameListBox.ItemsData == Com;
                    app.numTrials=app.numTrialsC(comCol);
                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);

                case 3 %Events
                    [app.numTrials, app.Events] = GetEvents(app);
                    if app.numTrials == 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        return
                    end

                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);
            end

            %Plotting
            switch app.EpochType
                case 1 %Block
                    Plot_Data=app.Block.Data{app.Ch,app.Trial};                                           %Define data based on current selected channel and trial
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate); %Time defined by sample rate
                    if app.PreTriggerTime ~= 0
                        Time=Time-app.PreTriggerTime/1000;                                                %Adjust for Pre trigger setting
                    end
                    if app.PostTriggerTime ~=0                                                            %Adjust for Post trigger setting
                        FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                        %check for if post trigger time is beyond the dataset
                        if FramesAfter > length(Plot_Data)
                            FramesAfter=length(Plot_Data);
                            app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                        end
                        Time=Time(1:FramesAfter);
                        Plot_Data=Plot_Data(1:FramesAfter);
                    end
                case 2 %Comments
                    Com=string(app.SelectedComtxt);                                                       %Determine current specified comment text
                    comCol=app.EventNameListBox.ItemsData == Com;                                         %Use text to define the column the data is in
                    Plot_Data=app.Comments.Data{app.Trial,comCol}(app.Ch,:);                              %Define data based on current selected channel and trial
                    app.numTrials=app.numTrialsC(comCol);                                                 %Determine number of trials based on comment text
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;                                     %Time defined by sample rate and pre/post duration settings
                case 3 %Events
                    Plot_Data=app.Events.Data{app.Trial}(app.Ch,:);
                    ChTitle=app.Block.Titles(app.Ch);
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;
            end


        end %end ExtractBrainVision

        function [Plot_Data,Time,ChTitle] = ExtractSpike(app)
            %==============================================================
            %Extracts data from Spike export files (.smr)
            %Extracts block data, then epochs based on  events
            %Blocks are from the Waveform channel type
            %Blocks are separated by the gaps in the Waveform data
            %If the channel units aren't defined they are assumed to be in mV
            %There is a check at the end to fill in any missing data
            %==============================================================

            %Blocks
            if app.DataExtracted == 0
                %Extract Data types of all possible channels
                maxChan = CEDS64MaxChan(app.Spike_Data.hdr); %max number of channels this file can hold
                dataTypeAll=zeros(maxChan,1);
                for i=1:maxChan %look through all possible channels
                    dataTypeAll(i,1)=CEDS64ChanType(app.Spike_Data.hdr,i); %channel type
                end

                %Determine channel type for each channel    %Channel Types
                % noChan=find(dataTypeAll=0);               %No channel
                WaveformChan=find(dataTypeAll == 1);        %Waveform
                % EventFallChan=find(dataTypeAll == 2);     %Event falling edge
                % EventRiseChan=find(dataTypeAll == 3);       %Event rising edge
                % EventBothChan=find(dataTypeAll == 4);     %Event both edges
                % MarkerChan=find(dataTypeAll == 5);        %Marker
                % WaveMarkChan=find(dataTypeAll == 6);      %WaveMark
                % RealMarkChan=find(dataTypeAll == 7);      %RealMark
                % TextMarkChan=find(dataTypeAll == 8);      %TextMark
                % RealWaveChan=find(dataTypeAll == 9);      %RealWave

                %Extract blocks
                CBlock=1; %current block
                for i=1:app.numChannels

                    maxTimeTicks = CEDS64ChanMaxTime(app.Spike_Data.hdr, WaveformChan(i))+1; %last tick to have an item
                    [~, ChData, FirstTick ] = CEDS64ReadWaveF(app.Spike_Data.hdr, WaveformChan(i), maxTimeTicks, 0, maxTimeTicks ); %(handle, channel number, max number of events to return, starting at, up to)
                    %FirstTick = first tick with an item
                    app.Spike_Data.data{i,1}=ChData';
                    app.Spike_Data.Ticks{i,1}=[FirstTick:CEDS64ChanDiv(app.Spike_Data.hdr,WaveformChan(i)):maxTimeTicks];

                    %ReadWaveF stops at gaps in the data
                    %Separating Blocks based on gaps
                    while (length(ChData)*CEDS64ChanDiv(app.Spike_Data.hdr,WaveformChan(i))+FirstTick) < maxTimeTicks %keep reading the data for this channel until the last tick with an item is reached
                        next=length(ChData)*CEDS64ChanDiv(app.Spike_Data.hdr,WaveformChan(i))+FirstTick;              %next starting tick for the search
                        [~, ChData, FirstTick ] = CEDS64ReadWaveF(app.Spike_Data.hdr, WaveformChan(i), maxTimeTicks, next, maxTimeTicks ); %search for next block of data
                        app.Spike_Data.data{i,CBlock+1}=ChData';
                        app.Spike_Data.Ticks{i,CBlock+1}=[FirstTick:CEDS64ChanDiv(app.Spike_Data.hdr,WaveformChan(i)):maxTimeTicks];
                        CBlock=CBlock+1;
                    end
                    CBlock=1;

                end

                %check units
                E=0; AmV=[]; AllChUnits=strings(app.numChannels,1);
                for i=1:app.numChannels
                    [~,ChUnits] = CEDS64ChanUnits(app.Spike_Data.hdr, i);
                    if isempty(ChUnits)
                        ChUnits="mV"; %if the units are not defined, assume mV !!!
                        E=E+1;
                        AmV(E)=i;

                    else
                        ChUnits=string(ChUnits);
                    end
                    ChUnitsChar=char(ChUnits);
                    ChUnitsChar(ChUnitsChar == ' ')='';
                    AllChUnits(i,1)=string(ChUnitsChar);
                end


                %Convert to volts
                %For channels assumed to be in mV convert to volts
                if E ~= 0
                    if E==app.numChannels
                        app.Spike_Data.data=cellfun(@(x) x*0.001,app.Spike_Data.data,'UniformOutput',false); %convert to volts
                        %msgbox('Note: All channels are assumed to be in mV');
                    else
                        for i=AmV %convert channels that are assumed
                            app.Spike_Data.data(i,:)=cellfun(@(x) x*0.001,app.Spike_Data.data(i,:),'UniformOutput',false); %convert to volts
                        end
                        %msgbox({'Note: Some channel units are not defined, they are assumed to be mV'; ['Channels ' num2str(AmV)]});

                    end
                else
                    %for each channel check units, convert to volts
                    for i=1:app.numChannels
                        ChUnit=AllChUnits(i);
                        switch ChUnit
                            case {"volt","V"}

                            case "mV"
                                app.Spike_Data.data(i,:)=cellfun(@(x) x*0.001,app.Spike_Data.data(i,:),'UniformOutput',false);
                            case "uV"
                                app.Spike_Data.data(i,:)=cellfun(@(x) x*1e-6,app.Spike_Data.data(i,:),'UniformOutput',false);
                        end
                    end
                end


                %check for missing data
                %make sure each block for every channel has the same amount of data
                maxArray = max(cellfun(@(x) size(x,2),app.Spike_Data.data)); %max length for each block
                Interp1=0;
                for iData = 1:size(app.Spike_Data.data,1) %for each channel
                    %check each block
                    for i=1:length(app.Spike_Data.data(iData,:)) %checks every block for each channel
                        if size(app.Spike_Data.data{iData,i},2) ~= maxArray(i)
                            factor = 1/(size(app.Spike_Data.data{iData},2)/maxArray);
                            app.Spike_Data.data{iData,i}= interp(app.Spike_Data.data{iData,i},round(factor)); %interpolate for missing data
                            Interp1=Interp1+1;
                        end
                    end
                end
                %display how many data points were interpolated
                % if Interp1 ~= 0
                %     disp(['Spike Interp1: ' num2str(Interp1)]);
                % end

                %create blocks
                app.Block.Data=app.Spike_Data.data;
                app.numBlocks=length(app.Block.Data(1,:));

                %extract channel titles
                app.Block.Titles=strings(8,1);
                for i=1:app.numChannels
                    [~, T]=CEDS64ChanTitle(app.Spike_Data.hdr,i);
                    app.Block.Titles(i)=string(T);
                end

                %Update channel titles if desired
                % ChangeTitles=questdlg('There are untitled channels. Would you like to rename them?','Change Titles?','Yes','No','No');
                % if ChangeTitles == 'Yes'
                %     untitles=find(app.Block.Titles == "untitled");
                %     prompt=strings(length(untitles),1);
                %     e=1;
                %     for i=untitles'
                %         prompt(e)=['Channel ' num2str(i) ':'];
                %         e=e+1;
                %     end
                %     dlgtitle='Title Change';
                %     NewChannelNames=inputdlg(prompt,dlgtitle);
                %     e=1;
                %     for i=untitles'
                %         if isempty(NewChannelNames{e,1})
                %             NewChannelNames{e,1}='Untitled';
                %         end
                %         app.Block.Titles(i)=string(NewChannelNames{e,1});
                %         e=e+1;
                %     end
                % end

                app.DataExtracted=1;
            end

            %Epoch Type
            switch app.EpochType
                case 1
                    app.numTrials=app.numBlocks;
                    updateTrialSpinnerLimits(app);

                case 2
                    %Comments !!! assumed there is only one comment text option
                    app.ComList="Events";
                    app.Comments.CommentTxtOptions=app.ComList;
                    app.Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;
                    for i=1:app.numChannels
                        for T=1:length(app.EventTicks)
                            DiffStart=0; DiffEnd=0;
                            EventIndx=find(app.Spike_Data.Ticks{1}==app.EventTicks(T)); %!!! why just the first channel ticks?
                            if ~isempty(EventIndx)
                                Data=app.Spike_Data.data{i};
                                StartIndx=EventIndx-app.PreDur*app.AllSampleRate;
                                EndIndx=EventIndx+app.PostDur*app.AllSampleRate;
                                if StartIndx <= 0
                                    DiffStart=-StartIndx+1;
                                    StartIndx=1;
                                end
                                if EndIndx > length(Data)
                                    DiffEnd=EndIndx-length(Data);
                                    EndIndx=length(Data);
                                end

                                app.Comments.Data{T,1}(i,:)=[nan(1,DiffStart) Data(StartIndx:EndIndx) nan(1,DiffEnd)];
                            end
                        end
                    end

                    if ~isfield(app.Comments,'Data') %no comment were found
                        msgbox('No Comments found');
                        app.CommentsButton.Enable=0;
                        app.BlocksButton.Value = 1;
                        EpochTypesButtonGroupSelectionChanged(app,1);
                        Plot_Data=[]; ChTitle=""; Time=[];
                        return
                    else
                        app.numTrialsC=length(app.Comments.Data);
                        app.numTrials=app.numTrialsC;
                    end
                case 3 %Events
                    [app.numTrials, app.Events] = GetEvents(app);
                    if app.numTrials == 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        return
                    end

                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);
            end

            %Plotting
            switch app.EpochType
                case 1 %Block
                    Plot_Data=app.Block.Data{app.Ch,app.Trial};                                           %Define data based on current selected channel and trial
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate); %Time defined by sample rate
                    if app.PreTriggerTime ~= 0
                        Time=Time-app.PreTriggerTime/1000;                                                %Adjust for Pre trigger setting
                    end
                    if app.PostTriggerTime ~=0                                                            %Adjust for Post trigger setting
                        FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                        %check for if post trigger time is beyond the dataset
                        if FramesAfter > length(Plot_Data)
                            FramesAfter=length(Plot_Data);
                            app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                        end
                        Time=Time(1:FramesAfter);
                        Plot_Data=Plot_Data(1:FramesAfter);
                    end

                case 2 %Comments
                    Time=app.Time;
                    Plot_Data=app.Comments.Data{app.Trial}(app.Ch,:);
                    ChTitle=app.Block.Titles(app.Ch);

                case 3 %Events
                    Plot_Data=app.Events.Data{app.Trial}(app.Ch,:);
                    ChTitle=app.Block.Titles(app.Ch);
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;
            end

        end %end ExtractSpike

        function [Plot_Data,Time,ChTitle] = ExtractCustom(app)
            %==============================================================
            %Extract Blocks and Comment data using a custom function
            %==============================================================
            if app.DataExtracted == 0
                for i=1:length(app.Filename)
                    [numChannelsF,numBlocksF,BlockF,AllSampleRateF,EpochTypeOptions,Custom_DataF,CommentLocAllF,numTrialsCF,CommentsF.CommentTxtOptions] = eval([app.CustomUploadFile '(app,i);']); %Run associated function

                    if i==1
                        %assume same number of channels for all files
                        %assume same sample rate for all files
                        app.numChannels=numChannelsF;                      %assume same number of channels for each file
                        app.AllSampleRate=AllSampleRateF;
                        app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
                        app.Block.Data=cell(app.numChannels,1);
                    end

                    %Update variables
                    app.numBlocks=app.numBlocks+numBlocksF;                                                                              %add to numBlocks
                    Range=length(app.Block.Data(1,:))+1:length(app.Block.Data(1,:))+length(BlockF.Data(1,:));
                    app.Block.Data(1:numChannelsF,Range)=BlockF.Data;    %Add to block data
                    app.Block.Titles(1:numChannelsF,Range)=repmat(BlockF.Titles,1,length(Range));
                    app.Custom_DataAll.(['File_' num2str(i) ]).Block=BlockF.Data;                                                        %Add block data for file
                    app.Custom_DataAll.(['File_' num2str(i) ]).Data=Custom_DataF;                                                        %save orignal data format
                    app.Custom_DataAll.(['File_' num2str(i) ]).CommentLocAll=CommentLocAllF;                                             %add to CommentLocAll
                    app.Custom_DataAll.(['File_' num2str(i)]).CommentTxtOptions=CommentsF.CommentTxtOptions;                             %add comment text options
                    app.Custom_DataAll.(['File_' num2str(i)]).numTrialsC=numTrialsCF;                                                    %add number of comments
                    app.ComList(end+1:end+length(CommentsF.CommentTxtOptions),1)=CommentsF.CommentTxtOptions;                            %update ComList (displayed in Event Name List Box)


                    %Checks for invalid values
                    %numChannels, AllSampleRate, numBlocks
                    if app.numChannels <= 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        msgbox('Invalid number of channels');
                        return
                    elseif app.AllSampleRate <=0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        msgbox('Invalid sample rate');
                        return
                    elseif app.numBlocks <= 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        msgbox('Invalid number of blocks');
                        return
                    end

                end %end File loop

                app.Block.Data(:,1)=[];
                app.Block.Titles(:,1)=[];
                app.ComList=app.ComList(2:end);
                app.Comments.CommentTxtOptions=app.ComList;

                %if multiple files, check channels
                CheckMultiFileChan(app)


                %Enable buttons for desired Epoch Types
                for i=1:length(EpochTypeOptions)
                    switch i
                        case 1 %Set up for Block data
                            app.BlocksButton.Enable=1;
                        case 2 %Set up for Comments
                            app.CommentsButton.Enable=1;
                        case 3 %Set up for Events
                            app.EventsButton.Enable=1;
                    end
                end
                app.EpochType=1; %set to block initially
                app.DataExtracted=1;

            end %end DataExtracted

            %If Block Data is empty
            if isempty(app.Block)
                Plot_Data=[]; ChTitle=""; Time=[];
                msgbox('Block Data is empty');
                return
            end

            %Check epoch type
            switch app.EpochType
                case 1
                    app.numTrials=app.numBlocks;
                    updateTrialSpinnerLimits(app);
                case 2 %Comments
                    PreDurValue=app.PreDur*app.AllSampleRate;
                    PostDurValue=app.PostDur*app.AllSampleRate;
                    Data2=zeros(app.numChannels,length(-PreDurValue:PostDurValue));

                    ComColAll=1; EndBlock=1; app.numTrialsC=[];
                    %!!! look into effiency of this
                    for e=1:length(app.Filename)                                            %for each file
                        app.Custom_Data=app.Custom_DataAll.(['File_' num2str(e)]); %Set current data file
                        %FilenumChannels=length(app.Custom_Data.Block(:,1));
                        numBlocksF=length(app.Custom_Data.Block(1,:));
                        app.Custom_Data.Block=app.Block.Data(:,EndBlock:EndBlock+numBlocksF-1);
                        EndBlock=EndBlock+numBlocksF;
                        for i=1:length(string(app.Custom_Data.CommentTxtOptions))           %for each comment text
                            for i2=1:app.Custom_Data.numTrialsC(i)                          %for each comment of the current comment text
                                CommentLoc=app.Custom_Data.CommentLocAll{i2,i}(1,2);
                                CommentBlk=app.Custom_Data.CommentLocAll{i2,i}(1,1);
                                %Parse out desired data
                                StartDiff=0; EndDiff=0;
                                Start=CommentLoc-PreDurValue; End=CommentLoc+PostDurValue;
                                if Start <= 0 %if start is less than zero, fill in with NaNs
                                    StartDiff=-Start+1;
                                    Start=1;
                                end
                                %first row with data
                                FR=find(cellfun(@isempty,app.Custom_Data.Block(:,CommentBlk)) == 0);
                                DataLength= length(app.Custom_Data.Block{FR(1),CommentBlk});                %use first row/channel that has data
                                if End > DataLength %if end is greater than the length of the data, fill with NaNs
                                    EndDiff=End-DataLength;
                                    End=DataLength;
                                end
                                Data=app.Custom_Data.Block(:,CommentBlk);
                                %reformat and cut data
                                for i3=1:app.numChannels
                                    if isempty(Data{i3})
                                        Data{i3}= nan(1,DataLength);
                                    end
                                    Data2(i3,:)=[nan(1,StartDiff) Data{i3}(Start:End) nan(1,EndDiff)]; %fill beginning and end with NaNs if needed
                                end
                                app.Comments.Data{i2,ComColAll}=Data2; %each column is for a comment text, order of comment texts is the same
                            end
                            ComColAll=ComColAll+1;
                        end %end for each comment text option
                        app.numTrialsC=[app.numTrialsC app.Custom_Data.numTrialsC];

                    end %end for each file

                    app.SelectedComtxt = app.EventNameListBox.Value;
                    %update trial spinner limits and trial number based on specified comment text
                    Com=string(app.SelectedComtxt);
                    comCol=app.EventNameListBox.ItemsData == Com;
                    app.numTrials=app.numTrialsC(comCol);           %redefine numTrials
                    updateTrialSpinnerLimits(app);
                    app.Trial = app.TrialSpinner.Value;             %update trial number, if current trial number selected is above the number of trials in the newly
                    %selected comment text the spinner will set to the max trial number, this will set the trial number to this value

                case 3 %Events
                    [app.numTrials, app.Events] = GetEvents(app);
                    if app.numTrials == 0
                        Plot_Data=[]; ChTitle=""; Time=[];
                        return
                    end


                    %update trial spinner limits
                    updateTrialSpinnerLimits(app);

            end %end epochtype switch


            %Plotting
            switch app.EpochType
                case 1 %Block
                    Plot_Data=app.Block.Data{app.Ch,app.Trial};                                           %Define data based on current selected channel and trial
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=0:1/app.AllSampleRate:length(Plot_Data)/app.AllSampleRate-(1/app.AllSampleRate); %Time defined by sample rate
                    if app.PreTriggerTime ~= 0
                        Time=Time-app.PreTriggerTime/1000;                                                %Adjust for Pre trigger setting
                    end
                    if app.PostTriggerTime ~=0                                                            %Adjust for Post trigger setting
                        FramesAfter=app.PostTriggerTime/1000*app.AllSampleRate+app.PreTriggerTime/1000*app.AllSampleRate;
                        %check for if post trigger time is beyond the dataset
                        if FramesAfter > length(Plot_Data)
                            FramesAfter=length(Plot_Data);
                            app.PostTriggermsEditField.Value=FramesAfter/app.AllSampleRate*1000;
                        end
                        Time=Time(1:FramesAfter);
                        Plot_Data=Plot_Data(1:FramesAfter);
                    end
                case 2 %Comments
                    Com=string(app.SelectedComtxt);                                                       %Determine current specified comment text
                    comCol=app.EventNameListBox.ItemsData == Com;                                         %Use text to define the column the data is in
                    Plot_Data=app.Comments.Data{app.Trial,comCol}(app.Ch,:);                              %Define data based on current selected channel and trial
                    app.numTrials=app.numTrialsC(comCol);                                                 %Determine number of trials based on comment text
                    ChTitle=app.Block.Titles(app.Ch);                                                     %Channel title of current selected channel
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;                                     %Time defined by sample rate and pre/post duration settings
                case 3 %Events
                    Plot_Data=app.Events.Data{app.Trial}(app.Ch,:);
                    ChTitle=app.Block.Titles(app.Ch);
                    Time=-app.PreDur:1/app.AllSampleRate:app.PostDur;
            end

        end %end ExtractCustom

        %Custom File Upload
        function CustomFileMenuSelected(app,event)

            %Select file
            [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.*'),'multiSelect','on');
            figure(app.UIFigure)            %focus back to app
            if isequal(app.Filename,0)      %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end

            %Check for one file
            if iscell(app.Filename) == 0
                TempFilename=app.Filename;
                app.Filename=cell(1,1);
                app.Filename{1,1}=TempFilename;
            end

            %print filename or list of files
            if length(app.Filename) > 1
                app.DataFileEditField.Value="See File Order";
                app.FileOrderListBox.Visible=1; app.FileOrderListBoxLabel.Visible=1;
                app.FileOrderListBox.Items=app.Filename;
            else
                app.DataFileEditField.Value=string(app.Filename);
                app.FileOrderListBox.Visible=0; app.FileOrderListBoxLabel.Visible=0;
            end

            app.DataExtracted=0;
            app.DataType=8;        %Custom file
            app.BlocksButton.Enable=0; app.CommentsButton.Enable=0; app.EventsButton.Enable=0;

            %Identify custom file function
            app.CustomUploadFile=event.Source.Text;

        end

        function loadingScreen(app,Enable1)
            %==============================================================
            %Disable all of app, so nothing can be interacted with during
            %certain processes
            %==============================================================
            switch Enable1
                case 1
                    app.App_Panel.Enable=0;
                    app.FileMenu.Enable=0;
                    pause(0.001);
                case 0
                    pause(0.001);
                    app.App_Panel.Enable=1;
                    app.FileMenu.Enable=1;
            end


        end

        function UIAxesLimitsChangedFcn(app)
            %==============================================================
            %Update axes edit fields when they are changed
            %Callback for UIAxes YLim and XLim listeners
            %==============================================================
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);

        end

        function axToolbarSelected(app,event,varargin)
            %==============================================================
            %Checks previous axes toolbar selection, ignores a warning
            %Callback of axes toolbar
            %==============================================================

            PrevSelection=app.PrevToolBarSelect;
            CurrSelection=string(event.Selection.Tag);
            app.CurrToolBarSelect=CurrSelection; %added for check in override
            if PrevSelection == "" %New item has been select, when nothing was selected before
                warning('off','MATLAB:modes:mode:InvalidPropertySet'); %ignore this warning because, the callback is not actually being set
            end
            if CurrSelection == app.PrevToolBarSelect %if the same item is selected again (deselected)
                app.PrevToolBarSelect="";
                app.CurrToolBarSelect="";
                warning('on','MATLAB:modes:mode:InvalidPropertySet');
            else
                app.PrevToolBarSelect=CurrSelection;
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %==============================================================
            %Startup function runs when app is initially opened
            %Adds functions folder
            %Resets figure and variables to default
            %==============================================================

            if ~isdeployed
                addpath(genpath('Fcns'));       %add Functions folder and subfolders to path
            end

            %Check directory
            if isfile(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'))                  %directory has been set before, use this directory
                DirSettings=load(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'));
                app.Directory.SaveFolder=DirSettings.Dir.SaveFolder;
                app.Directory.DataFolder=DirSettings.Dir.DataFolder;
            else                                                %directory has not been set before, prompt for directory
                %Select save folder
                uiwait(msgbox("Select Save Folder, this is where outputs will be saved"));
                app.Directory.SaveFolder=uigetdir(fullfile(userpath,'Save Folder'));
                %Select data folder
                uiwait(msgbox("Select Data Folder, this is where the raw data is stored"));
                app.Directory.DataFolder=uigetdir(fullfile(userpath,'Data Folder'));
                figure(app.UIFigure)
                if string(class(app.Directory.DataFolder)) ~= "char" || string(class(app.Directory.SaveFolder)) ~= "char"  %if either folder was not selected
                    if (string(class(app.Directory.DataFolder)) ~= "char" && app.Directory.DataFolder == 0) && (string(class(app.Directory.SaveFolder)) == "char") %cancel was hit for Data folder, make the same as Save folder
                        %app.Directory.SaveFolder=app.Directory.DataFolder;
                        app.Directory.DataFolder=app.Directory.SaveFolder;
                        Dir=app.Directory;
                        save(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'),'Dir');

                    elseif (string(class(app.Directory.SaveFolder)) ~= "char" && app.Directory.SaveFolder == 0) && (string(class(app.Directory.DataFolder)) == "char") %cancel was hit for Save folder, make the same as Save folder
                        app.Directory.SaveFolder=app.Directory.DataFolder;
                        Dir=app.Directory;
                        save(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'),'Dir');

                    else  %cancel was hit for both, default both to folder app is saved in
                        app.Directory.SaveFolder=userpath;
                        app.Directory.DataFolder=userpath;
                    end
                else %both were selected
                    Dir=app.Directory;refreshApp
                    if ~isfolder(fullfile(userpath, app.ToolboxDocFolderName))
                        mkdir(fullfile(userpath, app.ToolboxDocFolderName));
                    end
                    save(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'),'Dir');
                end
            end

            %Axes settings
            app.Toolbar=axtoolbar(app.UIAxes,{'export','datacursor','pan','zoomin','zoomout'},'SelectionChangedFcn',@(scrs,event) axToolbarSelected(app,event));
            saveasfig=axtoolbarbtn(app.Toolbar,"push");
            saveasfig.ButtonPushedFcn=@saveasmfig;
            saveasfig.Icon = "export";
            saveasfig.Tooltip = "Save to .fig file";

            addlistener(app.UIAxes, 'XLim', 'PostSet', @(src, event) app.UIAxesLimitsChangedFcn);
            addlistener(app.UIAxes, 'YLim', 'PostSet', @(src, event) app.UIAxesLimitsChangedFcn);

            %Reset app
            app.refreshApp;
            movegui(app.UIFigure,'center');   %moves app to center of screen

            %Disable Analyze EMG Analysis 12/3/25 !!!
            S=uistyle("BackgroundColor","#b82c43"); %#F77A8F red, #858482 dark gray
            addStyle(app.AnalysisOptionDropDown,S,"item",3);

            function saveasmfig(~,event)
                SaveFolder=app.Directory.SaveFolder;
                figure(); hold on;
                lgd=[];
                for i=1:length(event.Axes.Children)
                    switch string(class(event.Axes.Children(i)))
                        case "matlab.graphics.chart.primitive.Line"
                            XData=event.Axes.Children(i).XData;
                            YData=event.Axes.Children(i).YData;
                            plot(XData,YData);

                        case "matlab.graphics.chart.decoration.ConstantLine"
                            if event.Axes.Children(i).InterceptAxis == 'y'
                                yline(event.Axes.Children(i).Value,'color',event.Axes.Children(i).Color,'linestyle',event.Axes.Children(i).LineStyle,'linewidth',event.Axes.Children(i).LineWidth);
                                lgd=[lgd string(event.Axes.Children(i).DisplayName)];
                            else %should be 'y' or 'x'
                                xline(event.Axes.Children(i).Value,'color',event.Axes.Children(i).Color,'linestyle',event.Axes.Children(i).LineStyle,'linewidth',event.Axes.Children(i).LineWidth);
                                lgd=[lgd string(event.Axes.Children(i).DisplayName)];
                            end

                        case "matlab.graphics.primitive.Patch"
                            patch(event.Axes.Children(i).XData,event.Axes.Children(i).YData,'b','FaceColor','#969590','EdgeColor','black','FaceAlpha',0.2);
                            lgd=[lgd string(event.Axes.Children(i).DisplayName)];
                    end
                end
                legend(lgd);
                xlabel(event.Axes.XLabel.String)
                ylabel(event.Axes.YLabel.String)
                title(event.Axes.Title.String)

                %save figure
                SaveFigName=inputdlg('Enter filename:','Save figure',[1 35]);
                if isempty(SaveFigName)
                    return;
                end

                %Check if .fig was included in the filename
                if sum(SaveFigName{1,1}(end-3:end) == '.fig') == 4
                    SaveFigName{1,1}(end-3:end)='';
                end

                %Save figure
                savefig([SaveFolder '\' SaveFigName{1,1}]);
            end


        end

        % Button pushed function: ChangeDataFolderButton
        function ChangeDataFolderButtonPushed(app, event)
            %==============================================================
            %Change the Data folder of the directory
            %New directory path is saved in DirectorySettings.mat
            %==============================================================

            PrevDir=app.Directory.DataFolder; %save previous directory
            uiwait(msgbox("Select Data Folder, this is where the raw data is stored"));
            app.Directory.DataFolder=uigetdir(app.Directory.DataFolder,'Data Folder');
            figure(app.UIFigure)
            if app.Directory.DataFolder == 0 %cancel was hit, don't change directory
                app.Directory.DataFolder=PrevDir;
            else %update directory
                Dir=app.Directory;
                save(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'),'Dir');
            end
        end

        % Button pushed function: ChangeSaveFolderButton
        function ChangeSaveFolderButtonPushed(app, event)
            %==============================================================
            %Change the Save folder of the directory
            %New directory path is saved in DirectorySettings.mat
            %==============================================================

            PrevDir=app.Directory.SaveFolder; %save previous directory
            uiwait(msgbox("Select Save Folder, this is where outputs will be saved"));
            app.Directory.SaveFolder=uigetdir(app.Directory.SaveFolder,'Save Folder');
            figure(app.UIFigure)
            if app.Directory.SaveFolder == 0 %cancel was hit, don't change directory
                app.Directory.SaveFolder=PrevDir;
            else %update directory
                Dir=app.Directory;
                save(fullfile(userpath, app.ToolboxDocFolderName, 'DirectorySettings.mat'),'Dir');
            end
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            %==============================================================
            %Refresh before opening new file
            %==============================================================

            %Reset app
            app.refreshApp;

        end

        % Menu selected function: LabChartmatMenu
        function LabChartmatMenuSelected(app, event)
            %==============================================================
            %Loads LabChart export files (.mat)
            %Updates comment text options list
            %==============================================================

            %Import LabChart .mat file
            [app.Filename, app.Path] = uigetfile(app.Directory.DataFolder,'Select LabChart File','multiSelect','off');
            figure(app.UIFigure)            %focus back to app
            if isequal(app.Filename,0)      %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            app.DataFileEditField.Value=app.Filename;
            loadingScreen(app,1);                                          %loading indicator
            app.labChart_Data = load(fullfile(app.Path, app.Filename));
            loadingScreen(app,0);
            app.ComList=string(app.labChart_Data.comtext);                         %List of comment text

            app.DataExtracted=0;                                           %reset for block data
            app.DataType=1;                                                %LabChart Data


        end

        % Menu selected function: BrainsighttxtMenu
        function BrainsighttxtMenuSelected(app, event)
            %==============================================================
            %Loads BrainSight export files (.txt)
            %==============================================================

            %Import BrainSight .txt file
            [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.txt'),'Select BrainSight File','multiSelect','off');
            figure(app.UIFigure)
            if isequal(app.Filename,0) %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            app.DataFileEditField.Value=app.Filename;

            loadingScreen(app,1);
            app.BrainSight_Data=importBrainSightfile(strcat(app.Path,app.Filename));
            loadingScreen(app,0);

            app.DataType=2; %BrainSight Data

            %block type data only
            app.CommentsButton.Enable=0;
            app.EventsButton.Enable=0;


        end

        % Menu selected function: SignalmatcfsMenu
        function SignalmatcfsMenuSelected(app, event)
            %==============================================================
            %Loads Signal export files (.mat and .cfs)
            %Uses a dialog to determine which file type will be imported
            %If the user chooses to convert a .cfs file, the file will be
            %converted and the resulting .mat file will be loaded
            %==============================================================

            %Checks which file type was loaded
            %.cfs files are converted to .mat files with the same name,
            %they are saved to the same folder

            %Determine file type: exported .mat or .cfs file/converted .cfs file
            FileType = questdlg('File Type?','Import','Import .mat export','Convert .cfs or import converted .cfs','Convert .cfs or import converted .cfs');
            switch FileType
                case 'Import .mat export' % used if excported to .mat in Signal
                    %Import files
                    [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.mat'),'Select Signal File',"multiSelect","off");
                    if isequal(app.Filename,0) %if cancel is chosen return to app
                        msgbox('No File Chosen');
                        return
                    end
                    figure(app.UIFigure)

                    loadingScreen(app,1);
                    Signal_Data1 = load(fullfile(app.Path, app.Filename));
                    loadingScreen(app,0);
                    DataName = fieldnames(Signal_Data1);
                    app.Signal_Data=Signal_Data1.(DataName{1,1});

                    app.SignalFile = 1;

                case 'Convert .cfs or import converted .cfs' % used if file was originally saved as .cfs and/or converted within toolbox
                    %Import files
                    [app.Filename, app.Path] = uigetfile({'*.cfs;*.mat','CFS Files (.cfs), MAT Files (.mat)'}, 'Select Signal File',app.Directory.DataFolder,"multiselect", "off");
                    if numel(app.Filename) == 1 && all(app.Filename == 0)
                        msgbox('No File Chosen');
                        return
                    end
                    figure(app.UIFigure)

                    loadingScreen(app,1);
                    %determine data file type
                    Extension=app.Filename(end-3:end);
                    switch Extension
                        case '.cfs' %convert to .mat
                            cfs2mat.Convert(app.Filename, app.Path); %convert .cfs file to .mat file, saves to folder
                            Signal_Data1=load([app.Path app.Filename(1:end-4) '.mat']); %converted file should have the same filename
                            app.Signal_Data=Signal_Data1.CfsFile;
                        case '.mat'%load converted .cfs file
                            Signal_Data1=load([app.Path app.Filename(1:end-4) '.mat']);
                            app.Signal_Data=Signal_Data1.CfsFile;
                    end
                    loadingScreen(app,0);

                    app.SignalFile = 2;
            end

            app.DataFileEditField.Value=app.Filename;
            app.DataType=3; %Signal Data

            %block type data only
            app.CommentsButton.Enable=0;
            app.EventsButton.Enable=0;
            app.EventNameListBox.Visible=1; app.EventNameListBoxLabel.Visible=1;


        end

        % Menu selected function: AcqknowledgematacqMenu
        function AcqknowledgematacqMenuSelected(app, event)
            %==============================================================
            %Loads Acqknowledge export files (.mat and .acq)
            %Uses a dialog to determine which file type will be imported
            %If the user chooses to convert a .acq file, a .mat file of
            %the same name will be searched for, if that file doesn't
            %exist, the .acq file will be converted and the resulting .mat
            %file will be loaded
            %Mulitple file upload is possible
            %The filenames are listed in the FileOrderListBox
            %==============================================================

            %Determine file type: exported .mat or .cfs file/converted .cfs file
            FileType = questdlg('File Type?','Import','Import .mat export','Convert .acq or import converted .acq','Convert .acq or import converted .acq');
            switch FileType
                case 'Import .mat export' % used if excported to .mat in Signal
                    %Import files
                    [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.mat'), 'Select Acqknowledge File',"multiSelect","on");
                    if isequal(app.Filename,0) %if cancel is chosen return to app
                        msgbox('No File Chosen');
                        return
                    end
                    figure(app.UIFigure)

                    %check if there is only one file
                    if iscell(app.Filename) == 0
                        TempFilename=app.Filename;
                        app.Filename=cell(1,1);
                        app.Filename{1,1}=TempFilename;
                    end

                    loadingScreen(app,1);
                    %load each file
                    for i=1:length(app.Filename)
                        CurrFilename=app.Filename{i};
                        app.Acqknowledge_DataAll.(['File' num2str(i)]) = load(fullfile(app.Path, CurrFilename));
                    end
                    loadingScreen(app,0);

                    app.AcqFile=1; %exported .mat

                case 'Convert .acq or import converted .acq' % used if file was originally saved as .cfs and/or converted within toolbox
                    %Import files
                    [app.Filename, app.Path] = uigetfile({'*.acq;*.mat','ACQ Files (.acq), MAT Files (.mat)'}, 'Select Acqknowledge File',app.Directory.DataFolder,"multiselect", "on");
                    if numel(app.Filename) == 1 && all(app.Filename == 0)
                        msgbox('No File Chosen');
                        return
                    end
                    figure(app.UIFigure)

                    %check if there is only one file
                    if iscell(app.Filename) == 0
                        TempFilename=app.Filename;
                        app.Filename=cell(1,1);
                        app.Filename{1,1}=TempFilename;
                    end


                    loadingScreen(app,1);
                    %load each file
                    for i=1:length(app.Filename) %can import mixture of .mat and .acq
                        CurrFilename=app.Filename{i};
                        %determine data file type
                        Extension=CurrFilename(end-3:end);
                        switch Extension
                            case '.acq' %convert to .mat
                                acq2matF(app.Path,CurrFilename); %Check for a .mat version of the file, if there is one load that file, if there isn't create one
                                Acqknowledge_DataA = load(fullfile(app.Path, [CurrFilename(1:end-4) '.mat'])); %load .mat file
                                app.Acqknowledge_DataAll.(['File' num2str(i)])=Acqknowledge_DataA.acq;
                            case '.mat' %load converted .cfs file
                                Acqknowledge_DataA = load(fullfile(app.Path, CurrFilename));
                                app.Acqknowledge_DataAll.(['File' num2str(i)])=Acqknowledge_DataA.acq;
                        end
                    end
                    loadingScreen(app,0);

                    app.AcqFile = 2;
            end

            %print filename or list of files
            if length(app.Filename) > 1
                app.DataFileEditField.Value="See File Order";
                app.FileOrderListBox.Visible=1; app.FileOrderListBoxLabel.Visible=1;
                app.FileOrderListBox.Items=app.Filename;
            else
                app.DataFileEditField.Value=string(app.Filename);
                app.FileOrderListBox.Visible=0; app.FileOrderListBoxLabel.Visible=0;
            end
            app.DataExtracted=0;                                            %reset for block data
            app.DataType=4; %Acqknowledge Data

            %block and epoch type data only
            app.CommentsButton.Enable=0;
        end

        % Menu selected function: BrainVisioneegMenu
        function BrainVisioneegMenuSelected(app, event)
            %==============================================================
            %Loads BrainVision export files (.eeg)
            %Data folder must contain the .eeg, .vhdr, and .vmrk files
            %Updates comment text options list
            %Mulitple file upload is possible
            %The filenames are listed in the FileOrderListBox
            %==============================================================

            [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.eeg'), 'Select BrainVision File',"multiSelect","on");
            if isequal(app.Filename,0) %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            figure(app.UIFigure)

            %check if there is only one file
            if iscell(app.Filename) == 0
                TempFilename=app.Filename;
                app.Filename=cell(1,1);
                app.Filename{1,1}=TempFilename;
            end

            loadingScreen(app,1);
            %Load data
            for i=1:length(app.Filename)
                File=fullfile(app.Path,app.Filename{1,i});
                %Read file events
                FileEvents=ft_read_event(File);
                app.BrainVision_DataAll.(['File' num2str(i) ]).FileEvents=FileEvents;

                %read header file
                FileHeader=ft_read_header([File(1:end-3),'vhdr']);
                app.BrainVision_DataAll.(['File' num2str(i) ]).FileHeader=FileHeader;

                %read data
                FileData=ft_read_data([File(1:end-3),'vhdr']);
                app.BrainVision_DataAll.(['File' num2str(i) ]).Data=FileData; %chxsample
            end
            %loadingScreen(app,0);


            %% Find comment text
            for e=1:length(app.Filename) %for each file
                app.BrainVision_Data=app.BrainVision_DataAll.(['File' num2str(e)]);
                for i=1:length(app.BrainVision_Data.FileEvents) %For all comments
                    Sample=app.BrainVision_Data.FileEvents(i).sample;
                    CurrComText=string(app.BrainVision_Data.FileEvents(i).value);
                    if isempty(CurrComText) %empty trial
                        CurrComText="";
                    end

                    app.BrainVision_DataAll.(['Comments' num2str(e)])(i).CommentText=CurrComText;
                    app.BrainVision_DataAll.(['Comments' num2str(e)])(i).CommentLoc=Sample;

                    %Create comment text options list
                    if i==1
                        CommentTxtOptions=app.BrainVision_DataAll.(['Comments' num2str(e)])(1).CommentText; %add the first one
                    end
                    %check if current comment text is in the list already
                    L=CommentTxtOptions==CurrComText;
                    if sum(L)==0 %if not, add it to the list
                        CommentTxtOptions(end+1,1)=CurrComText;
                    end
                end
            end
            loadingScreen(app,0);

            %look for empty comments
            CommentTxtOptions(CommentTxtOptions=="")=[]; %remove empty comments

            app.Comments.CommentTxtOptions=CommentTxtOptions;
            app.ComList=app.Comments.CommentTxtOptions;


            %figure(app.UIFigure)

            %print filename or list of files
            if length(app.Filename) > 1
                app.DataFileEditField.Value="See File Order";
                app.FileOrderListBox.Visible=1; app.FileOrderListBoxLabel.Visible=1;
                app.FileOrderListBox.Items=app.Filename;
            else
                app.DataFileEditField.Value=string(app.Filename);
                app.FileOrderListBox.Visible=0; app.FileOrderListBoxLabel.Visible=0;
            end

            app.DataExtracted=0;                                           %reset for block data
            app.DataType=5; %BrainVision Data


        end

        % Menu selected function: SpikesmrMenu
        function SpikesmrMenuSelected(app, event)
            %==============================================================
            %Loads Spike export files (.smr)
            %Loads the CEDS64ML library
            %==============================================================

            [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.smr'), 'Select Spike File',"multiSelect","off");
            if isequal(app.Filename,0) %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            figure(app.UIFigure)

            %load CEDS64ML library, library is unloaded if the app is reset or closed
            AppFolder = fileparts(mfilename('fullpath')); 
            cedpath=fullfile(AppFolder,'Fcns','Data_File_Import','Spike','CEDS64ML');
            CEDS64LoadLib( cedpath );

            loadingScreen(app,1);
            app.Spike_Data.hdr=CEDS64Open(fullfile(app.Path, app.Filename));    %Integer file handle for this file
            if (app.Spike_Data.hdr <= 0); unloadlibrary ceds64int; return; end  %If an error code is returned unload the library, add error warning !!!

            %Check for comments
            %Extract Data types of all possible channels
            maxChan = CEDS64MaxChan(app.Spike_Data.hdr); %max number of channels this file can hold
            dataTypeAll=zeros(maxChan,1);
            for i=1:maxChan %look through all possible channels
                dataTypeAll(i,1)=CEDS64ChanType(app.Spike_Data.hdr,i); %channel type
            end
            EventRiseChan=find(dataTypeAll == 3);       %Event rising edge, for comments
            WaveformChan=find(dataTypeAll == 1);        %Waveform, for numChannels
            app.AllSampleRate=1/(CEDS64ChanDiv(app.Spike_Data.hdr, WaveformChan(1))*CEDS64TimeBase(app.Spike_Data.hdr)); %assumed same sampling rate for all channels and blocks
            app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];
            app.numChannels=length(WaveformChan);

            %Determine Comment locations if they exist
            [~, app.EventTicks]=CEDS64ReadEvents(app.Spike_Data.hdr, EventRiseChan, 10000, 1);
            if isempty(app.EventTicks) %there are no comments
                app.CommentsButton.Enable=0;
            else
                app.EventNameListBox.Items="Events"; app.ComList="Events";
                app.EventNameListBox.Value=app.EventNameListBox.Items{1};

            end

            loadingScreen(app,0);

            app.DataFileEditField.Value=app.Filename;
            app.DataExtracted=0;
            app.DataType=6;

            %block and epoch type data only
            %app.CommentsButton.Enable=0; %1/22/26 !!! adding comments


        end

        % Menu selected function: OpenConditionFileMenu
        function OpenConditionFileMenuSelected(app, event)
            %==============================================================
            %Open and load a Conditions File
            %This function is called when an old condition file is uploaded
            %as well. There is a check for if it is an old condition file
            %and the data is reformatted.
            %==============================================================

            [app.Filename, app.Path] = uigetfile(strcat(app.Directory.DataFolder,'/*.mat'), 'Select Condition File',"multiSelect","on");
            if isequal(app.Filename,0) %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end

            figure(app.UIFigure)
            loadingScreen(app,1);

            %check if there is only one file
            if iscell(app.Filename) == 0
                TempFilename=app.Filename;
                app.Filename=cell(1,1);
                app.Filename{1,1}=TempFilename;
            end

            %load files
            if app.Old == 1
                warning('off','MATLAB:load:classNotFound'); %turn off warning for trying to load an app property
            end
            AllFileTitles=[];AllSampleRates=zeros(length(app.Filename),1); Filenames=strings(1);
            for i=1:length(app.Filename)
                app.LoadedConditions=load(fullfile(app.Path, app.Filename{1,i}));
                if app.Old == 1                                                         %if an old condition file is uploaded, reformat the data
                    app.LoadedConditions=convertOldConditionFile(app.LoadedConditions);
                end
                % if length(app.LoadedConditions.numTrialsperCondition(1,:)) < 4
                %     app.LoadedConditions.numTrialsperCondition(:,4)=app.LoadedConditions.SampleRate;
                % end

                app.UITable.Data=[app.UITable.Data; app.LoadedConditions.ConditionsMatrix];
                app.Conditions_DataAll=[app.Conditions_DataAll; app.LoadedConditions.AllData];
                app.numTrialsCondition=[app.numTrialsCondition; app.LoadedConditions.numTrialsperCondition];
                app.LoadedConditionsAll.(['ConditionFile' num2str(i)])=app.LoadedConditions;
                AllFileTitles=[AllFileTitles; app.LoadedConditions.ChTitles];
                AllSampleRates(i)=app.LoadedConditions.SampleRate;
                Filenames=[Filenames string(app.LoadedConditions.FileName)];

                %app.Conditions_Data=[app.Conditions_Data; app.LoadedConditions.ConditionsData];

            end
            
            
            %check sample rates - downsample
            if length(AllSampleRates) > 1 && ~all(AllSampleRates == AllSampleRates(1)) %if there are multiple files with different sample rates 
                msgbox(['Warning: Different sample rates detected. Downsampling to the lowest sample rate of ' num2str(min(AllSampleRates)) '.']);
                [app.AllSampleRate, app.Conditions_DataAll]=checkSampleRate(app.Conditions_DataAll,AllSampleRates); %downsample to lowest sample rate
                %app.AllSampleRate=AllSampleRates; 

            else
                app.AllSampleRate=AllSampleRates(1);

            end

            if app.Old == 1
                warning('on','MATLAB:load:classNotFound'); %turn warning back on
            end
            app.Old=0; %reset Old variable

            %Redefine Loaded Conditions, assume channel titles and sample rate are the same for all condition files
            %!!! don't assume same channels, sample rate: data is saved with its time points
            app.LoadedConditions.ConditionsMatrix=app.UITable.Data;
            app.LoadedConditions.ChTitles=AllFileTitles;                                 %!!! 5/28 need to update channel list to match titles
            app.LoadedConditions.SampleRate=AllSampleRates;
            app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate(1)) 'Hz']; %!!!!! %!!! displays first sample rate, assume sample rates are the same
            app.LoadedConditions.FileName=Filenames(2:end);
            app.Conditions_DataAll=app.Conditions_DataAll(2:end,:);
            app.numTrialsCondition=app.numTrialsCondition(2:end,:);


            %combine Conditions_DataAll
            if length(app.Filename) > 1 % && length(app.AllSampleRate) == 1 %there are multiple files that have the same sample rate
                for i=1:length(app.Conditions_DataAll(:,1)) %for each file
                    MinMaxTimes(i,1)=min(app.Conditions_DataAll{i,2});
                    MinMaxTimes(i,2)=max(app.Conditions_DataAll{i,2});
                end
                newTime=min(MinMaxTimes(:,1)):1/app.AllSampleRate:max(MinMaxTimes(:,2));
                DataAll=nan(sum(app.numTrialsCondition(:,1)),length(newTime));
                e=1;
                for i=1:length(app.Conditions_DataAll(:,1)) %for each file
                    if MinMaxTimes(i,1) > newTime(1) %if the data starts later
                        %determine padding length
                        PadLength=(MinMaxTimes(i,1)-newTime(1))*app.AllSampleRate;
                        app.Conditions_DataAll{i,1}=[nan(length(app.Conditions_DataAll{i,1}(:,1)),single(PadLength)) app.Conditions_DataAll{i,1}];
                    end
                    if MinMaxTimes(i,2) < newTime(end) %if the data ends earlier
                        %determine padding length
                        PadLength=(newTime(end)-MinMaxTimes(i,2))*app.AllSampleRate;
                        app.Conditions_DataAll{i,1}=[app.Conditions_DataAll{i,1} nan(length(app.Conditions_DataAll{i,1}(:,1)),single(PadLength)) ];
                    end
                    DataAll(e:e+length(app.Conditions_DataAll{i,1}(:,1))-1,:)=app.Conditions_DataAll{i,1};
                    e=e+length(app.Conditions_DataAll{i,1}(:,1));
                end
                app.Conditions_DataAll=cell(1,2);
                app.Conditions_DataAll{1}=DataAll;
                app.Conditions_DataAll{2}=newTime;

                %combine numTrials
                CStart=1;
                for i=1:length(app.numTrialsCondition(:,1))
                    CEnd=CStart+app.numTrialsCondition(i,1)-1;
                    app.numTrialsCondition(i,2)=CStart;
                    app.numTrialsCondition(i,3)=CEnd;
                    CStart=CEnd+1;

                end
            elseif length(app.Filename) > 1 && length(app.AllSampleRate) > 1 %there are multiple files that have different sample rates %!!!!!
                a=1;
                % LengthsofTime=cellfun(@length, app.Conditions_DataAll(:,2));
                % maxTimeLength=max(LengthsofTime);
                % CondstoChange=1:length(LengthsofTime);
                % CondstoChange(LengthsofTime == maxTimeLength)=[];
                % for i=1:CondstoChange
                %     Data=app.Conditions_DataAll{i,1};
                %     FillAmount=maxTimeLength-length(Data(1,:));
                %     app.Conditions_DataAll{i,1}=[Data nan(length(Data(:,1)),FillAmount)];
                %     app.Conditions_DataAll{i,2}=[app.Conditions_DataAll{i,2} nan(1,FillAmount)];
                % end
                % %combine so it is a 1x2 cell
                % app.Conditions_DataAll{1}=cell2mat(app.Conditions_DataAll(:,1));
                % app.Conditions_DataAll{2}=cell2mat(app.Conditions_DataAll(:,2));


            end

            %print filename or list of files
            if length(app.Filename) > 1
                app.DataFileEditField.Value="See File Order";
                app.FileOrderListBox.Visible=1; app.FileOrderListBoxLabel.Visible=1;
                app.FileOrderListBox.Items=app.Filename;
            else
                app.DataFileEditField.Value=string(app.Filename);
                app.FileOrderListBox.Visible=0; app.FileOrderListBoxLabel.Visible=0;
            end


            %Hide/Show windows
            %Switch to Process tab automatically
            app.TabGroup.SelectedTab=app.ProcessTab;
            app.ProcessButton.Enable=1; app.AnalyzeButton.Enable=1; app.OverrideButton.Enable=1; app.NDButton.Enable=1; %Enable processing and analysis

            %Disable windows
            app.EpochTypesButtonGroup.Enable=0;
            app.TrialSpinner.Enable=0; app.TrialSpinnerLabel.Enable=0;
            app.ChannelSpinner.Enable=0; app.ChannelSpinnerLabel.Enable=0;
            app.FindEventsButton.Enable=0;
            app.PrePostTriggerTimemsPanel.Enable=0;
            app.UITable.ColumnEditable=false;
            app.TrialSelectListBox.Enable=0;
            app.TrialSelectListBoxLabel.Enable=0;
            app.SelectAllButton.Enable=0;

            app.ConditionTrialsListBox.Visible=1; app.ConditionTrialsLabel.Visible=1;
            app.SelectAllCondButton.Visible=1;
            app.RightArrowAnalyzeButton.Visible=1;
            app.LeftArrowAnalyzeButton.Visible=1;
            app.SButton.Visible=1;
            app.ConditionsListBox.Visible=1; app.ConditionsListBoxLabel.Visible=1;

            %Disable Conditions Table buttons
            app.AddSelectedTrialsButton.Enable=0;
            app.AddBlankRowButton.Enable=0;
            app.ImportEventsButton.Enable=0;
            app.AutoFillButton.Enable=0;
            app.AutoFillEditField.Enable=0;
            app.KeeporDeleteRowsButton.Enable=0;
            app.SaveButton.Enable=0;
            app.ClearButton.Enable=0;
            app.ApplyButton.Enable=0;
            app.LoadConditionsButton.Enable=0;
            app.UploadExcelFileButton.Enable=0;
            app.SheetNameEditField.Enable=0;

            %Auto select first conditions
            %Fill Conditions List box
            app.ConditionsListBox.Items=app.UITable.Data(:,2);          %List Group Names
            app.ConditionsListBox.ItemsData=app.ConditionsListBox.Items;

            %check for duplicate names
            ItemsData=string(app.ConditionsListBox.ItemsData);
            for i=1:length(ItemsData) %for each item
                Copies=ItemsData(i) == ItemsData; %check for copies
                if sum(Copies) ~= 1 %if there are copies,
                    CopyLoc=find(Copies==1); %find the locations of all copies
                    for e=2:length(CopyLoc)  %for each copy after the first
                        ItemsData(CopyLoc(e)) = strcat(ItemsData(CopyLoc(e)), num2str(e)); %rename the copy
                    end
                end

            end
            app.ConditionsListBox.ItemsData=ItemsData;
            app.DataType=9;

            loadingScreen(app,0);

            app.ConditionsListBox.Value=app.ConditionsListBox.ItemsData(1); %Initial selection is the first condition
            ConditionsListBoxValueChanged(app,1); %plot condition


        end

        % Menu selected function: OpenVersion1ConditionFilesMenu
        function OpenVersion1ConditionFilesMenuSelected(app, event)
            %==============================================================
            %Open and load an old Conditions File. Before new app.
            %==============================================================

            app.Old=1;
            OpenConditionFileMenuSelected(app, 1);

        end

        % Button pushed function: FindEventsButton
        function FindEventsButtonPushed(app, event)
            %==============================================================
            %Extracts data from files
            %Plots epoched data
            %==============================================================

            %Check if a file is loaded
            if isempty(app.DataType)
                msgbox('Load File First');
                return
            end

            if app.DisableLI_FindEvents == 0
                loadingScreen(app,1);
            end

            %Extract data based on which type of file is loaded
            switch app.DataType
                case 1  %LabChart
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractLabChart(app);     %Block, Comments, Events

                case 2 %BrainSight
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractBrainSight(app);   %Block only

                case 3 %Signal
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractSignal(app);       %Block only

                case 4 %Acqknowledge
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractAcqknowledge(app); %Block and Events

                case 5 %BrainVision
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractBrainVision(app);  %Block, Comments, Events

                case 6 %Spike
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractSpike(app);        %Block and Events

                case 8 %Custom file
                    [app.Plotted_Data,app.Time,app.CurrChTitle] = ExtractCustom(app);

            end

            if app.DisableLI_FindEvents == 0
                loadingScreen(app,0);
            end


            %update AllPlotted_Data, there will only be one column of data
            app.AllPlotted_Data=cell(1,1);
            app.AllPlotted_Data{1,1}=app.Plotted_Data;
            app.AllPlotted_Data{1,2}=app.Time;
            app.AllPlotted_Data{1,3}=app.EpochType;
            switch app.EpochType
                case 1
                    app.AllPlotted_Data{1,4}='Block';
                case 2
                    app.AllPlotted_Data{1,4}=app.EventNameListBox.Value;
                case 3
                    app.AllPlotted_Data{1,4}=app.EventChannel;
                    app.AllPlotted_Data{1,5}=app.EventThresh;
                    app.AllPlotted_Data{1,6}=app.SearchISI;
            end


            %convert to selected units
            app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;

            %Plot desired data
            cla(app.UIAxes);
            plot(app.UIAxes, app.Time, app.Plotted_Data,'Color','k');
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = app.PlottedUnits;
            app.UIAxes.Title.String = app.CurrChTitle;
            %update Scaling edit fields
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);

            %If there is only one trial or channel, disable the spinner
            if app.numChannels == 1
                app.ChannelSpinner.Enable=0;
            else
                app.ChannelSpinner.Enable=1;
            end
            if app.numTrials == 1
                app.TrialSpinner.Enable=0;
            else
                app.TrialSpinner.Enable=1;
            end

            %Update multiple trial select list
            updateMultiTrialList(app,app.numTrials);


        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            %==============================================================
            %Updates channel spinner limits
            %Plots new channel data
            %==============================================================

            app.ChannelSpinner.Limits = [1 app.numChannels];
            app.Ch = app.ChannelSpinner.Value;

            %Update data to be plotted
            [app.Plotted_Data, app.CurrChTitle, app.Time]=updateSpinners(app,app.Trial);

            %update AllPlotted_Data, there will only be one column of data
            app.AllPlotted_Data=cell(1,1);
            app.AllPlotted_Data{1,1}=app.Plotted_Data;
            app.AllPlotted_Data{1,2}=app.Time;
            app.AllPlotted_Data{1,3}=app.EpochType;
            switch app.EpochType
                case 1
                    app.AllPlotted_Data{1,4}='Block';
                case 2
                    app.AllPlotted_Data{1,4}=app.EventNameListBox.Value;
                case 3
                    app.AllPlotted_Data{1,4}=app.EventChannel;
                    app.AllPlotted_Data{1,5}=app.EventThresh;
                    app.AllPlotted_Data{1,6}=app.SearchISI;
            end

            %convert to selected units
            app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;

            %Plot desired data
            cla(app.UIAxes);
            plot(app.UIAxes, app.Time, app.Plotted_Data,'Color','k');
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = app.PlottedUnits;
            app.UIAxes.Title.String = app.CurrChTitle;
            %update Scaling edit fields
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);


        end

        % Value changed function: TrialSpinner
        function TrialSpinnerValueChanged(app, event)
            %==============================================================
            %Updates trial spinner limits
            %Plots new trial data
            %==============================================================

            updateTrialSpinnerLimits(app);
            app.Trial = app.TrialSpinner.Value;

            %Update data to be plotted
            [app.Plotted_Data, app.CurrChTitle, app.Time]=updateSpinners(app,app.Trial);

            %update AllPlotted_Data, there will only be one column of data
            app.AllPlotted_Data=cell(1,1);
            app.AllPlotted_Data{1,1}=app.Plotted_Data;
            app.AllPlotted_Data{1,2}=app.Time;
            app.AllPlotted_Data{1,3}=app.EpochType;
            switch app.EpochType
                case 1
                    app.AllPlotted_Data{1,4}='Block';
                case 2
                    app.AllPlotted_Data{1,4}=app.EventNameListBox.Value;
                case 3
                    app.AllPlotted_Data{1,4}=app.EventChannel;
                    app.AllPlotted_Data{1,5}=app.EventThresh;
                    app.AllPlotted_Data{1,6}=app.SearchISI;
            end

            %convert to selected units
            app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;

            %Plot desired data
            cla(app.UIAxes);
            plot(app.UIAxes, app.Time, app.Plotted_Data,'Color','k');
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = app.PlottedUnits;
            app.UIAxes.Title.String = app.CurrChTitle;
            %update Scaling edit fields
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);


        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            %==============================================================
            %Updates plotted units
            %The data is converted from the previous unit to volts, then
            %converted to the desired unit
            %app.Convert2PlottedUnits is applied in FindEvents,
            %ChannelSpinnerValueChanged, and TrialSpinnerValueChanged
            %The data pulled from these callbacks are in volts
            %==============================================================

            PlottedUnitsPrev=app.PlottedUnits;     %save previous unit setting before updating
            app.PlottedUnits = app.UnitsDropDown.Value;

            %convert data to volts
            switch PlottedUnitsPrev
                case 'V'
                    %units already in volts
                case 'mV'
                    app.Plotted_Data=app.Plotted_Data/1000;

                case 'uV'
                    app.Plotted_Data=app.Plotted_Data/1e6;
            end

            %convert data to selected units
            switch app.PlottedUnits
                case 'V'
                    %units remain in volts
                    app.Convert2PlottedUnits=1;
                case 'mV'
                    app.Convert2PlottedUnits=1000;
                    app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;
                case 'uV'
                    app.Convert2PlottedUnits=1e6;
                    app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;

            end

            %Plot
            %Plot desired data
            cla(app.UIAxes);
            if length(app.Plotted_Data(:,1)) == 1
                plot(app.UIAxes, app.Time, app.Plotted_Data,'Color','k'); %if single line plot, color black
            else
                hold(app.UIAxes,'On');
                plot(app.UIAxes, app.Time, app.Plotted_Data);
            end
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = app.PlottedUnits;
            app.UIAxes.Title.String = app.CurrChTitle;
            %update Scaling edit fields
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);

            hold(app.UIAxes,'Off');


        end

        % Selection changed function: EpochTypesButtonGroup
        function EpochTypesButtonGroupSelectionChanged(app, event)
            %==============================================================
            %Determines the epoch type (Blocks, Comments, or Events)
            %Default Epoch Type is Blocks
            %Udpates which windows are available based on the Epoch Type
            %Blocks: Pre/Post Trigger Time
            %Comments: EventsListBox, Set Data Window
            %Events: Event Parameters, Set Data Window
            %Also updates when the Multiple Trial Select Box is available
            %==============================================================

            %If comments are selected, show list of comment text
            if app.CommentsButton.Value == 1
                app.EpochType=2;
                %Update List of comments
                app.EventNameListBox.Items=string(app.ComList);
                app.EventNameListBox.ItemsData=app.EventNameListBox.Items;

                %check for duplicate names
                ItemsData=string(app.EventNameListBox.ItemsData);
                for i=1:length(ItemsData) %for each item
                    Copies=ItemsData(i) == ItemsData; %check for copies
                    if sum(Copies) ~= 1 %if there are copies,
                        CopyLoc=find(Copies==1); %find the locations of all copies
                        for e=2:length(CopyLoc)  %for each copy after the first
                            ItemsData(CopyLoc(e)) = strcat(ItemsData(CopyLoc(e)), num2str(e)); %rename the copy
                        end
                    end

                end
                app.EventNameListBox.ItemsData=ItemsData;
                app.SelectedComtxt = app.EventNameListBox.Value;

                %Update Window options
                app.SetDataWindowPanel.Visible=1;
                app.EventNameListBox.Visible=1; app.EventNameListBoxLabel.Visible=1;
                app.EventParametersPanel.Visible=0;
                app.PrePostTriggerTimemsPanel.Visible=0;
                app.ImportEventsButton.Enable=1;

                FindEventsButtonPushed(app,1);

            elseif app.EventsButton.Value ==1
                app.EpochType=3;
                %Update Window options
                app.SetDataWindowPanel.Visible=1;
                app.EventNameListBox.Visible=0; app.EventNameListBoxLabel.Visible=0;
                app.EventParametersPanel.Visible=1;
                app.PrePostTriggerTimemsPanel.Visible=0;
                app.ImportEventsButton.Enable=0;

                %if Search ISI is zero don't auto run find events, if it is not zero it is assumed that at some point the user used the Events epoch type and there is valid data in the other parameters, if not an error will occur (part of GetEvents function)
                if app.SearchISI ~=0
                    FindEventsButtonPushed(app,1);
                end


            elseif app.BlocksButton.Value == 1
                app.EpochType=1;
                %Update Window options
                app.SetDataWindowPanel.Visible=0;
                app.EventNameListBox.Visible=0; app.EventNameListBoxLabel.Visible=0;
                app.EventParametersPanel.Visible=0;
                app.PrePostTriggerTimemsPanel.Visible=1;
                app.ImportEventsButton.Enable=1;

                FindEventsButtonPushed(app,1);

            end


        end

        % Value changed function: EventNameListBox
        function EventNameListBoxValueChanged(app, event)
            %==============================================================
            %Updates selected comment text option or selected state (for Signal data type)
            %Also updates the Trial Select List box
            %==============================================================

            switch app.DataType
                case {1,5,8,6} %LabChart, BrainVision, Custom, Spike
                    app.SelectedComtxt = app.EventNameListBox.Value;
                    %update trial spinner limits and trial number based on specified comment text
                    if ~isempty(app.Comments) && app.CommentsButton.Value == 1
                        Com=string(app.SelectedComtxt);
                        comCol=app.EventNameListBox.ItemsData == Com;
                        app.numTrials=app.numTrialsC(comCol);           %redefine numTrials
                        updateTrialSpinnerLimits(app);
                        app.Trial = app.TrialSpinner.Value;             %update trial number, if current trial number selected is above the number of trials in the newly
                        %selected comment text the spinner will set to the max trial number, this will set the trial number to this value
                    end
                case 3 %Signal
                    app.StateV=app.EventNameListBox.Value;
                    %update trial spinner limits and trial number based on specified state
                    if ~isempty(app.Block)
                        app.numTrials=app.numTrialsState(app.StateV);
                        updateTrialSpinnerLimits(app);
                        app.Trial = app.TrialSpinner.Value;             %update trial number, if current trial number selected is above the number of trials in the newly
                        %selected comment text the spinner will set to the max trial number, this will set the trial number to this value
                    end
            end

            FindEventsButtonPushed(app,1);

            %Update Trial Select List Box
            app.TrialSelectListBox.Items=string(1:app.numTrials);
        end

        % Button pushed function: AutoScaleButton
        function AutoScaleButtonPushed(app, event)
            %==============================================================
            %Sets X and Y axis limits to auto
            %==============================================================

            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            %update Scaling edit fields
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);
        end

        % Value changed function: TrialSelectListBox
        function TrialSelectListBoxValueChanged(app, event)
            %==============================================================
            %Updates which trials are selected and plotted
            %==============================================================

            TrialSelectedList= app.TrialSelectListBox.Value;

            %Reset average check box if needed
            if app.AverageCheckBox.Value == 1
                app.AverageCheckBox.Value=0;
            end

            app.AllPlotted_Data=cell(1,1);
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            LengthTime=0;
            for i=1:length(TrialSelectedList)
                TrialSelected=str2double(TrialSelectedList{i});

                [app.Plotted_Data, app.CurrChTitle, app.Time]=updateSpinners(app,TrialSelected);

                %Save all data plotted
                %AllPlotted_Data updated
                app.AllPlotted_Data{i,1}=app.Plotted_Data;
                if app.EpochType ~= 1 && i==1
                    app.AllPlotted_Data{1,2}=app.Time; %time should be the same for all plotted
                elseif app.EpochType == 1 && app.PreTriggerTime == 0 && app.PostTriggerTime == 0 %if plotting Blocks without adjusting the data it is possible for the data to be different lengths
                    if length(app.Time) > LengthTime
                        app.AllPlotted_Data{1,2}=app.Time;
                        LengthTime=length(app.Time);
                    end
                else
                    app.AllPlotted_Data{1,2}=app.Time;
                end

                app.AllPlotted_Data{i,3}=app.EpochType;
                switch app.EpochType
                    case 1
                        app.AllPlotted_Data{i,4}='Block';
                    case 2
                        app.AllPlotted_Data{i,4}=app.EventNameListBox.Value;
                    case 3
                        app.AllPlotted_Data{i,4}=app.EventChannel;
                        app.AllPlotted_Data{i,5}=app.EventThresh;
                        app.AllPlotted_Data{i,6}=app.SearchISI;
                end

                %convert to selected units
                app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;

                %Plot desired data
                plot(app.UIAxes, app.Time, app.Plotted_Data);
                app.UIAxes.XLabel.String = 'Time (s)';
                app.UIAxes.YLabel.String = app.PlottedUnits;
                app.UIAxes.Title.String = app.CurrChTitle;
                %update Scaling edit fields
                app.MinEditField.Value=app.UIAxes.XLim(1);
                app.MaxEditField.Value=app.UIAxes.XLim(2);
                app.MinEditField_2.Value=app.UIAxes.YLim(1);
                app.MaxEditField_2.Value=app.UIAxes.YLim(2);
            end

            %Make lengths of the data the same
            DataLengths=cellfun(@length,app.AllPlotted_Data(:,1));
            if ~all(DataLengths == LengthTime)
                ShortLoc=find(DataLengths ~= LengthTime);
                for i=ShortLoc'
                    app.AllPlotted_Data{i,1}(end+1:end+(LengthTime-DataLengths(i)))=nan;
                end
            end


            hold(app.UIAxes,'off');


        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            %==============================================================
            %Select all trials in the Trial Select list box and plot them
            %==============================================================

            app.TrialSelectListBox.Value=app.TrialSelectListBox.Items;
            TrialSelectListBoxValueChanged(app, 1)


        end

        % Button pushed function: SelectAllCondButton
        function SelectAllCondButtonPushed(app, event)
            %==============================================================
            %Select all trials in the Condition Trials list box and plot them
            %==============================================================

            app.ConditionTrialsListBox.Value=app.ConditionTrialsListBox.Items;
            ConditionTrialsListBoxValueChanged(app, 1)

        end

        % Button pushed function: AddSelectedTrialsButton
        function AddSelectedTrialsButtonPushed(app, event)
            %==============================================================
            %Add selected trials to a group in conditions table (UITable)
            %Saves the data as well (Conditions_Data)
            %==============================================================


            %Uncheck Average button - averaging will be done to the condition data if needed
            app.AverageCheckBox.Value=0;
            AverageCheckBoxValueChanged(app,1);

            %Determine trials to be added
            TrialsToAdd= app.TrialSelectListBox.Value;

            %Convert to comma delimited character array
            TrialsToAdd=convert2commadel(TrialsToAdd,',');


            %Create group information
            %groupInfo=cell(1,7); %xyz and grid not implemented
            groupInfo=cell(1,3);
            groupInfo{1}=TrialsToAdd; %Trials


            %Prompt for group name
            if app.ChangeCurrentRow == 0

                if app.autoImportEvents == 0 %Don't update group name for Import Events button

                    GroupName=inputdlg('Enter group name:','Group Name',[1 35],{''});
                    if isempty(GroupName) %if cancel is hit, don't add trials
                        pause(0.2); %disappears quickly so it "flashes", added pause to reduce flash affect
                        figure(app.UIFigure)
                        return;

                    else
                        %Check for spaces
                        GroupName=GroupName{1};
                        if isempty(GroupName) %if Ok is hit without filling a name, leave the cell empty

                        else
                            GroupName=strrep(GroupName,' ','_');
                            if GroupName(1) == '_'
                                GroupName(1)=[];
                            end
                            if GroupName(end) == '_'
                                GroupName(end) = [];
                            end
                            groupInfo{2}=GroupName;
                        end

                    end

                else
                    GroupName=app.EventNameListBox.Items{app.ImportEventsNameL};
                    GroupName=strrep(GroupName,' ','_');
                    if GroupName(1) == '_'
                        GroupName(1)=[];
                    end
                    if GroupName(end) == '_'
                        GroupName(end) = [];
                    end
                    groupInfo{2}=GroupName;

                end

                groupInfo{3}=char(num2str(app.Ch));

                app.UITable.Data=[app.UITable.Data; groupInfo]; %update conditions table

                %Attach data
                numRows=length(app.UITable.Data(:,1)); %last row is the  newest row, so data is associated with that row
                CurrRow=numRows;

            elseif app.ChangeCurrentRow == 1 %A specific row in the table is being updated

                %Use info in the current row being changed
                groupInfo{2}=app.UITable.Data{app.CurrentRow,2};
                groupInfo{3}=app.UITable.Data{app.CurrentRow,3};
                app.UITable.Data(app.CurrentRow,:)=groupInfo;
                CurrRow=app.CurrentRow;
            end


            %Attach Data
            %Check if Data is all the same length
            % % LengthCheck=cellfun(@length, app.AllPlotted_Data(:,1));
            % % if ~all(LengthCheck == LengthCheck(1)) %if all the data isn't the same length
            % %     MaxLength=max(LengthCheck);
            % %     ShortLoc=find(LengthCheck ~= MaxLength);
            % %
            % % end

            app.Conditions_Data{CurrRow,1}=app.AllPlotted_Data(:,1); %data
            app.Conditions_Data{CurrRow,1}{1,2}=app.AllPlotted_Data{1,2}; %time
            app.Conditions_Data{CurrRow,2}=app.AllPlotted_Data{1,3};   %epoch type
            if length(app.AllPlotted_Data(1,:)) > 4
                app.Conditions_Data{CurrRow,3}=app.AllPlotted_Data(1,4:end); %more event details
            elseif length(app.AllPlotted_Data(1,:)) > 3
                app.Conditions_Data{CurrRow,3}=app.AllPlotted_Data{1,4};     %comment or event details
            end


        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)
            %==============================================================
            %Automatically creates rows for each of the items in the Event
            %Name List Box,
            %fills with all the trials for each Event Name
            %uses the current channel spinner value
            %==============================================================
            app.DefineConditionsPanel.Enable=0;
            pause(0.1);

            app.autoImportEvents=1; %for AddSelectedTrialsButtonPushed
            %Current Epoch Type
            switch app.EpochType
                case 1
                    %Check if data has been created
                    if isempty(app.Block)
                        msgbox('Create Block data first');
                        return
                    end

                    if app.DataType == 3 %Signal - as of 7/15/25 this is the only data type this would be needed for
                        NumGroups=app.numStates;
                        numTrialsList=zeros(1,NumGroups);
                        for e=1:NumGroups
                            numTrialsList(e)=size(app.Block.Data{e,1},2);
                        end
                    else
                        msgbox('Not applicable');
                        return;
                    end

                case 2
                    %Check if data has been created
                    if isempty(app.Comments)
                        msgbox('Create Comments data first');
                        return
                    end
                    NumGroups=length(app.numTrialsC);
                    numTrialsList=app.numTrialsC;

            end

            %Select and add trials for each Event Name
            app.ImportEventsNameL=1; app.StateV=0;
            for i=1:NumGroups      %for each "group" (item in Event Name list box)
                if app.DataType==3 %Signal states
                    app.StateV=app.StateV+1;
                end
                app.TrialSelectListBox.Items=string(1:numTrialsList(i));   %update items in ListBox

                app.TrialSelectListBox.Value=app.TrialSelectListBox.Items; %select them all
                TrialSelectListBoxValueChanged(app,1);                     %plot and update AllPlotted_Data
                AddSelectedTrialsButtonPushed(app,1)                       %Add Trials
                app.ImportEventsNameL=app.ImportEventsNameL+1;             %update which Event Name
            end
            if app.DataType == 3 %Signal
                app.StateV=1;
                app.EventNameListBox.Value=1;
            end

            app.autoImportEvents=0; %reset this variable
            app.DefineConditionsPanel.Enable=1;

        end

        % Button pushed function: AutoFillButton
        function AutoFillButtonPushed(app, event)
            %==============================================================
            %In the Conditions Table, add in the selected trials columns
            %trials in numerical order
            %The amount added is chosen by the user, AutoFillEditField
            %==============================================================
            Rows=sort(app.SelectedTableRows);
            for e=Rows
                TableData=app.UITable.Data{e,1};
                CurrentTrials=string(TableData);
                CurrentTrials=str2double(split(CurrentTrials,','))'; %convert character array to double vector
                AmountToAdd=app.AutoFillEditField.Value;             %Value in auto fill edit field
                FinalValue=CurrentTrials(end) + AmountToAdd;         %determine final value
                AddOn=string(CurrentTrials(end)+1:1:FinalValue);     %create string with values from the end of the current values to the final value
                AddOnC=cell(1,length(AddOn));                        %convert to cell
                for i=1:length(AddOn)
                    AddOnC{i}=char(AddOn(i));
                end

                %Convert to comma delimited
                TrialsToAdd=convert2commadel(AddOnC,',');

                NewTrialList=strcat(TableData,',',TrialsToAdd);
                app.UITable.Data{e,1}=NewTrialList;

                %Check channel number
                app.ChannelSpinner.Value = str2double(app.UITable.Data{e,3});   %update channel number of data
                ChannelSpinnerValueChanged(app,1);

                %Update Data
                app.TrialSelectListBox.Value=split(NewTrialList,',')';          %select them all
                TrialSelectListBoxValueChanged(app,1);                          %plot and update AllPlotted_Data
                app.ChangeCurrentRow=1;
                app.CurrentRow=e;
                AddSelectedTrialsButtonPushed(app,1)                            %Add Trials
                app.ChangeCurrentRow=0;
            end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %==============================================================
            %Update cell information using correct format
            %For Epoch column add commas between numbers
            %For Group Name column add underscores in whitespaces
            %==============================================================

            indices = event.Indices;       %Indices of table that are being edited
            editData = event.EditData;     %Updated data in indices
            PrevData = event.PreviousData; %Old data before updating

            TableColumn=indices(2);

            switch TableColumn
                case 1  %if Epochs column
                    if isempty(editData) %if the cell is edited to remove all trials,
                        app.UITable.Data(indices(1),indices(2))={PrevData}; %Don't update cell
                        return;
                    end
                    editData2=strrep(editData,' ',',');
                    if editData2(1) == ','
                        editData2(1)=[];
                    end
                    if editData2(end) == ','
                        editData2(end) = [];
                    end
                    NewTrialList=split(editData2,',')';

                    Low=find(cellfun(@str2double, NewTrialList) <= 0);
                    High=find(cellfun(@str2double, NewTrialList) > app.numTrials);
                    OutofBounds=[Low High];

                    if ~isempty(OutofBounds) %if there are any invalid trial numbers, only add valid ones
                        msgbox('There are invalid trial numbers listed. Only adding valid numbers.')
                        NewTrialList(OutofBounds)=[];
                        editData2=convert2commadel(NewTrialList,',');
                        app.UITable.Data(indices(1),indices(2))={editData2}; %update cell
                        return;
                    end

                    %Update Data
                    app.TrialSelectListBox.Value=NewTrialList;               %select the new trials
                    TrialSelectListBoxValueChanged(app,1);                   %plot and update AllPlotted_Data
                    app.ChangeCurrentRow=1;
                    app.CurrentRow=indices(1);
                    AddSelectedTrialsButtonPushed(app,1)                     %Add Trials
                    app.ChangeCurrentRow=0;

                    app.UITable.Data(indices(1), 3)={char(num2str(app.Ch))};

                case  2 %if group name
                    if isempty(editData)
                        app.UITable.Data(indices(1),indices(2))={PrevData};  %Don't update cell
                        return;
                    end
                    %fill spaces with underscores
                    editData2=strrep(editData,' ','_');
                    if editData2(1) == '_'
                        editData2(1)=[];
                    end
                    if editData2(end) == '_'
                        editData2(end) = [];
                    end
                case 3 %update channel
                    %Check trials
                    Trials=app.UITable.Data(indices(1),1);
                    if isempty(Trials{1}) || isempty(editData) || str2double(editData) > app.numChannels || str2double(editData) <= 0 %if there are no trials or if the new channel is blank,
                        app.UITable.Data(indices(1),indices(2))={PrevData}; %Don't update cell
                        return;
                    end


                    %Update plotted data
                    app.ChannelSpinner.Value=str2double(editData);
                    ChannelSpinnerValueChanged(app, 1);                     %Change channel

                    %check for trials outside of range
                    TableTrials=split(app.UITable.Data(indices(1),1),',')';
                    TrialSelectItems=string(app.TrialSelectListBox.Items); e=1;
                    for i=1:length(TableTrials)
                        TrialNum=string(TableTrials{i});
                        Present=TrialSelectItems == TrialNum;
                        if sum(Present) == 1
                            %The trial list contains this number
                            NewTrialList{e}=char(TrialNum);
                            e=e+1;
                        end
                    end

                    %Not all the trials were in the Trial select
                    if length(NewTrialList) ~= length(string(TableTrials)) %If there were invalid trials they are removed, there will be a difference in the length of the Trial list
                        %Prompt for whether to continue with new data, or to cancel and switch Event Name
                        K=questdlg('There are invalid trial numbers. Please check if the correct Event under Event Names is selected. If so, select continue and only valid trial numbers will be added/updated. If not, select cancel to change the Event Name.','Check Event Name','Continue','Cancel','Cancel');
                        switch K
                            case 'Continue'
                                app.UITable.Data(indices(1),1)={convert2commadel(NewTrialList,',')}; %update table
                            case 'Cancel'
                                app.UITable.Data(indices(1),indices(2))={PrevData}; %Don't update cell
                                return;

                        end
                    end


                    app.TrialSelectListBox.Value=split(app.UITable.Data(indices(1),1),',')';    %select trials
                    TrialSelectListBoxValueChanged(app,1);                                      %plot and update AllPlotted_Data
                    app.ChangeCurrentRow=1;
                    app.CurrentRow=indices(1);
                    AddSelectedTrialsButtonPushed(app,1)                                        %Add Trials
                    app.ChangeCurrentRow=0;

                    editData2=editData;


                otherwise
                    editData2=editData;
            end

            %Update table
            app.UITable.Data(indices(1),indices(2))={editData2};


        end

        % Button pushed function: AddBlankRowButton
        function AddBlankRowButtonPushed(app, event)
            %==============================================================
            %Add a blank row of data to conditions table
            %==============================================================
            groupInfo = cell(1,3);  %xyz and grid not implemented
            app.UITable.Data = [app.UITable.Data;groupInfo];
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %==============================================================
            %Highlight row selected, unhighlight row deselected
            %==============================================================

            indices = event.Indices;
            if isempty(indices)
                return
            end
            TableRow=indices(1); %row of selected cell

            if isempty(find(app.SelectedTableRows == TableRow, 1))
                s = uistyle('BackgroundColor','#a09fa1'); %Light #d8d7d9 #c6c5c7 #a09fa1 Dark
                addStyle(app.UITable,s,'row',TableRow);  %Highlight row
                app.SelectedTableRows(end+1)=TableRow;   %Add row to list of selected rows

            else %if value is selected already, deselect
                s = uistyle('BackgroundColor','white');
                addStyle(app.UITable,s,'row',TableRow); %Unhighlight row
                Row=find(app.SelectedTableRows == TableRow, 1); %locate row in vector
                app.SelectedTableRows(Row)=[]; %remove row from list
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %==============================================================
            %Clear Conditions table and Conditions_Data
            %==============================================================
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'table','');
            app.SelectedTableRows=[]; %Clear selected rows
            app.UITable.Data = [];
            app.Conditions_Data=cell(1,3); %xyz and grid not implemented
            app.SheetNameEditField.Value='';
        end

        % Button pushed function: KeeporDeleteRowsButton
        function KeeporDeleteRowsButtonPushed(app, event)
            %==============================================================
            %Keep or delete selected rows in the Conditions Table
            %Prompts user if they want to keep or delete the selected rows
            %==============================================================
            K=questdlg('Keep or delete selected row(s)?','Keep or Delete','Keep','Delete','Keep');

            %Save current table and data for Recover option
            app.DeletedRows.Table=app.UITable.Data;
            app.DeletedRows.Data=app.Conditions_Data;
            app.RecoverButton.Enable=1;

            %Determine which rows to delete
            switch K
                case 'Keep'
                    AllRows=1:length(app.UITable.Data(:,1));
                    %determine which rows to delete
                    DeleteRows=AllRows;
                    for i=app.SelectedTableRows
                        DeleteRows=DeleteRows(DeleteRows ~= i);
                    end
                case 'Delete'
                    %determine which rows to delete
                    DeleteRows=app.SelectedTableRows;
            end

            %Delete rows from table and data
            e=0;
            for i=DeleteRows
                app.UITable.Data(i-e,:)=[];
                if ~isempty(app.Conditions_Data) %if there is data, delete rows from data
                    app.Conditions_Data(i-e,:)=[];
                end
                e=e+1;
            end
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s);
            app.SelectedTableRows=[]; %Clear selected rows
        end

        % Button pushed function: RecoverButton
        function RecoverButtonPushed(app, event)
            %==============================================================
            %Undoes the most recent keep or delete action
            %app.DeletedRows variable is updated when rows are deleted in
            %KeeporDeleteRowsButtonPushed function
            %It is a copy of the table and data before deleting the rows
            %==============================================================

            %Replace table and data with old table and data
            app.UITable.Data=app.DeletedRows.Table;
            app.Conditions_Data=app.DeletedRows.Data;
            app.DeletedRows=struct();   %reset DeleteedRows variable
            app.RecoverButton.Enable=0; %disable recover button

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %==============================================================
            %Save condition file
            %==============================================================
            %check for blanks in rows
            if any(any(cellfun(@isempty,app.UITable.Data)))
                msgbox('There are empty cells in Condition Table. Please fill these in.');
                return;
            end

            if string(class(event)) == "matlab.ui.eventdata.ButtonPushedData" %button was pushed
                %Apply matrix
                ApplyButtonPushed(app, 1);

                %Define data for saving
                ConditionsMatrix=app.UITable.Data;
                ChTitles=app.Block.Titles;
                SampleRate=app.AllSampleRate;
                FileName=app.Filename;
                numTrialsperCondition=app.numTrialsCondition;
                AllData=app.Conditions_DataAll;
                EventInfo=app.Conditions_Data(:,2:end);

                %Prompt for file name, no need to add .mat at end of filename
                SavedCondFName=inputdlg('Enter filename:','Save Conditions File',[1 35]);
                if isempty(SavedCondFName)
                    return;
                end

            elseif string(class(event)) == "char" %called from override group name and a condition file was loaded in
                %Prompt for file name, no need to add .mat at end of filename
                BaseName=app.Filename{1}; %use first condition file name
                BaseName=BaseName(1:end-4);
                SavedCondFName=inputdlg('Enter filename:','Save Conditions File',[1 35],{[BaseName '_NewGroupNames']});
                if isempty(SavedCondFName)
                    return;
                end

                %Define data for saving
                ConditionsMatrix=app.LoadedConditions.ConditionsMatrix;
                ConditionsMatrix(:,2)=app.UITable.Data(:,2);
                AllData=app.LoadedConditions.AllData;
                numTrialsperCondition=app.LoadedConditions.numTrialsperCondition;
                ChTitles=app.LoadedConditions.ChTitles;
                SampleRate=app.LoadedConditions.SampleRate;
                EventInfo='Combined existing condition files, see the original condition files for the Event Info'; %see the previous existing Condition Files for the Event Info
                FileName=app.LoadedConditions.FileName;

            end


            %Check if .mat was included in the filename
            if sum(SavedCondFName{1,1}(end-3:end) == '.mat') == 4
                SavedCondFName{1,1}(end-3:end)='';
            end

            %check if "Conditions" folder exists if not, create it
            ConditionsFolderDir=fullfile(app.Directory.SaveFolder, 'Conditions');
            if exist(ConditionsFolderDir,'dir') ~= 7 %7 folder exists
                mkdir(ConditionsFolderDir);

            end

            %Disable panel to indicate loading
            app.DefineConditionsPanel.Enable=0;
            pause(0.1)
            save(fullfile(ConditionsFolderDir, [SavedCondFName{1, 1} '.mat']),'ConditionsMatrix','AllData','numTrialsperCondition','ChTitles','SampleRate','EventInfo','FileName');
            pause(0.1)
            %Enable panel to indicate saving is done
            app.DefineConditionsPanel.Enable=1;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %==============================================================
            %Apply current conditions table to current loaded data
            %==============================================================

            %check for blanks in rows
            if (isempty(app.UITable.Data)) || any(any(cellfun(@isempty,app.UITable.Data)))
                msgbox('There are empty cells in Condition Table. Please fill these in.');
                return;
            end

            %Uncheck average box
            app.AverageCheckBox.Value=0;
            if app.EventNameListBox.Visible == 1 %reset to first Event name items, if Import Events is used the last selections are the last manually selected Event Name and all the trials of the last Event Name item
                app.EventNameListBox.Value=app.EventNameListBox.ItemsData(1); %select first Event Name
                EventNameListBoxValueChanged(app,1);
            end
            AverageCheckBoxValueChanged(app,1);

            %Make all data same length
            numConditions=length(app.Conditions_Data(:,1));
            TimeRanges=zeros(numConditions,2);
            for i=1:numConditions
                TimeRanges(i,1)=app.Conditions_Data{i}{1,2}(1);
                TimeRanges(i,2)=app.Conditions_Data{i}{1,2}(end);
            end

            %Determine max time length, and which condition has this max length
            minTimeRange=min(TimeRanges(:,1));
            maxTimeRange=max(TimeRanges(:,2));

            %create time base on the smallest and largest time
            newTime=minTimeRange:1/app.AllSampleRate:maxTimeRange;

            newConditions_Data=cell(numConditions,length(app.Conditions_Data(1,:))+1);
            newConditions_Data(:,3:end)=app.Conditions_Data(:,2:end);
            newConditions_Data{1,2}=newTime;
            for i=1:numConditions
                Data=app.Conditions_Data{i}(:,1);
                DataTime=app.Conditions_Data{i}{1,2};
                %check if time already matches new Time
                if (length(DataTime) ~= length(newTime)) || all(DataTime ~= newTime) %if time doesn't match newTime
                    %find start index
                    [~,StartIndx]=min(abs(newTime - DataTime(1)));
                    [~,EndIndx]=min(abs(newTime - DataTime(end)));
                    DataMat=cell2mat(Data);
                    newData=[nan(length(DataMat(:,1)),StartIndx-1) DataMat nan(length(DataMat(:,1)),length(newTime)-EndIndx)];
                    newConditions_Data{i,1}=newData;

                else
                    newConditions_Data{i,1}=cell2mat(Data);


                end

            end

            app.Conditions_DataAll{1,1}=cell2mat(newConditions_Data(:,1));
            app.Conditions_DataAll{1,2}=newTime;

            %Create list of all Conditions Trials
            [numTrialsperCondition, ~]=cellfun(@size, app.Conditions_Data(:,1), 'UniformOutput', false);
            app.numTrialsCondition=zeros(length(numTrialsperCondition),3);
            app.numTrialsCondition(:,1)=cell2mat(numTrialsperCondition);
            CStart=1;
            for i=1:length(app.numTrialsCondition(:,1)) %for each trial in each condition
                CEnd=CStart+app.numTrialsCondition(i,1)-1;
                app.numTrialsCondition(i,2)=CStart;
                app.numTrialsCondition(i,3)=CEnd;
                %app.numTrialsCondition(i,4)=app.AllSampleRate;
                CStart=CEnd+1;
            end

            %Create LoadedConditions
            app.LoadedConditions=struct();
            app.LoadedConditions.ConditionsMatrix=app.UITable.Data;
            app.LoadedConditions.ConditionsData=app.Conditions_Data;
            app.LoadedConditions.ChTitles=app.Block.Titles;
            app.LoadedConditions.SampleRate=app.LoadedConditions;
            app.LoadedConditions.FileName=app.Filename;

            %Open Conditions List Box
            app.TrialSelectListBox.Visible=1; app.TrialSelectListBoxLabel.Visible=1;
            app.ConditionTrialsListBox.Visible=1; app.ConditionTrialsLabel.Visible=1;
            app.SelectAllCondButton.Visible=1;
            app.LeftArrowAnalyzeButton.Visible=1;
            app.RightArrowAnalyzeButton.Visible=1;
            app.SButton.Visible=1;
            app.ConditionsListBox.Visible=1; app.ConditionsListBoxLabel.Visible=1;
            app.ProcessButton.Enable=1; app.AnalyzeButton.Enable=1; app.OverrideButton.Enable=1; app.NDButton.Enable=1; %Enable processing and analysis

            %Auto select first condition
            %Fill Conditions List box
            app.ConditionsListBox.Items=app.UITable.Data(:,2);          %List Group Names
            app.ConditionsListBox.ItemsData=app.ConditionsListBox.Items;

            %check for duplicate names
            ItemsData=string(app.ConditionsListBox.ItemsData);
            for i=1:length(ItemsData) %for each item
                Copies=ItemsData(i) == ItemsData; %check for copies
                if sum(Copies) ~= 1 %if there are copies,
                    CopyLoc=find(Copies==1); %find the locations of all copies
                    for e=2:length(CopyLoc)  %for each copy after the first
                        ItemsData(CopyLoc(e)) = strcat(ItemsData(CopyLoc(e)), num2str(e)); %rename the copy
                    end
                end

            end
            app.ConditionsListBox.ItemsData=ItemsData;


            app.ConditionsListBox.Value=app.ConditionsListBox.ItemsData(1); %Initial selection is the first condition
            ConditionsListBoxValueChanged(app,1); %plot condition


        end

        % Button pushed function: LoadConditionsButton
        function LoadConditionsButtonPushed(app, event)
            %==============================================================
            %As of 12/3/25 Disabled
            %Load in a saved Conditions file
            %Fill in the Conditions table with saved matrix
            %Apply new matrix to current loaded data, if there is no loaded
            %data, apply matrix to table only
            %==============================================================
            [app.Filename, app.Path] = uigetfile(app.Directory.SaveFolder,'multiSelect','on');
            figure(app.UIFigure)            %focus back to app
            if isequal(app.Filename,0)      %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            app.DefineConditionsPanel.Enable=0;
            pause(0.1)

            %check if there is only one file
            if iscell(app.Filename) == 0
                TempFilename=app.Filename;
                app.Filename=cell(1,1);
                app.Filename{1,1}=TempFilename;
            end

            %load files
            for i=1:length(app.Filename)
                Conditions= load(fullfile(app.Path, app.Filename{1,i}));
                app.UITable.Data=[app.UITable.Data; Conditions.ConditionsMatrix];                  %Only load in matrix data

            end

            %Apply new matrix to current data
            if ~isempty(app.Block)  %if there is data loaded
                numConditions=length(app.UITable.Data(:,1));
                emptyRow=[];
                for i=1:numConditions %for each condition/row
                    TrialsToAdd=app.UITable.Data{i,1};

                    %Update Data
                    if str2double(app.UITable.Data{i,3}) > app.numChannels
                        app.ChannelSpinner.Value=app.numChannels;
                        app.UITable.Data{i,3}=num2str(app.numChannels);
                    else
                        app.ChannelSpinner.Value = str2double(app.UITable.Data{i,3});
                    end
                    ChannelSpinnerValueChanged(app,1);

                    %check for trial numbers outstide of range
                    MaxTrial=str2double(app.TrialSelectListBox.Items{end});
                    TrialsToAddDouble=str2double(split(TrialsToAdd,',')');
                    TrialsToAddDouble(TrialsToAddDouble > MaxTrial)=[];
                    if ~isempty(TrialsToAddDouble)
                        TrialsToAddNew=cellstr(string(TrialsToAddDouble));
                        TrialsToAdd=convert2commadel(TrialsToAddNew,',');

                        app.TrialSelectListBox.Value=split(TrialsToAdd,',')';      %select trials
                        TrialSelectListBoxValueChanged(app,1);                     %plot and update AllPlotted_Data
                        app.ChangeCurrentRow=1;
                        app.CurrentRow=i;
                        AddSelectedTrialsButtonPushed(app,1)                       %Add Trials
                        app.ChangeCurrentRow=0;
                    else
                        %if all trial numbers are out of range
                        app.UITable.Data{i,1}='';       %no trials
                        app.UITable.Data{i,3}='';       %no channel
                        app.Conditions_Data(i,:)=cell(1,3); %empty data
                        emptyRow=[emptyRow i];
                    end

                end %end update each condition row

                %check for empty rows
                if ~isempty(emptyRow)
                    e=0;
                    for i=emptyRow
                        %Remove empty rows
                        app.UITable.Data(i-e,:)=[];
                        app.Conditions_Data(i-e,:)=[];
                        e=e+1;
                    end
                end
            end

            pause(0.1)
            app.DefineConditionsPanel.Enable=1;


        end

        % Button pushed function: UploadExcelFileButton
        function UploadExcelFileButtonPushed(app, event)
            %==============================================================
            %Upload excel File that contains Conditions matrix
            %Column data is separated by * and all data is tab delimited
            %==============================================================

            %Import excel file
            [app.Filename, app.Path] = uigetfile('*.xlsx', 'Select Excel file',app.Directory.DataFolder);
            figure(app.UIFigure)            %focus back to app
            if isequal(app.Filename,0)      %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            app.DefineConditionsPanel.Enable=0;
            pause(0.01);

            CurrentExcelFile=fullfile(app.Path,app.Filename);

            T=readcell(CurrentExcelFile,'Sheet','DefineCondition');

            %Base Name
            if length(T(:,1)) < 2 %the whole second row is empty
                BaseNameChar='';
            else %if there is at least one thing in the second row
                BaseNameList=T(2,:);
                Classes=string(cellfun(@class,BaseNameList,'UniformOutput',false)); %classes of cells
                BaseNameList=BaseNameList(Classes ~= "missing"); %remove empty spaces
                BaseNameChar=char(convert2commadel(BaseNameList,'_'))';
                BaseNameChar=reshape(BaseNameChar,1,[]); %reformat
                BaseNameChar=BaseNameChar(BaseNameChar ~= ' '); %remove spaces

            end

            SheetNamesAll=sheetnames(CurrentExcelFile);
            SheetNames=char(SheetNamesAll(2:end,:));

            %Select which sheet to apply
            if length(SheetNamesAll) > 2 %more one cond sheet, assume DefineConditons still present
                %Create figure with drop down for sheet selection
                %figure
                SheetNameDropDownFig=uifigure();
                SheetNameDropDownFig.Position(3:4)=[350 400];
                movegui(SheetNameDropDownFig,'center');
                %label
                lbl=uilabel(SheetNameDropDownFig,'Text','Select Sheet Name','FontWeight', 'bold');
                lbl.FontSize=16;
                lbl.Position=[100 375 150 20];
                %dropdown
                SheetNameDropDown=uidropdown(SheetNameDropDownFig);
                SheetNameDropDown.Position=[75 345 200 20];
                SheetNameDropDown.Items={'Select'};

                %Fill with sheet names
                Items=SheetNameDropDown.Items;
                for i=1:size(SheetNames,1)
                    Items{i+1}=SheetNames(i,:);
                end
                SheetNameDropDown.Items = Items;

                %button
                SheetNameButton=uibutton(SheetNameDropDownFig,'Text','Confirm','ButtonPushedFcn',@(btn,event) Confirm2ButtonPushed(SheetNameDropDownFig,app,SheetNameDropDown));
                SheetNameButton.Position=[125 315 100 20];

                %wait for selection to be made
                uiwait(SheetNameDropDownFig);
                app.SheetNameEditField.Value=app.SelectedSheet;
                WhichSheet=find(string(SheetNames) == string(app.SelectedSheet));
            else
                app.SheetNameEditField.Value=SheetNames(1,:);
                WhichSheet=1;
            end


            CurrSheet=SheetNames(WhichSheet,:);
            CurrSheet(CurrSheet == ' ')=[];

            CurrSheet_Data=readcell(CurrentExcelFile,'Sheet',CurrSheet);
            numRows=length(CurrSheet_Data(:,1));
            e=1;
            for i2=1:numRows
                %Group Name
                Row=string(CurrSheet_Data(i2,:));
                AstLoc=find(Row == "*");
                %Second asterisk denotes start of group name, third asterisk denotes end of group name
                GroupNameCell=CurrSheet_Data(i2,AstLoc(2)+1:AstLoc(3)-1);
                GroupName=convert2commadel(GroupNameCell,'_');

                %Check for double data type
                for i3=1:length(GroupName)
                    Value=GroupName{i3};
                    if ~ischar(Value)
                        Value2=num2str(Value);
                        GroupName{i3}=Value2;
                    end
                end

                GroupName=reshape(char(GroupName)',1,[]);
                GroupName(GroupName ==' ')=[];
                if isempty(BaseNameChar)
                    CondTable{e,2}=GroupName;
                else
                    CondTable{e,2}=[BaseNameChar '_' GroupName];
                end
                app.ConditionGroupNames{e,1}=GroupName;

                %Trials to add
                TrialstoAdd=CurrSheet_Data(i2,AstLoc(1)+1:AstLoc(2)-1);
                for i4=1:length(TrialstoAdd)
                    if ismissing(TrialstoAdd{1,i4})
                        TrialstoAdd{1,i4}='';
                    end
                    TrialstoAdd{1,i4}=num2str(TrialstoAdd{1,i4}); %convert numbers to characters
                end
                %remove empty cells
                Empty=cellfun(@isempty, TrialstoAdd);
                TrialstoAdd=TrialstoAdd(~Empty);

                TrialstoAdd=convert2commadel(TrialstoAdd,',');
                CondTable{e,1}=TrialstoAdd;

                %Channel number
                ChannelNum=CurrSheet_Data(i2,AstLoc(3)+1);
                CondTable{e,3}=num2str(ChannelNum{1,1});

                e=e+1;
            end %end for numRows


            %Define data organization for conditions loaded
            %figure
            EpochTypeFig=uifigure();
            EpochTypeFig.Position(3:4)=[1337 400];
            movegui(EpochTypeFig,'center'); 

            %Header
            Header1=uilabel(EpochTypeFig, 'Text', 'Condition', 'FontWeight', 'bold');
            Header1.Position=[20 365 300 50];
            Header2=uilabel(EpochTypeFig, 'Text', 'Epoch Type', 'FontWeight', 'bold');
            Header2.Position=[332 365 100 50];
            Header3=uilabel(EpochTypeFig, 'Text', 'Pre (ms)', 'FontWeight', 'bold');
            Header3.Position=[440 365 100 50];
            Header4=uilabel(EpochTypeFig, 'Text', 'Post (ms)', 'FontWeight', 'bold');
            Header4.Position=[502 365 100 50];
            Header5=uilabel(EpochTypeFig, 'Text', 'Comment Text', 'FontWeight', 'bold');
            Header5.Position=[570 365 100 50];
            Header6=uilabel(EpochTypeFig, 'Text', 'Detection Settings ', 'FontWeight', 'bold');
            Header6.Position=[698 365 200 50];
            Header7=uilabel(EpochTypeFig, 'Text', 'Event Channel', 'FontWeight', 'bold');
            Header7.Position=[840 365 100 50];
            Header8=uilabel(EpochTypeFig, 'Text', 'Event Threshold (mV)', 'FontWeight', 'bold');
            Header8.Position=[948 365 150 50];
            Header9=uilabel(EpochTypeFig, 'Text', 'Event ISI (s)', 'FontWeight', 'bold');
            Header9.Position=[1089 365 100 50];

            %Epoch type panel
            Pan=uipanel(EpochTypeFig);
            Pan.Position=[10 40 1180 340];
            nRows=length(app.ConditionGroupNames);
            GridL=uigridlayout(Pan, [nRows, 9],'Scrollable','on');
            GridL.ColumnWidth = {300, 100, 52, 52, 100, 150, 100, 130, 75};
            GridL.RowHeight = repmat({'fit'}, 1, nRows);

            EpochTypeInputs = gobjects(nRows,1);
            PreDurInputs = gobjects(nRows,1);
            PostDurInputs = gobjects(nRows,1);
            CommentTextInputs = gobjects(nRows,1);
            DetectSetInputs = gobjects(nRows,1);
            EventChInputs = gobjects(nRows,1);
            EventThreshInputs = gobjects(nRows,1);
            EventISIInputs = gobjects(nRows,1);

            %first column: conditions
            for i=1:nRows
                %Group name
                CondList=uilabel(GridL);
                CondList.Text=app.ConditionGroupNames{i,1};
                CondList.Layout.Row = i; CondList.Layout.Column = 1;

                %Epoch Type dropdown
                EpochTypeDropDown=uidropdown(GridL);
                EpochTypeDropDown.Items={'Blocks','Comments','Events'};
                EpochTypeDropDown.Value=app.EpochTypesButtonGroup.SelectedObject.Text;          %Auto set to what is in the "Data Organization" tab
                EpochTypeDropDown.Layout.Row = i; EpochTypeDropDown.Layout.Column = 2;
                EpochTypeInputs(i)=EpochTypeDropDown;

                %Check with epoch type is used for pre and post values, for default values
                if EpochTypeDropDown.Value == "Blocks"
                    PreValue=app.PreTriggermsEditField.Value;
                    PostValue=app.PostTriggermsEditField.Value;
                else %Comments or events
                    PreValue=app.PreDurationsEditField.Value;
                    PostValue=app.PostDurationsEditField.Value;
                end

                %PreDur
                PreDurEditField=uieditfield(GridL,'numeric','Value',PreValue,'Limits',[0 Inf]);  %Auto set to what is in the "Data Organization" tab
                PreDurEditField.Layout.Row = i; PreDurEditField.Layout.Column = 3;
                PreDurInputs(i)=PreDurEditField;

                %PostDur
                PostDurEditField=uieditfield(GridL,'numeric','Value',PostValue,'Limits',[0 Inf]); %Auto set to what is in the "Data Organization" tab
                PostDurEditField.Layout.Row = i; PostDurEditField.Layout.Column = 4;
                PostDurInputs(i)=PostDurEditField;

                %Comment Text
                CommentTextDropDown=uidropdown(GridL);
                if app.ComList == ""
                    CommentTextDropDown.Items={''};
                else
                    ComListCell=cell(1,size(app.ComList,1));
                    for ComLF=1:size(app.ComList,1)
                        ComListCell{ComLF}=char(app.ComList(ComLF,:));
                    end
                    CommentTextDropDown.Items=ComListCell;
                end
                if EpochTypeDropDown.Value == "Comments"
                    CommentTextDropDown.Value=app.EventNameListBox.Value;
                end
                CommentTextDropDown.Layout.Row = i; CommentTextDropDown.Layout.Column = 5;
                CommentTextDropDown.Visible=0;
                CommentTextInputs(i)=CommentTextDropDown;


                %Event Detection Settings
                DetectSetDropDown=uidropdown(GridL);
                DetectSetDropDown.Items={'Threshold Crossing'}; %!!! add callback to change following parameters based on future detection setting methods
                DetectSetDropDown.Layout.Row = i; DetectSetDropDown.Layout.Column = 6;
                DetectSetDropDown.Visible=0;
                DetectSetInputs(i)=DetectSetDropDown;


                %Event Channel
                EventChEditField=uieditfield(GridL,'numeric','Value',app.EventChannelEditField.Value,'Limits',[1 app.numChannels]);
                EventChEditField.Layout.Row = i; EventChEditField.Layout.Column = 7;
                EventChEditField.Visible=0;
                EventChInputs(i)=EventChEditField;


                %Event Threshold
                EventThreshEditField=uieditfield(GridL,'numeric','Value',app.ThresholdmVEditField.Value,'Limits',[-Inf Inf]);
                EventThreshEditField.Layout.Row = i; EventThreshEditField.Layout.Column = 8;
                EventThreshEditField.Visible=0;

                EventThreshInputs(i)=EventThreshEditField;

                %Event ISI
                EventISIEditField=uieditfield(GridL,'numeric','Value',app.SearchISIsEditField.Value,'Limits',[0 Inf]);
                EventISIEditField.Layout.Row = i; EventISIEditField.Layout.Column = 9;
                EventISIEditField.Visible=0;
                EventISIInputs(i)=EventISIEditField;


            end

            %Consolidate graphic objects and headers
            AllInputs=[EpochTypeInputs,CommentTextInputs,DetectSetInputs,EventChInputs,EventThreshInputs,EventISIInputs,PreDurInputs,PostDurInputs];

            %add callback function for Epoch Type Dropdown
            for i=1:length(AllInputs(:,1))
                AllInputs(i,1).ValueChangedFcn=@(src,ev) EpochTypeChanged(i,AllInputs);
                EpochTypeChanged(i,AllInputs)
            end

            app.AllInputsCell=[];
            %add confirm button
            EpochTypeButton=uibutton(EpochTypeFig,'Text','Confirm','ButtonPushedFcn',@(btn,event) Confirm3ButtonButtonPushed(AllInputs,app.ConditionGroupNames));
            EpochTypeButton.Position=[332 10 100 20];

            uiwait(EpochTypeFig);

            figure(app.UIFigure);
            if isempty(app.AllInputsCell)
                msgbox('Canceled Action')
                app.DefineConditionsPanel.Enable=1;
                return;
            end

            app.UITable.Data=[app.UITable.Data; CondTable];

            %Apply to Data
            app.DisableLI_FindEvents=1; %Disable loading indicator for Find Events

            %number of current conditions in table
            if length(app.Conditions_Data(:,1)) == 1 &&  isempty(app.Conditions_Data{1}) %Cleared table
                CurrentRowinTable=1;
            else
                CurrentRowinTable=length(app.Conditions_Data(:,1))+1;
            end
            for i=1:length(app.ConditionGroupNames)

                CurrRow=app.AllInputsCell(i,:);
                CurrEpochType=CurrRow{2};

                switch CurrEpochType
                    case 'Block'
                        %Update Epoch Types display
                        app.BlocksButton.Value=1; app.EpochType=1;
                        EpochTypesButtonGroupSelectionChanged(app,1);
                        %set pre/post trigger time
                        app.PreTriggermsEditField.Value=CurrRow{3};
                        app.PostTriggermsEditField.Value=CurrRow{4};
                        PreTriggermsEditFieldValueChanged(app,1);
                        PostTriggermsEditFieldValueChanged(app,1);

                    case 'Comments'
                        %Update Epoch Types display
                        app.CommentsButton.Value=1; app.EpochType=2;
                        EpochTypesButtonGroupSelectionChanged(app,1);
                        FindEventsButtonPushed(app,1);

                        %Set Comment Text
                        app.EventNameListBox.Value=string(CurrRow{5}); %select chosen comment text
                        EventNameListBoxValueChanged(app,1);

                        %Set pre/post duration
                        app.PreDurationsEditField.Value=CurrRow{3};
                        app.PostDurationsEditField.Value=CurrRow{4};
                        PreDurationsEditFieldValueChanged(app,1);
                        PostDurationsEditFieldValueChanged(app,1);

                    case 'Events'
                        %Update Epoch Types display
                        app.EventsButton.Value=1; app.EpochType=3;
                        EpochTypesButtonGroupSelectionChanged(app,1);

                        %Set Event Detection Settings and parameters
                        app.DetectionSettingsDropDown.Value=CurrRow{6};
                        app.EventChannelEditField.Value=CurrRow{7};
                        app.ThresholdmVEditField.Value=CurrRow{8};
                        app.SearchISIsEditField.Value=CurrRow{9};
                        DetectionSettingsDropDownValueChanged(app,1);
                        EventChannelEditFieldValueChanged(app,1);
                        ThresholdmVEditFieldValueChanged(app,1);
                        SearchISIsEditFieldValueChanged(app,1);

                        %Set pre/post duration
                        app.PreDurationsEditField.Value=CurrRow{3};
                        app.PostDurationsEditField.Value=CurrRow{4};
                        PreDurationsEditFieldValueChanged(app,1);
                        PostDurationsEditFieldValueChanged(app,1);

                end

                %Apply setttings
                app.DisableLI_FindEvents=1;
                FindEventsButtonPushed(app,1);
                app.ChannelSpinner.Value = str2double(app.UITable.Data{CurrentRowinTable,3});
                ChannelSpinnerValueChanged(app, 1);

                %Update Data and add select trials
                NewTrialList=app.UITable.Data{CurrentRowinTable,1};
                NewTrials=split(NewTrialList,',');
                if any(~ismember(string(NewTrials),string(app.TrialSelectListBox.Items))) %if there are any values that aren't in the list
                    msgbox('Error: There are trials in excel that are not in data');
                    app.UITable.Data = [];
                    app.DefineConditionsPanel.Enable=1;
                    return;
                end

                app.TrialSelectListBox.Value=split(NewTrialList,',');      %select the new trials
                TrialSelectListBoxValueChanged(app,1);                     %plot and update AllPlotted_Data
                app.ChangeCurrentRow=1;
                app.CurrentRow=CurrentRowinTable;
                AddSelectedTrialsButtonPushed(app,1)                       %Add Trials
                app.ChangeCurrentRow=0;

                CurrentRowinTable=CurrentRowinTable+1;

            end

            app.DisableLI_FindEvents=0;
            pause(0.01);
            app.DefineConditionsPanel.Enable=1;


            %callback functions============================================
            function Confirm2ButtonPushed(SheetNameDropDownFig,app,SheetNameDropDown)
                app.SelectedSheet=SheetNameDropDown.Value;
                close(SheetNameDropDownFig);
            end

            function Confirm3ButtonButtonPushed(AllInputs,ConditionGroupNames)
                nRows=length(AllInputs(:,1));

                %Format parameters to cell
                app.AllInputsCell=cell(nRows,9);
                for w=1:nRows
                    app.AllInputsCell{w,1}=ConditionGroupNames{w};   %Condition
                    app.AllInputsCell{w,2}=AllInputs(w,1).Value;     %Epoch Type
                    app.AllInputsCell{w,3}=AllInputs(w,7).Value;  %Pred Dur
                    app.AllInputsCell{w,4}=AllInputs(w,8).Value; %Post Dur
                    app.AllInputsCell{w,5}=AllInputs(w,2).Value;     %Comment text
                    app.AllInputsCell{w,6}=AllInputs(w,3).Value;     %Detection settings
                    app.AllInputsCell{w,7}=AllInputs(w,4).Value;     %Event Channel
                    app.AllInputsCell{w,8}=AllInputs(w,5).Value;     %Event Threshold
                    app.AllInputsCell{w,9}=AllInputs(w,6).Value;     %Event ISI

                end

                close(EpochTypeFig);

            end

            function EpochTypeChanged(e,AllInputs)
                %For reference: AllInputs=[EpochTypeInputs,CommentTextInputs,DetectSetInputs,EventChInputs,EventThreshInputs,EventISIInputs,PreDurInputs,PostDurInputs];
                CurrEpochType=AllInputs(e,1).Value;
                switch CurrEpochType
                    case 'Comments'
                        AllInputs(e,2).Visible=1;      %make Commment text dropdown visible

                        %Hide event headers and parameters
                        AllInputs(e,3).Visible=0;
                        AllInputs(e,4).Visible=0;
                        AllInputs(e,5).Visible=0;
                        AllInputs(e,6).Visible=0;

                    case 'Events'
                        %Display event headers and parameters
                        AllInputs(e,3).Visible=1;
                        AllInputs(e,4).Visible=1;
                        AllInputs(e,5).Visible=1;
                        AllInputs(e,6).Visible=1;

                        %Hide Comment text header and parameter
                        AllInputs(e,2).Visible=0;
                    case 'Block'
                        %Hide Comment text header and parameter
                        AllInputs(e,2).Visible=0;

                        %Hide event headers and parameters
                        AllInputs(e,3).Visible=0;
                        AllInputs(e,4).Visible=0;
                        AllInputs(e,5).Visible=0;
                        AllInputs(e,6).Visible=0;
                end
            end

        end

        % Button pushed function: OverrideGroupNameButton
        function OverrideGroupNameButtonPushed(app, event)
            %==============================================================
            %Override the group names in the conditions table
            %==============================================================

            %Import excel file
            [file,path] = uigetfile({'*.xlsx;*.xls;*.csv','Spreadsheet Files (*.xlsx, *.xls, *.csv)'}, ...
                'Select group name file',app.Directory.DataFolder);
            figure(app.UIFigure)            %focus back to app
            if isequal(file,0)      %if cancel is chosen return to app
                msgbox('No File Chosen');
                return
            end
            app.OverrideGroupNameEditField.Value=file;
            File=fullfile(path,file);

            NewNames=readcell(File);
            
         
            %If file has one extra row, assume header and drop first row
            nTable = size(app.UITable.Data,1);
            if size(NewNames,1) > nTable %== nTable+1
                NewNames(1,:)=[];
            end
            %ConditionFileGroupNames=app.UITable.Data(:,2);

            NewNamesCat=cell(size(NewNames,1),1);
            for i2=1:size(NewNames,1)
                GroupNameCell=NewNames(i2,:);

                %remove empty spaces
                Classes=string(cellfun(@class,GroupNameCell,'UniformOutput',false));  %classes of cells
                GroupNameCell=GroupNameCell(Classes ~= "missing");
                GroupNameCell=GroupNameCell(~cellfun(@isempty,GroupNameCell));

                if isempty(GroupNameCell)   %nothing filled in on this row
                    NewNamesCat{i2,1}=' ';
                    continue;
                end

                GroupName=convert2commadel(GroupNameCell,'_');

                %Check for double data type
                for i3=1:length(GroupName)
                    Value=GroupName{i3};
                    if ~ischar(Value)
                        Value2=num2str(Value);
                        GroupName{i3}=Value2;
                    end
                end

                GroupName=reshape(char(GroupName)',1,[]);   %reformat
                GroupName(GroupName ==' ')=[];              %remove spaces
                NewNamesCat{i2,1}=GroupName;
            end
            NewNames=NewNamesCat;

            if length(NewNames) ~= length(app.UITable.Data(:,2))
                msgbox('Warning number of group names in csv file doesn''t match the number of rows in the conditions table');
                if length(NewNames) > length(app.UITable.Data(:,2))
                    NewNames=NewNames(1:length(app.UITable.Data(:,2)));
                elseif length(NewNames) < length(app.UITable.Data(:,2)) %the received number of group names is too small
                    Diff=length(app.UITable.Data(:,2))-length(NewNames);
                    %check if there is a name there already, if so keep it
                    if Diff == 1
                        EmptyNames=app.UITable.Data(end,2);
                    else
                        EmptyNames=app.UITable.Data(end-(Diff-1):end,2);
                    end
                    NewNames(end+1:end+Diff)=EmptyNames;

                end
                app.UITable.Data(:,2)=NewNames(:,1);

            else
                app.UITable.Data(:,2)=NewNames(:,1);
            end

            %SaveNewFile= 'No'; %questdlg('Do you want to save a new condition file with the updated group names?','Save File?','Yes','No','No');
            if app.DataType == 9 %condition file load in
                SaveNewFile=questdlg('Do you want to save a new condition file with the updated group names?','Save File?','Yes','No','No');
                %SaveNewFile='No'; %Disabled pop-up
                if string(SaveNewFile) == "No"
                    return;
                end

                if string(SaveNewFile) == "Yes"
                    SaveButtonPushed(app,file);

                end
            end


            % if string(SaveNewFile) == "Yes"
            %     if ~isempty(app.LoadedConditions)
                    % %Prompt for file name, no need to add .mat at end of filename
                    % SavedCondFName=inputdlg('Enter filename:','Save Conditions File',[1 35],{[file(1:end-4) '_NewGroupNames']});
                    % if isempty(SavedCondFName)
                    %     return;
                    % end
            % 
            %         %Check if .mat was included in the filename
            %         if sum(SavedCondFName{1,1}(end-3:end) == '.mat') == 4
            %             SavedCondFName{1,1}(end-3:end)='';
            %         end
            % 
            %         %check if "Conditions" folder exists if not, create it
            %         ConditionsFolderDir=fullfile(app.Directory.SaveFolder, 'Conditions');
            %         if exist(ConditionsFolderDir,'dir') ~= 7 %7 folder exists
            %             mkdir(ConditionsFolderDir);
            % 
            %         end
                    % ConditionsMatrix=app.LoadedConditions.ConditionsMatrix;
                    % ConditionsMatrix(:,2)=app.UITable.Data(:,2);
                    % AllData=app.LoadedConditions.AllData;
                    % numTrialsperCondition=app.LoadedConditions.numTrialsperCondition;
                    % ChTitles=app.LoadedConditions.ChTitles;
                    % SampleRate=app.LoadedConditions.SampleRate;
                    % EventInfo='Combined existing condition files, see the original condition files for the Event Info'; %see the previous existing Condition Files for the Event Info
                    % FileName=app.LoadedConditions.FileName;
            % 
            %     else
            %         %SaveButtonPushed(app,1);
            % 
            %     end
            % 
            % 
            %     %Disable panel to indicate loading
            %     app.DefineConditionsPanel.Enable=0;
            %     pause(0.1)
            %     save(fullfile(ConditionsFolderDir, [SavedCondFName{1, 1} '.mat']),'ConditionsMatrix','AllData','numTrialsperCondition','ChTitles','SampleRate','EventInfo','FileName');
            %     pause(0.1)
            %     app.DefineConditionsPanel.Enable=1;
            % 
            % end

            
        end

        % Value changed function: ConditionsListBox
        function ConditionsListBoxValueChanged(app, event)
            %==============================================================
            %Updates graph by plotting all trials in newly selected
            %condition(s) in Conditions List Box
            %==============================================================
            %tic
            app.SelectedCondition = app.ConditionsListBox.Value;

            %For each condition, plot all trials
            cla(app.UIAxes);
            app.ConditionTrialsListBox.Items={''};
            app.Analyze_ConditionsUsed=[];
            ValueIndex=matches(string(app.ConditionsListBox.ItemsData),app.SelectedCondition);
            ConditionIndex=find(ValueIndex);
            app.Analyze_ConditionsUsed=ConditionIndex;
            %update trial select list box for Conditions (ConditionTrialsListBox)
            TrialRange=nan(length(ConditionIndex),max(app.numTrialsCondition(ConditionIndex,1)));
            for i2=1:length(ConditionIndex)
                Range=app.numTrialsCondition(ConditionIndex(i2),2):app.numTrialsCondition(ConditionIndex(i2),3);
                TrialRange(i2,1:length(Range))=Range;
            end
            TrialRange=reshape(TrialRange',1,[]);
            TrialRange(isnan(TrialRange))=[];
            app.ConditionTrialsListBox.Items=mat2cell(num2str(TrialRange'),ones(1,length(TrialRange)));
            %c=toc

            if app.AverageCheckBox.Value == 0
                %select all trials of selected conditions
                SelectAllCondButtonPushed(app,1);
            else
                %plot averaged data
                PlotData=app.AvgConditionData(ConditionIndex,:);

                %AllPlotted_Data
                app.AllPlotted_Data=cell(length(app.AvgConditionData(:,1)),2);
                app.AllPlotted_Data{1,2}=app.Time;
                app.AllPlotted_Data(:,1)=mat2cell(app.AvgConditionData,ones(1,length(app.AvgConditionData(:,1))),length(app.AvgConditionData(1,:)));

                %convert to selected units
                app.Plotted_Data=PlotData*app.Convert2PlottedUnits;

                %Plot desired data
                cla(app.UIAxes);
                hold(app.UIAxes,'On');
                plot(app.UIAxes, app.Time, app.Plotted_Data); %,'Color','k');
                app.UIAxes.XLabel.String = 'Time (s)';
                app.UIAxes.YLabel.String = app.PlottedUnits;
                %update Scaling edit fields
                app.MinEditField.Value=app.UIAxes.XLim(1);
                app.MaxEditField.Value=app.UIAxes.XLim(2);
                app.MinEditField_2.Value=app.UIAxes.YLim(1);
                app.MaxEditField_2.Value=app.UIAxes.YLim(2);

                hold(app.UIAxes,'Off');

            end

            %CondTime=toc %time to plot conditions

        end

        % Value changed function: ConditionTrialsListBox
        function ConditionTrialsListBoxValueChanged(app, event)
            %==============================================================
            %Updates which trials are selected and plotted
            %==============================================================

            %Determine which trial is selected
            TrialSelectedList= app.ConditionTrialsListBox.Value;
            if length(app.SelectedCondition) == 1
                if isempty(app.LoadedConditions.ChTitles) %for if there are no titles
                    app.UIAxes.Title.String=" ";
                else
                    ValueIndex=string(app.ConditionsListBox.ItemsData) == string(app.SelectedCondition);
                    app.UIAxes.Title.String = app.LoadedConditions.ChTitles(str2double(app.LoadedConditions.ConditionsMatrix{ValueIndex,3}));
                end
            else
                app.UIAxes.Title.String="Multiple Conditions";
            end

            %Disable Analyze Arrows if needed
            if length(TrialSelectedList) == 1 %if only one trial is selected
                if string(TrialSelectedList) == string(app.ConditionTrialsListBox.Items{end})
                    app.RightArrowAnalyzeButton.Enable=0;
                elseif string(TrialSelectedList) == string(app.ConditionTrialsListBox.Items{1})
                    app.LeftArrowAnalyzeButton.Enable=0;
                else
                    app.RightArrowAnalyzeButton.Enable=1;
                    app.LeftArrowAnalyzeButton.Enable=1;
                end
            end


            %if multiple sample rates check which time vector to use
            %if length(app.AllSampleRate) > 1
                % TrialsSelected=cellfun(@str2double, TrialSelectedList);
                % for i=1:length(app.AllSampleRate)
                %     a=find(app.numTrialsCondition(:,4) == app.AllSampleRate(i));
                %     SampleRateRanges(i,:)=[app.numTrialsCondition(a(1),2) app.numTrialsCondition(a(end),3)]; %!!!!! need to add check for multiple files with the same sample rate
                % end
                % %check which condition
                % if all(ismembmer(TrialsSelected,TrialRange))
                % 
                % end
                % 
                % %Determine which data to plot depending on processing
                % if app.Processed==1
                %     app.Time=app.Processed_Conditions_DataAll{2};
                %     Data=app.Processed_Conditions_DataAll{1,1};
                % else
                %     app.Time=app.Conditions_DataAll{2};
                %     Data=app.Conditions_DataAll{1,1};
                % end


            %else
                %Determine which data to plot depending on processing
                if app.Processed==1
                    app.Time=app.Processed_Conditions_DataAll{2};
                    Data=app.Processed_Conditions_DataAll{1,1};
                else
                    app.Time=app.Conditions_DataAll{2};
                    Data=app.Conditions_DataAll{1,1};
                end

            %end


            %AllPlotted_Data updated
            if app.AverageCheckBox.Value ==0
                app.AllPlotted_Data=cell(length(Data(:,1)),2);
                Trials=cell2mat(cellfun(@str2double,TrialSelectedList,'UniformOutput',false));
                PlotData=Data';
                PlotData=PlotData(:,Trials);
                app.AllPlotted_Data{1,2}=app.Time;
                app.AllPlotted_Data(Trials,1)=mat2cell(Data(Trials,:),ones(1,length(Data(Trials,1))), length(Data(1,:)));
            else

                app.AllPlotted_Data=cell(length(app.AvgConditionData(:,1)),2);
                app.AllPlotted_Data{1,2}=app.Time;
                app.AllPlotted_Data(:,1)=mat2cell(app.AvgConditionData,ones(1,length(app.AvgConditionData(:,1))),length(app.AvgConditionData(1,:)));
                PlotData=app.AvgConditionData;
            end

            %convert to selected units
            app.Plotted_Data=PlotData*app.Convert2PlottedUnits;

            %Plot desired data
            cla(app.UIAxes);
            hold(app.UIAxes,'On');
            plot(app.UIAxes, app.Time, app.Plotted_Data); %,'Color','k');
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = app.PlottedUnits;
            %update Scaling edit fields
            app.MinEditField.Value=app.UIAxes.XLim(1);
            app.MaxEditField.Value=app.UIAxes.XLim(2);
            app.MinEditField_2.Value=app.UIAxes.YLim(1);
            app.MaxEditField_2.Value=app.UIAxes.YLim(2);

            hold(app.UIAxes,'Off');


        end

        % Value changed function: AnalysisOptionDropDown
        function AnalysisOptionDropDownValueChanged(app, event)
            %==============================================================
            %Update Analysis Panel for the selected analysis option
            %==============================================================
            SelectedAnalysisOption = app.AnalysisOptionDropDown.Value;

            %Change panel title
            switch SelectedAnalysisOption
                case app.AnalysisOptionDropDown.ItemsData{1} %MEP
                    app.MotorEvokedPotentialPanel.Title = 'Motor Evoked Potential';
                    app.OnsetRiseorFallCheckBox.Visible=0;

                    %If the custom pop-up figure exists, delete it
                    if (string(class(app.CustomAnalysisOpts)) ~= "double") && (string(class(app.CustomAnalysisOpts)) == "matlab.ui.Figure" && ishghandle(app.CustomAnalysisOpts))
                        delete(app.CustomAnalysisOpts);
                    end
                    app.CustomOutputsPanel.Visible=0;
                    delete(app.CustomOutputsGridLayout.Children);

                case app.AnalysisOptionDropDown.ItemsData{2} %SP
                    app.MotorEvokedPotentialPanel.Title = 'Silent Period';
                    app.OnsetRiseorFallCheckBox.Visible=0;

                    %If the custom pop-up figure exists, delete it
                    if (string(class(app.CustomAnalysisOpts)) ~= "double") && (string(class(app.CustomAnalysisOpts)) == "matlab.ui.Figure" && ishghandle(app.CustomAnalysisOpts))
                        delete(app.CustomAnalysisOpts);
                    end
                    app.CustomOutputsPanel.Visible=0;
                    delete(app.CustomOutputsGridLayout.Children);

                case app.AnalysisOptionDropDown.ItemsData{3} %EMG Analysis
                    %Not implemented 12/3/25 !!!
                    app.AnalysisOptionDropDown.Value=app.AnalysisOptionDropDown.ItemsData{1};
                    AnalysisOptionDropDownValueChanged(app,1);

                    app.CustomOutputsPanel.Visible=0;
                    delete(app.CustomOutputsGridLayout.Children);
                    

                otherwise %custom analysis
                    if string(app.OnsetOffsetDetectionDropDown.Value) == string(app.OnsetOffsetDetectionDropDown.Items{1}) || string(app.OnsetOffsetDetectionDropDown.Value) == string(app.OnsetOffsetDetectionDropDown.Items{2})
                        app.OnsetRiseorFallCheckBox.Visible=1;
                    else
                        app.OnsetRiseorFallCheckBox.Visible=0;
                    end
                    app.CustomOutputsPanel.Visible=1;

            end

        end

        % Value changed function: OnsetOffsetDetectionDropDown
        function OnsetOffsetDetectionDropDownValueChanged(app, event)
            %==============================================================
            %Updates Analysis Panel based on which onset/offset detection
            %method is selected
            %==============================================================
            SelectedMethod = app.OnsetOffsetDetectionDropDown.Value;

            %Update which items are visible
            switch SelectedMethod

                case app.OnsetOffsetDetectionDropDown.Items{2} %Garvey et al
                    app.OnsetSDEditField.Visible=0;  app.SDEditFieldLabel.Visible=0;
                    app.OffsetSDEditField.Visible=0; app.SDEditFieldLabel_2.Visible=0;
                    app.MCDConstantEditField.Visible=1; app.MCDConstantEditFieldLabel.Visible=1;

                    app.StartTimemsEditField.Visible=1; app.StartTimemsEditFieldLabel.Visible=1;
                    app.OnsetLabel.Visible=1; app.OffsetLabel.Visible=1;
                    app.OnsetMinDurmsEditField.Visible=1; app.MinDurmsEditFieldLabel.Visible=1;
                    app.OffsetMinDurmsEditField.Visible=1; app.MinDurmsEditFieldLabel_2.Visible=1;
                    app.FiftyPercentOnsetCheckBox.Visible=1; app.FiftyPercentOffsetCheckBox.Visible=1;

                    %check if a custom analysis is being used, if yes show the onset rise or fall check box
                    if string(app.AnalysisOptionDropDown.Value) == string(app.AnalysisOptionDropDown.ItemsData{1}) || string(app.AnalysisOptionDropDown.Value) == string(app.AnalysisOptionDropDown.ItemsData{2}) || string(app.AnalysisOptionDropDown.Value) == string(app.AnalysisOptionDropDown.ItemsData{3})
                        app.OnsetRiseorFallCheckBox.Visible=0;
                    else
                        app.OnsetRiseorFallCheckBox.Visible=1;
                    end

                case app.OnsetOffsetDetectionDropDown.Items{1} %SD Limits
                    app.OnsetSDEditField.Visible=1;  app.SDEditFieldLabel.Visible=1;
                    app.OffsetSDEditField.Visible=1; app.SDEditFieldLabel_2.Visible=1;
                    app.MCDConstantEditField.Visible=0; app.MCDConstantEditFieldLabel.Visible=0;

                    app.StartTimemsEditField.Visible=1; app.StartTimemsEditFieldLabel.Visible=1;
                    app.OnsetLabel.Visible=1; app.OffsetLabel.Visible=1;
                    app.OnsetMinDurmsEditField.Visible=1; app.MinDurmsEditFieldLabel.Visible=1;
                    app.OffsetMinDurmsEditField.Visible=1; app.MinDurmsEditFieldLabel_2.Visible=1;
                    app.FiftyPercentOnsetCheckBox.Visible=1; app.FiftyPercentOffsetCheckBox.Visible=1;

                    %check if a custom analysis is being used, if yes show the onset rise or fall check box
                    if string(app.AnalysisOptionDropDown.Value) == string(app.AnalysisOptionDropDown.ItemsData{1}) || string(app.AnalysisOptionDropDown.Value) == string(app.AnalysisOptionDropDown.ItemsData{2}) || string(app.AnalysisOptionDropDown.Value) == string(app.AnalysisOptionDropDown.ItemsData{3})
                        app.OnsetRiseorFallCheckBox.Visible=0;
                    else
                        app.OnsetRiseorFallCheckBox.Visible=1;
                    end

                otherwise %custom onset/offset detection method
                    app.OnsetSDEditField.Visible=0;  app.SDEditFieldLabel.Visible=0;
                    app.OffsetSDEditField.Visible=0; app.SDEditFieldLabel_2.Visible=0;
                    app.MCDConstantEditField.Visible=0; app.MCDConstantEditFieldLabel.Visible=0;
                    app.MCDConstantEditField.Visible=0; app.MCDConstantEditFieldLabel.Visible=0;

                    app.StartTimemsEditField.Visible=0; app.StartTimemsEditFieldLabel.Visible=0;
                    app.OnsetLabel.Visible=0; app.OffsetLabel.Visible=0;
                    app.OnsetMinDurmsEditField.Visible=0; app.MinDurmsEditFieldLabel.Visible=0;
                    app.OffsetMinDurmsEditField.Visible=0; app.MinDurmsEditFieldLabel_2.Visible=0;
                    app.FiftyPercentOnsetCheckBox.Visible=0; app.FiftyPercentOffsetCheckBox.Visible=0;

                    app.OnsetRiseorFallCheckBox.Visible=0;

            end

            %If the custom pop-up figure exists, delete it
            if (string(class(app.CustomOnOffDetectOpts)) ~= "double") && (string(class(app.CustomOnOffDetectOpts)) == "matlab.ui.Figure" && ishghandle(app.CustomOnOffDetectOpts))
                delete(app.CustomOnOffDetectOpts);
            end


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            %==============================================================
            %Determine onset and offset via Onset/Offset Detection method
            %Calculates the pre-stim RMS and STD and the results of the
            %analysis option chosen
            %==============================================================

            if (string(class(event)) == "matlab.ui.eventdata.ButtonPushedData") && (event ~= 2) %don't use loading screen when using analyze arrows
                loadingScreen(app,1);
            end
            %tic

            if app.OverrideUsed == 0
                %remove existing lines
                if ishghandle(app.OnsetLine) == 1
                    delete(app.OnsetLine);
                end
                if ishghandle(app.OffsetLine) == 1
                    delete(app.OffsetLine);
                end
                if ishghandle(app.PreStimArea) == 1
                    delete(app.PreStimArea);
                end
                if ishghandle(app.StartLine) == 1
                    delete(app.StartLine);
                end
                if ishghandle(app.OnsetLimitLines) == 1
                    delete(app.OnsetLimitLines);
                end
                if ishghandle(app.OffsetLimitLines) == 1
                    delete(app.OffsetLimitLines);
                end
                if ishghandle(app.meanPreStimLine) == 1
                    delete(app.meanPreStimLine);
                end
            end


            %check if the averages are being used
            if app.AverageCheckBox.Value == 0
                %Condense Trials
                TrialsLoc=cellfun(@isempty, app.AllPlotted_Data(:,1));
                app.Analyze_TrialsUsed=find(~TrialsLoc);
                SelectedTrialsData=app.AllPlotted_Data(~TrialsLoc,1); %in volts

                app.Analyze_TrialsUsedConditionName=cell(1,1);
                app.Analyze_TrialsUsedChannel=cell(1,1);

            else
                %average is taken of all trials in each condition, list which conditions were used
                SelectedConditionsData=find(matches(string(app.ConditionsListBox.ItemsData),app.SelectedCondition));
                %app.Analyze_TrialsUsedConditionName=app.LoadedConditions.ConditionsMatrix(SelectedConditionsData,2);
                app.Analyze_TrialsUsedConditionName=app.UITable.Data(SelectedConditionsData,2);
                app.Analyze_TrialsUsed=app.LoadedConditions.ConditionsMatrix(SelectedConditionsData,1);
                app.Analyze_TrialsUsedChannel=app.LoadedConditions.ConditionsMatrix(SelectedConditionsData,3);
                SelectedTrialsData(:,1)=mat2cell(app.AvgConditionData(SelectedConditionsData,:),ones(length(SelectedConditionsData),1),length(app.AvgConditionData(1,:)));

            end

            %Calculations for Pre Stim Period
            %check that prestim period is within the data range
            MinMaxTime=zeros(length(SelectedTrialsData(:,1)),2);
            %find time range where there is data in all trials (for trials with different time lengths)
            for i=1:length(SelectedTrialsData(:,1))
                if ~all(isnan(SelectedTrialsData{i,1}))
                    DataStartEnd=find(~isnan(SelectedTrialsData{i,1}));
                    MinMaxTime(i,1)=app.Time(DataStartEnd(1));
                    MinMaxTime(i,2)=app.Time(DataStartEnd(end));
                end
            end
            MinTimeLimit=max(MinMaxTime(:,1)); %start data time (s)
            MaxTimeLimit=min(MinMaxTime(:,2)); %end data time (s)
            PreStimPeriodmsStartEditFieldValueChanged(app, 1);
            PreStimPeriodmsEndEditFieldValueChanged(app, 1);
            if app.PreStimStart/1000 < MinTimeLimit && app.PreStimEnd/1000 < MinTimeLimit %if both the prestim start and end are below the min start time
                app.PreStimStart=MinTimeLimit*1000;
                app.PreStimEnd=(MinTimeLimit+0.01)*1000; %10ms
                app.PreStimPeriodmsStartEditField.Value=app.PreStimStart;
                app.PreStimPeriodmsEndEditField.Value=app.PreStimEnd;

            elseif app.PreStimStart/1000 < MinTimeLimit %if the start time is below the min start time
                app.PreStimStart=MinTimeLimit*1000;
                app.PreStimPeriodmsStartEditField.Value=app.PreStimStart;

                %if the start is above the min time limit the end should be too, so no check for that

            elseif app.PreStimEnd/1000 > MaxTimeLimit %if the End time is above the max time limit
                app.PreStimEnd=app.PreStimStart+10;
                app.PreStimPeriodmsEndEditField.Value=app.PreStimEnd;
            end
            %check if the prestim values are the same
            if app.PreStimStart == app.PreStimEnd
                app.PreStimEnd=app.PreStimStart+10;
                app.PreStimPeriodmsEndEditField.Value=app.PreStimEnd;
            end


            %calculate Pre Stim RMS and STDV
            PreStimRangeTime=app.Time(single(app.Time) >= single(app.PreStimStart/1000) & single(app.Time) <= single(app.PreStimEnd/1000));
            PreStimData=zeros(length(PreStimRangeTime),length(SelectedTrialsData(:,1)));
            for i=1:length(SelectedTrialsData(:,1)) %for each trial
                TrialData=SelectedTrialsData{i,1}; %data
                PreStimData(:,i)=TrialData(single(app.Time) >= single(app.PreStimStart/1000) & single(app.Time) <= single(app.PreStimEnd/1000))';
            end

            %RMS and STDV
            app.PreStimRMS=rms(PreStimData,1); %take RMS of each trial
            app.PreStimSTDV=std(PreStimData); %find the standard deviation for each trial
            app.PreStimMean=mean(abs(PreStimData),'omitnan');


            %Display results
            app.PreStimMeanEditField.Value=mean(app.PreStimMean(~isnan(app.PreStimMean)))*1000;  %convert to mV
            app.PreStimStdEditField.Value=mean(app.PreStimSTDV(~isnan(app.PreStimRMS)))*1000; %convert to mV

            %plot area of preStim
            PreStimStartSec=app.PreStimStart/1000;
            PreStimEndSec=app.PreStimEnd/1000;
            x=[PreStimStartSec PreStimStartSec PreStimEndSec PreStimEndSec];
            y=[app.UIAxes.YLim(1) app.UIAxes.YLim(2) app.UIAxes.YLim(2) app.UIAxes.YLim(1)];
            hold(app.UIAxes,'on');
            app.PreStimArea=patch(app.UIAxes,x,y,'b','FaceColor','#969590','EdgeColor','black','FaceAlpha',0.2); %plot prestim area

            %check units for plotting
            switch app.UnitsDropDown.Value
                case 'mV'
                    Units=1;
                    UnitsV=1000;
                case 'V'
                    Units=0.001;
                    UnitsV=1;
                case 'uV'
                    Units=1000;
                    UnitsV=1e6;
            end
            %app.Annot(1)=text(app.UIAxes,x(1)+0.001,y(2)-0.1*Units,'Pre Stim Area');

            %% Onset/Offset
            %check which analysis option and set initial variables
            CustomAnalysis=0;
            app.CustomOutputs=[];
            %initialize outputs
            switch app.AnalysisOptionDropDown.Value
                case app.AnalysisOptionDropDown.ItemsData{1} %MEP
                    app.MEP_Amp=nan(length(SelectedTrialsData(:,1)),1);
                    app.MEP_Area=nan(length(SelectedTrialsData(:,1)),1);
                    AnalyzeMethod="MEP";

                case app.AnalysisOptionDropDown.ItemsData{2} %SP
                    app.SP_PercDecrease=nan(length(SelectedTrialsData(:,1)),1);
                    app.SP_Area=nan(length(SelectedTrialsData(:,1)),1);
                    AnalyzeMethod="SP";

                case app.AnalysisOptionDropDown.ItemsData{3} %EMG

                otherwise 

                    CustomAnalysis=1;
            end

            %if the data is down sampled update the sample rate used for analysis
            if ~isempty(app.ProcessOrder) && any(string(app.ProcessOrder(:,1)) == "Down Sample")
                AnalyzeSampleRate=app.DownSampledRate;
            else
                AnalyzeSampleRate=app.AllSampleRate;
            end

            %if override isn't use
            if app.OverrideUsed == 0
                %Check onset/offset method
                switch app.OnsetOffsetDetectionDropDown.Value
                    case app.OnsetOffsetDetectionDropDown.Items{2} %Garvey
                        if ~exist('AnalyzeMethod','var') 
                            if app.OnsetRiseorFallCheckBox.Value == 1
                                AnalyzeMethod="MEP";
                            else
                                AnalyzeMethod="SP";
                            end
                        end
                        [app.AllOnOffsetTime, OnsetLimit, OffsetLimit,meanPreStimData]=GarveyMethod(app,AnalyzeSampleRate,PreStimData,SelectedTrialsData,AnalyzeMethod,'MCD',app.FiftyPercentOnsetCheckBox.Value,app.FiftyPercentOffsetCheckBox.Value);

                    case app.OnsetOffsetDetectionDropDown.Items{1} %SD Limits
                        if ~exist('AnalyzeMethod','var') 
                            if app.OnsetRiseorFallCheckBox.Value == 1
                                AnalyzeMethod="MEP";
                            else
                                AnalyzeMethod="SP";
                            end
                        end
                        [app.AllOnOffsetTime, OnsetLimit, OffsetLimit,meanPreStimData]=GarveyMethod(app,AnalyzeSampleRate,PreStimData,SelectedTrialsData,AnalyzeMethod,'SD',app.FiftyPercentOnsetCheckBox.Value,app.FiftyPercentOffsetCheckBox.Value);

                    otherwise 
                        MethodName=app.OnsetOffsetDetectionDropDown.Value; PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection');
                        %if the Custom Opts figure doesn't exist, create it
                        if (string(class(app.CustomOnOffDetectOpts)) ~= "double") && (string(class(app.CustomOnOffDetectOpts)) == "matlab.ui.Figure" && ishghandle(app.CustomOnOffDetectOpts))
                            existFig=1;
                        else
                            app.CustomOnOffDetectOpts=uifigure('Name','Custom OnOffset Detection');
                            existFig=0;
                        end
                        [app.AllOnOffsetTime, OnsetLimit, OffsetLimit,meanPreStimData,app.CustomOnOffDetectOpts]= ...
                            eval([MethodName '(app,existFig,PluginsFolderName,AnalyzeSampleRate,PreStimData,SelectedTrialsData);']);

                        %check for empty return values
                        numSelectedTrials=length(SelectedTrialsData);
                        if isempty(app.AllOnOffsetTime)
                            app.AllOnOffsetTime = nan(numSelectedTrials,2);
                        end
                        if isempty(OnsetLimit)
                            OnsetLimit = nan(1,numSelectedTrials);
                        end
                        if isempty(OffsetLimit)
                            OffsetLimit = nan(1,numSelectedTrials);
                        end


                end
                Start=[]; End=[];
            else %override is used
                %Set legend
                legend(app.UIAxes,[app.PreStimArea app.OnsetLine app.OffsetLine],{'Pre Stim Area','Onset','Offset'},'AutoUpdate','off');

                %Onset and offset times
                OnsetTime=app.AllOnOffsetTime(1,1);
                OffsetTime=app.AllOnOffsetTime(1,2);

                %check times, change so on/offset times are the closest time value
                DiffTimeOnset=abs(app.Time-OnsetTime); %get the differences between the selected Onset time and time
                minDiffTimeOnset=find(DiffTimeOnset == min(DiffTimeOnset)); %find the smallest difference
                OnsetTime=app.Time(minDiffTimeOnset); %use the location of the smallest difference to determine time

                DiffTimeOffset=abs(app.Time-OffsetTime);
                minDiffTimeOffset=find(DiffTimeOffset == min(DiffTimeOffset));
                OffsetTime=app.Time(minDiffTimeOffset);

                app.AllOnOffsetTime(1,1)=OnsetTime;
                app.AllOnOffsetTime(1,2)=OffsetTime;

                Tol=eps("double");
                Start=find(abs(app.Time - OnsetTime) < Tol); %frames
                End=find(abs(app.Time - OffsetTime) < Tol);

                if End < Start %if the offset line is placed before the onset line %!!!!! sample rate
                    End=Start+round(0.01*AnalyzeSampleRate); %whatever the start was placed at plus ~0.01 seconds, this is just so the code runs after this, it is assumed that the user do the override again
                    app.AllOnOffsetTime(1,2)=End/AnalyzeSampleRate;
                    msgbox('Warning: Offset was placed after Onset');
                end

            end

            %If a Custom analysis is used
            if CustomAnalysis == 1 
                PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Analysis');
                MethodName=app.AnalysisOptionDropDown.Value;
                MethodName(1)=[]; %remove initial underscore
                %if the Custom Opts figure doesn't exist, create it
                if (string(class(app.CustomAnalysisOpts)) ~= "double") && (string(class(app.CustomAnalysisOpts)) == "matlab.ui.Figure" && ishghandle(app.CustomAnalysisOpts))
                    existFig=1;
                else
                    app.CustomAnalysisOpts=uifigure('Name','Custom Analysis');
                    existFig=0;
                end

                MissingAnalyze=0; %initialize MissingAnalyze, so the user doesn't have to
                [MissingAnalyze,app.CustomOutputs,app.CustomAnalysisOpts]= ...
                    eval([MethodName '(app,existFig,PluginsFolderName,AnalyzeSampleRate,PreStimData,SelectedTrialsData,MissingAnalyze,Start,End);']);
                
                
                %if one trial analzed print results
                if length(SelectedTrialsData(:,1)) == 1
                    a=1;
                    app.CustomOutputsPanel.Visible=1;
                    delete(app.CustomOutputsGridLayout.Children) %clear the current outputs
                    CustomOutputLabels=fieldnames(app.CustomOutputs);
                    for i=1:length(CustomOutputLabels)%!!!!!
                            OutputName_lbl=uilabel(app.CustomOutputsGridLayout,'Text',CustomOutputLabels{i});
                            OutputName_lbl.Layout.Row=i;
                            OutputName_lbl.Layout.Column=1;

                            Data_lbl=uilabel(app.CustomOutputsGridLayout,'Text',num2str(app.CustomOutputs.(CustomOutputLabels{i})));
                            Data_lbl.Layout.Row=i;
                            Data_lbl.Layout.Column=2;

                    end
                else
                    delete(app.CustomOutputsGridLayout.Children) %clear the current outputs
                end
                
                
                %ConditionList and ChannelList for saving Analyze results
                if app.AverageCheckBox.Value == 0 %if average is not being used update which conditions belong two which trials
                    for i=1:length(SelectedTrialsData(:,1)) %for each trial
                        TrialUsed=app.Analyze_TrialsUsed(i);
                        for i2=app.Analyze_ConditionsUsed
                            inRange=TrialUsed >= app.numTrialsCondition(i2,2) && TrialUsed <= app.numTrialsCondition(i2,3); %if the trial is in the range of the condition trial numbers,
                            if inRange==1                                                                                   %add condition name to list
                                %app.Analyze_TrialsUsedConditionName{i,1}=app.LoadedConditions.ConditionsMatrix(i2,2);
                                app.Analyze_TrialsUsedConditionName{i,1}=app.UITable.Data(i2,2);
                                app.Analyze_TrialsUsedChannel(i,1)=app.LoadedConditions.ConditionsMatrix(i2,3);
                                break;                                                                                      %there should only be one condition name per trial, so break search if the name is found
                            end
                        end

                    end
                end

            else %Built-in analysis
                %For each trial calculate the analysis results
                MissingAnalyze=0;
                for i=1:length(SelectedTrialsData(:,1)) %for each trial
                    if app.OverrideUsed == 0 %Override is not used
                        OnsetTime=app.AllOnOffsetTime(i,1);
                        OffsetTime=app.AllOnOffsetTime(i,2);

                        Tol=eps("double");
                        Start=find(abs(app.Time - OnsetTime) < Tol);
                        End=find(abs(app.Time - OffsetTime) < Tol);

                    end

                    %For MEP data use non-rectified data if needed
                    if ~isempty(app.Processed_Conditions_DataAll{3}) && AnalyzeMethod=="MEP" %if the third cell isn't empty then the non-rectified data was saved and if MEP is done, use the non-rectified data
                        if app.AverageCheckBox.Value == 1
                            TrialDataNR=app.Processed_Conditions_DataAll{3}(1,:);
                        else
                            TrialDataNR=app.Processed_Conditions_DataAll{3}(app.Analyze_TrialsUsed(i),:);
                        end
                    else
                        TrialDataNR=SelectedTrialsData{i,1};
                    end

                    %if there is an onset/offset
                    if ~isnan(OnsetTime) && ~isnan(OffsetTime)
                        %Data range
                        AnalyzeData=TrialDataNR(Start:End); %calculate for non-rectified data

                        %Calculate Results
                        switch AnalyzeMethod
                            case "MEP"
                                %Calculate amplitude and area
                                if ~isempty(app.ProcessOrder) && any(string(app.ProcessOrder(:,1)) == "Rectify") && isempty(app.Processed_Conditions_DataAll{3})
                                    app.MEP_Amp=nan;
                                else
                                    Peak=max(AnalyzeData);
                                    Trough=min(AnalyzeData);
                                    app.MEP_Amp(i,:)=Peak-Trough;
                                end
                                %remove nans %!!!!!
                                AnalyzeData(isnan(AnalyzeData))=[];
                                app.MEP_Area(i,:)=trapz(abs(AnalyzeData)); %trapezoidal integration method, for MEP area should be of absolute value of data
                            case "SP"
                                app.SP_PercDecrease(i,:)=100-(mean(AnalyzeData,'omitnan')/mean(PreStimData(:,i),'omitnan')*100); % percent of silent period mean value decreased compared with pre stimulation period

                                %Normalized area of SP
                                %remove nans %!!!!!
                                AnalyzeData(isnan(AnalyzeData))=[];
                                SPArea=trapz(AnalyzeData); %trapezoidal integration method
                                %mean preStimulation times length of silent period duration equals the area
                                areaMeanSP = abs(mean(PreStimData(:,i),'omitnan')*length(AnalyzeData));
                                app.SP_Area(i,:) = (1-SPArea/areaMeanSP)*100;      %normalized area of slient period unit

                        end


                    else %if not fill with nan
                        switch AnalyzeMethod
                            case "MEP"
                                app.MEP_Amp(i,:)=nan;
                                app.MEP_Area(i,:)=nan;
                            case "SP"
                                app.SP_PercDecrease(i,:)=nan;
                                app.SP_Area(i,:)=nan;
                        end

                        MissingAnalyze=MissingAnalyze+1;
                    end

                    %If there is only one trial print results
                    if length(SelectedTrialsData(:,1)) == 1
                        app.OnsetmsValueLabel.Text=num2str(app.Time(Start)*1000); %convert to ms
                        app.DurationmsValueLabel.Text=num2str((app.Time(End)-app.Time(Start))*1000); %convert to ms

                        switch AnalyzeMethod
                            case "MEP"
                                app.DecreaseValueLabel.Text=num2str(round(app.MEP_Amp(1)*1000,2)); %convert to mV for display
                                app.AmplitudemVLabel.Text='Amplitude (mV)';
                                app.AreaValueLabel.Text=num2str(round(app.MEP_Area(1)*1000,2));

                            case "SP"
                                app.DecreaseValueLabel.Text=num2str(round(app.SP_PercDecrease(1),2));
                                app.AmplitudemVLabel.Text='% Decrease';
                                app.AreaValueLabel.Text=num2str(round(app.SP_Area(1),2));
                        end

                    else
                        app.DecreaseValueLabel.Text='N/A';
                        app.AmplitudemVLabel.Text='N/A';
                        app.AreaValueLabel.Text='N/A';

                        app.OnsetmsValueLabel.Text='N/A'; %convert to ms
                        app.DurationmsValueLabel.Text='N/A'; %convert to ms


                    end

                    %ConditionList and ChannelList for saving Analyze results
                    if app.AverageCheckBox.Value == 0
                        TrialUsed=app.Analyze_TrialsUsed(i);
                        for i2=app.Analyze_ConditionsUsed
                            inRange=TrialUsed >= app.numTrialsCondition(i2,2) && TrialUsed <= app.numTrialsCondition(i2,3); %if the trial is in the range of the condition trial numbers,
                            if inRange==1                                                                                   %add condition name to list
                                %app.Analyze_TrialsUsedConditionName{i,1}=app.LoadedConditions.ConditionsMatrix(i2,2);
                                app.Analyze_TrialsUsedConditionName{i,1}=app.UITable.Data(i2,2);
                                app.Analyze_TrialsUsedChannel(i,1)=app.LoadedConditions.ConditionsMatrix(i2,3);
                                break;                                                                                      %there should only be one condition name per trial, so break search if the name is found
                            end
                        end

                    end
                end %end for each trial
            end %if custom

            %Plot Onset and Offset Lines
            if app.OverrideUsed == 0
                %plotting
                OnsetLimitsV=OnsetLimit*UnitsV; %convert to Unit
                OffsetLimitsV=OffsetLimit*UnitsV;
                if  all(all(isnan(app.AllOnOffsetTime)))  %no onset/offset found for all trials
                    %Plot start line and onset/offset thresholds
                    app.StartLine=xline(app.UIAxes,app.StartTimemsEditField.Value*0.001,'k','LineStyle','-','LineWidth',2);
                    %Adjust negation of thresholds based on if the data is rectified or not %!!!
                    MedianOnsetThresh=median(OnsetLimitsV(~isnan(OnsetLimitsV)));
                    MedianOffsetThresh=median(OffsetLimitsV(~isnan(OffsetLimitsV)));
                    if exist('AnalyzeMethod','var')
                        plotThresholdLinesMainGraph(app,AnalyzeMethod,MedianOnsetThresh,MedianOffsetThresh);
                    end
                    meanPreStimDataV=meanPreStimData*UnitsV; %convert to units
                    app.meanPreStimLine=yline(app.UIAxes,median(meanPreStimDataV(~isnan(meanPreStimDataV))),'k--');

                    %Pop-out figures for if no onset/offset found
                    if string(app.PlotOnsetOffsetSwitch.Value) == "On"
                        if string(class(app.PlotOnsetOffsetSwitchFig)) ~= "matlab.ui.Figure"
                            app.PlotOnsetOffsetSwitchFig=figure();
                        else
                            if ~isgraphics(app.PlotOnsetOffsetSwitchFig,'Figure')
                                app.PlotOnsetOffsetSwitchFig=figure();
                            else
                                clf(app.PlotOnsetOffsetSwitchFig);
                            end
                        end
                        ax5=axes(app.PlotOnsetOffsetSwitchFig);
                        %rectify data for pop-out plot
                        for i=1:length(app.UIAxes.Children)
                            a=string(class(app.UIAxes.Children(i)));
                            b1=strsplit(a,'.');
                            ChildrenClass=b1(end);

                            if ChildrenClass == "Line"
                                CurrentXData(i,:)=app.UIAxes.Children(i).XData;
                                CurrentYData(i,:)=abs(app.UIAxes.Children(i).YData);

                            end
                        end
                        plot(ax5,CurrentXData',CurrentYData'); hold on;
                        %boxplot for thresholds
                        g1=repmat({'OnsetLimits'},length(OnsetLimitsV),1);
                        g2=repmat({'OffsetLimits'},length(OffsetLimitsV),1);
                        g=[g1;g2];

                        Bx2=boxplot(ax5,[OnsetLimitsV';OffsetLimitsV'],g,"Orientation","vertical","Positions",[app.StartTimemsEditField.Value*0.001 app.StartTimemsEditField.Value*0.001+0.1],"Widths",0.02); %!!! adjust location of box plots base on data?
                        set(Bx2,'LineWidth',2)
                        xticks(ax5,'auto');
                        xticklabels(ax5,'auto');
                        yline(ax5,median(OnsetLimitsV(~isnan(OnsetLimitsV))),'r:','LineWidth',2);
                        yline(ax5,median(OffsetLimitsV(~isnan(OffsetLimitsV))),'b--','LineWidth',2);

                        %boxplot for mean pre-stim data
                        g1=repmat({'meanPrestimData'},length(meanPreStimDataV),1);
                        g=g1;

                        Bx3=boxplot(ax5,meanPreStimDataV,g,"Orientation","vertical","Positions",median([app.PreStimPeriodmsStartEditField.Value app.PreStimPeriodmsEndEditField.Value])*0.001,"Widths",((app.PreStimPeriodmsEndEditField.Value-app.PreStimPeriodmsStartEditField.Value)/2)*0.001); %!!! adjust location of box plots base on data?
                        set(Bx3,'LineWidth',2)
                        xticks(ax5,'auto');
                        xticklabels(ax5,'auto');
                        xlim('auto');
                        yline(ax5,median(meanPreStimDataV(~isnan(meanPreStimDataV))),'k--','LineWidth',2);
                        ylabel(ax5,app.UIAxes.YLabel.String);
                        title(ax5,'Onset and Offset Threshold Boxplots')
                    end
                    %Set legend
                    warning('off','MATLAB:legend:IgnoringExtraEntries')
                    legend(app.UIAxes,[app.PreStimArea app.StartLine app.OnsetLimitLines app.OffsetLimitLines app.meanPreStimLine],{'Pre Stim Area','Start','Onset Limit','Offset Limit','Pre Stim Mean'},'AutoUpdate','off');
                    warning('on','MATLAB:legend:IgnoringExtraEntries')

                    hold(app.UIAxes,'off');

                    %update missing from analyze label
                    app.MissingfromanalyzeLabel.Visible=1;
                    app.MissingfromanalyzeLabel.Text=['Missing from analyze: ' num2str(MissingAnalyze) '/' num2str(length(SelectedTrialsData(:,1)))];

                    loadingScreen(app,0);
                    return;
                end %end if there are no onset/offsets found for all trials

                %All Onsets and Offsets
                AllOffset=app.AllOnOffsetTime(~isnan(app.AllOnOffsetTime(:,2)),2);
                AllOnset=app.AllOnOffsetTime(~isnan(app.AllOnOffsetTime(:,1)),1);

                %Plot start line and onset/offset thresholds
                app.StartLine=xline(app.UIAxes,app.StartTimemsEditField.Value*0.001,'k','LineStyle','-','LineWidth',2); %,'DisplayName', 'Start Time');
                app.OnsetLine=xline(app.UIAxes,median(AllOnset),'r','LineStyle','--',"LineWidth",2); %,'DisplayName', 'Onset');
                app.OffsetLine=xline(app.UIAxes,median(AllOffset),'b','LineStyle','--',"LineWidth",2); %,'DisplayName', 'Offset');
                %Adjust negation of thresholds based on if the data is rectified or not %!!!
                MedianOnsetThresh=median(OnsetLimitsV(~isnan(OnsetLimitsV)));
                MedianOffsetThresh=median(OffsetLimitsV(~isnan(OffsetLimitsV)));
                if exist('AnalyzeMethod','var')
                    plotThresholdLinesMainGraph(app,AnalyzeMethod,MedianOnsetThresh,MedianOffsetThresh);
                end
                meanPreStimDataV=meanPreStimData*UnitsV; %convert to units
                app.meanPreStimLine=yline(app.UIAxes,median(meanPreStimDataV(~isnan(meanPreStimDataV))),'k--');

                %Set legend
                warning('off','MATLAB:legend:IgnoringExtraEntries') 
                if (string(class(app.OnsetLimitLines)) ~= "double") && (string(class(app.OnsetLimitLines)) == "matlab.graphics.chart.decoration.ConstantLine" && ishghandle(app.OnsetLimitLines))
                    legend(app.UIAxes,[app.PreStimArea app.StartLine app.OnsetLine app.OffsetLine app.OnsetLimitLines app.OffsetLimitLines app.meanPreStimLine],{'Pre Stim Area','Start','Onset','Offset','Onset Limit','Offset Limit','Pre Stim Mean'},'AutoUpdate','off');
                else
                    legend(app.UIAxes,[app.PreStimArea app.StartLine app.OnsetLine app.meanPreStimLine],{'Pre Stim Area','Start','Onset','Offset','Pre Stim Mean'},'AutoUpdate','off');

                end
                %app.OnsetLimitLines app.OffsetLimitLines 'Onset Limit','Offset Limit'
                warning('on','MATLAB:legend:IgnoringExtraEntries')

                %Popup figure - Box plot of onsets and offsets
                if string(app.PlotOnsetOffsetSwitch.Value) == "On" %&& (string(app.OnsetOffsetDetectionDropDown.Value) == "Garvey et al" ||string(app.OnsetOffsetDetectionDropDown.Value) == "SD Bounds")

                    %Box Plot Data =========================================
                    g1_OnsetOffset=repmat({'Onset'},length(AllOnset),1);
                    g2_OnsetOffset=repmat({'Offset'},length(AllOffset),1);
                    g_OnsetOffset=[g1_OnsetOffset;g2_OnsetOffset];

                    %Box plot for thresholds-----------------------------------------------------------------------
                    g1_Limits=repmat({'OnsetLimits'},length(OnsetLimitsV),1);
                    g2_Limits=repmat({'OffsetLimits'},length(OffsetLimitsV),1);
                    g_Limits=[g1_Limits;g2_Limits];

                    %Boxplot for mean pre-stim data----------------------------------------------------------------------
                    meanPreStimDataV=meanPreStimData*UnitsV; %convert to units
                    g1_PreStim=repmat({'meanPrestimData'},length(meanPreStimDataV),1);
                    g_PreStim=g1_PreStim;

                    %Plotting=============================================
                    if string(class(app.PlotOnsetOffsetSwitchFig)) ~= "matlab.ui.Figure"
                        app.PlotOnsetOffsetSwitchFig=figure();
                    else
                        if ~isgraphics(app.PlotOnsetOffsetSwitchFig,'Figure')
                            app.PlotOnsetOffsetSwitchFig=figure();
                        else
                            clf(app.PlotOnsetOffsetSwitchFig);
                        end
                    end
                    ax=subplot(1,2,1,'Parent',app.PlotOnsetOffsetSwitchFig); %=======================================Pop up subplot 1
                    for i=1:length(app.UIAxes.Children)
                        a=string(class(app.UIAxes.Children(i)));
                        b1=strsplit(a,'.');
                        ChildrenClass=b1(end);

                        if ChildrenClass == "Line"
                            CurrentXData(i,:)=app.UIAxes.Children(i).XData;
                            CurrentYData(i,:)=abs(app.UIAxes.Children(i).YData);

                        end
                    end
                    plot(ax,CurrentXData',CurrentYData'); hold on;

                    %Onset and Offset
                    BP=boxplot(ax,[AllOnset;AllOffset],g_OnsetOffset,"Orientation","horizontal","Positions",[-1*Units 1*Units],"Widths",0.5*Units);
                    set(BP,'LineWidth',2)
                    xline(ax,median(AllOnset),'r','LineStyle','--',"LineWidth",2); %'DisplayName', 'Median Onset Time');
                    xline(ax,median(AllOffset),'b','LineStyle','--',"LineWidth",2); %'DisplayName', 'Median Offset Time');
                    yticks('auto')
                    yticklabels('auto')
                    xlabel(ax,'Time (s)');
                    ylabel(ax,app.UIAxes.YLabel.String);
                    xlim(ax,[min([AllOnset;AllOffset])-0.005 max([AllOnset;AllOffset])+0.005]); %zoom into onset/offset ranges +/-5ms
                    title(ax,'Times Boxplots');
                    %Plot start line and onset/offset thresholds
                    xline(ax,app.StartTimemsEditField.Value*0.001,'k','LineStyle','-','LineWidth',2); %,'DisplayName', 'Start Time');
                    %if a single trial is analyzed, add threshold lines
                    if length(app.ConditionTrialsListBox.Value) == 1
                        yline(ax,OnsetLimitsV,'r:','LineWidth',2);
                        yline(ax,OffsetLimitsV,'b--','LineWidth',2);
                    end

                    ax2=subplot(1,2,2,'Parent',app.PlotOnsetOffsetSwitchFig); %======================================Pop up subplot 2
                    plot(ax2,CurrentXData',CurrentYData'); hold on;
                    %Onset and Offset Limits
                    Bx2=boxplot(ax2,[OnsetLimitsV';OffsetLimitsV'],g_Limits,"Orientation","vertical","Positions",[median(AllOnset) median(AllOffset)],"Widths",0.02);
                    set(Bx2,'LineWidth',2)
                    xticks(ax2,'auto');
                    xticklabels(ax2,'auto');
                    if ~any(isnan(OnsetLimitsV)) || ~any(isnan(OffsetLimitsV)) %if there are any nans in the OnsetLimits or OffsetLimits auto scale this plot
                        ylim(ax2,[min([OnsetLimitsV';OffsetLimitsV'])-0.05*Units max([OnsetLimitsV';OffsetLimitsV'])+0.05*Units]); %+/-0.05 of unit
                    end
                    yline(ax2,median(OnsetLimitsV),'r:','LineWidth',2);
                    yline(ax2,median(OffsetLimitsV),'b--','LineWidth',2);
                    %Mean Pre Stim
                    Bx3=boxplot(ax2,meanPreStimDataV,g_PreStim,"Orientation","vertical","Positions",median([app.PreStimPeriodmsStartEditField.Value app.PreStimPeriodmsEndEditField.Value])*0.001,"Widths",((app.PreStimPeriodmsEndEditField.Value-app.PreStimPeriodmsStartEditField.Value))*0.001);
                    set(Bx3,'LineWidth',2)
                    xticks(ax2,'auto');
                    xticklabels(ax2,'auto');
                    yline(ax2,median(meanPreStimDataV),'k--','LineWidth',2);
                    xlim(ax2,[app.PreStimPeriodmsStartEditField.Value*0.001-0.005 max([AllOnset;AllOffset])+0.005]); %zoom into onset/offset ranges +/-5ms
                    xlabel(ax2,'Time (s)');
                    ylabel(ax2,app.UIAxes.YLabel.String);
                    title(ax2,'Threshold Boxplots');
                    sgtitle('Onset and Offset')
                    hold(ax,'off');
                    hold(ax2,'off');
                end %end pop-up figure plotting

            end %end if override is not used

            %Update "missing from analyze" label
            if MissingAnalyze > 0
                app.MissingfromanalyzeLabel.Visible=1;
                app.MissingfromanalyzeLabel.Text=['Missing from analyze: ' num2str(MissingAnalyze) '/' num2str(length(SelectedTrialsData(:,1)))];
            else
                app.MissingfromanalyzeLabel.Visible=0;
                app.MissingfromanalyzeLabel.Text=['Missing from analyze: ' num2str(MissingAnalyze) '/' num2str(length(SelectedTrialsData(:,1)))];
            end

            hold(app.UIAxes,'off');
            if (string(class(event)) == "matlab.ui.eventdata.ButtonPushedData") && (event ~= 2)
                loadingScreen(app,0);
            end
            %AnalyzeTime=toc %time to analyze


        end

        % Button pushed function: OverrideButton
        function OverrideButtonPushed(app, event)
            %==============================================================
            %Allow user to choose limits for data analysis via point and
            %click
            %If override button is pushed again, disables override mode
            %==============================================================


            zoom(app.UIAxes, 'off')

            %Check if override has been clicked
            %Update booleans and reset variables
            if app.OverrideUsed == 0
                app.UIFigure.Pointer = 'crosshair';
                app.OverrideUsed=1;
                app.Analyze_OverrideUsed=1;
                app.OverrideRange=[];
                app.OverrideLamp.Color=[1 0 0];
            elseif app.OverrideUsed == 1 %Override button is pressed again, cancel override
                app.UIFigure.Pointer = 'arrow';
                app.OverrideUsed=0;
                app.Analyze_OverrideUsed=0;
                app.OverrideRange=[];
                app.OverrideLamp.Color=[0.8 0.8 0.8];
            end

            loadingScreen(app,1);
            %Delete any existing xlines and areas
            if any(ishghandle(app.OnsetLine) == 1)
                delete(app.OnsetLine);
            end
            if any(ishghandle(app.OffsetLine) == 1)
                delete(app.OffsetLine);
            end
            if any(ishghandle(app.PreStimArea) == 1)
                delete(app.PreStimArea);
            end
            if any(ishghandle(app.StartLine) == 1)
                delete(app.StartLine);
            end
            if ishghandle(app.OnsetLimitLines) == 1
                delete(app.OnsetLimitLines);
            end
            if ishghandle(app.OffsetLimitLines) == 1
                delete(app.OffsetLimitLines);
            end
            if ishghandle(app.meanPreStimLine) == 1
                delete(app.meanPreStimLine);
            end
            loadingScreen(app,0);


        end

        % Button pushed function: RightArrowAnalyzeButton
        function RightArrowAnalyzeButtonPushed(app, event)
            %==============================================================
            %Use to scroll down individual trials and analyze them
            %==============================================================

            %Determine currently selected trial
            CurrTrial=string(app.ConditionTrialsListBox.Value{1});
            AllTrials=string(app.ConditionTrialsListBox.Items);
            CurrTrialLoc=find(CurrTrial == AllTrials);

            %Next trial
            app.ConditionTrialsListBox.Value=app.ConditionTrialsListBox.Items{CurrTrialLoc+1};
            ConditionTrialsListBoxValueChanged(app,2);
            AnalyzeButtonPushed(app,1);

        end

        % Button pushed function: LeftArrowAnalyzeButton
        function LeftArrowAnalyzeButtonPushed(app, event)
            %==============================================================
            %Use to scroll up individual trials and analyze them
            %==============================================================

            %Determine currently selected trial
            CurrTrial=string(app.ConditionTrialsListBox.Value{1});
            AllTrials=string(app.ConditionTrialsListBox.Items);
            CurrTrialLoc=find(CurrTrial == AllTrials);

            %Next trial
            app.ConditionTrialsListBox.Value=app.ConditionTrialsListBox.Items{CurrTrialLoc-1};
            ConditionTrialsListBoxValueChanged(app,2);
            AnalyzeButtonPushed(app,1);
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            %==============================================================
            %Copy of the save (.csv) button that is located closer to the 
            %analyze arrows for easy saving of trial by trial analysis
            %==============================================================
            app.SButton.BackgroundColor=[0.8,0.8,0.8];
            pause(0.1);
            SavecsvButtonPushed(app,1);
            app.SButton.BackgroundColor=[0.39,0.83,0.07];
        end

        % Button pushed function: NDButton
        function NDButtonPushed(app, event)
            %==============================================================
            %Manually mark this trial as there is no onset, so there are no
            %metrics
            %==============================================================
            %Change the button color so the user knows it was pressed
            app.NDButton.BackgroundColor=[0.8 0.8 0.8];
            pause(0.1);

            app.ManualMark=1;
            %Fill the metrics with Nans
            app.MEP_Amp=nan;
            app.MEP_Area=nan;
            app.SP_PercDecrease=nan;
            app.SP_Area=nan;

            if isempty(app.Analyze_TrialsUsed)
                app.Analyze_TrialsUsedConditionName={char(app.ConditionsListBox.Value)};
                ChLoc=find(string(app.UITable.Data(:,2))==string(app.Analyze_TrialsUsedConditionName));
                app.Analyze_TrialsUsedChannel=app.UITable.Data(ChLoc,3);
                app.Analyze_TrialsUsed=app.ConditionTrialsListBox.Value; %for trial by trial only
                app.PreStimStart=nan;
                app.PreStimEnd=nan;
                app.PreStimMean=nan;
                app.PreStimRMS=nan;
                app.PreStimSTDV=nan;
                app.Analyze_OverrideUsed=2;
                app.AllOnOffsetTime=[nan nan];

            end


        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            %==============================================================
            %Allow user to choose limits for data analysis via point and
            %click
            %Updates onset/offset times selected and plots selected times
            %via xlines
            %==============================================================

            %If override is active and a click occurs on the graph
            if app.OverrideUsed==1
                currPt = app.UIAxes.CurrentPoint; %determine click location
                app.OverrideRange=[app.OverrideRange currPt(1,1)]; %update override range

                hold(app.UIAxes,'on');
                %if this is the first click, plot onset line
                if length(app.OverrideRange) == 1 %onset selected
                    app.OnsetLine=xline(app.UIAxes,app.OverrideRange(1),'r','LineStyle','--',"LineWidth",2); %,'DisplayName', 'Override Onset');

                    %if this is the second click, plot offset line
                    %reset mouse pointer, start analyze, deactivate override
                elseif length(app.OverrideRange) == 2 %offset selected
                    app.AllOnOffsetTime=[app.OverrideRange(1) app.OverrideRange(2)];
                    app.OffsetLine=xline(app.UIAxes,app.OverrideRange(2),'b','LineStyle','--',"LineWidth",2); %,'DisplayName', 'Override Onset');
                    pause(0.01);
                    app.UIFigure.Pointer = 'arrow';

                    AnalyzeButtonPushed(app,1);

                    app.OverrideUsed=0;
                    app.OverrideLamp.Color=[0.8 0.8 0.8];

                end

            end
        end

        % Button pushed function: SavecsvButton
        function SavecsvButtonPushed(app, event)
            %==============================================================
            %Save analyze results to csv file
            %==============================================================

            %Change button color
            app.SavecsvButton.BackgroundColor=[0.8 0.8 0.8];
            pause(1);
            %tic

            %check if filename edit field is empty, if so prompt for filename
            if isempty(app.SavecsvEditField.Value)
                app.SavecsvButton.BackgroundColor=[0.39,0.83,0.07];
                msgbox('Please Enter Filename');
                return;
            end

            %File name
            csvFileName=app.SavecsvEditField.Value;

            %check for .csv extension
            if length(csvFileName) >= 4 && all(csvFileName(end-3:end) == '.csv')
                csvFileName(end-3:end)='';
            end

            %Check for AddOn in file name
            AO_FileEndLoc=find(csvFileName == '_');
            if ~isempty(AO_FileEndLoc)
                Ending=csvFileName(AO_FileEndLoc(end):end);
            end
            %Selected analysis option
            AO_Opt=app.AnalysisOptionDropDown.Value;
            if any(~ismember(["_MEP","_SP","_EMGA"],string(AO_Opt))) %if custom analysis option
                ULoc=find(AO_Opt=='_');
                ULoc=ULoc(end);
                AO_Opt=AO_Opt(ULoc:end);

            end
            %if there is no underscore in the name OR the lengths of the endings don't match OR the Values of the endings don't match
            if (isempty(AO_FileEndLoc)) || (length(AO_Opt) ~= length(Ending)) || (any(AO_Opt ~= Ending))
                csvFileName=[csvFileName AO_Opt];
                app.SavecsvEditField.Value=csvFileName;
            end

            %check if "Results" folder exists if not, create it
            ResultsFolderDir=fullfile(app.Directory.SaveFolder, 'Results');
            if exist(ResultsFolderDir,'dir') ~= 7 %7 folder exists
                mkdir(ResultsFolderDir);

            end

            ResultFile=fullfile(ResultsFolderDir, [csvFileName '.csv']);

            %create csv file
            NewFile=0;

            %MEP Analysis
            if exist(ResultFile,'file') ~= 2 && string(AO_Opt) == "_MEP" %if file doesn't exist, create it
                Headers{1}='Filename';
                Headers{2}='Trial Number';
                Headers{3}='Condition Channel #';
                Headers{4}='Condition';
                Headers{5}='Process Order';
                Headers{6}='Analyze Option';
                Headers{7}='Override';
                Headers{8}='Onset/Offset Detection Method';
                Headers{9}='Start Time (ms)';
                Headers{10}='unit';
                Headers{11}='Sample Rate';
                Headers{12}='Pre-stim Start Time (ms)';
                Headers{13}='Pre-stim Duration (ms)';
                Headers{14}='Rectified Pre-stim Mean';
                Headers{15}='Pre-stim RMS';
                Headers{16}='Pre-stim STDV';
                Headers{17}='Analyze Average Boolean';
                Headers{18}='Date';
                Headers{19}='Notes';
                Headers{20}='Manually Marked No Onset';
                Headers{21}='MEP Onset (ms)';
                Headers{22}='MEP Duration (ms)';
                Headers{23}='MEP Amplitude';
                Headers{24}='MEP Area';
                NumBaseHeaders=24;

                %check onset/offset detection method
                if app.Analyze_OverrideUsed == 0 
                    OnOffsetDetectMethod=string(app.OnsetOffsetDetectionDropDown.Value);
                    switch OnOffsetDetectMethod

                        case "SD Bounds"
                            Headers{25}='OnsetSD';
                            Headers{26}='OffsetSD';
                            Headers{27}='OnsetMinDur (ms)';
                            Headers{28}='OffsetMinDur (ms)';
                            Headers{29}='Onset 50% Checkbox';
                            Headers{30}='Offset 50% Checkbox';

                        case "Garvey et al"
                            Headers{25}='MCD Constant';
                            Headers{26}='OnsetMinDur (ms)';
                            Headers{27}='OffsetMinDur (ms)';
                            Headers{28}='Onset 50% Checkbox';
                            Headers{29}='Offset 50% Checkbox';


                        otherwise %custom method
                            PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection');
                            Settings=load(fullfile(PluginsFolderName,strcat(OnOffsetDetectMethod,"_Settings.mat")),"UserVar"); %load in custom settings
                            for i=1:length(Settings.UserVar(:,1))    %use the editfieldnames for the headers
                                Headers{NumBaseHeaders+i}=Settings.UserVar{i,1}; 
                            end
                    end
                end

                ResultTable=table(Headers);

                writetable(ResultTable,ResultFile,'WriteMode','Append','WriteVariableNames',0);
                NewFile=1;

            end

            %SP Analysis
            if exist(ResultFile,'file') ~= 2 && string(AO_Opt) == "_SP" %if file doesn't exist, create it
                Headers{1}='Filename';
                Headers{2}='Trial Number';
                Headers{3}='Condition Channel #';
                Headers{4}='Condition';
                Headers{5}='Process Order';
                Headers{6}='Analyze Option';
                Headers{7}='Override';
                Headers{8}='Onset/Offset Detection Method';
                Headers{9}='Start Time (ms)';
                Headers{10}='unit';
                Headers{11}='Sample Rate';
                Headers{12}='Pre-stim Start Time (ms)';
                Headers{13}='Pre-stim Duration (ms)';
                Headers{14}='Rectified Pre-stim Mean';
                Headers{15}='Pre-stim RMS';
                Headers{16}='Pre-stim STDV';
                Headers{17}='Analyze Average Boolean';
                Headers{18}='Date';
                Headers{19}='Notes';
                Headers{20}='Manually Marked No Onset';
                Headers{21}='SP Onset (ms)';
                Headers{22}='SP Duration (ms)';
                Headers{23}='SP Percent Decrease';
                Headers{24}='SP Area';
                NumBaseHeaders=24;

                %check onset/offset detection method
                if app.Analyze_OverrideUsed == 0
                    OnOffsetDetectMethod=string(app.OnsetOffsetDetectionDropDown.Value);
                    switch OnOffsetDetectMethod

                        case "SD Bounds"
                            Headers{25}='OnsetSD';
                            Headers{26}='OffsetSD';
                            Headers{27}='OnsetMinDur (ms)';
                            Headers{28}='OffsetMinDur (ms)';
                            Headers{29}='Onset 50% Checkbox';
                            Headers{30}='Offset 50% Checkbox';


                        case "Garvey et al"
                            Headers{25}='MCD Constant';
                            Headers{26}='OnsetMinDur (ms)';
                            Headers{27}='OffsetMinDur (ms)';
                            Headers{28}='Onset 50% Checkbox';
                            Headers{29}='Offset 50% Checkbox';


                        otherwise %custom method
                            PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection');
                            Settings=load(fullfile(PluginsFolderName,strcat(OnOffsetDetectMethod,"_Settings.mat")),"UserVar"); %load in custom settings
                            for i=1:length(Settings.UserVar(:,1))    %use the editfieldnames for the headers
                                Headers{NumBaseHeaders+i}=Settings.UserVar{i,1};
                            end


                    end
                end

                ResultTable=table(Headers);

                writetable(ResultTable,ResultFile,'WriteMode','Append','WriteVariableNames',0);
                NewFile=1;
            end

            %Custom Analysis
            if exist(ResultFile,'file') ~= 2 && string(AO_Opt) ~= "_MEP" && string(AO_Opt) ~= "_SP" %if file doesn't exist, create it
                Headers{1}='Filename';
                Headers{2}='Trial Number';
                Headers{3}='Condition Channel #';
                Headers{4}='Condition';
                Headers{5}='Process Order';
                Headers{6}='Analyze Option';
                Headers{7}='Override';
                Headers{8}='Onset/Offset Detection Method';
                Headers{9}='Start Time (ms)';
                Headers{10}='unit';
                Headers{11}='Sample Rate';
                Headers{12}='Pre-stim Start Time (ms)';
                Headers{13}='Pre-stim Duration (ms)';
                Headers{14}='Rectified Pre-stim Mean';
                Headers{15}='Pre-stim RMS';
                Headers{16}='Pre-stim STDV';
                Headers{17}='Analyze Average Boolean';
                Headers{18}='Date';
                Headers{19}='Notes';
                Headers{20}='Manually Marked No Onset';
                Headers{21}='Onset (ms)';
                Headers{22}='Duration (ms)';
                NumBaseHeaders=22;

                %check onset/offset detection method
                if app.Analyze_OverrideUsed == 0
                    OnOffsetDetectMethod=string(app.OnsetOffsetDetectionDropDown.Value);
                    switch OnOffsetDetectMethod

                        case "SD Bounds"
                            Headers{23}='OnsetSD';
                            Headers{24}='OffsetSD';
                            Headers{25}='OnsetMinDur (ms)';
                            Headers{26}='OffsetMinDur (ms)';
                            Headers{27}='Onset 50% Checkbox';
                            Headers{28}='Offset 50% Checkbox';

                        case "Garvey et al"
                            Headers{23}='MCD Constant';
                            Headers{24}='OnsetMinDur (ms)';
                            Headers{25}='OffsetMinDur (ms)';
                            Headers{26}='Onset 50% Checkbox';
                            Headers{27}='Offset 50% Checkbox';


                        otherwise %custom method
                            PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection');
                            Settings=load(fullfile(PluginsFolderName,strcat(OnOffsetDetectMethod,"_Settings.mat")),"UserVar"); %load in custom settings
                            for i=1:length(Settings.UserVar(:,1))    %use the editfieldnames for the headers
                                Headers{NumBaseHeaders+i}=Settings.UserVar{i,1};
                            end
                    end
                end

                %check custom analysis outputs
                %Add inputs for analysis plugin pop-up
                PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Analysis');
                Name=app.AnalysisOptionDropDown.Value;
                Settings=load(fullfile(PluginsFolderName,strcat(Name(2:end),"_Settings.mat")),"UserVar"); %load in custom settings
                HeadersLength=length(Headers);
                for i=1:length(Settings.UserVar(:,1))    %use the editfieldnames for the headers
                    Headers{HeadersLength+i}=Settings.UserVar{i,1};
                end
                %Add outcomes for analysis plugin
                Outputs=fieldnames(app.CustomOutputs);
                HeadersLength=length(Headers);
                for h=1:length(Outputs)
                    Headers{HeadersLength+h}=Outputs{h};
                end


                ResultTable=table(Headers);

                writetable(ResultTable,ResultFile,'WriteMode','Append','WriteVariableNames',0);
                NewFile=1;

            end
            %}

            if exist(ResultFile,'file') == 2 && NewFile == 0 %the file does exist and it wasn't just created, check the header line


                [Headers,CarriageReturn,FileData]=findHeadersandCarriage(ResultFile);

                if app.Analyze_OverrideUsed == 0
                    
                    OnOffsetDetectMethod=string(app.OnsetOffsetDetectionDropDown.Value);
                    switch OnOffsetDetectMethod
    
                        case "SD Bounds"
                            HeaderLineText=["OnsetSD","OffsetSD","OnsetMinDur (ms)","OffsetMinDur (ms)","Onset 50% Checkbox","Offset 50% Checkbox"];
    
                        case "Garvey et al"
                            HeaderLineText=["MCD Constant","OnsetMinDur (ms)","OffsetMinDur (ms)","Onset 50% Checkbox","Offset 50% Checkbox"];
    
                        otherwise %custom method
                            PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection');
                            Settings=load(fullfile(PluginsFolderName,strcat(OnOffsetDetectMethod,"_Settings.mat")),"UserVar"); %load in custom settings
                            HeaderLineText=strings(1,length(Settings.UserVar(:,1)));
                            for i=1:length(Settings.UserVar(:,1))    %use the editfieldnames for the headers
                                HeaderLineText(i)=string(Settings.UserVar{i,1});
                            end


                    end
                    %check if the headers for the custom onset/offset method exist in the csv file, looks for all the headers together
                    checkFile(FileData,Headers,HeaderLineText,CarriageReturn,ResultFile);
                    [Headers,CarriageReturn,FileData]=findHeadersandCarriage(ResultFile); %update file info
                end


                %file exists and wasn't just created, check header line
                if ~isempty(app.CustomOutputs)
                    %Add inputs for analysis plugin pop-up
                    PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Analysis');
                    Name=app.AnalysisOptionDropDown.Value;
                    Settings=load(fullfile(PluginsFolderName,strcat(Name(2:end),"_Settings.mat")),"UserVar"); %load in custom settings
                    HeaderLineText=string(Settings.UserVar(:,1))';
                    checkFile(FileData,Headers,HeaderLineText,CarriageReturn,ResultFile);
                    [Headers,CarriageReturn,FileData]=findHeadersandCarriage(ResultFile); %update file info

                    %Add outcomes for analysis plugin
                    Outputs=fieldnames(app.CustomOutputs);
                    HeaderLineText=string(Outputs)';
                    checkFile(FileData,Headers,HeaderLineText,CarriageReturn,ResultFile);
                    [Headers,~,~]=findHeadersandCarriage(ResultFile); %update file info
                end
            end


            %Save Analysis Results
            numColumns=length(Headers);
            DataOut=cell(length(app.Analyze_TrialsUsed),numColumns);
            switch AO_Opt
                case '_MEP'
                    DataOut(:,23)=num2cell((app.MEP_Amp.*1000)');    %convert to mV
                    DataOut(:,24)=num2cell((app.MEP_Area.*1000)');   %convert to mV
                case '_SP'
                    DataOut(:,23)=num2cell(app.SP_PercDecrease');        %this is a percentage
                    DataOut(:,24)=num2cell((app.SP_Area)');              %normalized
            end

            %General Info
            %create filename list
            FilenameList=string(app.Filename);
            Analyze_Filenames=cell(1,length(FilenameList)*2-1);
            Analyze_Filenames(1:2:end)=cellstr(FilenameList);
            Analyze_Filenames(2:2:end)={','};
            Analyze_Filenames=char(Analyze_Filenames);
            Analyze_Filenames=reshape(Analyze_Filenames',1,[]);
            Analyze_Filenames(Analyze_Filenames==' ')=[];
            DataOut(:,1)={Analyze_Filenames};
            if app.AverageCheckBox.Value == 0
                DataOut(:,2)=num2cell(app.Analyze_TrialsUsed);
            else
                DataOut(:,2)=cellstr(app.Analyze_TrialsUsed);
            end
            DataOut(:,3)=app.Analyze_TrialsUsedChannel;
            DataOut(:,4)=app.Analyze_TrialsUsedConditionName;
            %format process order into comma delimited list
            if ~isempty(app.ProcessOrder)
                ProcessOrderRS=reshape(app.ProcessOrder',1,[]);
                ProcessOrderRS(cellfun(@isempty,ProcessOrderRS))=[]; %remove empty cells
                ProcessOrderString=cellfun(@string, ProcessOrderRS, 'UniformOutput', false);

                ProcessOrderUsed='';
                for i=1:length(ProcessOrderString)
                    CellLength=length(ProcessOrderString{i});
                    if CellLength > 1
                        for i2=1:CellLength
                            ProcessOrderUsed=[ProcessOrderUsed char(ProcessOrderString{i}(i2)) ','];
                        end
                    else
                        ProcessOrderUsed=[ProcessOrderUsed char(ProcessOrderString{i}) ','];
                    end

                end
                ProcessOrderUsed(end)='';

                if any(string(app.ProcessOrder(:,1)) == "Down Sample")
                    AnalyzeSampleRate=app.DownSampledRate;
                else
                    AnalyzeSampleRate=app.AllSampleRate;
                end
            else
                ProcessOrderUsed=[];
                AnalyzeSampleRate=app.AllSampleRate;
            end
            DataOut(:,5)={ProcessOrderUsed};
            DataOut(:,6)={app.AnalysisOptionDropDown.Value(2:end)};
            DataOut(:,9)={app.StartTimemsEditField.Value};
            DataOut(:,10)={'mV'}; %all calculations are saved in millivolts

            DataOut(:,11)={AnalyzeSampleRate}; %sample rate assumed to be the same for all condition files !!!
            DataOut(:,18)={datetime('now')};
            if length(app.NotesTextArea.Value(:,1)) > 1
                numNotes=length(app.NotesTextArea.Value(:,1));
                Notes='';
                for N=1:numNotes
                    CurrNote=app.NotesTextArea.Value{N};
                    Notes=[Notes '/' CurrNote];
                end
                Notes=Notes(2:end); %remove '/' at the beginning
            else
                Notes=app.NotesTextArea.Value;

            end
            DataOut(:,19)={Notes};
            DataOut(:,20)={app.ManualMark};
            DataOut(:,17)={app.AverageCheckBox.Value};
            %PreStim Info
            DataOut(:,12)={app.PreStimStart}; %unit ms
            DataOut(:,13)={app.PreStimEnd-app.PreStimStart}; %unit ms
            DataOut(:,14)=num2cell(app.PreStimMean'*1000);
            DataOut(:,15)=num2cell(app.PreStimRMS'*1000);
            DataOut(:,16)=num2cell(app.PreStimSTDV'*1000);

            %Onset/Offset Info
            DataOut(:,7)={app.Analyze_OverrideUsed};
            DataOut(:,8)={app.OnsetOffsetDetectionDropDown.Value}; %onset/offset detection method
            DataOut(:,21)=num2cell(app.AllOnOffsetTime(:,1)*1000); %unit ms %onset time
            DataOut(:,22)=num2cell((app.AllOnOffsetTime(:,2)-app.AllOnOffsetTime(:,1))*1000); %unit ms %duration

            %check onset/offset detection method
            if app.Analyze_OverrideUsed == 0
                if NewFile == 0
                    %check which column the data should go to
                    ResultFile_FID=fopen(ResultFile,'r');
                    FileData=fread(ResultFile_FID); %double dec
                    fclose(ResultFile_FID);
                    CarriageReturn=find(FileData == 13); %13 dec == carriage return
                    if isempty(CarriageReturn) %files written on macOS/Linux use LF only (10 dec)
                        CarriageReturn=find(FileData == 10);
                    end
                    CarriageReturn=CarriageReturn(1);
                    HeaderLine=char(FileData(1:CarriageReturn-1))';
                    Headers=string(strsplit(HeaderLine,','));
                end
                OnOffsetDetectMethod=string(app.OnsetOffsetDetectionDropDown.Value);
                switch OnOffsetDetectMethod

                    case "SD Bounds"
                        HeaderLineText=["OnsetSD","OffsetSD","OnsetMinDur (ms)","OffsetMinDur (ms)","Onset 50% Checkbox","Offset 50% Checkbox"];
                        DataOut(:,Headers == HeaderLineText(1))={app.OnsetSDEditField.Value};
                        DataOut(:,Headers == HeaderLineText(2))={app.OffsetSDEditField.Value};
                        DataOut(:,Headers == HeaderLineText(3))={app.OnsetMinDurmsEditField.Value};
                        DataOut(:,Headers == HeaderLineText(4))={app.OffsetMinDurmsEditField.Value};
                        DataOut(:,Headers == HeaderLineText(5))={app.FiftyPercentOnsetCheckBox.Value};
                        DataOut(:,Headers == HeaderLineText(6))={app.FiftyPercentOffsetCheckBox.Value};

                    case "Garvey et al"
                        HeaderLineText=["MCD Constant","OnsetMinDur (ms)","OffsetMinDur (ms)","Onset 50% Checkbox","Offset 50% Checkbox"];
                        DataOut(:,Headers == HeaderLineText(1))={app.MCDConstantEditField.Value};
                        DataOut(:,Headers == HeaderLineText(2))={app.OnsetMinDurmsEditField.Value};
                        DataOut(:,Headers == HeaderLineText(3))={app.OffsetMinDurmsEditField.Value};
                        DataOut(:,Headers == HeaderLineText(4))={app.FiftyPercentOnsetCheckBox.Value};
                        DataOut(:,Headers == HeaderLineText(5))={app.FiftyPercentOffsetCheckBox.Value};

                    otherwise %custom method
                        PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','OnsetOffsetDetection');
                        Settings=load(fullfile(PluginsFolderName,strcat(OnOffsetDetectMethod,"_Settings.mat")),"UserVar"); %load in custom settings
                        for i=1:length(Settings.UserVar(:,1))    %use the editfieldnames for the headers
                            DataOut(:,Headers == string(Settings.UserVar{i,1}))={Settings.UserVar{i,2}};
                        end

                end
            end
            app.Analyze_OverrideUsed=0; %reset after saving

            %if there are custom outputs
            if ~isempty(app.CustomOutputs) %custom analysis outputs exist
                %plugin inputs
                PluginsFolderName=fullfile(userpath, app.ToolboxDocFolderName, 'Plugins','Analysis');
                Name=app.AnalysisOptionDropDown.Value;
                Settings=load(fullfile(PluginsFolderName,strcat(Name(2:end),"_Settings.mat")),"UserVar"); %load in custom settings
                HeaderLineText=string(Settings.UserVar(:,1))';
                ContainsHeaderLine=find(contains(Headers,HeaderLineText)); 
                HeaderTextLoc=ContainsHeaderLine;
                HeaderText=string(Headers(HeaderTextLoc));
                UserVarText=string(Settings.UserVar(:,1))';
                for h=1:length(UserVarText)
                    %check that the headers match
                    CurrText=UserVarText(h);
                    Loc=find(contains(HeaderText,CurrText)); %should only appear once
                    DataOut(:,HeaderTextLoc(Loc))={Settings.UserVar{h,2}};
                end

                %plugin results
                Outputs=fieldnames(app.CustomOutputs);
                HeaderLineText=string(Outputs)';
                ContainsHeaderLine=find(contains(Headers,HeaderLineText));
                HeaderTextLoc=ContainsHeaderLine;
                HeaderText=string(Headers(HeaderTextLoc));
                for h=1:length(Outputs)
                    %check that the headers match
                    CurrText=string(Outputs{h});
                    Loc=find(contains(HeaderText,CurrText)); %should only appear once
                    Data=app.CustomOutputs.(CurrText);
                    DataOut(:,HeaderTextLoc(Loc))=num2cell(Data);
                end

            end


            %Write data to file
            ResultTable=table(DataOut);
            if fopen(ResultFile,'a') == -1
                msgbox('Cannot open file. Please try again.');
                app.SavecsvButton.BackgroundColor=[0.39,0.83,0.07];
                fclose('all');
                return;
            else
                fclose('all');
            end
            writetable(ResultTable,ResultFile,'WriteMode','Append','WriteVariableNames',0);

            %SaveCSVTime=toc %time to save CSV file
            app.SavecsvButton.BackgroundColor=[0.39,0.83,0.07];

            %If the manually mark no onset button was clicked return it to its original color and reset ManualMark variable
            app.Analyze_TrialsUsed=[]; %reset this, so if a manual mark is used it can tell if the data was analyzed initially or not
            app.ManualMark=0;
            if app.NDButton.BackgroundColor == [0.8 0.8 0.8]
                app.NDButton.BackgroundColor=[0.93,0.69,0.13];
            end


        end

        % Callback function: ProcessTree
        function ProcessTreeCheckedNodesChanged(app, event)
            %==============================================================
            %Update which edit field options are available base on which
            %processes are selected
            %Also update Process Order list box
            %==============================================================

            %check which nodes are selected and which nodes were previously selected
            CheckedNodes=event.CheckedNodes;
            PrevCheckedNodes=event.PreviousCheckedNodes;

            %Selected processes
            SelectedProcesses=zeros(1,9);
            SelectedProcNames=cell(1,length(CheckedNodes));
            for i=1:length(CheckedNodes)
                NodeData=CheckedNodes(i).NodeData;
                SelectedProcesses(NodeData)=NodeData;
                SelectedProcNames{i}=CheckedNodes(i).Text;
            end

            %Previously selected processes
            PrevSelectedProcesses=zeros(1,9);
            for i=1:length(PrevCheckedNodes)
                PrevNodeData=PrevCheckedNodes(i).NodeData;
                PrevSelectedProcesses(PrevNodeData)=PrevNodeData;
            end

            %Check for which processes was added/removed
            if sum(SelectedProcesses~=0) > sum(PrevSelectedProcesses~=0)
                %one was added
                if ~all(PrevSelectedProcesses==0)
                    Diff=PrevSelectedProcesses~=SelectedProcesses;
                    New=SelectedProcesses(Diff);
                else
                    New=SelectedProcesses(SelectedProcesses ~=0);
                end

                %Update visible edit fields
                switch New
                    %Rectify 8
                    %Custom 9
                    case 1 %Down sample
                        app.FactorEditField.Visible=1;
                        app.FactorLabel.Visible=1;

                    case 3 %Band pass
                        app.BandPassStartEditField.Visible=1;
                        app.BandPassDashLabel.Visible=1;
                        app.BandPassEndEditField.Visible=1;
                        app.BandPassHzLabel.Visible=1;
                        app.BandPassFilterType.Visible=1;
                        app.BandPassFilterOrder.Visible=1;
                        app.BandPassFilterOrderLabel.Visible=1;

                    case 4 %Low pass
                        app.LowPassEditField.Visible=1;
                        app.LowPassHzLabel.Visible=1;
                        app.LowPassFilterType.Visible=1;
                        app.LowPassFilterOrder.Visible=1;
                        app.LowPassFilterOrderLabel.Visible=1;

                    case 5 %High pass
                        app.HighPassEditField.Visible=1;
                        app.HighPassHzLabel.Visible=1;
                        app.HighPassFilterType.Visible=1;
                        app.HighPassFilterOrder.Visible=1;
                        app.HighPassFilterOrderLabel.Visible=1;

                    case 2 %Notch
                        app.NotchUSButton.Visible=1;
                        app.NotchEuropeanButton.Visible=1;
                        app.OtherNotchButton.Visible=1;
                        app.OtherNotchEditField.Visible=1;
                        app.OtherNotchHzLabel.Visible=1;

                    case 7 %Delay
                        app.DelayEditField.Visible=1;
                        app.DelaymsLabel.Visible=1;

                    case 6 %Derivative
                        app.DerivativeEditField.Visible=1;
                        app.DerivativeOrderLabel.Visible=1;
                    case 8 %Rectify
                        app.FullWaveButton.Visible=1;
                        app.HalfWaveButton.Visible=1;


                end

            elseif sum(SelectedProcesses~=0) < sum(PrevSelectedProcesses~=0)
                %one was removed
                if ~all(SelectedProcesses==0)
                    Diff=PrevSelectedProcesses~=SelectedProcesses;
                    New=PrevSelectedProcesses(Diff);
                else
                    New=PrevSelectedProcesses(PrevSelectedProcesses ~=0);
                end

                %Update visible edit fields
                switch New
                    %Rectify 8
                    %Custom 9
                    case 1 %Down sample
                        app.FactorEditField.Visible=0;
                        app.FactorLabel.Visible=0;

                    case 3 %Band pass
                        app.BandPassStartEditField.Visible=0;
                        app.BandPassDashLabel.Visible=0;
                        app.BandPassEndEditField.Visible=0;
                        app.BandPassHzLabel.Visible=0;
                        app.BandPassFilterType.Visible=0;
                        app.BandPassFilterOrder.Visible=0;
                        app.BandPassFilterOrderLabel.Visible=0;
                        app.BandPassFilterRp.Visible = 0;
                        app.BandPassFilterRpLabel.Visible = 0;
                        app.BandPassFilterRs.Visible = 0;
                        app.BandPassFilterRsLabel.Visible = 0;

                    case 4 %Low pass
                        app.LowPassEditField.Visible=0;
                        app.LowPassHzLabel.Visible=0;
                        app.LowPassFilterType.Visible=0;
                        app.LowPassFilterOrder.Visible=0;
                        app.LowPassFilterOrderLabel.Visible=0;
                        app.LowPassFilterRp.Visible = 0;
                        app.LowPassFilterRpLabel.Visible = 0;
                        app.LowPassFilterRs.Visible = 0;
                        app.LowPassFilterRsLabel.Visible = 0;

                    case 5 %High pass
                        app.HighPassEditField.Visible=0;
                        app.HighPassHzLabel.Visible=0;
                        app.HighPassFilterType.Visible=0;
                        app.HighPassFilterOrder.Visible=0;
                        app.HighPassFilterOrderLabel.Visible=0;
                        app.HighPassFilterRp.Visible = 0;
                        app.HighPassFilterRpLabel.Visible = 0;
                        app.HighPassFilterRs.Visible = 0;
                        app.HighPassFilterRsLabel.Visible = 0;

                    case 2 %Notch
                        app.NotchUSButton.Visible=0;
                        app.NotchEuropeanButton.Visible=0;
                        app.OtherNotchButton.Visible=0;
                        app.OtherNotchEditField.Visible=0;
                        app.OtherNotchHzLabel.Visible=0;

                    case 7 %Delay
                        app.DelayEditField.Visible=0;
                        app.DelaymsLabel.Visible=0;

                    case 6 %Derivative
                        app.DerivativeEditField.Visible=0;
                        app.DerivativeOrderLabel.Visible=0;
                    case 8 %Rectify
                        app.FullWaveButton.Visible=0;
                        app.HalfWaveButton.Visible=0;

                end

            end %end check for which processes was added/removed


            %Update Process Order List
            app.ProcessOrderListBox.Items=SelectedProcNames;
            app.ProcessOrderListBox.ItemsData=SelectedProcesses(SelectedProcesses ~=0);

            %Add custom processes
            CustomSelected=app.CustomProcessTree.CheckedNodes;
            for i=1:length(CustomSelected)
                app.ProcessOrderListBox.Items(end+1)={CustomSelected(i).Text};
                app.ProcessOrderListBox.ItemsData(end+1)=8+CustomSelected(i).NodeData; %8 is the last item data value for the built-in processes
            end


        end

        % Callback function: CustomProcessTree
        function CustomProcessTreeCheckedNodesChanged(app, event)
            %==============================================================
            %Add custom processes to Process Order List Box
            %==============================================================

            %Run Process Tree Nodes changed
            eventS.CheckedNodes=app.ProcessTree.CheckedNodes;
            eventS.PreviousCheckedNodes=app.ProcessTree.CheckedNodes;
            ProcessTreeCheckedNodesChanged(app,eventS );

        end

        % Value changed function: NotchUSButton
        function NotchUSButtonValueChanged(app, event)
            %==============================================================
            %Use US powerline noise 60Hz in Notch filter,
            %checked in ProcessingData.m
            %Deselect other Notch filter option buttons
            %==============================================================

            %Deactive other options
            if app.NotchUSButton.Value
                app.NotchEuropeanButton.Value = false;
                app.OtherNotchButton.Value = false;
            end
            %if this button is deselected, check if another button is selected, if not reselect this button
            if ~app.NotchUSButton.Value && (app.NotchEuropeanButton.Value == 0 && app.OtherNotchButton.Value == 0)
                app.NotchUSButton.Value =1;
            end


        end

        % Value changed function: NotchEuropeanButton
        function NotchEuropeanButtonValueChanged(app, event)
            %==============================================================
            %Use European powerline noise 50Hz in Notch filter,
            %checked in ProcessingData.m
            %Deselect other Notch filter option buttons
            %==============================================================
            if app.NotchEuropeanButton.Value
                app.NotchUSButton.Value = false;
                app.OtherNotchButton.Value = false;
            end
            %if this button is deselected, check if another button is selected, if not reselect this button
            if ~app.NotchEuropeanButton.Value && (app.NotchUSButton.Value == 0 && app.OtherNotchButton.Value == 0)
                app.NotchEuropeanButton.Value =1;
            end

        end

        % Value changed function: OtherNotchButton
        function OtherNotchButtonValueChanged(app, event)
            %==============================================================
            %Use user input for Notch filter,
            %checked in ProcessingData.m
            %Deselect other Notch filter option buttons
            %==============================================================
            if app.OtherNotchButton.Value
                app.NotchEuropeanButton.Value = false;
                app.NotchUSButton.Value = false;
            end
            %if this button is deselected, check if another button is selected, if not reselect this button
            if ~app.OtherNotchButton.Value && (app.NotchUSButton.Value == 0 && app.NotchEuropeanButton.Value == 0)
                app.OtherNotchButton.Value =1;
            end


        end

        % Value changed function: BandPassFilterType
        function BandPassFilterTypeValueChanged(app, event)
            %==============================================================
            %Depending on the selected filter type, display related options
            %==============================================================
            if strcmp(app.BandPassFilterType.Value, "Butterworth")
                app.BandPassFilterRp.Visible = false;
                app.BandPassFilterRpLabel.Visible = false;
                app.BandPassFilterRs.Visible = false;
                app.BandPassFilterRsLabel.Visible = false;
            elseif strcmp(app.BandPassFilterType.Value, "Chebyshev I")
                app.BandPassFilterRp.Visible = true;
                app.BandPassFilterRpLabel.Visible = true;
                app.BandPassFilterRs.Visible = false;
                app.BandPassFilterRsLabel.Visible = false;
            elseif strcmp(app.BandPassFilterType.Value, "Chebyshev II")
                app.BandPassFilterRp.Visible = false;
                app.BandPassFilterRpLabel.Visible = false;
                app.BandPassFilterRs.Visible = true;
                app.BandPassFilterRsLabel.Visible = true;
            elseif strcmp(app.BandPassFilterType.Value, "Elliptic")
                app.BandPassFilterRp.Visible = true;
                app.BandPassFilterRpLabel.Visible = true;
                app.BandPassFilterRs.Visible = true;
                app.BandPassFilterRsLabel.Visible = true;
            end

        end

        % Value changed function: LowPassFilterType
        function LowPassFilterTypeValueChanged(app, event)
            %==============================================================
            %Depending on the selected filter type, display related options
            %==============================================================
            if strcmp(app.LowPassFilterType.Value, "Butterworth")
                app.LowPassFilterRp.Visible = false;
                app.LowPassFilterRpLabel.Visible = false;
                app.LowPassFilterRs.Visible = false;
                app.LowPassFilterRsLabel.Visible = false;
            elseif strcmp(app.LowPassFilterType.Value, "Chebyshev I")
                app.LowPassFilterRp.Visible = true;
                app.LowPassFilterRpLabel.Visible = true;
                app.LowPassFilterRs.Visible = false;
                app.LowPassFilterRsLabel.Visible = false;
            elseif strcmp(app.LowPassFilterType.Value, "Chebyshev II")
                app.LowPassFilterRp.Visible = false;
                app.LowPassFilterRpLabel.Visible = false;
                app.LowPassFilterRs.Visible = true;
                app.LowPassFilterRsLabel.Visible = true;
            elseif strcmp(app.LowPassFilterType.Value, "Elliptic")
                app.LowPassFilterRp.Visible = true;
                app.LowPassFilterRpLabel.Visible = true;
                app.LowPassFilterRs.Visible = true;
                app.LowPassFilterRsLabel.Visible = true;
            end
        end

        % Value changed function: HighPassFilterType
        function HighPassFilterTypeValueChanged(app, event)
            %==============================================================
            %Depending on the selected filter type, display related options
            %==============================================================
            if strcmp(app.HighPassFilterType.Value, "Butterworth")
                app.HighPassFilterRp.Visible = false;
                app.HighPassFilterRpLabel.Visible = false;
                app.HighPassFilterRs.Visible = false;
                app.HighPassFilterRsLabel.Visible = false;
            elseif strcmp(app.HighPassFilterType.Value, "Chebyshev I")
                app.HighPassFilterRp.Visible = true;
                app.HighPassFilterRpLabel.Visible = true;
                app.HighPassFilterRs.Visible = false;
                app.HighPassFilterRsLabel.Visible = false;
            elseif strcmp(app.HighPassFilterType.Value, "Chebyshev II")
                app.HighPassFilterRp.Visible = false;
                app.HighPassFilterRpLabel.Visible = false;
                app.HighPassFilterRs.Visible = true;
                app.HighPassFilterRsLabel.Visible = true;
            elseif strcmp(app.HighPassFilterType.Value, "Elliptic")
                app.HighPassFilterRp.Visible = true;
                app.HighPassFilterRpLabel.Visible = true;
                app.HighPassFilterRs.Visible = true;
                app.HighPassFilterRsLabel.Visible = true;
            end
        end

        % Value changed function: FullWaveButton
        function FullWaveButtonValueChanged(app, event)
            %==============================================================
            %Use full wave rectification,
            %checked in ProcessingData.m
            %Deselect other Rectify option buttons
            %==============================================================
            if app.FullWaveButton.Value
                app.HalfWaveButton.Value = false;
            end
            %if this button is deselected, check if another button is selected, if not reselect this button
            if ~app.FullWaveButton.Value && app.HalfWaveButton.Value == 0
                app.FullWaveButton.Value =1;
            end
        end

        % Value changed function: HalfWaveButton
        function HalfWaveButtonValueChanged(app, event)
            %==============================================================
            %Use half wave rectification,
            %checked in ProcessingData.m
            %Deselect other Rectify option buttons
            %==============================================================
            if app.HalfWaveButton.Value
                app.FullWaveButton.Value = false;
            end
            %if this button is deselected, check if another button is selected, if not reselect this button
            if ~app.HalfWaveButton.Value && app.FullWaveButton.Value == 0
                app.HalfWaveButton.Value =1;
            end
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            %==============================================================
            %Move selected process in list Process Order list box up in
            %the order
            %==============================================================

            %check that a process is selected
            if ~isempty(app.ProcessOrderListBox.Value)

                %Determine location in list box
                SelectedLocation=find(app.ProcessOrderListBox.Value == app.ProcessOrderListBox.ItemsData);
                SelectedValue=app.ProcessOrderListBox.Value; %selected value

                if SelectedLocation ~= 1
                    SelectedItem=app.ProcessOrderListBox.Items(SelectedLocation); %selected item name

                    %switch current selection with selection above
                    app.ProcessOrderListBox.Items(SelectedLocation)=app.ProcessOrderListBox.Items(SelectedLocation-1);
                    app.ProcessOrderListBox.Items(SelectedLocation-1)=SelectedItem;
                    app.ProcessOrderListBox.ItemsData(SelectedLocation)=app.ProcessOrderListBox.ItemsData(SelectedLocation-1);
                    app.ProcessOrderListBox.ItemsData(SelectedLocation-1)=SelectedValue;
                    app.ProcessOrderListBox.Value=app.ProcessOrderListBox.ItemsData(SelectedLocation-1);

                end
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            %==============================================================
            %Move selected process in list Process Order list box down in
            %the order
            %==============================================================

            %check that a process is selected
            if ~isempty(app.ProcessOrderListBox.Value)
                %Determine location in list box
                SelectedLocation=find(app.ProcessOrderListBox.Value == app.ProcessOrderListBox.ItemsData);
                SelectedValue=app.ProcessOrderListBox.Value; %selected value

                if SelectedLocation ~= length(app.ProcessOrderListBox.Items)
                    SelectedItem=app.ProcessOrderListBox.Items(SelectedLocation); %selected item name

                    %switch current selection with selection below
                    app.ProcessOrderListBox.Items(SelectedLocation)=app.ProcessOrderListBox.Items(SelectedLocation+1);
                    app.ProcessOrderListBox.Items(SelectedLocation+1)=SelectedItem;
                    app.ProcessOrderListBox.ItemsData(SelectedLocation)=app.ProcessOrderListBox.ItemsData(SelectedLocation+1);
                    app.ProcessOrderListBox.ItemsData(SelectedLocation+1)=SelectedValue;
                    app.ProcessOrderListBox.Value=app.ProcessOrderListBox.ItemsData(SelectedLocation+1);

                end
            end
        end

        % Button pushed function: ResetOrderButton
        function ResetOrderButtonPushed(app, event)
            %==============================================================
            %Resets order of processes to default order
            %==============================================================

            CustomProcessTreeCheckedNodesChanged(app, app.CustomProcessTree);
            % %check which nodes are checked
            % CheckedNodes=app.ProcessTree.CheckedNodes;
            %
            % %Reorder processes based on order in tree
            % SelectedProcesses=zeros(1,9);
            % SelectedProcNames=cell(1,length(CheckedNodes));
            % for i=1:length(CheckedNodes)
            %     NodeData=CheckedNodes(i).NodeData;
            %     SelectedProcesses(NodeData)=NodeData;
            %     SelectedProcNames{i}=CheckedNodes(i).Text;
            % end
            % app.ProcessOrderListBox.Items=SelectedProcNames;
            % app.ProcessOrderListBox.ItemsData=SelectedProcesses(SelectedProcesses ~=0);

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            %==============================================================
            %Runs selected processes
            %==============================================================

            %Processing applied
            app.Processed=1;
            %Enable Reset data button
            app.ResetDataButton.Enable=1;
            %Disable Process button while processing
            app.ProcessButton.Enable=0;
            %Allow for saving the processed data
            app.SaveProcessedDatamatButton.Enable=1; app.SavematEditField.Enable=1;

            app.Processed_Conditions_DataAll=cell(1,4);

            %check if being applied to average of data
            if app.AverageCheckBox.Value == 1
                Data=app.POriginalAvgPlottedData;
                DataTime=app.Time;
                app.AverageCheckBox.Enable=0;

            else %trials data
                %Data and time
                Data=app.Conditions_DataAll{1,1};
                DataTime=app.Conditions_DataAll{1,2}; %time in seconds

            end

            %Process Data
            [DataTime,Data]=ProcessingData(app,Data,DataTime);

            %Save order of processes
            app.ProcessOrder(:,1)=app.ProcessOrderListBox.Items';

            %update app time and allplotted data/avgPlotted data
            app.Time=DataTime;
            if app.AverageCheckBox.Value == 1 %Process average of data
                app.AvgConditionData=Data;

                %Plot
                ConditionsListBoxValueChanged(app,1); %plot current selected condition

            else
                app.Processed_Conditions_DataAll{1,1}=Data;
                app.Processed_Conditions_DataAll{1,2}=DataTime;

                %Plot
                SelectAllCondButtonPushed(app,1); %Select all
                ConditionTrialsListBoxValueChanged(app,1); %plot current selected condition

            end

            %Enable Process button after processing
            app.ProcessButton.Enable=1;
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            %==============================================================
            %Resets data and plot to before processing
            %==============================================================

            app.Processed=0;
            app.Processed_Conditions_DataAll=cell(1,4);
            app.Time=app.Conditions_DataAll{1,2}; %reset time
            app.SaveProcessedDatamatButton.Enable=0; app.SavematEditField.Enable=0;
            %Reset data
            if app.AverageCheckBox.Value == 0
                app.AllPlotted_Data=app.POriginalAllPlottedData;
                ConditionTrialsListBoxValueChanged(app,1);
            else
                app.AvgConditionData=app.POriginalAvgPlottedData;
                ConditionsListBoxValueChanged(app,1);
                app.AverageCheckBox.Enable=1;
            end

            app.ProcessOrder=[]; %clear process order
            app.DownSampledRate=[]; %clear downsampled rate
            app.SampleRateLabel.Text=['Sample Rate: ' num2str(app.AllSampleRate) 'Hz'];

        end

        % Button pushed function: SaveProcessedDatamatButton
        function SaveProcessedDatamatButtonPushed(app, event)
            %==============================================================
            %Save data that is currently in the main graph to a .mat file
            %The purpose of this button is to save processed data, so it
            %can be used outside of the app
            %==============================================================


            app.SaveProcessedDatamatButton.BackgroundColor=[0.8,0.8,0.8];
            pause(1);

            %filename
            if isempty(app.SavematEditField.Value)
                app.SaveProcessedDatamatButton.BackgroundColor=[0.39,0.83,0.07];
                msgbox('Please Enter Filename');
                return;
            end

            SelectedTrials=cellfun(@str2double, app.ConditionTrialsListBox.Value');

            %Create variable
            AllPlottedData(:,1)=app.AllPlotted_Data(~cellfun(@isempty, app.AllPlotted_Data(:,1)),1); %remove empty cells
            AllPlottedData{1,2}=app.AllPlotted_Data{1,2};
            AllPlottedData(:,3)=mat2cell(SelectedTrials,ones(1,length(SelectedTrials)));

            %Add condition names
            for i=1:length(AllPlottedData(:,1)) %check for each trial
                STrial=AllPlottedData{i,3};
                for i2=1:length(app.ConditionsListBox.Items) %which condition it belongs to
                    if STrial >= app.numTrialsCondition(i2,2) && STrial <= app.numTrialsCondition(i2,3)
                        AllPlottedData{i,4}=app.ConditionsListBox.Items{i2}; %add condition name to variable
                    end
                end
            end

            AllPlottedData{end+1,1}=0;
            AllPlottedData(2:end,:)=AllPlottedData(1:end-1,:);
            AllPlottedData{1,1}='Data'; AllPlottedData{1,2}='Time'; AllPlottedData{1,3}='Trial'; AllPlottedData{1,4}='Condition';
            ProcessedOrder=app.ProcessOrder;

            %check if "Results" folder exists if not, create it
            ResultsFolderDir=fullfile(app.Directory.SaveFolder, 'Results');
            if exist(ResultsFolderDir,'dir') ~= 7 %7 folder exists
                mkdir(ResultsFolderDir);
            end

            %Processed Data folder
            ProcessedDataFolder=fullfile(ResultsFolderDir,'Processed Data');
            if exist(ProcessedDataFolder,'dir') ~= 7 %7 folder exists
                mkdir(ProcessedDataFolder);
            end


            %check for .mat extension
            matFileName=app.SavematEditField.Value;
            if length(matFileName) >= 4 && all(matFileName(end-3:end) == '.mat')
                matFileName(end-3:end)='';
            end
            save(fullfile(ProcessedDataFolder,[matFileName '.mat']),'AllPlottedData','ProcessedOrder');
            pause(1);
            app.SaveProcessedDatamatButton.BackgroundColor=[0.39,0.83,0.07];

        end

        % Value changed function: AverageCheckBox
        function AverageCheckBoxValueChanged(app, event)
            %==============================================================
            %Plots average of currently plotted data
            %==============================================================

            %Determine if the plot needs to be averaged or separated
            switch app.AverageCheckBox.Value
                case 1 %take average

                    %check if working with raw data
                    if string(app.TabGroup.SelectedTab.Title) == "Data Organization" %raw data
                        SelectedTrialsData=cell2mat(app.AllPlotted_Data(:,1));
                        app.Plotted_Data=mean(SelectedTrialsData,1,'omitnan');

                        %AllPlotted_Data updated
                        app.AllPlotted_Data=cell(1,2);
                        app.AllPlotted_Data{1,1}=app.Plotted_Data;
                        app.AllPlotted_Data{1,2}=app.Time;

                        app.Plotted_Data=app.Plotted_Data*app.Convert2PlottedUnits;
                        %Plot desired data
                        cla(app.UIAxes);
                        plot(app.UIAxes, app.Time, app.Plotted_Data);
                        app.UIAxes.XLabel.String = 'Time (s)';
                        app.UIAxes.YLabel.String = app.PlottedUnits;
                        app.UIAxes.Title.String = app.CurrChTitle;
                        %update Scaling edit fields
                        app.MinEditField.Value=app.UIAxes.XLim(1);
                        app.MaxEditField.Value=app.UIAxes.XLim(2);
                        app.MinEditField_2.Value=app.UIAxes.YLim(1);
                        app.MaxEditField_2.Value=app.UIAxes.YLim(2);

                    else %work with condition data
                        %check if data was processed
                        if app.Processed == 1
                            Data=app.Processed_Conditions_DataAll{1,1};
                            app.ResetDataButton.Enable=0;
                        else
                            Data=app.Conditions_DataAll{1,1};
                        end

                        %Take average of each condition
                        Loc=app.numTrialsCondition; %columns: # trials, first trial, last trial
                        app.AvgConditionData=[];
                        for i=1:length(Loc(:,1)) %for each condition take the average
                            ConditionData=Data(Loc(i,2):Loc(i,3),:);
                            app.AvgConditionData(i,:)=mean(ConditionData,1,'omitnan');
                        end

                        %AllPlotted_Data updated
                        app.AllPlotted_Data=cell(1,2);
                        app.AllPlotted_Data{1,1}=app.AvgConditionData;
                        app.AllPlotted_Data{1,2}=app.Time;

                        app.POriginalAvgPlottedData=app.AvgConditionData; %for processing reset

                        %Disable individual trials for conditions and select all trials button
                        app.ConditionTrialsListBox.Enable=0;
                        app.SelectAllCondButton.Enable=0;

                        ConditionsListBoxValueChanged(app,1);
                    end

                case 0 %plot all selected plots

                    if string(app.TabGroup.SelectedTab.Title) == "Data Organization" %raw data
                        TrialSelectListBoxValueChanged(app,1);

                    else %condition data

                        %Enable individual trials for conditions and select all trials button
                        app.ConditionTrialsListBox.Enable=1;
                        app.SelectAllCondButton.Enable=1;
                        app.ResetDataButton.Enable=1;

                        ConditionsListBoxValueChanged(app,1);
                        app.POriginalAvgPlottedData=[]; %for processing reset


                    end


            end


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %==============================================================
            %When the selected tab changes, change what data is plotted
            %==============================================================

            CurrSelectedTab=string(app.TabGroup.SelectedTab.Title);
            if app.EpochTypesButtonGroup.Enable == 1

                switch CurrSelectedTab

                    case "Data Organization" %if the newly selected tab is the "Data Organization" tab, run Find Events to load in raw data
                        FindEventsButtonPushed(app,1);
                        app.LeftArrowAnalyzeButton.Enable=0;
                        app.RightArrowAnalyzeButton.Enable=0;
                        app.SButton.Enable=0;

                    case {"Process", "Analysis"} %if the newly selected tab is the "Process" or "Analyze" tab
                        if app.PrevSelectedTab == "Data Organization" %if the previously selected tab was the "Data Organization" tab
                            %Check if there is condition data
                            if (all(size(app.Conditions_DataAll) == [1 2])) && all(cellfun(@isempty,app.Conditions_DataAll))
                                %do nothing
                            else %switch to condition data
                                ConditionsListBoxValueChanged(app,1);
                            end
                        end
                        if CurrSelectedTab == "Analysis"
                            app.LeftArrowAnalyzeButton.Enable=1;
                            app.RightArrowAnalyzeButton.Enable=1;
                            app.SButton.Enable=1;
                        else
                            app.LeftArrowAnalyzeButton.Enable=0;
                            app.RightArrowAnalyzeButton.Enable=0;
                            app.SButton.Enable=0;
                        end

                end

                app.PrevSelectedTab = CurrSelectedTab;

            else
                if CurrSelectedTab == "Analysis"
                    app.LeftArrowAnalyzeButton.Enable=1;
                    app.RightArrowAnalyzeButton.Enable=1;
                    app.SButton.Enable=1;
                else
                    app.LeftArrowAnalyzeButton.Enable=0;
                    app.RightArrowAnalyzeButton.Enable=0;
                    app.SButton.Enable=0;
                end
            end

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            %==============================================================
            %Resets app
            %==============================================================

            app.refreshApp;
        end

        % Value changed function: AutoFillEditField
        function AutoFillEditFieldValueChanged(app, event)
            %==============================================================
            %Sets how many trials to add on automatically using the
            %Auto fill button
            %==============================================================
            AddOnValue = app.AutoFillEditField.Value;
            %Value should be greater than zero
            if AddOnValue <=0
                app.AutoFillEditField.Value=0;
            end
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            %==============================================================
            %Set minimum value for x limit on graph
            %==============================================================
            NewXLim_Min = app.MinEditField.Value;
            app.UIAxes.XLim=[NewXLim_Min app.MaxEditField.Value];

        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            %==============================================================
            %Set maximum value for x limit on graph
            %==============================================================
            NewXLim_Max = app.MaxEditField.Value;
            app.UIAxes.XLim=[app.MinEditField.Value NewXLim_Max];

        end

        % Value changed function: MinEditField_2
        function MinEditField_2ValueChanged(app, event)
            %==============================================================
            %Set minimum value for y limit on graph
            %==============================================================
            NewYLim_Min = app.MinEditField_2.Value;
            app.UIAxes.YLim=[NewYLim_Min app.MaxEditField_2.Value];

        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            %==============================================================
            %Set maximum value for y limit on graph
            %==============================================================
            NewYLim_Max = app.MaxEditField_2.Value;
            app.UIAxes.YLim=[app.MinEditField_2.Value NewYLim_Max];

        end

        % Value changed function: PreDurationsEditField
        function PreDurationsEditFieldValueChanged(app, event)
            %==============================================================
            %Update the time before the event (comment or event) to plot
            %If value is invalid, reset to previous value
            %Update plot and data
            %==============================================================

            app.PreDur = app.PreDurationsEditField.Value;       %Time before comment/event to include in seconds
            %if value is less than or equal to zero, auto fill with previous value
            if app.PreDur<= 0
                app.PreDurationsEditField.Value=event.PreviousValue;
                app.PreDur=app.PreDurationsEditField.Value;                     %add check for if value is too large !!!
            end
            app.DisableLI_FindEvents=1; %Disable loading indicator for Find Events
            FindEventsButtonPushed(app,1);
            app.DisableLI_FindEvents=0;


        end

        % Value changed function: PostDurationsEditField
        function PostDurationsEditFieldValueChanged(app, event)
            %==============================================================
            %Update the time after the event (comment or event) to plot
            %If value is invalid, reset to previous value
            %Update plot and data
            %==============================================================
            app.PostDur = app.PostDurationsEditField.Value;     %Time after comment/event to include in seconds
            %if value is less than or equal to zero, auto fill with previous value
            if app.PostDur<= 0
                app.PostDurationsEditField.Value=event.PreviousValue;
                app.PostDur=app.PostDurationsEditField.Value;                     %add check for if value is too large !!!
            end
            app.DisableLI_FindEvents=1; %Disable loading indicator for Find Events
            FindEventsButtonPushed(app,1);
            app.DisableLI_FindEvents=0;

        end

        % Value changed function: PreTriggermsEditField
        function PreTriggermsEditFieldValueChanged(app, event)
            %==============================================================
            %Update the time to shift the data "back" by !!!need better wording for this
            %Update plot and data
            %==============================================================
            app.PreTriggerTime = app.PreTriggermsEditField.Value;               %add check for if value is too large !!!
            app.DisableLI_FindEvents=1; %Disable loading indicator for Find Events
            FindEventsButtonPushed(app,1);
            app.DisableLI_FindEvents=0;

        end

        % Value changed function: PostTriggermsEditField
        function PostTriggermsEditFieldValueChanged(app, event)
            %==============================================================
            %Update the time after zero to plot
            %Update plot and data
            %==============================================================
            app.PostTriggerTime = app.PostTriggermsEditField.Value;             %add check for if value is too large !!!
            app.DisableLI_FindEvents=1; %Disable loading indicator for Find Events
            FindEventsButtonPushed(app,1);
            app.DisableLI_FindEvents=0;

        end

        % Value changed function: DetectionSettingsDropDown
        function DetectionSettingsDropDownValueChanged(app, event)
            %==============================================================
            %Type of detection for finding events
            %==============================================================

            app.DetectionSetting = app.DetectionSettingsDropDown.Value;

        end

        % Value changed function: EventChannelEditField
        function EventChannelEditFieldValueChanged(app, event)
            %==============================================================
            %Set event channel for finding events
            %If value entered is invalid, set value to previous value
            %==============================================================

            app.EventChannel = app.EventChannelEditField.Value;
            %Check if value entered is less than zero or larger than the number of channels
            if app.EventChannel > app.numChannels
                app.EventChannelEditField.Value=event.PreviousValue;
                app.EventChannel = app.EventChannelEditField.Value;
            elseif app.EventChannel < 0
                app.EventChannelEditField.Value=event.PreviousValue;
                app.EventChannel = app.EventChannelEditField.Value;
            end


        end

        % Value changed function: ThresholdmVEditField
        function ThresholdmVEditFieldValueChanged(app, event)
            %==============================================================
            %Set event threshold for finding events
            %==============================================================
            app.EventThresh = app.ThresholdmVEditField.Value;

        end

        % Value changed function: SearchISIsEditField
        function SearchISIsEditFieldValueChanged(app, event)
            %==============================================================
            %Set search ISI for finding events
            %If value entered is invalid, set value to previous value
            %==============================================================

            app.SearchISI = app.SearchISIsEditField.Value;
            if app.SearchISI < 0
                app.SearchISIsEditField.Value=event.PreviousValue;
                app.SearchISI = app.SearchISIsEditField.Value;
            end

        end

        % Value changed function: PreStimPeriodmsStartEditField
        function PreStimPeriodmsStartEditFieldValueChanged(app, event)
            %==============================================================
            %Set start time for pre-stim period
            %==============================================================
            app.PreStimStart= app.PreStimPeriodmsStartEditField.Value;

        end

        % Value changed function: PreStimPeriodmsEndEditField
        function PreStimPeriodmsEndEditFieldValueChanged(app, event)
            %==============================================================
            %Set end time for pre-stim period
            %==============================================================
            app.PreStimEnd= app.PreStimPeriodmsEndEditField.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %==============================================================
            %Close Matlab App
            %Unload any libraries, close any other apps and figures
            %==============================================================

            %Confirm close
            ConfirmClose=questdlg('If not already done, save the condition file. Do you want to close the app?','Confirm Close','Close','Cancel','Cancel');
            if string(ConfirmClose) == "Cancel"
                return;
            end

            %unload CEDS64ML library if loaded
            if libisloaded('ceds64int')
                unloadlibrary ceds64int;
            end

            %close figures
            try
                delete(app.BashFig); %close bash app 5/19/26: not implemented
            end
            try
                close(app.PlotOnsetOffsetSwitchFig) %close Plot Onset/Offset figure
            end
            try
                close(app.CustomOnOffDetectOpts) %close Custom Analyze pop-up
            end
            try
                close(app.CustomAnalysisOpts);
            end

            %Close custom processing pop-ups
            if string(class(app.CustomProcessingOpts)) == "struct"
                Processes=fieldnames(app.CustomProcessingOpts);
                for i=1:length(Processes)
                    try
                        close(app.CustomProcessingOpts.(Processes{i}));
                    end
                end
            end

            delete(app);

        end

        % Button pushed function: ClearLoadingScreenButton
        function ClearLoadingScreenButtonPushed(app, event)
            %This is for bug testing purposes and will eventually be removed
            loadingScreen(app,0); %Enable GUI Screen
            app.DefineConditionsPanel.Enable=1; %Enable Conditions Panel
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.OpenNewFileMenu = uimenu(app.FileMenu);
            app.OpenNewFileMenu.Text = 'Open New File';
            app.LabChartmatMenu = uimenu(app.OpenNewFileMenu);
            app.LabChartmatMenu.MenuSelectedFcn = createCallbackFcn(app, @LabChartmatMenuSelected, true);
            app.LabChartmatMenu.Text = 'LabChart (.mat)';
            app.BrainsighttxtMenu = uimenu(app.OpenNewFileMenu);
            app.BrainsighttxtMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainsighttxtMenuSelected, true);
            app.BrainsighttxtMenu.Text = 'Brainsight (.txt)';
            app.SignalmatcfsMenu = uimenu(app.OpenNewFileMenu);
            app.SignalmatcfsMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalmatcfsMenuSelected, true);
            app.SignalmatcfsMenu.Text = 'Signal (.mat,.cfs)';
            app.AcqknowledgematacqMenu = uimenu(app.OpenNewFileMenu);
            app.AcqknowledgematacqMenu.MenuSelectedFcn = createCallbackFcn(app, @AcqknowledgematacqMenuSelected, true);
            app.AcqknowledgematacqMenu.Text = 'Acqknowledge (.mat,.acq)';
            app.BrainVisioneegMenu = uimenu(app.OpenNewFileMenu);
            app.BrainVisioneegMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainVisioneegMenuSelected, true);
            app.BrainVisioneegMenu.Text = 'BrainVision (.eeg)';
            app.SpikesmrMenu = uimenu(app.OpenNewFileMenu);
            app.SpikesmrMenu.MenuSelectedFcn = createCallbackFcn(app, @SpikesmrMenuSelected, true);
            app.SpikesmrMenu.Text = 'Spike (.smr)';
            app.CustomMenu = uimenu(app.OpenNewFileMenu);
            app.CustomMenu.Text = 'Custom';
            app.OpenConditionFileMenu = uimenu(app.FileMenu);
            app.OpenConditionFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenConditionFileMenuSelected, true);
            app.OpenConditionFileMenu.Text = 'Open Condition File';
            app.OpenVersion1ConditionFilesMenu = uimenu(app.FileMenu);
            app.OpenVersion1ConditionFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenVersion1ConditionFilesMenuSelected, true);
            app.OpenVersion1ConditionFilesMenu.Text = 'Open Version 1 Condition Files';
            app.App_Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.App_Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataFileEditFieldLabel = uilabel(app.App_Panel);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.App_Panel, 'text');
            app.DataFileEditField.Editable = 'off';
            app.RefreshButton = uibutton(app.App_Panel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ChangeDataFolderButton = uibutton(app.App_Panel, 'push');
            app.ChangeDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDataFolderButtonPushed, true);
            app.ChangeDataFolderButton.Text = 'Change Data Folder';
            app.ConditionTrialsLabel = uilabel(app.App_Panel);
            app.ConditionTrialsLabel.Visible = 'off';
            app.ConditionTrialsLabel.Text = 'Condition Trials';
            app.ConditionTrialsListBox = uilistbox(app.App_Panel);
            app.ConditionTrialsListBox.Multiselect = 'on';
            app.ConditionTrialsListBox.ValueChangedFcn = createCallbackFcn(app, @ConditionTrialsListBoxValueChanged, true);
            app.ConditionTrialsListBox.Visible = 'off';
            app.ConditionTrialsListBox.Value = {'Item 1'};
            app.SelectAllCondButton = uibutton(app.App_Panel, 'push');
            app.SelectAllCondButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllCondButtonPushed, true);
            app.SelectAllCondButton.Visible = 'off';
            app.SelectAllCondButton.Text = 'Select All';
            app.ConditionsListBoxLabel = uilabel(app.App_Panel);
            app.ConditionsListBoxLabel.Visible = 'off';
            app.ConditionsListBoxLabel.Text = 'Conditions';
            app.ConditionsListBox = uilistbox(app.App_Panel);
            app.ConditionsListBox.Multiselect = 'on';
            app.ConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @ConditionsListBoxValueChanged, true);
            app.ConditionsListBox.Visible = 'off';
            app.ConditionsListBox.Value = {};
            app.DefineConditionsPanel = uipanel(app.App_Panel);
            app.DefineConditionsPanel.Title = 'Define Conditions';
            app.UITable = uitable(app.DefineConditionsPanel);
            app.UITable.ColumnName = {'Trials'; 'Group Name'; 'Channel #'; 'x'; 'y'; 'z'; 'Grid'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddBlankRowButton = uibutton(app.DefineConditionsPanel, 'push');
            app.AddBlankRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddBlankRowButtonPushed, true);
            app.AddBlankRowButton.Text = 'Add Blank Row';
            app.ImportEventsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.AddSelectedTrialsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.AddSelectedTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectedTrialsButtonPushed, true);
            app.AddSelectedTrialsButton.Text = 'Add Selected Trials';
            app.SaveButton = uibutton(app.DefineConditionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ApplyButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.AutoFillButton = uibutton(app.DefineConditionsPanel, 'push');
            app.AutoFillButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFillButtonPushed, true);
            app.AutoFillButton.Text = 'Auto Fill';
            app.AutoFillEditField = uieditfield(app.DefineConditionsPanel, 'numeric');
            app.AutoFillEditField.ValueChangedFcn = createCallbackFcn(app, @AutoFillEditFieldValueChanged, true);
            app.AutoFillEditField.Value = 10;
            app.LoadConditionsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.LoadConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConditionsButtonPushed, true);
            app.LoadConditionsButton.Enable = 'off';
            app.LoadConditionsButton.Visible = 'off';
            app.LoadConditionsButton.Text = 'Load Conditions';
            app.UploadExcelFileButton = uibutton(app.DefineConditionsPanel, 'push');
            app.UploadExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadExcelFileButtonPushed, true);
            app.UploadExcelFileButton.Text = 'Upload Excel File';
            app.KeeporDeleteRowsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.KeeporDeleteRowsButton.ButtonPushedFcn = createCallbackFcn(app, @KeeporDeleteRowsButtonPushed, true);
            app.KeeporDeleteRowsButton.Text = 'Keep or Delete Row(s)';
            app.RecoverButton = uibutton(app.DefineConditionsPanel, 'push');
            app.RecoverButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverButtonPushed, true);
            app.RecoverButton.Text = 'Recover';
            app.SheetNameEditField = uieditfield(app.DefineConditionsPanel, 'text');
            app.SheetNameEditField.Editable = 'off';
            app.OverrideGroupNameButton = uibutton(app.DefineConditionsPanel, 'push');
            app.OverrideGroupNameButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideGroupNameButtonPushed, true);
            app.OverrideGroupNameButton.Text = 'Override Group Name';
            app.OverrideGroupNameEditField = uieditfield(app.DefineConditionsPanel, 'text');
            app.OverrideGroupNameEditField.Editable = 'off';
            app.SampleRateLabel = uilabel(app.App_Panel);
            app.SampleRateLabel.Text = 'Sample Rate';
            app.UnitsDropDownLabel = uilabel(app.App_Panel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.App_Panel);
            app.UnitsDropDown.Items = {'mV', 'uV', 'V'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'mV';
            app.MinEditFieldLabel = uilabel(app.App_Panel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.App_Panel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.App_Panel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.App_Panel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.XScaleLabel = uilabel(app.App_Panel);
            app.XScaleLabel.Text = 'X Scale';
            app.MaxEditField_2 = uieditfield(app.App_Panel, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MinEditField_2 = uieditfield(app.App_Panel, 'numeric');
            app.MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinEditField_2ValueChanged, true);
            app.YScaleLabel = uilabel(app.App_Panel);
            app.YScaleLabel.Text = 'Y Scale';
            app.TabGroup = uitabgroup(app.App_Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataOrganizationTab = uitab(app.TabGroup);
            app.DataOrganizationTab.Title = 'Data Organization';
            app.EpochTypesButtonGroup = uibuttongroup(app.DataOrganizationTab);
            app.EpochTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EpochTypesButtonGroupSelectionChanged, true);
            app.EpochTypesButtonGroup.Title = 'Epoch Types';
            app.BlocksButton = uiradiobutton(app.EpochTypesButtonGroup);
            app.BlocksButton.Text = 'Blocks';
            app.BlocksButton.Value = true;
            app.CommentsButton = uiradiobutton(app.EpochTypesButtonGroup);
            app.CommentsButton.Text = 'Comments';
            app.EventsButton = uiradiobutton(app.EpochTypesButtonGroup);
            app.EventsButton.Text = 'Events';
            app.TrialSelectListBoxLabel = uilabel(app.DataOrganizationTab);
            app.TrialSelectListBoxLabel.Text = 'Trial Select';
            app.TrialSelectListBox = uilistbox(app.DataOrganizationTab);
            app.TrialSelectListBox.Multiselect = 'on';
            app.TrialSelectListBox.ValueChangedFcn = createCallbackFcn(app, @TrialSelectListBoxValueChanged, true);
            app.TrialSelectListBox.Value = {'Item 1'};
            app.SelectAllButton = uibutton(app.DataOrganizationTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.EventNameListBoxLabel = uilabel(app.DataOrganizationTab);
            app.EventNameListBoxLabel.Text = 'Event Name';
            app.EventNameListBox = uilistbox(app.DataOrganizationTab);
            app.EventNameListBox.ValueChangedFcn = createCallbackFcn(app, @EventNameListBoxValueChanged, true);
            app.EventParametersPanel = uipanel(app.DataOrganizationTab);
            app.EventParametersPanel.Title = 'Event Parameters';
            app.EventChannelEditFieldLabel = uilabel(app.EventParametersPanel);
            app.EventChannelEditFieldLabel.Text = 'Event Channel';
            app.EventChannelEditField = uieditfield(app.EventParametersPanel, 'numeric');
            app.EventChannelEditField.ValueChangedFcn = createCallbackFcn(app, @EventChannelEditFieldValueChanged, true);
            app.EventChannelEditField.Value = 1;
            app.ThresholdmVEditFieldLabel = uilabel(app.EventParametersPanel);
            app.ThresholdmVEditFieldLabel.Text = 'Threshold (mV)';
            app.ThresholdmVEditField = uieditfield(app.EventParametersPanel, 'numeric');
            app.ThresholdmVEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdmVEditFieldValueChanged, true);
            app.SearchISIsEditFieldLabel = uilabel(app.EventParametersPanel);
            app.SearchISIsEditFieldLabel.Text = 'Search ISI (s)';
            app.SearchISIsEditField = uieditfield(app.EventParametersPanel, 'numeric');
            app.SearchISIsEditField.ValueChangedFcn = createCallbackFcn(app, @SearchISIsEditFieldValueChanged, true);
            app.DetectionSettingsDropDownLabel = uilabel(app.EventParametersPanel);
            app.DetectionSettingsDropDownLabel.Text = 'Detection Settings';
            app.DetectionSettingsDropDown = uidropdown(app.EventParametersPanel);
            app.DetectionSettingsDropDown.Items = {'Threshold Crossing'};
            app.DetectionSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionSettingsDropDownValueChanged, true);
            app.DetectionSettingsDropDown.Value = 'Threshold Crossing';
            app.FindEventsButton = uibutton(app.DataOrganizationTab, 'push');
            app.FindEventsButton.ButtonPushedFcn = createCallbackFcn(app, @FindEventsButtonPushed, true);
            app.FindEventsButton.Text = 'Find Events';
            app.ChannelSpinnerLabel = uilabel(app.DataOrganizationTab);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.DataOrganizationTab);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Value = 1;
            app.TrialSpinnerLabel = uilabel(app.DataOrganizationTab);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.DataOrganizationTab);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.TrialSpinner.Value = 1;
            app.PrePostTriggerTimemsPanel = uipanel(app.DataOrganizationTab);
            app.PrePostTriggerTimemsPanel.Title = 'Pre/Post Trigger Time (ms)';
            app.PrePostTriggerTimemsPanel.Visible = 'off';
            app.PreTriggermsEditFieldLabel = uilabel(app.PrePostTriggerTimemsPanel);
            app.PreTriggermsEditFieldLabel.Text = 'Pre Trigger (ms)';
            app.PreTriggermsEditField = uieditfield(app.PrePostTriggerTimemsPanel, 'numeric');
            app.PreTriggermsEditField.ValueChangedFcn = createCallbackFcn(app, @PreTriggermsEditFieldValueChanged, true);
            app.PostTriggermsEditFieldLabel = uilabel(app.PrePostTriggerTimemsPanel);
            app.PostTriggermsEditFieldLabel.Text = 'Post Trigger (ms)';
            app.PostTriggermsEditField = uieditfield(app.PrePostTriggerTimemsPanel, 'numeric');
            app.PostTriggermsEditField.ValueChangedFcn = createCallbackFcn(app, @PostTriggermsEditFieldValueChanged, true);
            app.SetDataWindowPanel = uipanel(app.DataOrganizationTab);
            app.SetDataWindowPanel.Title = 'Set Data Window';
            app.SetDataWindowPanel.Visible = 'off';
            app.PreDurationsEditFieldLabel = uilabel(app.SetDataWindowPanel);
            app.PreDurationsEditFieldLabel.Text = 'Pre Duration (s)';
            app.PreDurationsEditField = uieditfield(app.SetDataWindowPanel, 'numeric');
            app.PreDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @PreDurationsEditFieldValueChanged, true);
            app.PreDurationsEditField.Value = 0.15;
            app.PostDurationsEditFieldLabel = uilabel(app.SetDataWindowPanel);
            app.PostDurationsEditFieldLabel.Text = 'Post Duration  (s)';
            app.PostDurationsEditField = uieditfield(app.SetDataWindowPanel, 'numeric');
            app.PostDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @PostDurationsEditFieldValueChanged, true);
            app.PostDurationsEditField.Value = 0.15;
            app.FileOrderListBox = uilistbox(app.DataOrganizationTab);
            app.FileOrderListBox.Items = {};
            app.FileOrderListBox.Value = {};
            app.FileOrderListBoxLabel = uilabel(app.DataOrganizationTab);
            app.FileOrderListBoxLabel.Text = 'File Order';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ProcessTree = uitree(app.ProcessTab, 'checkbox');
            app.DownSampleNode = uitreenode(app.ProcessTree);
            app.DownSampleNode.NodeData = 1;
            app.DownSampleNode.Text = 'Down Sample';
            app.NotchNode = uitreenode(app.ProcessTree);
            app.NotchNode.NodeData = 2;
            app.NotchNode.Text = 'Notch';
            app.BandPassNode = uitreenode(app.ProcessTree);
            app.BandPassNode.NodeData = 3;
            app.BandPassNode.Text = 'Band Pass';
            app.LowPassNode = uitreenode(app.ProcessTree);
            app.LowPassNode.NodeData = 4;
            app.LowPassNode.Text = 'Low Pass';
            app.HighPassNode = uitreenode(app.ProcessTree);
            app.HighPassNode.NodeData = 5;
            app.HighPassNode.Text = 'High Pass';
            app.DerivativeNode = uitreenode(app.ProcessTree);
            app.DerivativeNode.NodeData = 6;
            app.DerivativeNode.Text = 'Derivative';
            app.DelayNode = uitreenode(app.ProcessTree);
            app.DelayNode.NodeData = 7;
            app.DelayNode.Text = 'Delay';
            app.RectifyNode = uitreenode(app.ProcessTree);
            app.RectifyNode.NodeData = 8;
            app.RectifyNode.Text = 'Rectify';
            % Assign Checked Nodes
            app.ProcessTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ProcessTreeCheckedNodesChanged, true);
            app.ProcessButton = uibutton(app.ProcessTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.ResetOrderButton = uibutton(app.ProcessTab, 'push');
            app.ResetOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOrderButtonPushed, true);
            app.ResetOrderButton.Text = 'Reset Order';
            app.MoveDownButton = uibutton(app.ProcessTab, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.MoveUpButton = uibutton(app.ProcessTab, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.FactorLabel = uilabel(app.ProcessTab);
            app.FactorLabel.Visible = 'off';
            app.FactorLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.ProcessTab, 'numeric');
            app.FactorEditField.Visible = 'off';
            app.FactorEditField.Value = 1;
            app.LowPassHzLabel = uilabel(app.ProcessTab);
            app.LowPassHzLabel.Visible = 'off';
            app.LowPassHzLabel.Text = 'Hz';
            app.DelaymsLabel = uilabel(app.ProcessTab);
            app.DelaymsLabel.Visible = 'off';
            app.DelaymsLabel.Text = 'ms';
            app.DelayEditField = uieditfield(app.ProcessTab, 'numeric');
            app.DelayEditField.Visible = 'off';
            app.DelayEditField.Value = 1;
            app.DerivativeEditField = uieditfield(app.ProcessTab, 'numeric');
            app.DerivativeEditField.Visible = 'off';
            app.DerivativeEditField.Value = 1;
            app.DerivativeOrderLabel = uilabel(app.ProcessTab);
            app.DerivativeOrderLabel.Visible = 'off';
            app.DerivativeOrderLabel.Text = 'Order';
            app.HighPassHzLabel = uilabel(app.ProcessTab);
            app.HighPassHzLabel.Visible = 'off';
            app.HighPassHzLabel.Text = 'Hz';
            app.BandPassStartEditField = uieditfield(app.ProcessTab, 'numeric');
            app.BandPassStartEditField.Visible = 'off';
            app.BandPassDashLabel = uilabel(app.ProcessTab);
            app.BandPassDashLabel.Visible = 'off';
            app.BandPassDashLabel.Text = '---';
            app.BandPassHzLabel = uilabel(app.ProcessTab);
            app.BandPassHzLabel.Visible = 'off';
            app.BandPassHzLabel.Text = 'Hz';
            app.ResetDataButton = uibutton(app.ProcessTab, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Enable = 'off';
            app.ResetDataButton.Text = 'Reset Data';
            app.BandPassFilterType = uidropdown(app.ProcessTab);
            app.BandPassFilterType.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic'};
            app.BandPassFilterType.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterTypeValueChanged, true);
            app.BandPassFilterType.Visible = 'off';
            app.BandPassFilterType.Placeholder = 'Butterworth';
            app.BandPassFilterType.Value = 'Butterworth';
            app.LowPassFilterType = uidropdown(app.ProcessTab);
            app.LowPassFilterType.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic'};
            app.LowPassFilterType.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterTypeValueChanged, true);
            app.LowPassFilterType.Visible = 'off';
            app.LowPassFilterType.Placeholder = 'Butterworth';
            app.LowPassFilterType.Value = 'Butterworth';
            app.HighPassFilterType = uidropdown(app.ProcessTab);
            app.HighPassFilterType.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic'};
            app.HighPassFilterType.ValueChangedFcn = createCallbackFcn(app, @HighPassFilterTypeValueChanged, true);
            app.HighPassFilterType.Visible = 'off';
            app.HighPassFilterType.Placeholder = 'Butterworth';
            app.HighPassFilterType.Value = 'Butterworth';
            app.LowPassFilterOrderLabel = uilabel(app.ProcessTab);
            app.LowPassFilterOrderLabel.Visible = 'off';
            app.LowPassFilterOrderLabel.Text = 'Order';
            app.HighPassFilterOrderLabel = uilabel(app.ProcessTab);
            app.HighPassFilterOrderLabel.Visible = 'off';
            app.HighPassFilterOrderLabel.Text = 'Order';
            app.BandPassFilterOrderLabel = uilabel(app.ProcessTab);
            app.BandPassFilterOrderLabel.Visible = 'off';
            app.BandPassFilterOrderLabel.Text = 'Order';
            app.FullWaveButton = uibutton(app.ProcessTab, 'state');
            app.FullWaveButton.ValueChangedFcn = createCallbackFcn(app, @FullWaveButtonValueChanged, true);
            app.FullWaveButton.Visible = 'off';
            app.FullWaveButton.Text = 'Full Wave';
            app.FullWaveButton.Value = true;
            app.HalfWaveButton = uibutton(app.ProcessTab, 'state');
            app.HalfWaveButton.ValueChangedFcn = createCallbackFcn(app, @HalfWaveButtonValueChanged, true);
            app.HalfWaveButton.Visible = 'off';
            app.HalfWaveButton.Text = 'Half Wave';
            app.NotchUSButton = uibutton(app.ProcessTab, 'state');
            app.NotchUSButton.ValueChangedFcn = createCallbackFcn(app, @NotchUSButtonValueChanged, true);
            app.NotchUSButton.Visible = 'off';
            app.NotchUSButton.Text = '60 Hz (US)';
            app.NotchUSButton.Value = true;
            app.NotchEuropeanButton = uibutton(app.ProcessTab, 'state');
            app.NotchEuropeanButton.ValueChangedFcn = createCallbackFcn(app, @NotchEuropeanButtonValueChanged, true);
            app.NotchEuropeanButton.Visible = 'off';
            app.NotchEuropeanButton.Text = '50 Hz (European)';
            app.OtherNotchButton = uibutton(app.ProcessTab, 'state');
            app.OtherNotchButton.ValueChangedFcn = createCallbackFcn(app, @OtherNotchButtonValueChanged, true);
            app.OtherNotchButton.Visible = 'off';
            app.OtherNotchButton.Text = 'Other';
            app.LowPassFilterRpLabel = uilabel(app.ProcessTab);
            app.LowPassFilterRpLabel.Visible = 'off';
            app.LowPassFilterRpLabel.Text = 'Rp (dB)';
            app.BandPassFilterRpLabel = uilabel(app.ProcessTab);
            app.BandPassFilterRpLabel.Visible = 'off';
            app.BandPassFilterRpLabel.Text = 'Rp (dB)';
            app.HighPassFilterRpLabel = uilabel(app.ProcessTab);
            app.HighPassFilterRpLabel.Visible = 'off';
            app.HighPassFilterRpLabel.Text = 'Rp (dB)';
            app.BandPassEndEditField = uieditfield(app.ProcessTab, 'numeric');
            app.BandPassEndEditField.Visible = 'off';
            app.LowPassEditField = uieditfield(app.ProcessTab, 'numeric');
            app.LowPassEditField.Visible = 'off';
            app.HighPassEditField = uieditfield(app.ProcessTab, 'numeric');
            app.HighPassEditField.Visible = 'off';
            app.BandPassFilterOrder = uieditfield(app.ProcessTab, 'numeric');
            app.BandPassFilterOrder.Limits = [0 Inf];
            app.BandPassFilterOrder.Visible = 'off';
            app.BandPassFilterOrder.Value = 4;
            app.LowPassFilterOrder = uieditfield(app.ProcessTab, 'numeric');
            app.LowPassFilterOrder.Limits = [0 Inf];
            app.LowPassFilterOrder.Visible = 'off';
            app.LowPassFilterOrder.Value = 6;
            app.HighPassFilterOrder = uieditfield(app.ProcessTab, 'numeric');
            app.HighPassFilterOrder.Limits = [0 Inf];
            app.HighPassFilterOrder.Visible = 'off';
            app.HighPassFilterOrder.Value = 4;
            app.BandPassFilterRp = uieditfield(app.ProcessTab, 'numeric');
            app.BandPassFilterRp.Limits = [0 Inf];
            app.BandPassFilterRp.Visible = 'off';
            app.BandPassFilterRp.Value = 0.5;
            app.LowPassFilterRp = uieditfield(app.ProcessTab, 'numeric');
            app.LowPassFilterRp.Limits = [0 Inf];
            app.LowPassFilterRp.Visible = 'off';
            app.LowPassFilterRp.Value = 0.5;
            app.HighPassFilterRp = uieditfield(app.ProcessTab, 'numeric');
            app.HighPassFilterRp.Limits = [0 Inf];
            app.HighPassFilterRp.Visible = 'off';
            app.HighPassFilterRp.Value = 0.5;
            app.LowPassFilterRsLabel = uilabel(app.ProcessTab);
            app.LowPassFilterRsLabel.Visible = 'off';
            app.LowPassFilterRsLabel.Text = 'Rs (dB)';
            app.BandPassFilterRsLabel = uilabel(app.ProcessTab);
            app.BandPassFilterRsLabel.Visible = 'off';
            app.BandPassFilterRsLabel.Text = 'Rs (dB)';
            app.HighPassFilterRsLabel = uilabel(app.ProcessTab);
            app.HighPassFilterRsLabel.Visible = 'off';
            app.HighPassFilterRsLabel.Text = 'Rs (dB)';
            app.BandPassFilterRs = uieditfield(app.ProcessTab, 'numeric');
            app.BandPassFilterRs.Limits = [0 Inf];
            app.BandPassFilterRs.Visible = 'off';
            app.BandPassFilterRs.Value = 60;
            app.LowPassFilterRs = uieditfield(app.ProcessTab, 'numeric');
            app.LowPassFilterRs.Limits = [0 Inf];
            app.LowPassFilterRs.Visible = 'off';
            app.LowPassFilterRs.Value = 60;
            app.HighPassFilterRs = uieditfield(app.ProcessTab, 'numeric');
            app.HighPassFilterRs.Limits = [0 Inf];
            app.HighPassFilterRs.Visible = 'off';
            app.HighPassFilterRs.Value = 60;
            app.OtherNotchEditField = uieditfield(app.ProcessTab, 'numeric');
            app.OtherNotchEditField.Visible = 'off';
            app.OtherNotchHzLabel = uilabel(app.ProcessTab);
            app.OtherNotchHzLabel.Visible = 'off';
            app.OtherNotchHzLabel.Text = 'Hz';
            app.ProcessOrderListBox = uilistbox(app.ProcessTab);
            app.ProcessOrderListBox.Items = {};
            app.ProcessOrderListBox.Value = {};
            app.ProcessOrderListBoxLabel = uilabel(app.ProcessTab);
            app.ProcessOrderListBoxLabel.Text = 'Process Order';
            app.CustomProcessTree = uitree(app.ProcessTab, 'checkbox');
            app.CustomProcessTree.Visible = 'off';
            % Assign Checked Nodes
            app.CustomProcessTree.CheckedNodesChangedFcn = createCallbackFcn(app, @CustomProcessTreeCheckedNodesChanged, true);
            app.CustomProcessesLabel = uilabel(app.ProcessTab);
            app.CustomProcessesLabel.Visible = 'off';
            app.CustomProcessesLabel.Text = 'Custom Processes';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.MotorEvokedPotentialPanel = uipanel(app.AnalysisTab);
            app.MotorEvokedPotentialPanel.Title = 'Motor Evoked Potential';
            app.OnsetOffsetDetectionDropDown = uidropdown(app.MotorEvokedPotentialPanel);
            app.OnsetOffsetDetectionDropDown.Items = {'SD Bounds', 'Garvey et al'};
            app.OnsetOffsetDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @OnsetOffsetDetectionDropDownValueChanged, true);
            app.OnsetOffsetDetectionDropDown.Value = 'SD Bounds';
            app.OnsetOffsetDetectionLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.OnsetOffsetDetectionLabel.Text = 'Onset/Offset Detection';
            app.FiftyPercentOnsetCheckBox = uicheckbox(app.MotorEvokedPotentialPanel);
            app.FiftyPercentOnsetCheckBox.Text = '50%';
            app.StartTimemsEditFieldLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.StartTimemsEditFieldLabel.Text = 'Start Time  (ms)';
            app.StartTimemsEditField = uieditfield(app.MotorEvokedPotentialPanel, 'numeric');
            app.StartTimemsEditField.Value = 1;
            app.OnsetLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.OnsetLabel.Text = 'Onset';
            app.OffsetLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.OffsetLabel.Text = 'Offset';
            app.OnsetmsLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.OnsetmsLabel.Text = 'Onset (ms)';
            app.DurationmsLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.DurationmsLabel.Text = 'Duration (ms)';
            app.AmplitudemVLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.AmplitudemVLabel.Text = 'Amplitude (mV)';
            app.AreamVsLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.AreamVsLabel.Text = 'Area (mV*s)';
            app.OnsetmsValueLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.OnsetmsValueLabel.Text = 'N/A';
            app.DurationmsValueLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.DurationmsValueLabel.Text = 'N/A';
            app.DecreaseValueLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.DecreaseValueLabel.Text = 'N/A';
            app.AreaValueLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.AreaValueLabel.Text = 'N/A';
            app.SDEditFieldLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.SDEditFieldLabel.Text = 'SD';
            app.OnsetSDEditField = uieditfield(app.MotorEvokedPotentialPanel, 'numeric');
            app.OnsetSDEditField.Value = 1;
            app.MinDurmsEditFieldLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.MinDurmsEditFieldLabel.Text = 'Min Dur (ms)';
            app.OnsetMinDurmsEditField = uieditfield(app.MotorEvokedPotentialPanel, 'numeric');
            app.OnsetMinDurmsEditField.Value = 1;
            app.SDEditFieldLabel_2 = uilabel(app.MotorEvokedPotentialPanel);
            app.SDEditFieldLabel_2.Text = 'SD';
            app.OffsetSDEditField = uieditfield(app.MotorEvokedPotentialPanel, 'numeric');
            app.OffsetSDEditField.Value = 1;
            app.MinDurmsEditFieldLabel_2 = uilabel(app.MotorEvokedPotentialPanel);
            app.MinDurmsEditFieldLabel_2.Text = 'Min Dur (ms)';
            app.OffsetMinDurmsEditField = uieditfield(app.MotorEvokedPotentialPanel, 'numeric');
            app.OffsetMinDurmsEditField.Value = 1;
            app.MCDConstantEditFieldLabel = uilabel(app.MotorEvokedPotentialPanel);
            app.MCDConstantEditFieldLabel.Visible = 'off';
            app.MCDConstantEditFieldLabel.Text = 'MCD Constant';
            app.MCDConstantEditField = uieditfield(app.MotorEvokedPotentialPanel, 'numeric');
            app.MCDConstantEditField.Visible = 'off';
            app.MCDConstantEditField.Value = 2.66;
            app.FiftyPercentOffsetCheckBox = uicheckbox(app.MotorEvokedPotentialPanel);
            app.FiftyPercentOffsetCheckBox.Text = '50%';
            app.OnsetRiseorFallCheckBox = uicheckbox(app.MotorEvokedPotentialPanel);
            app.OnsetRiseorFallCheckBox.Visible = 'off';
            app.OnsetRiseorFallCheckBox.Text = {'Onset'; 'Find Rise [checked]'; 'Find Fall [unchecked]'};
            app.AnalysisOptionDropDown = uidropdown(app.AnalysisTab);
            app.AnalysisOptionDropDown.Items = {'Motor Evoked Potential', 'Silent Period', 'EMG Analysis (WIP)'};
            app.AnalysisOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisOptionDropDownValueChanged, true);
            app.AnalysisOptionDropDown.Value = 'Motor Evoked Potential';
            app.AnalysisOptionLabel = uilabel(app.AnalysisTab);
            app.AnalysisOptionLabel.Text = 'Analysis Option';
            app.PreStimPeriodUnitLabel = uilabel(app.AnalysisTab);
            app.PreStimPeriodUnitLabel.Text = 'ms';
            app.PreStimMeanLabel = uilabel(app.AnalysisTab);
            app.PreStimMeanLabel.Text = 'mV';
            app.PreStimStdLabel = uilabel(app.AnalysisTab);
            app.PreStimStdLabel.Text = 'mV';
            app.PreStimPeriodLabel = uilabel(app.AnalysisTab);
            app.PreStimPeriodLabel.Text = 'PreStim Period (ms):';
            app.PreStimPeriodmsStartEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PreStimPeriodmsStartEditField.ValueChangedFcn = createCallbackFcn(app, @PreStimPeriodmsStartEditFieldValueChanged, true);
            app.PreStimPeriodmsStartEditField.Value = -110;
            app.PreStimPeriodDashLabel = uilabel(app.AnalysisTab);
            app.PreStimPeriodDashLabel.Text = '-----';
            app.PreStimPeriodmsEndEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PreStimPeriodmsEndEditField.ValueChangedFcn = createCallbackFcn(app, @PreStimPeriodmsEndEditFieldValueChanged, true);
            app.PreStimPeriodmsEndEditField.Value = -10;
            app.PreStimMeanEditFieldLabel = uilabel(app.AnalysisTab);
            app.PreStimMeanEditFieldLabel.Text = 'Pre-Stim Mean:';
            app.PreStimMeanEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PreStimMeanEditField.Editable = 'off';
            app.PreStimStdEditFieldLabel = uilabel(app.AnalysisTab);
            app.PreStimStdEditFieldLabel.Text = 'Pre-Stim Std:';
            app.PreStimStdEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PreStimStdEditField.Editable = 'off';
            app.PreStimLabel = uilabel(app.AnalysisTab);
            app.PreStimLabel.Text = 'Pre-Stim';
            app.AnalyzeButton = uibutton(app.AnalysisTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.OverrideButton = uibutton(app.AnalysisTab, 'push');
            app.OverrideButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideButtonPushed, true);
            app.OverrideButton.Enable = 'off';
            app.OverrideButton.Text = 'Override';
            app.OverrideLamp = uilamp(app.AnalysisTab);
            app.PlotOnsetOffsetSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.PlotOnsetOffsetSwitchLabel = uilabel(app.AnalysisTab);
            app.PlotOnsetOffsetSwitchLabel.Text = 'Plot Onset/Offset';
            app.NDButton = uibutton(app.AnalysisTab, 'push');
            app.NDButton.ButtonPushedFcn = createCallbackFcn(app, @NDButtonPushed, true);
            app.NDButton.Text = 'ND';
            app.CustomOutputsPanel = uipanel(app.AnalysisTab);
            app.CustomOutputsPanel.Title = 'Custom Outputs';
            app.CustomOutputsPanel.Visible = 'off';
            app.CustomOutputsGridLayout = uigridlayout(app.CustomOutputsPanel);
            app.AutoScaleButton = uibutton(app.App_Panel, 'push');
            app.AutoScaleButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScaleButtonPushed, true);
            app.AutoScaleButton.Text = 'Auto Scale';
            app.SavecsvButton = uibutton(app.App_Panel, 'push');
            app.SavecsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavecsvButtonPushed, true);
            app.SavecsvButton.Text = 'Save (.csv)';
            app.SavecsvEditField = uieditfield(app.App_Panel, 'text');
            app.SaveProcessedDatamatButton = uibutton(app.App_Panel, 'push');
            app.SaveProcessedDatamatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedDatamatButtonPushed, true);
            app.SaveProcessedDatamatButton.Enable = 'off';
            app.SaveProcessedDatamatButton.Text = 'Save Processed Data (.mat)';
            app.SavematEditField = uieditfield(app.App_Panel, 'text');
            app.SavematEditField.Enable = 'off';
            app.AverageCheckBox = uicheckbox(app.App_Panel);
            app.AverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageCheckBoxValueChanged, true);
            app.AverageCheckBox.Text = 'Average';
            app.MissingfromanalyzeLabel = uilabel(app.App_Panel);
            app.MissingfromanalyzeLabel.Visible = 'off';
            app.MissingfromanalyzeLabel.Text = 'Missing from analyze';
            app.ChangeSaveFolderButton = uibutton(app.App_Panel, 'push');
            app.ChangeSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSaveFolderButtonPushed, true);
            app.ChangeSaveFolderButton.Text = 'Change Save Folder';
            app.Image = uiimage(app.App_Panel);
            app.Image.URL = 'https://cunninghamlab.framer.website/tools';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo', 'Group 13.png');
            app.TutorialLabel = uilabel(app.App_Panel);
            app.TutorialLabel.Text = 'Tutorial';
            app.Citation = uilabel(app.App_Panel);
            app.Citation.Text = {'Please Cite: D. Cunningham, B. Zhang and A. Cahn, "Transcranial Magnetic Stimulation (TMS) Analysis Toolbox: '; 'A user friendly open source software for basic and advanced analysis and data sharing of TMS related '; 'outcomes", Brain Stimul., vol. 14, no. 6, pp. 1641-1642, Nov. 2021'};
            app.RightArrowAnalyzeButton = uibutton(app.App_Panel, 'push');
            app.RightArrowAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @RightArrowAnalyzeButtonPushed, true);
            app.RightArrowAnalyzeButton.Enable = 'off';
            app.RightArrowAnalyzeButton.Visible = 'off';
            app.RightArrowAnalyzeButton.Text = '>';
            app.LeftArrowAnalyzeButton = uibutton(app.App_Panel, 'push');
            app.LeftArrowAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowAnalyzeButtonPushed, true);
            app.LeftArrowAnalyzeButton.Enable = 'off';
            app.LeftArrowAnalyzeButton.Visible = 'off';
            app.LeftArrowAnalyzeButton.Text = '<';
            app.NotesTextAreaLabel = uilabel(app.App_Panel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.App_Panel);
            app.SButton = uibutton(app.App_Panel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Visible = 'off';
            app.SButton.Text = 'S';
            app.ClearLoadingScreenButton = uibutton(app.UIFigure, 'push');
            app.ClearLoadingScreenButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadingScreenButtonPushed, true);
            app.ClearLoadingScreenButton.Text = 'Clear Loading Screen';
            app.UsewhenanerroroccursLabel = uilabel(app.UIFigure);
            app.UsewhenanerroroccursLabel.Text = 'Use when an error occurs';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.OverrideButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMSAnalysisToolBox_v2_1_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end