classdef TMSAnalysisToolBoxV1_Resize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenNewFileMenu                 matlab.ui.container.Menu
        LabChartmatMenu                 matlab.ui.container.Menu
        AcqknowledgematMenu             matlab.ui.container.Menu
        SignalmatMenu                   matlab.ui.container.Menu
        BrainVisioneegvhdrvmkrMenu      matlab.ui.container.Menu
        SpikesmrsmrxMenu                matlab.ui.container.Menu
        BrainSighttxtMenu               matlab.ui.container.Menu
        ExoForcejsonMenu                matlab.ui.container.Menu
        CustomMenu                      matlab.ui.container.Menu
        GeorgetownMenu                  matlab.ui.container.Menu
        RochesterMenu                   matlab.ui.container.Menu
        CCFMenu                         matlab.ui.container.Menu
        MCRRMenu                        matlab.ui.container.Menu
        OpenSavedConcatFileMenu         matlab.ui.container.Menu
        OpenConditionFileMenu           matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        FileOrderListBox                matlab.ui.control.ListBox
        FileOrderListBoxLabel           matlab.ui.control.Label
        PleaseCiteLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Processing                      matlab.ui.control.Label
        FigureCheckCheckBox             matlab.ui.control.CheckBox
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        EventNameListBoxLabel           matlab.ui.control.Label
        EventNameListBox                matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        SilentPeriodPanel               matlab.ui.container.Panel
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        AlgorithmCheckBox               matlab.ui.control.CheckBox
        StartTimemsEditField            matlab.ui.control.NumericEditField
        StartTimemsEditFieldLabel       matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        Panel_8                         matlab.ui.container.Panel
        SDoffsetEditField               matlab.ui.control.NumericEditField
        SDoffsetEditFieldLabel          matlab.ui.control.Label
        MinDurmsEditField_2             matlab.ui.control.NumericEditField
        MinDurmsEditField_2Label        matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        MinDurmsEditField               matlab.ui.control.NumericEditField
        MinDurmsEditFieldLabel          matlab.ui.control.Label
        SDonsetEditField                matlab.ui.control.NumericEditField
        SDonsetEditFieldLabel           matlab.ui.control.Label
        NALabel_4                       matlab.ui.control.Label
        NALabel_3                       matlab.ui.control.Label
        NALabel_2                       matlab.ui.control.Label
        NALabel                         matlab.ui.control.Label
        AreaLabel                       matlab.ui.control.Label
        DecreaseLabel                   matlab.ui.control.Label
        DurationmsLabel                 matlab.ui.control.Label
        OnsetmsLabel                    matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        HighLabel                       matlab.ui.control.Label
        LowLabel                        matlab.ui.control.Label
        RemovedCount                    matlab.ui.control.Label
        HighThreshEditField             matlab.ui.control.EditField
        LowThreshEditField              matlab.ui.control.EditField
        RemoveCheckBox                  matlab.ui.control.CheckBox
        MCDConstantEditField            matlab.ui.control.NumericEditField
        MCDConstantEditFieldLabel       matlab.ui.control.Label
        ClearBackgroundLinesCheckBox    matlab.ui.control.CheckBox
        HzEditField_4                   matlab.ui.control.NumericEditField
        HzEditField_4Label              matlab.ui.control.Label
        MCDCheckBox                     matlab.ui.control.CheckBox
        LowPassEnvelopeCheckBox         matlab.ui.control.CheckBox
        AnalyzetheAverageCheckBox       matlab.ui.control.CheckBox
        OverrideButton                  matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        EMGAnalysisPanel                matlab.ui.container.Panel
        OnsetmsEditField                matlab.ui.control.NumericEditField
        OnsetmsEditFieldLabel           matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        NALabel_20                      matlab.ui.control.Label
        RelaxLabel                      matlab.ui.control.Label
        ContractLabel                   matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        NALabel_19                      matlab.ui.control.Label
        areaWindowStart                 matlab.ui.control.NumericEditField
        areaWindowStart2                matlab.ui.control.NumericEditField
        areaWindowTime                  matlab.ui.control.NumericEditField
        EDENAnalyzeButton               matlab.ui.control.Button
        OverrideButton_4                matlab.ui.control.Button
        AnalyzeButton_4                 matlab.ui.control.Button
        Panel_20                        matlab.ui.container.Panel
        MDFText                         matlab.ui.control.Label
        MDF                             matlab.ui.control.Label
        NALabel_17                      matlab.ui.control.Label
        modRatioWindowText              matlab.ui.control.Label
        modRatioWindow                  matlab.ui.control.NumericEditField
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        StartTimemsEditField_4          matlab.ui.control.NumericEditField
        StartTimemsEditField_4Label     matlab.ui.control.Label
        Panel_21                        matlab.ui.container.Panel
        Panel_22                        matlab.ui.container.Panel
        MinDurmsEditField_3             matlab.ui.control.NumericEditField
        MinDurmsEditField_3Label        matlab.ui.control.Label
        SDonsetEditField_2              matlab.ui.control.NumericEditField
        SDonsetEditField_2Label         matlab.ui.control.Label
        NALabel_16                      matlab.ui.control.Label
        NALabel_15                      matlab.ui.control.Label
        NALabel_14                      matlab.ui.control.Label
        PeakLabel                       matlab.ui.control.Label
        ModulationRatioLabel            matlab.ui.control.Label
        OnsetmsLabel_3                  matlab.ui.control.Label
        HzEditField_6                   matlab.ui.control.NumericEditField
        HzEditField_4Label_2            matlab.ui.control.Label
        LowPassEnvelopeCheckBox_3       matlab.ui.control.CheckBox
        ClearBackgroundLinesCheckBox_3  matlab.ui.control.CheckBox
        AnalyzetheAverageCheckBox_3     matlab.ui.control.CheckBox
        OverrideButton_2                matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        StartTimemsEditField_2          matlab.ui.control.NumericEditField
        StartTimemsEditField_2Label     matlab.ui.control.Label
        ZSDEditField                    matlab.ui.control.NumericEditField
        ZSDEditFieldLabel               matlab.ui.control.Label
        LowPassHzEditField              matlab.ui.control.NumericEditField
        LowPassHzEditFieldLabel         matlab.ui.control.Label
        ZTransButton                    matlab.ui.control.Button
        PROLIFICButton                  matlab.ui.control.Button
        RestingMEPPanel                 matlab.ui.container.Panel
        Panel_15                        matlab.ui.container.Panel
        HighThreshEditField_2           matlab.ui.control.EditField
        LowThreshEditField_2            matlab.ui.control.EditField
        HighLabel_2                     matlab.ui.control.Label
        LowLabel_2                      matlab.ui.control.Label
        RemovedCount_2                  matlab.ui.control.Label
        RemoveCheckBox_2                matlab.ui.control.CheckBox
        ClearBackgroundLinesCheckBox_2  matlab.ui.control.CheckBox
        HzEditField_5                   matlab.ui.control.NumericEditField
        HzEditField_5Label              matlab.ui.control.Label
        LowPassEnvelopeCheckBox_2       matlab.ui.control.CheckBox
        AnalyzetheAverageCheckBox_2     matlab.ui.control.CheckBox
        Panel_14                        matlab.ui.container.Panel
        Button_9                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        OverrideButton_3                matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        StartTimemsEditField_3          matlab.ui.control.NumericEditField
        StartTimemsEditField_3Label     matlab.ui.control.Label
        LowPassHzEditField_2            matlab.ui.control.NumericEditField
        LowPassHzEditField_2Label       matlab.ui.control.Label
        ZSDEditField_2                  matlab.ui.control.NumericEditField
        ZSDEditField_2Label             matlab.ui.control.Label
        ZTransButton_2                  matlab.ui.control.Button
        NALabel_8                       matlab.ui.control.Label
        NALabel_7                       matlab.ui.control.Label
        NALabel_6                       matlab.ui.control.Label
        NALabel_5                       matlab.ui.control.Label
        AreaLabel_2                     matlab.ui.control.Label
        AmplitudemVLabel                matlab.ui.control.Label
        DurationmsLabel_2               matlab.ui.control.Label
        OnsetmsLabel_2                  matlab.ui.control.Label
        ConditionTab                    matlab.ui.container.Tab
        Mapping                         matlab.ui.container.Panel
        SaveCSVButton_2                 matlab.ui.control.Button
        LoadxlsButton                   matlab.ui.control.Button
        PercentIncLabel                 matlab.ui.control.Label
        EditField_13                    matlab.ui.control.NumericEditField
        ThresholdLabel_2                matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        ExportNeuroMeasurecsvButton     matlab.ui.control.Button
        ReorderButton_4                 matlab.ui.control.Button
        MeanCheckBox_4                  matlab.ui.control.CheckBox
        MetricstoAnalyzeListBox_4       matlab.ui.control.ListBox
        MetricstoAnalyzeListBox_4Label  matlab.ui.control.Label
        PlotButton_2                    matlab.ui.control.Button
        UITable_CondOder_4              matlab.ui.control.Table
        EditField_9                     matlab.ui.control.NumericEditField
        CentralityCheckBox_4            matlab.ui.control.CheckBox
        RecruitmentCurvePanel           matlab.ui.container.Panel
        ReorderButton                   matlab.ui.control.Button
        MeanCheckBox                    matlab.ui.control.CheckBox
        MetricstoAnalyzeListBox         matlab.ui.control.ListBox
        MetricstoAnalyzeListBoxLabel    matlab.ui.control.Label
        AnalyzeButton_2                 matlab.ui.control.Button
        UITable_CondOder                matlab.ui.control.Table
        EditField_6                     matlab.ui.control.NumericEditField
        CentralityCheckBox              matlab.ui.control.CheckBox
        Panel_18                        matlab.ui.container.Panel
        Panel_19                        matlab.ui.container.Panel
        FromEditField                   matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        AUCLabel                        matlab.ui.control.Label
        NALabel_13                      matlab.ui.control.Label
        NALabel_9                       matlab.ui.control.Label
        NALabel_12                      matlab.ui.control.Label
        NALabel_11                      matlab.ui.control.Label
        NALabel_10                      matlab.ui.control.Label
        KSlopeLabel                     matlab.ui.control.Label
        InflectionLabel                 matlab.ui.control.Label
        PlateauLabel                    matlab.ui.control.Label
        BaseLabel                       matlab.ui.control.Label
        Visualize                       matlab.ui.container.Panel
        EditField_12                    matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        SaveCSVButton                   matlab.ui.control.Button
        ReorderButton_2                 matlab.ui.control.Button
        MeanCheckBox_2                  matlab.ui.control.CheckBox
        MetricstoAnalyzeListBox_2       matlab.ui.control.ListBox
        MetricstoAnalyzeListBox_2Label  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UITable_CondOder_2              matlab.ui.control.Table
        EditField_7                     matlab.ui.control.NumericEditField
        CentralityCheckBox_2            matlab.ui.control.CheckBox
        PairedPulse                     matlab.ui.container.Panel
        SaveButton_2                    matlab.ui.control.Button
        Ratio21Label                    matlab.ui.control.Label
        Ratio                           matlab.ui.control.Label
        ReorderButton_3                 matlab.ui.control.Button
        MeanCheckBox_3                  matlab.ui.control.CheckBox
        MetricstoAnalyzeListBox_3       matlab.ui.control.ListBox
        MetricstoAnalyzeListBox_3Label  matlab.ui.control.Label
        UITable_CondOder_3              matlab.ui.control.Table
        EditField_8                     matlab.ui.control.NumericEditField
        CentralityCheckBox_3            matlab.ui.control.CheckBox
        AnalyzeButton_3                 matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        StackPlotCheckBox               matlab.ui.control.CheckBox
        DefineResultsDirectoryButton    matlab.ui.control.Button
        OverlayNonRectifiedCheckBox     matlab.ui.control.CheckBox
        ScaleButton                     matlab.ui.control.Button
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        XMax                            matlab.ui.control.EditField
        XMin                            matlab.ui.control.EditField
        YMax                            matlab.ui.control.EditField
        YMin                            matlab.ui.control.EditField
        MultiConditionAnalysisCheckBox  matlab.ui.control.CheckBox
        GroupAnalysisPanel              matlab.ui.container.Panel
        ListBox_2                       matlab.ui.control.ListBox
        ConditionMeanCheckBox           matlab.ui.control.CheckBox
        DefineDataDirectoryButton       matlab.ui.control.Button
        ConditionsListBox               matlab.ui.control.ListBox
        ConditionsListBoxLabel          matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        PostTriggerTime                 matlab.ui.control.NumericEditField
        PreTriggerTime                  matlab.ui.control.NumericEditField
        PrPotriggertimemsLabel          matlab.ui.control.Label
        EventNameListBox_2              matlab.ui.control.ListBox
        EventNameListBox_2Label         matlab.ui.control.Label
        DefineConditionsPanel           matlab.ui.container.Panel
        ChangeGroupNamestxttabButton    matlab.ui.control.Button
        ImportEventsButton              matlab.ui.control.Button
        LoadGroupNamesxlsButton         matlab.ui.control.Button
        SearchFileLabel                 matlab.ui.control.Label
        EditField_10                    matlab.ui.control.EditField
        LoadBrainsighttxtButton         matlab.ui.control.Button
        KeepSelectedRowsButton          matlab.ui.control.Button
        FillFactor                      matlab.ui.control.NumericEditField
        AutoFillButton                  matlab.ui.control.Button
        AddBlankRowButton               matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        LoadConditionButton             matlab.ui.control.Button
        Panel_16                        matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        TrialSelectButton               matlab.ui.control.Button
        TrialSelectPanel_3              matlab.ui.container.Panel
        OKButton_3                      matlab.ui.control.Button
        MultiChanListBox_3              matlab.ui.control.ListBox
        UITable                         matlab.ui.control.Table
        SampleRateHzEditField           matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        TrialsnotfoundEditField         matlab.ui.control.NumericEditField
        TrialsnotfoundEditFieldLabel    matlab.ui.control.Label
        InitializationButton            matlab.ui.control.Button
        TrialSelectPanel                matlab.ui.container.Panel
        DefineConditionButton           matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        MultiChanListBox                matlab.ui.control.ListBox
        AnalysisOptionPanel             matlab.ui.container.Panel
        mVLabel_2                       matlab.ui.control.Label
        PreStimStdEditField             matlab.ui.control.NumericEditField
        PreStimStdEditFieldLabel        matlab.ui.control.Label
        mVLabel                         matlab.ui.control.Label
        PreStimMeanEditField            matlab.ui.control.NumericEditField
        PreStimMeanLabel                matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        PreStimPeriodmsEditField        matlab.ui.control.NumericEditField
        PreStimPeriodmsEditFieldLabel   matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        Panel_3                         matlab.ui.container.Panel
        EditField_5                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        SavecsvButton                   matlab.ui.control.Button
        SavematButton                   matlab.ui.control.Button
        ResetScaleButton                matlab.ui.control.Button
        ProcessPanel                    matlab.ui.container.Panel
        NALabel_21                      matlab.ui.control.Label
        NonRectifiedLabel               matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        VelocityCheckBox                matlab.ui.control.CheckBox
        DelayCheckBox                   matlab.ui.control.CheckBox
        msEditField                     matlab.ui.control.NumericEditField
        msEditFieldLabel                matlab.ui.control.Label
        HzEditField_3                   matlab.ui.control.NumericEditField
        HzEditField_3Label              matlab.ui.control.Label
        LowPassCheckBox                 matlab.ui.control.CheckBox
        RectifyCheckBox                 matlab.ui.control.CheckBox
        TKEOCheckBox                    matlab.ui.control.CheckBox
        HzEditField_2                   matlab.ui.control.NumericEditField
        HzEditField_2Label              matlab.ui.control.Label
        NotchCheckBox                   matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        HzEditField                     matlab.ui.control.NumericEditField
        HzEditFieldLabel                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        BandPassCheckBox                matlab.ui.control.CheckBox
        FactorEditField                 matlab.ui.control.NumericEditField
        FactorEditFieldLabel            matlab.ui.control.Label
        DownSampleCheckBox              matlab.ui.control.CheckBox
        EnableCheckBox                  matlab.ui.control.CheckBox
        ButtonGroupUnits                matlab.ui.container.ButtonGroup
        VButton                         matlab.ui.control.RadioButton
        mVButton                        matlab.ui.control.RadioButton
        uVButton                        matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        SearchISIs                      matlab.ui.control.NumericEditField
        SearchISIsLabel                 matlab.ui.control.Label
        ThresholdmV                     matlab.ui.control.NumericEditField
        ThresholdmVEditFieldLabel       matlab.ui.control.Label
        EventChannel                    matlab.ui.control.NumericEditField
        EventChannelLabel               matlab.ui.control.Label
        FileName                        matlab.ui.control.EditField
        FileNameLabel                   matlab.ui.control.Label
        SetEventWindowPanel             matlab.ui.container.Panel
        SelectMultipleTrialsButton      matlab.ui.control.Button
        PostDur                         matlab.ui.control.EditField
        PostDurEditFieldLabel           matlab.ui.control.Label
        PreDurs                         matlab.ui.control.EditField
        PreDursEditFieldLabel           matlab.ui.control.Label
        TrialSpinner                    matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
        FindallEventsButton             matlab.ui.control.Button
        EventTypePanel                  matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        BlocksFramesButton              matlab.ui.control.RadioButton
        EventsegTTLButton               matlab.ui.control.RadioButton
        CommentsButton                  matlab.ui.control.RadioButton
        Panel_4                         matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% TMS data analysis %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %{
        % OFFICIAL NAME
        % This is a demo of .mat TMS data exported from LabChart analysis.The main
        % function of the demo includes:
        %
        %
        % Edited by David Cunningham and Bei Zhang from Case Western Reserve University/MetroHealth.
        %   Email:dxc536@case.edu,bxz266@case.edu
        %   Date: 2021/04/23
        %   Version 1.01
        %}

        labChartData        % data package of the TMS data exported from LabChart
        AcqknowledgeData    % data package of the TMS data exported from Acqknowledge
        SignalData          % data package of the TMS data exported from Signal
        BrainVisionData     % data package of the TMS data exported from BrainVision
        SpikeData           % data package of the TMS data exported from Spike
        CustomData          % data package of the TMS data exported from your unique software (e.g. labview)
        BrainSightData      % data package of stereotaxix data exported from brainsight
        BrainSightDataWave  % data package of stereotaxix data exported from brainsight with EMG
        origSampleRate      % define the sample rate variable
        origTime            % save the time when find all events. Because time can be changed in following process
        eventInfo           % define the event/trial information
        totChannel          % define the channel number variable
        totEvent            % define the event/trial number variable
        totBlock            % define the block number variable
        UnprocessedData     % define the data before processed.
        unit                % define the unit of the TMS data
        EventType           % define the type of event 1/2/3 refers to comment/event/block ???
        allData             % define the matrix which saves data of all channels and all trials
        reloadInd           % define a mark to reveal if it is the first time to load the data. If the data is saved, next time just call
        fileName            % name of the data
        dataType            % data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
        time                % time points of the data
        timeUnit            % unit of the time
        MultiChan           % selected multiple channels
        clickCount          % count the clicks of 'Select Multiple Trials' button
        chosenData          % data to plot
        meanData            % mean data of multiple selected trials
        DSData              % save the down sampled data
        DSTime              % save the down sampled time
        DelayTime           % save the delayed time
        BPData              % save the bandpass data
        NotData             % save the data after Notch filter
        LPData              % save the Low Pass data
        dwnSampleRate       % save the down samplerate
        TKEOData            % save TKEO data
        VelocityData        % save Velocity Data
        RectifyData         % save rectified data
        DataToPlot          % save the plot data
        TimeToPlot          % save the time vector to plot
        ProcessInd          % data is pre-processed or not
        onsetLine           % onset line to determine the silent period
        offsetLine          % offset line to determine the silent period
        overRideMark        % if override button is clicked
        EMGBurstoverRideMark% if override button in EMG Burst detection is clicked
        rMEPoverrideMark    % resting MEP override
        overRideInd         % save the override onset and offset index
        dataOut             % variables to export
        tempH               % temporally saves the lines of the figure in mV
        WastedTrials        % counts of wasted trials
        Patch               % Patch the prestimulation period
        AnalyzeInd          % Analyze button clicked
        PreStimMean         % Pre stimulation mean
        Ztrans              % if Ztrans is clicked
        Prolific            % if Prolific is clicked
        TrialNum_CurrentGroup  % trial numbers when hit 'add new group'. Create new groups and assignl trials to them
        TrialName_CurrentGroup % group name when hit 'add new group'
        conditionData       % save the condition data if condition applied
        conditionMean       % save the mean data of all conditions in the condition list
        conditionTotalEvent % save the number of trials within the condition
        EventName           % save the event name
        allConditionEvents  % save all event names to the conditions
        allConditionData    % save the data to all condition and event names
        conditionInd        % save the condition index when select multiple conditions
        LowThresh           % Contains Remove LowThresh Value for Active Conditions
        HighThresh          % Contains Remove HighThresh Value for Active Conditions
        LowThreshRest       % Contains Remove LowThresh Value for Rest Conditions
        HighThreshRest      % Contains Remove HighThresh Value for Rest Conditions
        toolboxDir          % Locates where full toolbox directory and contents
        DataDir             % Define Data Directory
        removeIDX           %stores removed trials for group analysis
        conditionIndRmIDX
        boltzY              % RC Boltzman Fit
        boltzX              % RC Boltzman Fit
        rcData              % RC Trial x Trial MEP
        percentMT           % RC X axis
        RC                  % used to save mat file
        OnsetThrePlot       % Contains threshold onset plot
        OffsetThrePlot      % Contains threshold offset plot
        PreMeanPlot         % Contains premean plot
        wave                % Contains raw data for Spike type data
        tick                % Contains tick information for Spike type data
        tickcat             % Contains tick information for Spike type data
        TableRow            % Contains UITable information to delete
        condition           % Contains condition IDX for MultiSelect
        conditionOutcome    % Contains condition outcome for MultiSelect
        On
        importEvent
        stream              % Contains custom data
        ENVDataToPlot
        EMGAnalysisOR
        BrainsightTrialNum
        EDENData
        allDataPosition
        force               %Used to Store Rochester Force Levels
        allForce            %Used to Store Rochester Force Levels In Condition File
        Sulzer              %Custom Sulzer Data
        %{
        % The meaning of related papameters to the "labChartdata" are listed below:
        %     {'blocktimes'       } --- time of day at which each block was
        %       records.
        %     {'com'              } --- 1. comment channel. -1 means the
        %       comment is in all channels
        %                               2. comment block. the block number
        %                                   the comment was made in.
        %                               3. comment tick posision. The comment
        %                                   position realative to the block start position.
        %                               4. comment type. "1"-comments,
        %                                   "2"-event markers.
        %                               5. comment text map. Indices that
        %                                   refer to entries in "context"
        %     {'context'          } --- information about comments. Each
        %       comment corresponds to one row.
        %     {'data'             } --- all the data points
        %     {'datastart'        } --- the start index of every channel and block s
        %     {'dataend'          } --- end index of every channel and block
        %     {'titles'           } --- channel names
        %     {'rangemin'         } --- minimal range of each block
        %     {'rangemax'         } --- maximal range of each block
        %     {'unittext'         } --- unit of the data
        %     {'unittextmap'      } --- the content of "unittext". If units
        %       across the blocks are the same, each string is only exported
        %       once. '-1' refers to an empty channel
        %     {'blocktimes'       } --- duration of every block
        %     {'tickrate'         } --- tickrate (maximum sample rate) of
        %       each block
        %     {'titles'           } --- names of each channel. Empty
        %     channels will also have a name
        %
        %     {'samplerate'       } --- sample rate of every channel and block
        %     {'firstsampleoffset'} ---
        %     {'comtext'          } --- names of the
        events/comments/blockss

        
        % For empty channel:
        %       samplerate = 0
        %       datastart and dataend indices = -1
        %       unittextmap = -1
        %       rangemax = rangemin = 0
        %}

    end

    methods (Access = private)
        function results = func(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Pointer = 'arrow';
            app.UIFigure.Resize = 'off';
            app.UIFigure.AutoResizeChildren = 'off';
            %             set(app.UIAxes,'defaultLegendAutoUpdate','off'); % stop legend autoupdate
            % %             set(fig,'defaultLegendAutoUpdate','off');
            % app resize according to screen resolution
            %             screenSize = get(groot,'ScreenSize');
            %             screenWidth = screenSize(3);
            %             screenHeight = screenSize(4);
            %             left = screenWidth*0.1;
            %             bottom = screenHeight*0.1;
            %             width = screenWidth*0.7;
            %             height = screenHeight*0.7;
            %             drawnow;
            %             app.UIFigure.Position = [left bottom width height];
            %             warning('off','all')
            %
            app.UIFigure.Resize = 'on';
            %             app.UIFigure.AutoResizeChildren = 'on';
            isdeployed


            %%%%%%%%%%%%%%%%%
            app.reloadInd = 0;                  % it is possible the user load the conditions without loading any file
            app.ProcessInd = 0;


            %!!!!!!!!!!!!! Save startup.m to userpath!!!!!!!!!!!!!!!!!!!!!!%
            %app.toolboxDir = what('TMSAnalysisToolBox'); %defines directory for saving folders
            strcat(userpath,'/SavedSettings.mat');
            if isfile(ans)
                load(ans)
                app.toolboxDir = SavedSettings.toolboxpath;
            else
                msgbox('Define TMSAnalysisToolBox Directory: Directory will be saved for next startup. Redifine directory using the "Define Results Directory" button ')
                app.toolboxDir = uigetdir; %Defines where open file directs you to
                SavedSettings.toolboxpath = app.toolboxDir;
                strcat(userpath,'/SavedSettings');
                save(ans,'SavedSettings');
            end
            app.DataDir = pwd; %Sets data directory to current directory, can be changed with Define Data Directory button
            %=====================================================================================================================================
            % add path if toolbox has not been added to path

            %             p_array=strsplit(path(),pathsep);
            %             if any(strcmp(p_array,[pwd,'/Support Toolbox/PROLIFIC']))
            %             else
            %                 pause(0.5)
            %                 fprintf('Add toolbox to path, please wait...')
            %                 h = msgbox('Add toolbox to path, please wait...');
            %                 addpath(genpath('Support Toolbox/PROLIFIC'));
            %                 delete(h)
            %                 fprintf('Add path finished')
            %             end
            %
            %             if any(strcmp(p_array,[pwd,'/Support Toolbox/fieldtrip-20191127']))
            %             else
            %                 pause(0.5)
            %                 fprintf('Add toolbox to path, please wait...')
            %                 h = msgbox('Add toolbox to path, please wait...');
            %                 addpath(genpath('Support Toolbox/fieldtrip-20191127'));              % toolbox 'fieldtrip' is used here to read BrainVision data
            %                 delete(h)
            %                 fprintf('Add path finished')
            %             end

            %===================================================================================================================================

        end

        % Callback function
        function ImportDataButtonPushed(app, event)
            app.reloadInd = 0;
            app.ProcessInd = 0;
            app.EditField.Visible = 'off';
            app.EventTypePanel.Visible = 'on';
            app.SetEventWindowPanel.Visible = 'on';

        end

        % Size changed function: EventTypePanel
        function EventTypePanelSizeChanged(app, event)
            position = app.EventTypePanel.Position;

        end

        % Callback function: ChannelSpinner, FindallEventsButton, 
        % TrialSpinner
        function FindallEventsButtonPushed(app, event)
            prePointsNum = str2double(app.PreDurs.Value)*app.origSampleRate;        % pre and post duration in points
            postPointsNum = str2double(app.PostDur.Value)*app.origSampleRate;


            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.ConditionsListBox.Visible = 0;
            app.ConditionsListBoxLabel.Visible  = 0;

            switch app.dataType
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 1                                                      % data type is labChart
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if the event type is comment based

                    if app.reloadInd == 0
                        app.time = [];

                        % Event type is 'Comment'
                        if app.CommentsButton.Value == true
                            % if choose comment based
                            % put all trials data of all channels into a cell
                            % type variable "allData", the following operations just call the varible

                            % determine if the comment name is matched
                            app.EventName = app.EventNameListBox.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.labChartData.comtext)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end


                            PEvents = app.eventInfo(app.eventInfo(:,5) == eventNameInd, :);                % find trials under the event name save as Positive Events
                            %%%%%%%%%%%%FORBILAT
                            %temp1 = [2000; diff(Pvents(:,3))];

                            %keepidx = temp1<0 | temp1>=2000;

                            %PEvents = Pvents(keepidx,:);

                            clear temp1 keepidx
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                            app.totEvent = size(PEvents,1);                                                % total event number change with data size under different event name


                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                 % convert start and endpoint to index matrix. The output is a cell matrix/
                            indMatrix = cell2mat(indMatrix);

                            % save the data of all trials into one matirx
                            app.allData = cell(app.totChannel,app.totEvent);            % initialize to pre-distribute the space to accelerate the loading speed

                            for iChannel = 1 :app.totChannel
                                %                                 oneChannelData = zeros(app.totEvent,prePointsNum+postPointsNum+1);                                          % initialize the matrix

                                % For a specified channel, save the data of all trails into a
                                % matrix.
                                for nTrial = 1 : app.totEvent

                                    tempTriggerInd = PEvents(nTrial, 3);                                                              % temporally save the comment index relative to the start point of this block
                                    temBlockNum = PEvents(nTrial, 2);                                                                 % temporally save the block number

                                    tempStartPoint = app.labChartData.datastart(iChannel, temBlockNum);                                     % locate the start point of this trail
                                    tempPrePoint = tempTriggerInd -prePointsNum;                                                            % pre duration start point in respect to the trigger index
                                    % Input of editFiled (Text) is string. Need to trans to double
                                    tempPostPoint = tempTriggerInd + postPointsNum;                                                        % post duration start point in respect to the trigger index

                                    oneTrialData = app.labChartData.data(tempStartPoint+tempPrePoint:tempStartPoint+tempPostPoint);         % data of the trial in the channel and the block
                                    app.allData{iChannel,nTrial} = oneTrialData;                                                                % save the data of all trails into one matrix
                                end
                                %                                 app.allData{iChannel} = oneChannelData;                                                                     % save the data of every channel into a cell type variable
                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if the event type is event(e.g. TTL) based

                        if app.EventsegTTLButton.Value == true
                            % save the event location with respect to the
                            % choose channel.
                            app.totEvent = length(app.eventInfo);
                            app.totBlock = size(app.labChartData.datastart,2);                                                              % save the block numbers
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            %                             delay = app.Delay.Value * app.origSampleRate/1000;
                            % find index of every block and every channel
                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                 % convert start and endpoint to index matrix. The output is a cell matrix
                            indMatrix = cell2mat(indMatrix);
                            eventChanData = app.labChartData.data(indMatrix(app.EventChannel.Value,:));

                            % find all events index in event Channel
                            %                             aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold
                            aboveThreshNorm = eventChanData > app.ThresholdmV.Value;
                            aboveThreshNorm      = diff(aboveThreshNorm, [], 2)';
                            aboveThreshOnset       = find(aboveThreshNorm(:)== 1);                % 1 means 0 jump to 1. The first point >.5 （rising edge of a pulse）
                            %                             aboveThreshOnset(aboveThreshOnset<delay) = [];

                            if isempty(aboveThreshOnset) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            % search interval
                            aboveThreshOnset(find(diff(aboveThreshOnset)<ISI)+1) = [];
                            eventInd = aboveThreshOnset;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                msgbox('No Trials Found')
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.
                            app.allData = cell(app.totChannel,app.totEvent);               % initialize to pre-distribute the space to accelerate the loading speed
                            if eventInd(1)-prePointsNum < 0
                                msgbox('Pre Duration is too large');
                                return
                            end

                            if eventInd(end)+postPointsNum>length(indMatrix(end,:))
                                msgbox('Post Duration is too large');
                                return
                            end

                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) indMatrix(iChannel,x-prePointsNum:x+postPointsNum),...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                oneChanData = arrayfun(@(x) app.labChartData.data(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                app.allData(iChannel,:) = oneChanData';
                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if the event type is block based

                        if app.BlocksFramesButton.Value == true
                            app.totEvent = length(app.labChartData.datastart);                                      % Here the trials refer to the blocks

                            app.allData = cell(app.totChannel,app.totEvent);                                                   % initialize to pre-distribute the space to accelerate the loading speed

                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                                  % convert start and endpoint to index matrix. The output is a cell matrix

                            app.allData = arrayfun(@(x) app.labChartData.data(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                indMatrix, 'UniformOutput', false);

                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                    end
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                        app.origTime = app.time -(app.PreTriggerTime.Value/1000);
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 2                                                      % data type is Acqknowledge
                    if app.reloadInd == 0
                        app.totEvent = [];
                        % save data from original data in channels
                        app.allData = cell(app.totChannel,1);
                        for iChannel = 1 : app.totChannel
                            app.allData{iChannel} = app.AcqknowledgeData.data(:,iChannel)';                                  % transpose to uniform the format
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is comment


                        if app.BlocksFramesButton.Value == true
                            %app.chosenData = app.allData{app.ChannelSpinner.Value};
                            prePointsNum = app.PreTriggerTime.Value/app.AcqknowledgeData.isi;
                            postPointsNum = app.PostTriggerTime.Value/app.AcqknowledgeData.isi;
                            totWindow = prePointsNum + postPointsNum;
                            app.totEvent = size(app.allData{iChannel},2)/totWindow;

                            allData = cell(app.totChannel,1);
                            for iChannel = 1 : app.totChannel
                                allData{iChannel} = app.AcqknowledgeData.data(:,iChannel)';                                  % transpose to uniform the format
                            end


                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1 : app.totChannel


                                tempData = reshape(app.AcqknowledgeData.data(:,iChannel),totWindow,{});

                                app.allData(iChannel,:) = num2cell(tempData,1);


                            end


                            a = cellfun(@transpose,app.allData,'UniformOutput',false);
                            app.allData = a;


                        end
                        app.time = ((1-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is event(TTL) based

                        if app.EventsegTTLButton.Value == true

                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            eventChanData = app.allData{app.EventChannel.Value};

                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font si
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = app.AcqknowledgeData.data(:, iChannel);
                                endArray = size(tempData,1);

                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond postdur limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x),nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData';


                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                    end

                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};

                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        %\app.time = (1:length(app.chosenData))/app.origSampleRate;
                        app.origTime = app.time;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 3                                                           % data type is Signal
                    if app.reloadInd == 0

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % event type is comment
                        if app.CommentsButton.Value == true

                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % event type is event(e.g., TTL)
                        if app.EventsegTTLButton.Value == true
                            tempData = app.allData;                                                                                     % app.allData will be redefined
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points

                            eventChanData = tempData(app.EventChannel.Value,:);
                            eventChanData = cell2mat(eventChanData);
                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            if eventInd(end)+postPointsNum>length(eventChanData)
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.


                                oneChanData = cell2mat(tempData(iChannel, :));
                                oneChanData = arrayfun(@(x) oneChanData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                app.allData(iChannel,:) = oneChanData';
                            end

                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if app.BlocksFramesButton.Value == true && isempty(app.SignalData.frameinfo) == 0                                    % if it is block based, adapt the time scale accroding to the pre-Trigger time

                            if isfield(app.SignalData.frameinfo, 'label') == 1
                                CommentNames = extractfield(app.SignalData.frameinfo,'label');
                                app.EventName = app.EventNameListBox_2.Value;
                                eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));     % find event name in the comtext, figure out its sequence number
                            else

                                CommentNames = extractfield(app.SignalData.frameinfo,'state');
                                CommentNames = strsplit(num2str(CommentNames));
                                app.EventName = app.EventNameListBox_2.Value;
                                eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));
                                %state = strsplit(num2str(state));
                                %app.EventNameListBox_2.Items = cellstr(state);
                                %app.EventNameListBox_2.Visible = 1;
                            end

                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end

                            % in case preduration beyond default pretrigger
                            % time
                            DefaultPreTriggerTime = double(app.SignalData.start);
                            if prePointsNum + DefaultPreTriggerTime*app.origSampleRate>0        % the input pre-duration must be within the default pre-trigger time
                                msgbox('Please re-enter pre-duration')
                                return;
                            end


                            PEvents = app.SignalData.frameinfo(eventNameInd, :);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total events change with data size under different event name
                            app.allData = cell(app.totChannel,app.totEvent); DefaultPreTriggerTime
                            TriggerInd = abs(DefaultPreTriggerTime)*app.origSampleRate;
                            for iChannel = 1 : app.totChannel
                                for nTrial = 1 : app.totEvent
                                    tempData(:,:) = app.SignalData.values(:,iChannel,eventNameInd(nTrial)); % 3 dimensions to 1
                                    app.allData{iChannel,nTrial} = tempData';
                                    app.allData{iChannel,nTrial} = app.allData{iChannel,nTrial}((1+TriggerInd-prePointsNum):(TriggerInd+postPointsNum));
                                end
                            end
                            app.time = (1:length(tempData))/app.origSampleRate;                           % time according to different data length. Points in signal is saved as int32

                            app.time = app.time((1+TriggerInd-prePointsNum):(TriggerInd+postPointsNum));
                            app.time = app.time-(app.PreTriggerTime.Value/1000);


                            app.reloadInd = app.reloadInd + 1;
                            app.origTime = app.time;

                        else
                            CommentNames = app.SignalData.StateInfo;
                            app.EventName = app.EventNameListBox_2.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));


                            DefaultPreTriggerTime = double(app.SignalData.start);
                            if prePointsNum + DefaultPreTriggerTime*app.origSampleRate>0        % the input pre-duration must be within the default pre-trigger time
                                msgbox('Please re-enter pre-duration')
                                return;
                            end


                            PEvents = app.SignalData.StateInfo(:, eventNameInd);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total events change with data size under different event name
                            TriggerInd = abs(DefaultPreTriggerTime)*app.origSampleRate;
                            app.allData = cell(app.totChannel,app.totEvent);
                            for iChannel = 1 : app.totChannel
                                for nTrial = 1 : app.totEvent
                                    tempData(:,:) = app.SignalData.values(:,iChannel,eventNameInd(nTrial)); % 3 dimensions to 1
                                    app.allData{iChannel,nTrial} = tempData';
                                end
                            end
                            app.reloadInd = app.reloadInd + 1;
                            app.time = (1:size(app.SignalData.CfsFile.Chans(1).DS(1).Data,1)).* (app.SignalData.CfsFile.Chans(1).DS(1).xScale);
                            app.origTime = app.time-(abs(DefaultPreTriggerTime));
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end

                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 4                                                      % data type is BrainVision
                    if app.reloadInd == 0

                        app.totEvent = [];
                        % save data from original data in channels
                        app.allData = cell(app.totChannel,1);
                        for iChannel = 1 : app.totChannel
                            app.allData{iChannel} = app.BrainVisionData.data(iChannel,:)';                                  % transpose to uniform the format
                        end


                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if app.CommentsButton.Value == true
                            app.EventName = app.EventNameListBox.Value;
                            idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));


                            empty = isempty(idx);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX
                            PEvents = app.eventInfo(idx);
                            app.allData = cell(app.totChannel,length(PEvents));
                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    temptrialIDX = PEvents(nTrial);
                                    tempTrialfVals = app.BrainVisionData.data(iChannel,:);
                                    endArray = length(tempTrialfVals);
                                    window = tempTrialfVals(max(1,temptrialIDX-prePointsNum):min(endArray,temptrialIDX+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-temptrialIDX),window,nan(1,(temptrialIDX+postPointsNum)-numel(tempTrialfVals))];
                                    app.allData{iChannel,nTrial} = trialfVals;


                                    %app.allData{iChannel,nTrial} = app.BrainVisionData.data(iChannel,PEvents(nTrial)-prePointsNum:PEvents(nTrial)+postPointsNum);
                                end
                            end
                            app.totEvent = length(PEvents);
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if block based
                        if app.BlocksFramesButton.Value == true
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is event(TTL) based

                        if app.EventsegTTLButton.Value == true
                            tempData = app.allData;                                                                                     % app.allData will be redefined
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points

                            eventChanData = tempData(app.EventChannel.Value,:);
                            eventChanData = cell2mat(eventChanData);
                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return;
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum > length(eventChanData)
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel


                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = app.BrainVisionData.data(iChannel, :);
                                endArray = size(tempData,2);

                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond postdur limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x)',nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData';
                                %                                 oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                %                                     eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                %                                 % Must be transposed in column, or all events would be in one row.
                                %
                                %                                 oneChanData = cell2mat(tempData(iChannel, :));
                                %                                 oneChanData = arrayfun(@(x) oneChanData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                %                                     oneChanInd, 'UniformOutput', false);
                                %
                                %                                 app.allData(iChannel,:) = oneChanData';
                            end

                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                    end
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 5                                                      % data type is SpikeData
                    if app.reloadInd == 0

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is comment based
                        if app.CommentsButton.Value == true
                            %idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));
                            %app.EventName = app.EventNameListBox.Value;

                            empty = isempty(app.eventInfo);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX
                            PEvents = app.eventInfo;
                            app.totEvent = length(PEvents);
                            base = CEDS64TimeBase(app.SpikeData.hdr);
                            div = CEDS64ChanDiv(app.SpikeData.hdr,app.wave(app.ChannelSpinner.Value));
                            app.allData = cell(app.totChannel,length(PEvents));
                            app.time = (0-str2num(app.PreDurs.Value)):base*div:(0+str2num(app.PostDur.Value));
                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    temptrialIDX=find(app.tick{1}==PEvents(nTrial));
                                    tempTrialfVals = app.SpikeData.data{iChannel};
                                    endArray = length(tempTrialfVals);
                                    window = tempTrialfVals(max(1,temptrialIDX-prePointsNum):min(endArray,temptrialIDX+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-temptrialIDX),window,nan(1,(temptrialIDX+postPointsNum)-numel(tempTrialfVals))];
                                    app.allData{iChannel,nTrial} = trialfVals;

                                end

                            end
                            app.reloadInd = app.reloadInd + 1;
                        end

                    end

                    % if event type is block based
                    if app.BlocksFramesButton.Value == true
                        if app.reloadInd == 0
                            base = CEDS64TimeBase(app.SpikeData.hdr);
                            div = CEDS64ChanDiv(app.SpikeData.hdr,app.wave(app.ChannelSpinner.Value));

                            app.time = (0-str2num(app.PreDurs.Value)):base*div:(0+str2num(app.PostDur.Value));
                            app.allData = cell(app.totChannel,app.totEvent);
                            postPointsNum = postPointsNum*div;
                            prePointsNum = prePointsNum*div;
                            for iChannel =1: app.totChannel
                                for iTrial = 1:app.totEvent
                                    [fRead, fVals, fTime ] = CEDS64ReadWaveF(app.SpikeData.hdr, app.wave(iChannel), app.eventInfo(iTrial), (app.eventInfo(iTrial))-prePointsNum, (app.eventInfo(iTrial))+postPointsNum+div);
                                    app.allData{iChannel,iTrial} = fVals';
                                end
                            end
                        end
                    end
                    if app.EventsegTTLButton.Value == true
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%

                        if app.reloadInd == 0
                            app.totEvent = [];
                            % save data from original data in channels
                            app.allData = cell(app.totChannel,1);
                            for iChannel = 1 : app.totChannel
                                app.allData(iChannel) = app.SpikeData.data(iChannel,:)';                                  % transpose to uniform the format
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            eventChanData = app.allData{app.EventChannel.Value};

                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font si
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = cell2num(app.SpikeData.data(iChannel,:));
                                endArray = size(tempData,2);


                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond postdur limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x)',nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData';


                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    app.reloadInd = app.reloadInd + 1;
                    app.origTime = app.time;


                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                case 6                                                      % data type is BrainSight
                    if app.reloadInd == 0
                        if app.CommentsButton.Value == true
                            %idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));
                            %app.EventName = app.EventNameListBox.Value;


                            prePointsNum = round(str2double(app.PreDurs.Value)*app.origSampleRate);        % pre and post duration in points
                            postPointsNum = round(str2double(app.PostDur.Value)*app.origSampleRate);

                            app.EventName = app.EventNameListBox.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.CustomData.eventname)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end
                            PEvents =app.CustomData.cueIDX(eventNameInd);                % find trials under the event name save as Positive Events
                            positionEvents = app.CustomData.positionTypeIDX(eventNameInd);
                            app.totEvent = length(PEvents);                                                % total event number change with data size under different event name

                            empty = isempty(app.eventInfo);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX


                            app.allData = cell(app.totChannel,length(PEvents));
                            app.allDataPosition = cell(app.totChannel,length(positionEvents));
                            %app.force = cell(1,length(PEvents));


                            %adding a 1 to the first element to allow for < in for loop
                            levelTime = [1,app.CustomData.levelTime];
                            level = app.CustomData.level(1,:);
                            level = [1,level];
                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    tempTrialfVals = app.CustomData.data;
                                    tempTrialPosition = app.CustomData.positionActual;
                                    tempTrialPositionTime = app.CustomData.positionTime;
                                    endArray = length(tempTrialfVals);
                                    endpositionArray = length(tempTrialPosition);
                                    window = tempTrialfVals(iChannel,max(1,PEvents(nTrial)-prePointsNum):min(endArray,PEvents(nTrial)+postPointsNum));
                                    positionWindow =tempTrialPosition(max(1,positionEvents(nTrial)-100):min(endpositionArray,positionEvents(nTrial)+100));
                                    positionTimeWindow = tempTrialPositionTime(max(1,positionEvents(nTrial)-100):min(endpositionArray,positionEvents(nTrial)+100));
                                    %levelTemp = (iChannel,max(1,PEvents(nTrial)-prePointsNum):min(endArray,PEvents(nTrial)+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-PEvents(nTrial)),window,nan(1,(PEvents(nTrial)+postPointsNum)-numel(tempTrialfVals))];
                                    %positionfVals = [nan(1,1+100-positionEvents(nTrial)),positionTimeWindow,nan(1,(positionEvents(nTrial)+100)-numel(tempTrialPosition))];
                                    app.allData{iChannel,nTrial} = trialfVals;
                                    app.allDataPosition{1,nTrial} = positionWindow;
                                    app.allDataPosition{2,nTrial} = positionTimeWindow;

                                    for i = 2:size(levelTime,2)
                                        if positionTimeWindow(1) < levelTime(i) && positionTimeWindow(1) > levelTime(i-1)
                                            %app.force{1,nTrial} = level(i);
                                            break
                                        end
                                    end


                                end

                            end


                            if app.FigureCheckCheckBox.Value == 1
                                figure;plot(app.CustomData.positionTime,(app.CustomData.positionActual*100))
                                hold
                                rochData = plot(app.CustomData.emgTime,app.CustomData.data');
                                rochLines = [rochData(1) rochData(2)];


                                for i = 1:length(PEvents)
                                    x1 = xline(app.CustomData.emgTime(PEvents(i)));
                                    x1.Color ='red';
                                    x1.LineWidth = 2;
                                end

                                for i = 1:size(app.CustomData.levelTime,2)
                                    x1 = xline(app.CustomData.levelTime(i));
                                    x1.Color ='blue';
                                    x1.LineWidth = 5;
                                end

                                x2 = xline(app.CustomData.eventTime);

                                legend(rochLines,"Ch1","Ch2")
                                %legend(num2str(cell2mat(%app.force)));

                            end

                            app.totEvent = length(PEvents);
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                        end
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end
                    app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                case 7

                    if app.BlocksFramesButton.Value == true
                        if app.reloadInd == 0

                            app.allData = app.BrainSightDataWave;

                        end
                        app.reloadInd = app.reloadInd + 1;
                    end
                    app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                case 10

                    %%%%%%%%%%%%%%%%%%Block Frame%%%%%%%%%%%%
                    if app.BlocksFramesButton.Value == true
                        if app.reloadInd == 0

                            app.allData = app.CustomData.exoForce;

                        end
                        app.reloadInd = app.reloadInd + 1;
                    end
                    app.totEvent = 1;
                    app.time = ((1:postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};

                case 8

                   if app.reloadInd == 0
                        app.totEvent = [];
                        % save data from original data in channels
                        app.allData = cell(app.totChannel,1);
                       
                        app.time = app.EDENData.data_eogclean.time{1,1};
                        for iChannel = 1 : app.totChannel
                            app.allData{1} = app.EDENData.data(1,:);                                  % transpose to uniform the format
                        end


                        app.allData = app.allData{1,1};


                        restructure = {};

                        for i = 1:size(app.allData,2)

                            restructure{end+1} = app.allData{1,i}(1,:);

                            for p = 2:size(app.allData{1,1},1)
                                restructure{end+1} = app.allData{1,i}(p,:);
                            end

                        end
                       app.totEvent = size(app.allData,2);
                       app.allData = reshape(restructure,size(app.allData{1,1},1),size(app.allData,2));


                        if app.BlocksFramesButton.Value == true
                            if app.reloadInd == 0

                                app.allData = app.allData;

                            end
                            app.reloadInd = app.reloadInd + 1;
                        end
                        app.time = app.time
                        app.origTime = app.time;
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                        if app.EventsegTTLButton.Value == true

                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            eventChanData = app.allData{app.EventChannel.Value};

                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            %search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end


                            %                              down = diff(aboveThresh);
                            %                              aboveThresh = aboveThresh(down>2);


                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font si
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = app.EDENData.data(iChannel,:);
                                endArray = size(tempData,2);

                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond postdur limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x),nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData;


                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        app.reloadInd = app.reloadInd + 1;

                    end
                    
                    
                    if app.BlocksFramesButton.Value == true %Need to check if this works for all block

                    else
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                        app.origTime = app.time;
                    end
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                case 9                                                      % data type is Georgetown
                    if app.reloadInd == 0
                        if app.CommentsButton.Value == true
                            %idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));
                            %app.EventName = app.EventNameListBox.Value;


                            prePointsNum = round(str2double(app.PreDurs.Value)*app.origSampleRate);        % pre and post duration in points
                            postPointsNum = round(str2double(app.PostDur.Value)*app.origSampleRate);

                            app.EventName = app.EventNameListBox.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.CustomData.eventname)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end
                            PEvents = app.CustomData.time(eventNameInd);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total event number change with data size under different event name

                            empty = isempty(app.eventInfo);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX

                            app.totEvent = length(PEvents);
                            app.allData = cell(app.totChannel,length(PEvents));

                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    tempTrialfVals = app.CustomData.data';
                                    endArray = length(tempTrialfVals);
                                    window = tempTrialfVals(max(1,PEvents(nTrial)-prePointsNum):min(endArray,PEvents(nTrial)+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-PEvents(nTrial)),window,nan(1,(PEvents(nTrial)+postPointsNum)-numel(tempTrialfVals))];
                                    app.allData{iChannel,nTrial} = trialfVals;
                                end
                            end

                            app.totEvent = length(PEvents);
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                        end
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end
                    app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot the figure

            cla(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'on';
            app.Patch = [];
            app.AnalyzeInd = 0;   % reset Analyze button in case reselection
            app.DataToPlot = app.chosenData;
            app.TimeToPlot = app.origTime;
            app.UnprocessedData = app.chosenData;


            legend(app.UIAxes,'off');
            plot(app.UIAxes, app.TimeToPlot, app.DataToPlot,'Color','k');                                                        % Plot data of the one seleted trail, but only the first time.
            % The following click would call the Trial/Channel spinner to
            % directly plot the figure.
            app.UIAxes.XLabel.String = app.timeUnit;
            app.UIAxes.YLabel.String = app.unit;
            % plot in mV first, then change the unit accordingly
            app.UIAxes.YLabel.String = app.mVButton.Text;
            app.SampleRateHzEditField.Value = app.origSampleRate;

            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            app.tempH = zeros(size(h,1),length(h(1).YData));
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end
            % change the unit
            ButtonGroupUnitsSelectionChanged(app, event)
            % if pre-process toolbox is enabled, then directly plot
            % processed data
            if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                ProcessButtonPushed(app, event);
            end
            app.uVButton.Enable = 'on';
            app.mVButton.Enable = 'on';
            app.VButton.Enable = 'on';


            app.YMin.Value = num2str(app.UIAxes.YLim(1));
            app.YMax.Value = num2str(app.UIAxes.YLim(2));
            app.XMin.Value = num2str(app.UIAxes.XLim(1));
            app.XMax.Value = num2str(app.UIAxes.XLim(2));

            %%%Retians Low Pass Envelope In EMG Analysis Option
            if app.LowPassEnvelopeCheckBox_3.Value == 1
                LowPassEnvelopeCheckBox_3ValueChanged(app, event)
            end


        end

        % Callback function
        function TrailsDropDownValueChanged(app, event)
            app.TrailsDropDown.ItemsData;

        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            app.ChannelSpinner.Limits = [1 app.totChannel];                                                   % app.totChannel saves the number of total channel
            if app.reloadInd ~=0
                cla(app.UIAxes);
                app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                app.DataToPlot = app.chosenData;
                app.TimeToPlot = app.time;
                plot(app.UIAxes, app.TimeToPlot, app.DataToPlot,'Color','k');                                                        % Plot data of the one seleted trail, but only the first time.
                FindallEventsButtonPushed(app, event)
            end

            %             app.tempH = [];
            %             h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            %             for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
            %                 app.tempH(iLine,:) = h(iLine).YData(1,:);
            %             end
            %             % change the unit
            %             ButtonGroupUnitsSelectionChanged(app, event)
        end

        % Callback function: ButtonGroupUnits, MultiChanListBox, 
        % TrialSpinner
        function TrialSpinnerValueChanged(app, event)

            if app.ConditionsListBox.Visible == 1
                OKButtonPushed(app, event)
            else
                % Trial spinner and MultiChanListBox share the same spinner

                TrailsLimit = app.totEvent;                                                                       % app.eventInfo saves the matrix of event information
                app.TrialSpinner.Limits = [1 TrailsLimit];

                % ensure figure be consistent along multi-channels selecting.
                if isempty(app.MultiChan)
                    FindallEventsButtonPushed(app, event)
                elseif length(app.MultiChan)>1

                elseif app.MultiChan ~= app.TrialSpinner.Value
                else
                    FindallEventsButtonPushed(app, event)
                end
            end

        end

        % Value changed function: PreDurs
        function PreDursValueChanged(app, event)
            app.PreDurs.Value;
            app.XMin.Value = strcat('-',app.PreDurs.Value);
            InitializationButtonPushed(app, event)

        end

        % Value changed function: PostDur
        function PostDurValueChanged(app, event)
            app.PostDur.Value;
            app.XMax.Value = app.PostDur.Value;
            % whenever change the postdur, refind event
            InitializationButtonPushed(app, event)

        end

        % Value changed function: FileName
        function FileNameValueChanged(app, event)
            app.FileName.Value;

        end

        % Callback function
        function ListBoxValueChanged(app, event)

        end

        % Menu selected function: LabChartmatMenu
        function LabChartmatMenuSelected(app, event)
            %lab chart data import

            app.dataType = 1;


            [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");

            if iscell(fname) == 0
                fname = {fname};
            end

            app.FileOrderListBox.Items = fname;

            for iFile = 1:length(fname)
                app.labChartData = load(fullfile(path, fname{iFile}));                         % does not register in the main program
                if iFile == 1
                else
                    app.labChartData.data = [tempData, app.labChartData.data];
                    app.labChartData.datastart = [tempDataStart, app.labChartData.datastart+tempDataEnd(end)];
                    app.labChartData.dataend = [tempDataEnd, app.labChartData.dataend+tempDataEnd(end)];
                    app.labChartData.com = [tempCom; app.labChartData.com(:,1), app.labChartData.com(:,2)+tempCom(end,2),app.labChartData.com(:,3), app.labChartData.com(:,4),app.labChartData.com(:,5)]; %Only adds com IDX and Block #
                end
                tempData = app.labChartData.data;
                tempDataStart = app.labChartData.datastart;
                tempDataEnd =  app.labChartData.dataend;
                tempCom =  app.labChartData.com;
            end


            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront
            %% Define the parameters

            app.labChartData.datastart(app.labChartData.datastart==-1) = [];                                           %| Removes empty channles
            app.labChartData.datastart = reshape(app.labChartData.datastart,[],size(app.labChartData.dataend,2));      %|

            app.labChartData.dataend(app.labChartData.dataend==-1) = [];                                               %|
            app.labChartData.dataend = reshape(app.labChartData.dataend,[],size(app.labChartData.datastart,2));        %|

            app.origSampleRate = app.labChartData.samplerate(1);                        % Sample rate should be the same across all channels and blocks
            app.totChannel = size(app.labChartData.datastart,1);                        % Define Total Number of Channels. totChannel(1) refers to channels. totChannel(2) refers to blocks
            app.unit = app.labChartData.unittext(1);                                    % defines unit. Unit of the LabChart data
            app.reloadInd = 0;                                                          % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.eventInfo = app.labChartData.com;
            app.totEvent = length(app.eventInfo);
            app.FileName.Value = fname{1};                                        % display the file name
            app.timeUnit = 's';                                                         % time unit unified to second
            % find all trails where event type is comment (5th column of 'com' equal 1)

            % unify the units across different data types to mV
            switch app.unit
                case 'V'                    % other units?
                    unitsMutiple = 1000;
                    app.labChartData.data = app.labChartData.data .* unitsMutiple;
            end
            app.unit = 'mV';
        end

        % Menu selected function: BrainVisioneegvhdrvmkrMenu
        function BrainVisioneegvhdrvmkrMenuSelected(app, event)
            % brain vision data import
            % There are three BrainVision exported files end with '*eeg',
            % '*vhdr' and '*vmrk'
            app.dataType = 4;
            %             app.CommentsButton.Enable = 'off';
            % read Brain Vision events

            app.BrainVisionData.events = [];
            app.BrainVisionData.comtext = [];
            app.BrainVisionData.header = [];
            app.BrainVisionData.data = [];
            app.BrainVisionData.fileLength = [];
            app.eventInfo = [];

            strcat(app.toolboxDir,'/*.eeg');
            [fname, path] = uigetfile(ans,"multiSelect","on"); %Load in Multiple Files

            if iscell(fname) == 0
                fname = {fname};   %If only one file chosen, convert to cell
            end

            app.FileOrderListBox.Items = fname;

            for iFile = 1:length(fname)

                file = fullfile(path, fname{iFile});

                % read events
                tempevents = ft_read_event(file);
                if isempty(app.BrainVisionData.fileLength)
                    sample = extractfield(tempevents,'sample');
                else
                    sample = extractfield(tempevents,'sample')+length(app.BrainVisionData.data); %Changes IDX of concat comment
                end
                %app.BrainVisionData.fileLength(end)
                app.BrainVisionData.events = [app.BrainVisionData.events,tempevents];
                % define the papameters
                tempcomtext = (extractfield(app.BrainVisionData.events,'value'));
                app.BrainVisionData.comtext = tempcomtext;
                comtext = app.BrainVisionData.comtext;
                comtext(cellfun(@isempty,comtext))=[];                     % exclude empty trial
                app.totEvent = length(comtext);
                app.eventInfo = [app.eventInfo, sample];

                % read header
                fnameCurrent = fname{iFile}(1:end-3);
                fnameCurrent = strcat(fnameCurrent,'vhdr');
                file = fullfile(path, fnameCurrent);
                app.BrainVisionData.header = ft_read_header(file);
                app.origSampleRate = app.BrainVisionData.header.Fs;                % Sample rate should be the same across all channels and blocks
                app.totChannel = app.BrainVisionData.header.nChans;                % Define Total Number of Channels.
                app.unit = char(app.BrainVisionData.header.chanunit(1));           % defines unit. Unit of the BrainVision data
                app.reloadInd = 0;                                                 % the first time find the events, save all data into one variable
                app.ProcessInd = 0;
                app.timeUnit = 's';                                                % no direct information. second in default
                % the following times just call the varible
                % read data
                tempdata = ft_read_data(file);
                tempfileLength = length(tempdata);
                app.BrainVisionData.fileLength = [app.BrainVisionData.fileLength, tempfileLength];
                app.BrainVisionData.data = [app.BrainVisionData.data, tempdata];
                app.FileName.Value = fnameCurrent;                               % display the file name
            end

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            switch app.unit
                case 'uV'                    % other units?
                    unitsMutiple = .001;
                    app.BrainVisionData.data = app.BrainVisionData.data .* unitsMutiple;
            end
            app.unit = 'mV';


            answer = questdlg('Save File?','Save Files','Yes','No','No');
            switch answer
                case 'Yes'
                    sv = 0;
                case 'No'
                    sv = 1;
            end

            while sv == 0
                labChartData         = app.labChartData;% data package of the TMS data exported from LabChart
                AcqknowledgeData     = app.AcqknowledgeData;% data package of the TMS data exported from Acqknowledge
                SignalData           = app.SignalData;% data package of the TMS data exported from Signal
                BrainVisionData      = app.BrainVisionData;% data package of the TMS data exported from BrainVision
                origSampleRate       = app.origSampleRate;% define the sample rate variable
                eventInfo            = app.eventInfo;% define the event/trial information
                totChannel           = app.totChannel;% define the channel number variable
                totEvent             = app.totEvent;% define the event/trial number variable
                totBlock             = app.totBlock;% define the block number variable
                unit                 = app.unit;% define the unit of the TMS data
                EventType            = app.EventType;% define the type of event 1/2/3 refers to comment/event/block ???
                allData              = app.allData;% define the matrix which saves data of all channels and all trials
                reloadInd            = app.reloadInd;% define a mark to reveal if it is the first time to load the data. If the data is saved, next time just call
                fileName             = app.fileName;% name of the data
                dataType             = app.dataType;% data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
                time                 = app.time;% time points of the data
                timeUnit             = app.timeUnit;% unit of the time %%%%%% Save Up to Here %%%%%%

                prompt = {'Enter plan name:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {app.FileName.Value};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                save([char(answer),'.mat'], ...
                    'labChartData',...
                    'AcqknowledgeData',...
                    'SignalData',...
                    'BrainVisionData',...
                    'origSampleRate',...
                    'eventInfo',...
                    'totChannel',...
                    'totEvent',...
                    'totBlock',...
                    'unit',...
                    'EventType',...
                    'allData',...
                    'reloadInd',...
                    'fileName',...
                    'dataType',...
                    'time',...
                    'timeUnit');
                sv = 1;
            end
        end

        % Menu selected function: AcqknowledgematMenu
        function AcqknowledgematMenuSelected(app, event)
            %%  Import Acqknowledge Data
            app.dataType = 2;
            app.CommentsButton.Enable = 'off';
            % Here the data should be .mat file
            %====================================================================================================%

            [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");

            if iscell(fname) == 0
                fname = {fname};
            end

            app.FileOrderListBox.Items = fname;

            for iFile = 1:length(fname)
                app.AcqknowledgeData = load(fullfile(path, fname{iFile}));                         % does not register in the main program
                tempData = app.AcqknowledgeData.data;
                if iFile == 1
                    AcqknowledgeDatadataAll = tempData;
                else
                    AcqknowledgeDatadataAll = [AcqknowledgeDatadataAll;app.AcqknowledgeData.data];
                end
                %tempData = app.AcqknowledgeData.data;
            end
            if exist('AcqknowledgeDatadataAll','var') == 1
                app.AcqknowledgeData.data=AcqknowledgeDatadataAll;
            end

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %====================================================================================================%

            % the import data type should be Acqknowledge, if not, display warning
            %             if exist('isi') == 0
            %                 [Visible1,Visible2,Visible3] = wrongType();
            %                 app.EditField.Visible = Visible1;                                    % if wrong type data, display warning
            %                 app.EventTypePanel.Visible = Visible2;
            %                 app.SetEventWindowPanel.Visible = Visible3;
            %             else

            % default unit unifies to mV
            units = cellstr(app.AcqknowledgeData.units);                                              % char to cell. "strcmp" requires cell type data
            unitsMultiple = strcmp('mV', units);                                 % find the channel where the unit is mV
            unitsMultiple = double(unitsMultiple);
            if prod(unitsMultiple)>0                                             % if all units are "mV", IsUnify=0 means no units unification
                IsUnify = 0;
            else
                IsUnify = 1;                                                     % if units not unified
            end

            if IsUnify == 1
                VoltsChannel = find(units == "Volts");                           % find the channel where the unit is Volits
                unitsMultiple(VoltsChannel) = 1000;                              % unify the units to mV
                app.AcqknowledgeData.data = app.AcqknowledgeData.data.*unitsMultiple'; % save the data and other information in app.AcqknowledgeData
                % if need to only save the data into "allData", Acqknowledge
                % type data does not need further calculation
            end

            app.FileName.Value = fname{1};
            app.eventInfo = [];
            app.reloadInd = 0;                                                   % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.unit ='mV';
            app.totChannel = size(app.AcqknowledgeData.labels,1);
            app.time = 1:app.AcqknowledgeData.isi:length(app.AcqknowledgeData.data);                                       % save the time  isi should be divided or multipled?????
            app.origSampleRate = 1/(app.AcqknowledgeData.isi/1000);                                            % ????
            app.timeUnit = app.AcqknowledgeData.isi_units;
            if strcmp(app.timeUnit, 'ms')                                        % unify time unit to second
                app.time = app.time/1000;
                app.timeUnit = 's';
                app.origSampleRate = 2000;
            end


            answer = questdlg('Save File?','Save Files','Yes','No','No');
            switch answer
                case 'Yes'
                    sv = 0;
                case 'No'
                    sv = 1;
            end

            while sv == 0
                labChartData         = app.labChartData;% data package of the TMS data exported from LabChart
                AcqknowledgeData     = app.AcqknowledgeData;% data package of the TMS data exported from Acqknowledge
                SignalData           = app.SignalData;% data package of the TMS data exported from Signal
                BrainVisionData      = app.BrainVisionData;% data package of the TMS data exported from BrainVision
                origSampleRate       = app.origSampleRate;% define the sample rate variable
                eventInfo            = app.eventInfo;% define the event/trial information
                totChannel           = app.totChannel;% define the channel number variable
                totEvent             = app.totEvent;% define the event/trial number variable
                totBlock             = app.totBlock;% define the block number variable
                unit                 = app.unit;% define the unit of the TMS data
                EventType            = app.EventType;% define the type of event 1/2/3 refers to comment/event/block ???
                allData              = app.allData;% define the matrix which saves data of all channels and all trials
                reloadInd            = app.reloadInd;% define a mark to reveal if it is the first time to load the data. If the data is saved, next time just call
                fileName             = app.fileName;% name of the data
                dataType             = app.dataType;% data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
                time                 = app.time;% time points of the data
                timeUnit             = app.timeUnit;% unit of the time %%%%%% Save Up to Here %%%%%%

                prompt = {'Enter plan name:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {app.FileName.Value};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                save([char(answer),'.mat'], ...
                    'labChartData',...
                    'AcqknowledgeData',...
                    'SignalData',...
                    'BrainVisionData',...
                    'origSampleRate',...
                    'eventInfo',...
                    'totChannel',...
                    'totEvent',...
                    'totBlock',...
                    'unit',...
                    'EventType',...
                    'allData',...
                    'reloadInd',...
                    'fileName',...
                    'dataType',...
                    'time',...
                    'timeUnit');
                sv = 1;
            end


        end

        % Menu selected function: SignalmatMenu
        function SignalmatMenuSelected(app, event)
            %%  Import Signal type data
            app.dataType = 3;
            % Here the data should be .mat file
            cont = 0;
            app.eventInfo = [];
            values = [];
            frameinfo = [];


            answer = questdlg('Import?','Type','Import .mat export','Import .cfs convert','Convert .cfs','Convert .cfs');
            switch answer
                case 'Import .mat export' % used if excported to .mat in Signal
                    type = 0;
                case 'Import .cfs convert' % used if file was originally saved as .cfs and converted within toolbox
                    type = 1;
                case 'Convert .cfs' %Will convert .cfs files to .mat. Can convert multiple files at once.
                    type = 2;

            end

            %%%%%%%.mat exported from Signal%%%%%%%%%%%%%%%%%%%%%
            if type == 0


                [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");

                if iscell(fname) == 0
                    fname = {fname};
                end

                app.FileOrderListBox.Items = fname;

                for iFile = 1:length(fname)

                    load(fullfile(path, fname{iFile}));                                    % to identify the data type within current workspace
                    app.SignalData = load(fullfile(path, fname{iFile}));                   % does not register in the main program
                    drawnow; %Puts UI Figure at the forfront
                    figure(app.UIFigure) %Puts UI Figure at the forfront
                    tempName = fieldnames(app.SignalData);
                    Index = strfind(tempName, 'wave');
                    find(~cellfun(@isempty,Index))
                    app.SignalData = app.SignalData.(tempName{ans});
                    SignalFieldNames = fieldnames(app.SignalData);                  % extract field names of signal data
                    SignalDefautlFieldNames = {'xlabel'; 'xunits'; 'start'; 'interval'; 'points'; 'chans'; 'frames'; 'chaninfo'; 'frameinfo'; 'values'};
                    % the import data type should be Signal, if not, display warning
                    %                     if any(strcmp(SignalFieldNames,SignalDefautlFieldNames) == 0) == 1
                    %                         [Visible1,Visible2,Visible3] = wrongType();
                    %                         app.EditField.Visible = Visible1;                           % if wrong type data, display warning
                    %                         app.EventTypePanel.Visible = Visible2;
                    %                         app.SetEventWindowPanel.Visible = Visible3;
                    %                     end

                    frameinfo = [frameinfo;app.SignalData.frameinfo];
                    values = cat(3,values,app.SignalData.values);


                    app.FileName.Value = fname{iFile};                            % display the file name
                    app.totChannel = size(app.SignalData.chaninfo,1);
                    app.eventInfo = [app.eventInfo; app.SignalData.frameinfo];
                    app.totEvent = size(app.eventInfo,1);
                    app.timeUnit = app.SignalData.xunits;
                    app.time = (1:length(app.SignalData.values)).* app.SignalData.interval;
                    app.origSampleRate = 1/app.SignalData.interval;
                    app.reloadInd = 0;                                                   % the first time find the events, save all data into one variable, so
                    app.ProcessInd = 0;


                    %                     answer = questdlg('Add File?','Load Files','Yes','No','No');
                    %                     switch answer
                    %                         case 'Yes'
                    %                             cont = 0;
                    %                         case 'No'
                    %                             cont = 1;
                    %                     end

                end

                app.SignalData.values = values; %add concat values 3dmatrix
                app.SignalData.frameinfo = frameinfo; %add concat eventinfo
                % define the limits of predur and postdur
                app.PreDurs.Value = num2str(abs(double(app.SignalData.start)));
                app.PostDur.Value = num2str(app.time(end)-abs(double(app.SignalData.start)));


                answer = questdlg('Save File?','Save Files','Yes','No','No');
                switch answer
                    case 'Yes'
                        sv = 0;
                    case 'No'
                        sv = 1;
                end

                while sv == 0
                    labChartData         = app.labChartData;% data package of the TMS data exported from LabChart
                    AcqknowledgeData     = app.AcqknowledgeData;% data package of the TMS data exported from Acqknowledge
                    SignalData           = app.SignalData;% data package of the TMS data exported from Signal
                    BrainVisionData      = app.BrainVisionData;% data package of the TMS data exported from BrainVision
                    origSampleRate       = app.origSampleRate;% define the sample rate variable
                    eventInfo            = app.eventInfo;% define the event/trial information
                    totChannel           = app.totChannel;% define the channel number variable
                    totEvent             = app.totEvent;% define the event/trial number variable
                    totBlock             = app.totBlock;% define the block number variable
                    unit                 = app.unit;% define the unit of the TMS data
                    EventType            = app.EventType;% define the type of event 1/2/3 refers to comment/event/block ???
                    allData              = app.allData;% define the matrix which saves data of all channels and all trials
                    reloadInd            = app.reloadInd;% define a mark to reveal if it is the first time to load the data. If the data is saved, next time just call
                    fileName             = app.fileName;% name of the data
                    dataType             = app.dataType;% data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
                    time                 = app.time;% time points of the data
                    timeUnit             = app.timeUnit;% unit of the time %%%%%% Save Up to Here %%%%%%

                    prompt = {'Enter plan name:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {app.FileName.Value};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    save([char(answer),'.mat'], ...
                        'labChartData',...
                        'AcqknowledgeData',...
                        'SignalData',...
                        'BrainVisionData',...
                        'origSampleRate',...
                        'eventInfo',...
                        'totChannel',...
                        'totEvent',...
                        'totBlock',...
                        'unit',...
                        'EventType',...
                        'allData',...
                        'reloadInd',...
                        'fileName',...
                        'dataType',...
                        'time',...
                        'timeUnit');
                    sv = 1;
                end
                %%%%%%%.cfs Convert%%%%%%%%%%%%%%%%%%%%%
            elseif type == 1

                [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");
                frameinfo =[];
                values = [];
                StateInfo = {};
                app.totEvent = 0;
                app.SignalData.values = [];

                if iscell(fname) == 0
                    fname = {fname};
                end

                app.FileOrderListBox.Items = fname;


                for iFile = 1:size(fname,2)


                    load(fullfile(path, fname{iFile}));                                    % to identify the data type within current workspace
                    app.SignalData = load(fullfile(path, fname{iFile}));                   % does not register in the main program
                    drawnow; %Puts UI Figure at the forfront
                    figure(app.UIFigure) %Puts UI Figure at the forfront
                    tempName = fieldnames(app.SignalData);


                    values = cat(3,values,app.SignalData.CfsFile.D.data);


                    app.FileName.Value = fname{1};
                    app.totChannel = size(app.SignalData.CfsFile.D.data,2); %need to determine if data chan or other
                    app.totEvent = size(app.SignalData.CfsFile.Chans(1).DS,2) + app.totEvent;
                    app.timeUnit = app.SignalData.CfsFile.Chans(1).xUnits;
                    app.time = (1:size(app.SignalData.CfsFile.Chans(1).DS(1).Data,1)).* (app.SignalData.CfsFile.Chans(1).DS(1).xScale);
                    app.origSampleRate = 1/(app.SignalData.CfsFile.Chans(1).DS(1).xScale);
                    app.SignalData.frameinfo = [];
                    app.reloadInd = 0;                                                   % the first time find the events, save all data into one variable, so
                    app.ProcessInd = 0;
                    app.SignalData.start = app.SignalData.CfsFile.Chans(1).DS(1).xOffset;
                    app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;

                    StateInfoDesc = {app.SignalData.CfsFile.DSVars.Desc};
                    State = find(contains(StateInfoDesc,'State'));
                    StateL = find(contains(StateInfoDesc,'StateL'));
                    StateInfo = [StateInfo,CfsFile.DSVars(State(1)).Values];

                end

                app.SignalData.values = values;
                %app.SignalData.frameinfo = frameinfo; %add concat eventinfo
                app.PreDurs.Value =  num2str(abs(app.SignalData.CfsFile.Chans(1).DS(1).xOffset));
                app.PostDur.Value = num2str(app.time(end)-abs(app.SignalData.CfsFile.Chans(1).DS(1).xOffset));


                %StateInfoDesc = {app.SignalData.CfsFile.DSVars.Desc};


                %State = find(contains(StateInfoDesc,'State'));
                %StateL = find(contains(StateInfoDesc,'StateL'));
                %StateInfo = CfsFile.DSVars(State(1)).Values;

                if isempty(CfsFile.DSVars(StateL)) == 0
                    StateInfoL = CfsFile.DSVars(StateL(1)).Values;
                    StateInfoLCheck = cellfun(@isempty,StateInfoL);
                else
                    StateInfoLCheck = 1;
                end


                if sum(StateInfoLCheck) == size(StateInfoLCheck,2)
                    app.SignalData.StateInfo = StateInfo;
                    cell2mat(app.SignalData.StateInfo)
                    app.SignalData.StateInfo = strsplit(num2str(ans));
                else
                    app.SignalData.StateInfo = StateInfo;
                end


                %%%%%%%Converts .cfs%%%%%%%%%%%%%%%%%%%%%
            else
                cfs2mat.Convert()
                drawnow; %Puts UI Figure at the forfront
                figure(app.UIFigure) %Puts UI Figure at the forfront
            end


            app.EventsegTTLButton.Enable = "off";
            app.CommentsButton.Enable = "off";

        end

        % Menu selected function: OpenNewFileMenu, RefreshMenu
        function OpenMenuSelected(app, event)
            %refresh the setting

            app.reloadInd = 0;
            app.ProcessInd = 0;
            app.ProcessButton.Enable = 1;
            app.EditField.Visible = 0;
            app.TrialSelectPanel.Visible = 'off';
            app.ChannelSpinner.Value = 1;
            app.TrialSpinner.Value = 1;
            app.DefineConditionsPanel.Visible = 'off';
            app.UIAxes.Toolbar.Visible = 'on';
            cla(app.UIAxes);
            app.PreDurs.Value = '0';
            app.PostDur.Value = '0';
            app.Button.Value = true;
            app.CommentsButton.Enable = 'on';
            app.EventsegTTLButton.Enable = "on";
            app.FileName.Value = '';
            app.uVButton.Enable = 'off';
            app.mVButton.Enable = 'off';
            app.VButton.Enable = 'off';
            app.Panel.Visible = 'off';
            app.EventNameListBox.Visible = 'off';
            app.EventNameListBoxLabel.Visible = 'off';
            app.msEditField.Enable = 'off';
            app.Panel_4.Visible = 0;
            app.EMGAnalysisPanel.Visible = 0;
            app.FigureCheckCheckBox.Visible = 'off';
            app.FigureCheckCheckBox.Value = 0;
            %             app.ChannelSpinner.Value = 1;
            %             app.TrialSpinner = 1;
            app.ChannelSpinner.Enable = 1;
            app.TrialSpinner.Enable = 1;
            app.TrialSpinnerLabel.Text = 'Trial #';
            %reset all private properties
            app.labChartData = [];        % data package of the TMS data exported from LabChart
            app.AcqknowledgeData = [];     % data package of the TMS data exported from Acqknowledge
            app.SignalData = [];          % data package of the TMS data exported from Signal
            app.BrainVisionData = [];     % data package of the TMS data exported from BrainVision
            app.origSampleRate = [];      % define the sample rate variable
            app.eventInfo = [];           % define the event/trial information
            app.totChannel = [];          % define the channel number variable
            app.totEvent = [];            % define the event/trial number variable
            app.totBlock = [];            % define the block number variable
            app.unit = [];                % define the unit of the TMS data
            app.EventType = [];           % define the type of event 1/2/3 refers to comment/event/block ???
            app.allData = [];             % define the matrix which saves data of all channels and all trials
            app.reloadInd = 0;           % define a mark to reveal if it is the first time to load the data
            app.fileName = [];            % name of the data
            app.dataType = [];            % data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
            app.time = [];                % time points of the data
            app.timeUnit = [];            % unit of the time
            app.MultiChan = [];           % clear multiple channels variable
            app.clickCount = 0;                % count the clicks of 'Select Multiple Trials' button. '0' for unclick
            app.chosenData = [];                % data to plot
            app.meanData = [];                 % mean data of multiple selected trials
            app.DSData = [];                 % save the down sampled data
            app.DSTime = [];                    % save the down sampled time
            app.DelayTime = [];                % save the delayed time
            app.BPData = [];                   % save the bandpass data
            app.NotData = [];                  % save the data after Notch filter
            app.LPData = [];                   % save the Low Pass data
            app.dwnSampleRate = [];            % save the down samplerate
            app.TKEOData = [];                 % save TKEO data
            app.RectifyData = [];              % save rectified data
            app.DataToPlot = [];               % save the plot data
            app.TimeToPlot = [];               % save the time vector to plot
            app.dataOut = [];               % variables to export
            app.UITable.Data = [];          % clear UITable
            app.DelayTime = [];               % initialize delayed time, which is the time variable after pre-processing
            app.origTime = [];                %clear time
            app.VelocityData = [];
            app.BPData = [];              % save the bandpass data
            app.NotData = [];          % save the data after Notch filter
            app.LPData = [];              % save the Low Pass data
            app.dwnSampleRate = [];       % save the down samplerate
            app.TKEOData = [];            % save TKEO data
            app.RectifyData = [];         % save rectified data
            app.DataToPlot = [];          % save the plot data
            app.TimeToPlot = [];          % save the time vector to plot
            app.ProcessInd = 0;          % data is pre-processed or not
            app.onsetLine = [];           % onset line to determine the silent period
            app.offsetLine = [];          % offset line to determine the silent period
            app.overRideMark = [];        % if override button is clicked
            app.EMGBurstoverRideMark = 0;% if override button in EMG Burst detection is clicked
            app.EMGAnalysisOR = 0;
            app.rMEPoverrideMark = 0;    % resting MEP override
            app.overRideInd = 0;         % save the override onset and offset index
            app.dataOut = [];             % variables to export
            app.tempH = [];               % temporally saves the lines of the figure in mV
            app.WastedTrials = [];       % counts of wasted trials
            app.Patch = [];              % Patch the prestimulation period
            app.AnalyzeInd = 0;          % Analyze button clicked
            app.PreStimMean = [];         % Pre stimulation mean
            app.Ztrans = 0;              % if Ztrans is clicked
            app.Prolific = 0;            % if Prolific is clicked
            app.TrialNum_CurrentGroup = []; % trial numbers when hit 'add new group'. Create new groups and assignl trials to them
            app.TrialName_CurrentGroup = []; % group name when hit 'add new group'
            app.conditionData = [];       % save the condition data if condition applied
            app.conditionMean = [];       % save the mean data of all conditions in the condition list
            app.conditionTotalEvent = []; % save the number of trials within the condition
            app.EventName = [];           % save the event name
            app.allConditionEvents = [];  % save all event names to the conditions
            app.allConditionData = [];    % save the data to all condition and event names
            app.conditionInd = [];        % save the condition index when select multiple conditions
            app.LowThresh = [];           % Contains Remove LowThresh Value for Active Conditions
            app.HighThresh = [];          % Contains Remove HighThresh Value for Active Conditions
            app.LowThreshRest = [];         % Contains Remove LowThresh Value for Rest Conditions
            app.HighThreshRest = [];        % Contains Remove HighThresh Value for Rest Conditions
            app.removeIDX = [];            % Contains Removed TMS values based on background
            app.BrainVisionData  = [];         % Contains Removed brainsight             app.RestingMEPPanel.Visible = 0; % Hides Resting MEP Analysis
            app.EMGAnalysisPanel.Visible = 0; % Hides EMG Burst Analysis
            app.SilentPeriodPanel.Visible = 0; % Hides SP/ActiveMEP Analysis
            app.MCDCheckBox.Visible = 0; % Hides MCD CheckBox
            app.GroupAnalysisPanel.Visible = 0; %Hides Group Analysis Option
            app.RecruitmentCurvePanel.Visible = 0; %Hides Recruitment Curve Analysis Options
            app.Panel_17.Visible = 0; %Hides Block Event options
            app.Visualize.Visible = 0; %Hides Visualize Panel
            app.Processing.Visible = 0; %Hides Processing indicator
            app.ConditionsListBox.Visible = 0; %Hides Condition Select Options
            app.ConditionsListBoxLabel.Visible  = 0; %Hides Condition Select Options
            app.TrialSelectPanel.Visible = 0; %Hides Condition Select Options
            app.MultiConditionAnalysisCheckBox.Visible = 0; %Hides Condition Select Options
            app.ConditionMeanCheckBox.Visible = 0; %Hides Condition Select Options
            app.PairedPulse.Visible = 0;  %Hides Paired Pulse Panel
            app.PreStimMeanEditField.Value = 0; %resete mean
            app.PreStimStdEditField.Value = 0'; %resets STD
            app.SampleRateHzEditField.Value = 0; %resets SampleRate
            app.EnableCheckBox.Value = 0;
            app.Mapping.Visible = 0; %Hides Mapping MultiSelect
            %EnableCheckBoxValueChanged(app, event)
            app.boltzY =[];            % RC Boltzman Fit
            app.boltzX =[];              % RC Boltzman Fit
            app.rcData =[];               % RC Trial x Trial MEP
            app.percentMT =[];           % RC X axis
            app.RC =[] ;               % used to save mat file
            app.OnsetThrePlot =[];       % Contains threshold onset plot
            app.OffsetThrePlot =[];      % Contains threshold offset plot
            app.PreMeanPlot =[];        % Contains premean plot
            app.wave =[];               % Contains raw data for Spike type data
            app.tick =[];               % Contains tick information for Spike type data
            app.tickcat =[];            % Contains tick information for Spike type data
            app.TableRow =[];           % Contains UITable information to delete
            app.condition =[];        % Contains condition IDX for MultiSelect
            app.conditionOutcome =[];   % Contains condition outcome for MultiSelect


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            % initialize parameters
            app.PreDurs.Enable = 'off';
            app.PostDur.Enable = 'off';
            InitializationButtonPushed(app, event);

            % if the data is comment based
            if app.CommentsButton.Value == true
                app.PreDurs.Enable = 'on';
                app.PostDur.Enable = 'on';
                app.EventNameListBox.Visible = 'on';
                app.EventNameListBoxLabel.Visible = 'on';
                app.Panel_17.Visible = 0;
                if app.dataType == 1
                    app.EventNameListBox.Items = cellstr(app.labChartData.comtext);
                end

                if app.dataType == 4
                    comtext = app.BrainVisionData.comtext;
                    comtext(cellfun(@isempty,comtext))=[];            % clear empty point
                    app.EventNameListBox.Items = cellstr(unique(comtext));
                end
            else
                app.EventNameListBox.Visible = 'off';
                app.EventNameListBoxLabel.Visible = 'off';
            end

            if app.dataType == 5
                app.EventNameListBox.Items = cellstr(app.SignalData.event);
            end

            if app.dataType == 6
                app.CustomData.eventname = app.eventInfo;
                app.CustomData.event = unique(app.CustomData.eventname);
                app.CustomData.eventname = app.CustomData.eventname;
                events = app.CustomData.event;
                app.EventNameListBox.Items = events;
            end

            %  if the data is event based
            if app.EventsegTTLButton.Value == true
                app.Panel.Visible = 'on';
                app.PreDurs.Enable = 'on';
                app.PostDur.Enable = 'on';
                app.TrialSpinner.Visible = 'on';
                app.Panel_17.Visible = 0;
            else
                app.Panel.Visible = 'off';
            end

            if app.dataType == 9
                app.CustomData.eventname = app.CustomData.target;
                app.CustomData.event = unique(app.CustomData.eventname);
                app.CustomData.eventname = strsplit(num2str(app.CustomData.eventname));
                events = strsplit(num2str(app.CustomData.event));
                app.EventNameListBox.Items = events;
            end


            % if block based

            if app.BlocksFramesButton.Value == true
                app.Panel_17.Visible = 1;
                app.PreDurs.Enable = 'off';
                app.PostDur.Enable = 'off';
                app.EventNameListBox_2.Visible = 0;

                if app.dataType == 2
                    app.TrialSpinner.Visible = 'on';
                    app.SelectMultipleTrialsButton.Enable = 'on';
                    app.PreDurs.Enable = 'off';
                    app.PostDur.Enable = 'off';

                elseif app.dataType == 5
                    app.PreDurs.Enable = 'on';
                    app.PostDur.Enable = 'on';

                else
                    app.TrialSpinner.Visible = 'on';
                    app.SelectMultipleTrialsButton.Enable = 'on';
                end


                if app.dataType == 3 && app.BlocksFramesButton.Value == true && isempty(app.SignalData.frameinfo) == 0

                    if isfield(app.SignalData.frameinfo, 'label') == 1
                        unique(extractfield(app.SignalData.frameinfo,'label'));
                        app.EventNameListBox_2.Items = cellstr(ans);
                        app.EventNameListBox_2.Visible = 1;
                    else
                        state = unique(extractfield(app.SignalData.frameinfo,'state'));
                        state = strsplit(num2str(state));
                        app.EventNameListBox_2.Items = cellstr(state);
                        app.EventNameListBox_2.Visible = 1;
                    end

                    app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;
                    app.PreTriggerTime.Enable = 0;
                    app.PreDurs.Enable = 'on';
                    app.PostDur.Enable = 'on';

                elseif app.dataType == 3 && isempty(app.SignalData) ~=1
                    unique(app.SignalData.StateInfo);
                    app.EventNameListBox_2.Items = cellstr(ans);
                    app.EventNameListBox_2.Visible = 1;
                    app.PreDurs.Enable = 'off';
                    app.PostDur.Enable = 'off';
                else
                    %app.EventNameListBox_2.Visible = 0;
                    %app.PreDurs.Enable = 'on';
                    %app.PostDur.Enable = 'on';
                end


                if app.dataType == 7 %brainsight

                    app.EventNameListBox_2.Items = {'Sample'};
                    app.EventNameListBox_2.Visible = 1;
                    %app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;
                    app.PreTriggerTime.Enable = 0;
                    app.PreDurs.Enable = 'off';
                    app.PostDur.Enable = 'off';

                end

                if app.dataType == 10 %Exoforce

                    app.EventNameListBox_2.Items = {'File'};
                    app.EventNameListBox_2.Visible = 1;
                    %app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;
                    app.PreTriggerTime.Enable = 0;
                    app.PreDurs.Enable = 'off';
                    app.PostDur.Enable = 'off';

                end


            else
                %app.Panel_17.Visible = 0;

                %app.PreDurs.Enable = 'on';
                %app.PostDur.Enable = 'on';
            end

        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            % initialize
            InitializationButtonPushed(app, event)
        end

        % Value changed function: EventNameListBox
        function EventNameListBoxValueChanged(app, event)
            value = app.EventNameListBox.Value;
            % initialize
            InitializationButtonPushed(app, event)  % whenever choose another event name, find event again instead of call the saved data matrix

            % if conditions are applied, automatically find events when switch event name
            if app.DefineConditionsPanel.Visible == 1
                FindallEventsButtonPushed(app, event);
            end
        end

        % Value changed function: EventChannel
        function EventChannelValueChanged(app, event)
            value = app.EventChannel.Value;
            % initialize
            InitializationButtonPushed(app, event)

            if value>app.totChannel
                mh = msgbox(['maximal trial is',' ', num2str(app.totChannel)]);
                th = findall(mh, 'Type', 'Text');                   %get handle to text within msgbox
                th.FontSize = 14;                                   %Change the font size
                return
            end
        end

        % Button pushed function: SelectMultipleTrialsButton
        function SelectMultipleTrialsButtonPushed(app, event)
            app.clickCount  = app.clickCount + 1;
            if isempty(app.totEvent) == 1   %No trail found
                msgbox('Not Applicable');
                return;
            end
            if rem(app.clickCount,2)                    % rem(app.clickCount,2) means multiple trials. Click again, multiple selection panel cancel
                app.TrialSelectPanel.Visible = 'on';
                app.TrialSpinner.Enable = 'off';
                app.ChannelSpinner.Enable = 'off';
                tempEvents = 1 : app.totEvent;
                %if app.dataType == 7
                %   app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), BrainsightTrialNum);
                %else
                app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
                %end


                % low pass envelope for silent period is only available for
                % single trial, take average is only for multiple trials
                app.AnalyzetheAverageCheckBox.Enable = 1;

                app.LowPassEnvelopeCheckBox.Value = 0;
                app.SelectMultipleTrialsButton.Text = 'Click to Select Single Trial';
            else                                % means when single trial
                app.AnalyzetheAverageCheckBox.Enable = 0;
                app.AnalyzetheAverageCheckBox.Value = 0;
                app.MultiChan = [];
                app.meanData = [];
                app.MultiChanListBox.Value = {};
                app.TrialSelectPanel.Visible = 'off';
                app.TrialSpinner.Enable = 'on';
                app.ChannelSpinner.Enable = 'on';
                app.ConditionsListBox.Visible = 0;
                FindallEventsButtonPushed(app, event);              %
                app.SelectMultipleTrialsButton.Text = 'Select Multiple Trials';
                if app.EnableCheckBox.Value == true && app.ProcessInd == 1
                    ProcessButtonPushed(app, event);
                else
                    % change the scale accordingly
                    % plot in mV first. Then change the units accordingly
                    cla(app.UIAxes);
                    plot(app.UIAxes,app.TimeToPlot,app.DataToPlot,'Color','k');
                    if ~isempty(app.MultiChanListBox.Value)
                        hold(app.UIAxes,'on')
                        %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                        hold(app.UIAxes,'off')
                    end
                    app.UIAxes.YLabel.String = app.mVButton.Text;

                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end                    % change the unit
                    ButtonGroupUnitsSelectionChanged(app, event)

                end

            end

        end

        % Callback function
        function ListBoxValueChanged2(app, event)
            value = app.MultiChanListBox.Value;

        end

        % Selection changed function: ButtonGroupUnits
        function ButtonGroupUnitsSelectionChanged(app, event)
            selectedButton = app.ButtonGroupUnits.SelectedObject;


            h = findobj(app.UIAxes,'Type','line');
            if app.uVButton.Value == true
                for iLine = 1:size(h,1)
                    h(iLine).YData =  app.tempH(iLine,:)*1000;            % uV * 1000 = mV
                end
                app.UIAxes.YLabel.String = app.uVButton.Text;
                app.mVLabel.Text = 'uV';
                app.mVLabel_2.Text = 'uV';
                app.unit = 'uV';
            end
            if app.mVButton.Value == true
                for iLine = 1:size(h,1)
                    h(iLine).YData =  app.tempH(iLine,:);                    % uV * 1000 = mV
                end
                app.UIAxes.YLabel.String = app.mVButton.Text;
                app.mVLabel.Text = 'mV';
                app.mVLabel_2.Text = 'mV';
                app.unit = 'mV';
            end
            if app.VButton.Value == true
                for iLine = 1:size(h,1)
                    h(iLine).YData =  app.tempH(iLine,:)/1000;            % mV * 1000 = V
                end
                app.UIAxes.YLabel.String = app.VButton.Text;
                app.mVLabel.Text = 'V';
                app.mVLabel_2.Text = 'V';
                app.unit = 'V';
            end

            if app.AnalyzeInd == 1
                if isempty(app.Patch)~=1
                    delete(app.Patch);
                end
                % patch the pre stimulation durations
                h = findobj(app.UIAxes,'Type','line');
                preStimOnset = app.PreStimPeriodmsEditField.Value/1000;
                preStimOffset = app.EditField_3.Value/1000;
                Lim = get(app.UIAxes,'Ylim');
                maxLim = max(Lim);
                minLim = min(Lim);
                v2 = [preStimOnset minLim;preStimOffset minLim;preStimOffset maxLim; preStimOnset maxLim];
                f2 = [1 2 3 4];
                app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','black','FaceAlpha',.1,'Parent',app.UIAxes,'DisplayName', 'PreStimDur');   %denote the prestimulation period with patch
            end

        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)

        end

        % Value changed function: msEditField
        function msEditFieldValueChanged(app, event)
            value = app.msEditField.Value;

        end

        % Button pushed function: ResetScaleButton
        function ResetScaleButtonPushed(app, event)
            if ~isempty(app.DataToPlot) && ~isempty(app.TimeToPlot)

                if app.uVButton.Value == true
                    app.UIAxes.XLim = [min(min(app.TimeToPlot)),max(max(app.TimeToPlot))];
                    app.UIAxes.YLim = [1.2*min(min(app.DataToPlot))*1000,1.2*max(max(app.DataToPlot))*1000];
                end
                if app.mVButton.Value == true
                    app.UIAxes.XLim = [min(min(app.TimeToPlot)),max(max(app.TimeToPlot))];
                    app.UIAxes.YLim = [1.2*min(min(app.DataToPlot)),1.2*max(max(app.DataToPlot))];

                end
                if app.VButton.Value == true
                    app.UIAxes.XLim = [min(min(app.TimeToPlot)),max(max(app.TimeToPlot))];
                    app.UIAxes.YLim = [1.2*min(min(app.DataToPlot))/1000,1.2*max(max(app.DataToPlot))/1000];
                end

                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.YMin.Value = num2str(app.UIAxes.YLim(1));
                app.YMax.Value = num2str(app.UIAxes.YLim(2));
                app.XMin.Value = num2str(app.UIAxes.XLim(1));
                app.XMax.Value = num2str(app.UIAxes.XLim(2));


            end
        end

        % Size changed function: ProcessPanel
        function ProcessPanelSizeChanged(app, event)
            position = app.ProcessPanel.Position;

        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            value = app.EnableCheckBox.Value;
            if value == true
                app.DownSampleCheckBox.Enable = 'on';
                app.BandPassCheckBox.Enable = 'on';
                app.NotchCheckBox.Enable = 'on';
                app.TKEOCheckBox.Enable = 'on';
                app.RectifyCheckBox.Enable = 'on';
                app.LowPassCheckBox.Enable = 'on';
                app.VelocityCheckBox.Enable = 'on';
                if app.DownSampleCheckBox.Value == 1
                    app.FactorEditField.Enable = 'on';
                end
                app.DelayCheckBox.Enable = 'on';
                if  app.DelayCheckBox.Value == 1
                    app.msEditField.Enable = 'on';
                end
            else
                app.ProcessInd = 0;
                app.ProcessButton.Enable = 1;
                app.FactorEditField.Enable = 'off';
                app.FactorEditField.Value = 0;
                app.DownSampleCheckBox.Enable = 'off';
                app.DownSampleCheckBox.Value = 0;
                app.BandPassCheckBox.Enable = 'off';
                app.BandPassCheckBox.Value = 0;
                app.NotchCheckBox.Enable = 'off';
                app.NotchCheckBox.Value = 0;
                app.TKEOCheckBox.Enable = 'off';
                app.TKEOCheckBox.Value = 0;
                app.RectifyCheckBox.Enable = 'off';
                app.RectifyCheckBox.Value = 0;
                app.LowPassCheckBox.Enable = 'off';
                app.LowPassCheckBox.Value = 0;
                app.DelayCheckBox.Enable = 'off';
                app.DelayCheckBox.Value = 0;
                app.FactorEditField.Enable = 'off';
                app.DelayCheckBox.Enable = 'off';
                app.DelayCheckBox.Value = 0;
                app.msEditField.Enable = 'off';
                app.VelocityCheckBox.Enable = 'off';
                app.VelocityCheckBox.Value = 0;
                app.VelocityData = [];
                app.SampleRateHzEditField.Value = app.origSampleRate;
                app.time = app.origTime;
                app.UIAxes.XLim = [min(app.time),max(app.time)];


                if isempty(rem(app.clickCount,2)) ==0 && rem(app.clickCount,2)                    % rem(app.clickCount,2) means multiple trials. Click again, multiple selection panel cancel
                    if app.ConditionsListBox.Visible == 1    % if conditions are applied
                        ConditionsListBoxValueChanged(app, event)
                    else
                        OKButtonPushed(app, event);
                    end
                else
                    if app.ConditionsListBox.Visible == 1    % if conditions are applied
                        ConditionsListBoxValueChanged(app, event)
                    else
                        FindallEventsButtonPushed(app, event);
                    end
                end
            end
        end

        % Size changed function: Panel_2
        function Panel_2SizeChanged(app, event)
            position = app.Panel_2.Position;

        end

        % Value changed function: DownSampleCheckBox
        function DownSampleCheckBoxValueChanged(app, event)
            value = app.DownSampleCheckBox.Value;

            if value == true
                app.FactorEditField.Enable = 'on';
            else
                app.FactorEditField.Enable = 'off';
                app.FactorEditField.Value = 1;
            end
        end

        % Value changed function: FactorEditField
        function FactorEditFieldValueChanged(app, event)
            value = app.FactorEditField.Value;

        end

        % Value changed function: HzEditField_3
        function HzEditField_3ValueChanged(app, event)
            value = app.HzEditField_3.Value;

        end

        % Value changed function: LowPassCheckBox
        function LowPassCheckBoxValueChanged(app, event)
            value = app.LowPassCheckBox.Value;
            if value == true
                app.HzEditField_3.Enable = 'on';
            else
                app.HzEditField_3.Enable = 'off';
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;

            if app.EnableCheckBox.Value ~= true
                msgbox('No filter found')
                return;
            end
            app.AnalyzeInd = 0;     % reset analyze button

            % Down Sampling
            if  app.DownSampleCheckBox.Value == true
                app.DSData = num2cell(app.UnprocessedData,2);
                app.DSData = cellfun(@(x) downsample(x,app.FactorEditField.Value), app.DSData,'UniformOutput', false);
                app.DSTime = downsample(app.origTime,app.FactorEditField.Value);
                app.DSData = cell2mat(app.DSData);
                app.dwnSampleRate = app.origSampleRate / app.FactorEditField.Value;
            else
                app.DSData = num2cell(app.UnprocessedData,2);
                app.DSData = cellfun(@(x) downsample(x,1), app.DSData,'UniformOutput', false);
                app.DSTime = downsample(app.origTime,1);
                app.DSData = cell2mat(app.DSData);
                app.dwnSampleRate = app.origSampleRate / 1;
            end

            % delay
            if app.DelayCheckBox.Value == true
                app.DelayTime = app.DSTime - app.msEditField.Value/1000;
                %app.UIAxes.XLim = [min(app.DelayTime),max(app.DelayTime)];
            else
                app.DelayTime = app.DSTime;
            end

            % Band Pass Filter
            if app.BandPassCheckBox.Value == true && app.ConditionMeanCheckBox.Value ~= 1
                aFq = app.EditField_2.Value;
                bFq =  app.HzEditField.Value;
                [b,a] = butter(4,[(aFq/(app.dwnSampleRate/2)) (bFq/(app.dwnSampleRate/2))],'bandpass');
                app.DSData = num2cell(app.DSData,2);
                app.BPData = cellfun(@(x) filtfilt(b,a,x), app.DSData,'UniformOutput', false);
                app.BPData = cell2mat(app.BPData);
            else
                app.BPData = app.DSData;
            end


            % Notch Filter
            if app.NotchCheckBox.Value == true
                FqNotch = app.HzEditField_2.Value;
                aNotch = FqNotch - 1;
                bNotch = FqNotch + 1;
                %[nb na] = butter(4,[(aNotch/(handles.refresh.sampleRate/2)) (bNotch/(handles.refresh.sampleRate/2))],'stop');
                [nb,na] = butter(2, [aNotch bNotch]./((app.dwnSampleRate/2)),'stop');
                app.BPData = num2cell(app.BPData,2);
                app.NotData = cellfun(@(x) filtfilt(nb,na,x), app.BPData,'UniformOutput', false);
                app.NotData = cell2mat(app.NotData);
            else
                app.NotData = app.BPData;
            end

            % TKEO (teager-kaiser energy operator)
            if app.TKEOCheckBox.Value == true
                app.TKEOData = {};
                for i = 1:size(app.NotData,1)
                    app.TKEOData{end+1} = EnergyTKEO(app.NotData(i,:)', app.dwnSampleRate);   % data input must be column
                end
                app.TKEOData = (cell2mat(app.TKEOData))';
            else
                app.TKEOData = app.NotData;
            end

            % Low Pass Filter
            if app.LowPassCheckBox.Value == true
                Fq = app.HzEditField_3.Value;
                [b,a] = (butter(6,(Fq/(app.dwnSampleRate/2)),'low'));
                app.TKEOData = num2cell( app.TKEOData,2);
                app.LPData = cellfun(@(x) filtfilt(b,a,x),  app.TKEOData,'UniformOutput', false);
                app.LPData = cell2mat(app.LPData);
            else
                app.LPData = app.TKEOData;
            end

            if app.VelocityCheckBox.Value == true
                data = app.LPData;
                time = app.DelayTime;
                app.VelocityData = num2cell(app.LPData,2);
                app.VelocityData = cellfun(@(x) Velocity(x,time),app.VelocityData,'UniformOutput', false);
                app.VelocityData = cell2mat(app.VelocityData);
            else
                app.VelocityData = app.LPData;
            end

            % Rectify
            if app.RectifyCheckBox.Value == true
                app.RectifyData = abs(app.VelocityData);
            else
                app.RectifyData = app.VelocityData;    % Non Rectify data
            end

            app.ProcessInd = 1;
            app.ProcessButton.Enable = 0;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot the figure
            app.chosenData = app.RectifyData;
            app.time = app.DelayTime;

            %app.UIAxes.XLim = [min(app.time),max(app.time)];

            app.DataToPlot = app.RectifyData;


            if app.VelocityCheckBox.Value == true
                VelocityAdjustedTime = app.DelayTime - (app.DelayTime(1+1)-app.DelayTime(1));
                app.TimeToPlot = VelocityAdjustedTime;
            else
                app.TimeToPlot = app.DelayTime;
            end


            if ~isempty(app.MultiChanListBox.Value)
                app.meanData = sum(app.DataToPlot)/size(app.DataToPlot,1);
            end

            if app.ConditionsListBox.Visible == 1
                app.meanData = sum(app.DataToPlot)/size(app.DataToPlot,1);
            end
            % plot accroding to the unit the user choose

            cla(app.UIAxes);
            app.Patch = [];
            % plot in mV first, if the unit is uV or V, change accordingly
            plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);

            if app.ConditionMeanCheckBox.Value ~= 1
                if ~isempty(app.MultiChanListBox.Value) && length(app.MultiChan)>1
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",1,"LineStyle","-");
                    hold(app.UIAxes,'off')
                elseif app.ConditionsListBox.Visible == 1 && length(app.meanData)>1
                    hold(app.UIAxes,'on')
                    %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                    hold(app.UIAxes,'off')
                end
            end
            app.UIAxes.YLabel.String = app.mVButton.Text;
            app.SampleRateHzEditField.Value = app.dwnSampleRate;

            app.tempH = [];                          % the length of tempH could have been changed due to downsampling
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end
            legend(app.UIAxes,'off');
            % change the unit
            %figure;plot(app.LPData' )
            check = (min(app.LPData(1,:)));
            if check < 0
                app.NALabel_21.Text = 'Non-Rectified';
                app.NALabel_21.BackgroundColor = 'green';
            else
                app.NALabel_21.Text = 'Rectified';
                app.NALabel_21.BackgroundColor = 'red';
            end
            ButtonGroupUnitsSelectionChanged(app, event)
        end

        % Value changed function: BandPassCheckBox
        function BandPassCheckBoxValueChanged(app, event)
            value = app.BandPassCheckBox.Value;
            if value == true
                app.EditField_2.Enable = 'on';
                app.HzEditField.Enable = 'on';
            else
                app.EditField_2.Enable = 'off';
                app.HzEditField.Enable = 'off';
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;

        end

        % Value changed function: HzEditField
        function HzEditFieldValueChanged(app, event)
            value = app.HzEditField.Value;

        end

        % Value changed function: NotchCheckBox
        function NotchCheckBoxValueChanged(app, event)
            value = app.NotchCheckBox.Value;
            if value == true
                app.HzEditField_2.Enable = 'on';
            else
                app.HzEditField_2.Enable = 'off';
            end
        end

        % Value changed function: HzEditField_2
        function HzEditField_2ValueChanged(app, event)
            value = app.HzEditField_2.Value;

        end

        % Value changed function: TKEOCheckBox
        function TKEOCheckBoxValueChanged(app, event)
            value = app.TKEOCheckBox.Value;

        end

        % Value changed function: RectifyCheckBox
        function RectifyCheckBoxValueChanged(app, event)
            value = app.RectifyCheckBox.Value;

        end

        % Value changed function: ThresholdmV
        function ThresholdmVValueChanged(app, event)
            value = app.ThresholdmV.Value;
            % initialize
            InitializationButtonPushed(app, event)
        end

        % Value changed function: SearchISIs
        function SearchISIsValueChanged(app, event)
            value = app.SearchISIs.Value;
            % initialize
            InitializationButtonPushed(app, event)
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.AnalyzeInd = 1;   % Analyze button clicked
            app.overRideMark = 0;                              % it is not overrided
            app.EMGBurstoverRideMark = 0;
            app.EMGAnalysisOR = 0;
            % define parameters
            SPData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
            SPTime = app.TimeToPlot;
            tempRemovedDataToPlot = app.DataToPlot;            %retains individual trials after trials are removed
            tempRemovedMeanDataToPlot = app.meanData;          %retains average signal after trials are removed

            if app.ProcessInd == 1
                NoRecData =  app.LPData;                           % Non rectify data saved
            else
                NoRecData =  SPData;
            end

            %determne sample rate
            if app.ProcessInd == 1
                sampleRate = app.dwnSampleRate;
            else
                sampleRate = app.origSampleRate;
            end

            % determine if it is multiple trials
            if size(SPData,1) == 1
                singleTrial = 1;
            else
                singleTrial = 0;
            end
            fprintf('Processing, please wait...');
            pause (.1)
            app.Processing.Visible = "on";
            app.Processing.Text = 'Processing, please wait...';
            app.Processing.BackgroundColor = 'yellow';
            pause (.1)
            tic;

            preStimDurInd = find(SPTime >= (app.PreStimPeriodmsEditField.Value/1000) & SPTime<= app.EditField_3.Value/1000);
            minDurPointsOn = round(app.MinDurmsEditField.Value/1000*sampleRate);    % min duration to determine onset
            minDurPointsOff = round(app.MinDurmsEditField_2.Value/1000*sampleRate);    % min duration to determine offset

            % define pre-stimulation data in case multiple trials selected
            tempData = SPData;       % SPData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
            tempData = num2cell(tempData,2);
            preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
            preStimData = cell2mat(preStimData);

            if app.RemoveCheckBox.Value == 1 %removes trials based on pre-difined thresholds
                % find average and std of the Pre-stimulation Period
                preStimMean = sum(preStimData')/(size(preStimData,2));
                preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                retainIDX = (find(preStimMean > app.LowThresh.Value & preStimMean < app.HighThresh.Value)); %Find IDX inbetween Low and HighThresh
                removeIDX = (find(preStimMean < app.LowThresh.Value | preStimMean > app.HighThresh.Value)); %Find IDX outside Low and HighThresh

                if isempty(app.conditionInd) ~= 1
                    app.conditionIndRmIDX = app.conditionInd;               %!Changes conditionInd to remove trials for group trial analysis
                    for i = 1:length(app.conditionIndRmIDX)                 %|
                        a = cell2num(app.conditionIndRmIDX(i));             %|
                        app.conditionIndRmIDX(i) = {setdiff(a, removeIDX)}; %|
                    end                                                     %|
                    %|
                    N = cellfun(@numel,app.conditionIndRmIDX);             %|
                    app.conditionIndRmIDX = mat2cell(1:sum(N),1,N);        %! Changes conditionInd to remove trials for group trial analysis
                end

                removed = length(preStimMean)-length(retainIDX); %Counts the number of removed trials
                removed = num2str(removed);
                app.RemovedCount.Text = removed; % Displays number of removed trials
                SPData = SPData(retainIDX,:); %Remove Rect IDX that are not within threshold
                NoRecData = NoRecData(retainIDX,:);  %Remove NonRect IDX that are not within treshold
                tempMultiChan = app.MultiChan(retainIDX); %retains trial numbers after removal
                tempRemovedDataToPlot = SPData; %retains individual trials after trials are removed
                tempRemovedMeanDataToPlot = sum(SPData)/size(SPData,1); %retains average signal after trials are removed
                tempData = SPData;
                tempData = num2cell(tempData,2);
                preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false); %Redifine prestimData based on removed trials
                preStimData = cell2mat(preStimData);

                if str2num(removed) == length(preStimMean)
                    msgbox('No Trials Retianed')
                    return;
                end
            end

            % calculate average
            if app.AnalyzetheAverageCheckBox.Value == 1
                SPData = sum(SPData)/size(SPData,1);
                preStimData = sum(preStimData)/size(preStimData,1);
                NoRecData = sum(NoRecData)/size(NoRecData,1);
                app.ClearBackgroundLinesCheckBox.Visible = 1;
            else %  clear background lines can only be checked when take average is checked
                app.ClearBackgroundLinesCheckBox.Visible = 0;
                app.ClearBackgroundLinesCheckBox.Value = 0;
            end

            % show low pass enverlop
            if app.LowPassEnvelopeCheckBox.Value == 1
                Fq = app.HzEditField_4.Value;
                [b,a] = (butter(6,(Fq/(sampleRate/2)),'low'));
                LPEverl = num2cell(SPData, 2);                       % low pass everlope
                LPEverl = cellfun(@(x) filtfilt(b,a,x),  LPEverl,'UniformOutput', false);
                LPEverl = cell2mat(LPEverl);
                SPDataEnv = LPEverl;
            end

            % find average and std of the Pre-stimulation Period
            preStimMean = sum(preStimData')/(size(preStimData,2));
            preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
            app.PreStimMeanEditField.Value = double(mean(preStimMean)); %Displays 1) Mean of all Single Trial 2) Mean of average
            app.PreStimStdEditField.Value = double(mean(preStimStd));   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            % variables definition      if implement low pass envelop, only
            % used in onset and offset detection
            onsetInd = zeros(1,size(SPData,1));          % if choose multiple trials, onsetUbd, offsetInd, SPDur, areaSP and spPercentDerease saved but not displayed
            offsetInd = zeros(1,size(SPData,1));
            SPDur = zeros(1,size(SPData,1));
            areaSP = zeros(1,size(SPData,1));
            if strcmp(app.ListBox.Value, 'Silent Period')
                spPercentDecrease = zeros(1,size(SPData,1));
            elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                mepAmp = zeros(1,size(SPData,1));
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % figure plot
            cla(app.UIAxes);
            % plot in mV first. Then change the unit accordingly
            legend(app.UIAxes,'AutoUpdate','off');
            if app.ClearBackgroundLinesCheckBox.Value ~= 1
                dataPlot = plot(app.UIAxes,app.TimeToPlot,tempRemovedDataToPlot);
            end
            hold(app.UIAxes,'on')

            if app.AnalyzetheAverageCheckBox.Value == true     % here SP data is the mean of all trials
                AveragePlot = plot(app.UIAxes,app.TimeToPlot,SPData,'Color','k',"LineWidth",2);
            end
            if app.LowPassEnvelopeCheckBox.Value == true
                if app.AnalyzetheAverageCheckBox.Value == true     % here SP data is the mean of all trials
                    %delete(AveragePlot);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.TimeToPlot,SPDataEnv,'Color',1/255*[0,104,87],"LineWidth",2);

                else
                    %delete(dataPlot);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.TimeToPlot,SPDataEnv,'Color',1/255*[0,104,87],"LineWidth",2);

                end
            end
            legend(app.UIAxes,'AutoUpdate','on');

            if ~isempty(app.MultiChanListBox.Value) &&length(app.MultiChan)>1 % If multiple trials selected, plot mean value
                if app.AnalyzetheAverageCheckBox.Value == true     % here SP data is the mean of all trials
                else
                    MeanDataPlot = plot(app.UIAxes,app.TimeToPlot,tempRemovedMeanDataToPlot,'Color','k',"LineWidth",2,'DisplayName', 'mean');
                end
            end

            % draw prestimulation mean
            if size(SPData,1) == 1 % exclude the situation that multiple trials selected but dont analyze average
                app.PreMeanPlot = plot(app.UIAxes,app.TimeToPlot,repmat(preStimMean,1,length(app.TimeToPlot)),'Color','k','DisplayName', 'PreStimMean');
            end


            StartTimeInd = find(app.TimeToPlot <= app.StartTimemsEditField.Value/1000);

            app.On = xline(app.UIAxes,SPTime(StartTimeInd(end)),'k','LineStyle','--',"LineWidth",1,'DisplayName', 'Start Time');
            hold(app.UIAxes,'off');

            %             h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            %             for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
            %                 app.tempH(iLine,:) = h(iLine).YData(1,:);
            %             end            % change the unit
            %             ButtonGroupUnitsSelectionChanged(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % find onset, if single trial
            if singleTrial == 1
                % if low pass envelop Check box is checked
                if app.LowPassEnvelopeCheckBox.Value == 1
                    tempData = SPDataEnv;
                else
                    tempData = SPData;
                end

                if strcmp(app.ListBox.Value, 'Silent Period')                    % for silent period, threshold of non-rectified data would be below prestimulation mean
                    if app.ProcessInd == 1 && app.RectifyCheckBox.Value == true   % rectified data
                        lowEndLimit = preStimMean - preStimStd*app.SDonsetEditField.Value;
                        highEndLimit = preStimMean - preStimStd*app.SDoffsetEditField.Value;

                    else % non-rectified data
                        lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField.Value;
                        highEndLimit = preStimMean + preStimStd*app.SDoffsetEditField.Value;

                    end
                elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField.Value;
                    highEndLimit = preStimMean + preStimStd*app.SDoffsetEditField.Value;
                end

                % MCD Algorithm (except mulitiple trials analyzed individually)
                if app.MCDCheckBox.Value == 1

                    if strcmp(app.ListBox.Value, 'Silent Period')
                        MCDconstant = app.MCDConstantEditField.Value;
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        MCDconstant = -app.MCDConstantEditField.Value;
                    end

                    if app.AnalyzetheAverageCheckBox.Value == true
                        [lowEndLimit, highEndLimit]=MCD(app.TimeToPlot,app.meanData,preStimDurInd,MCDconstant);
                    else
                        [lowEndLimit, highEndLimit]=MCD(app.TimeToPlot,tempData,preStimDurInd,MCDconstant);

                    end
                end
                % plot the low/highEndLimit
                hold(app.UIAxes,'on')
                app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');
                app.OffsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(highEndLimit,1,length(app.TimeToPlot)),':','Color','b','DisplayName', 'offsetThreshold');

                hold(app.UIAxes,'off');
                app.tempH = [];
                h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                    app.tempH(iLine,:) = h(iLine).YData(1,:);
                end            % change the unit
                ButtonGroupUnitsSelectionChanged(app, event)


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % find onset

                if strcmp(app.ListBox.Value, 'Silent Period')
                    OnThreshold = lowEndLimit; % onset threshold
                    StartTimeInd = find(SPTime <= app.StartTimemsEditField.Value/1000);
                    tempData(1:StartTimeInd(end)-1) = OnThreshold;
                    tempIndAboveThresh = find(tempData < OnThreshold); % find index where data of iTrial below onset threshold for SP
                elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    OnThreshold = lowEndLimit; % onset threshold
                    StartTimeInd = find(SPTime <= app.StartTimemsEditField.Value/1000);
                    tempData(1:StartTimeInd(end)-1) = OnThreshold;

                    %Test Deriv
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    %                     max_peak = max(tempData);
                    %                     min_peak = min(tempData);
                    %                     ran1 = find(tempData == max_peak);
                    %                     ran1 = find(tempData == max_peak)
                    %                     max_peak_time = SPTime(ran1);
                    %                     ran2 = find(tempData == min_peak);
                    %                     min_peak_time = SPTime(ran2);
                    %                     if max_peak_time > min_peak_time
                    %                         min_peak = -min_peak;
                    %                     end
                    %                     if max_peak > min_peak
                    %                         primary_peak_point = max_peak;
                    %                         primary_peak_time = max_peak_time;
                    %                     else
                    %                         primary_peak_point = min_peak;
                    %                         primary_peak_time = min_peak_time;
                    %                     end
                    %                     for i = 1:length(tempData)-1
                    %                         derivatives(i) = tempData(i+1) - tempData(i);
                    %                     end
                    %                     if min_peak_time < max_peak_time
                    %                         ran3 = find(SPTime == min_peak_time);
                    %                         ran4 = find(SPTime == max_peak_time);
                    %                     else
                    %                         ran3 = find(SPTime == max_peak_time);
                    %                         ran4 = find(SPTime == min_peak_time);
                    %                     end
                    %                     derivatives1 = derivatives(1:ran3);
                    %                     indexcount = 1; % Keep adding one regardless if positive or negative
                    %                     count = 0; % Add one if positive, set count to 0 if approaches negative number
                    %                     while indexcount < length(derivatives1) + 1
                    %                         if derivatives1(indexcount) < 0
                    %                             count = 0;
                    %                         elseif derivatives1(indexcount) > 0
                    %                             count = count + 1;
                    %                         end
                    %                         master(1, indexcount) = count;
                    %                         master(2,indexcount) = indexcount;
                    %                         indexcount = indexcount + 1;
                    %                     end
                    %                     master_peak = max(master(1,:));
                    %                     index_master_peak = find(master(1,:) == master_peak);
                    %                     % Finding "start" of the consecutive by working backwards until hit 0
                    %                     while master_peak > 0
                    %                         master_peak = master_peak - 1;
                    %                         index_master_peak = index_master_peak - 1;
                    %                     end
                    %
                    %                     mep_start = SPTime(index_master_peak(1,1));
                    %                     mep_start

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    tempIndAboveThresh = find(tempData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                    msgbox ('no trial found')
                    return;
                end

                tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold

                if length(tempIndAboveThresh) == length(tempData(StartTimeInd(end)-1:end))-1
                    onsetInd = StartTimeInd(end)+1;
                elseif isempty(tempBreakPointInd) == 1
                    tempBreakPointInd = 1;                           % only one pick detected, then the first point
                    (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                    if ans>minDurPointsOn
                        onsetInd = tempIndAboveThresh(1);
                    else
                        msgbox ('no trial found')
                    end
                else            % more than one session found
                    tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                    tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                    tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                    tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.

                    if isempty(tempIndMeetReq) == 1
                        msgbox ('no trial found')
                        return;
                    else
                        onsetInd = tempStartInd(tempIndMeetReq(1));
                    end
                end

                % find the offset
                while 1
                    if strcmp(app.ListBox.Value, 'Silent Period')
                        OffThreshold = lowEndLimit; % offset threshold
                        tempData(1:onsetInd) = OffThreshold;
                        tempIndAboveThresh = find(tempData > OffThreshold); % find index where data of iTrial above offset threshold for SP
                        logThresh = (tempData > OffThreshold);
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        OffThreshold = lowEndLimit; % onset threshold
                        tempData(1:onsetInd) = OffThreshold;
                        tempIndAboveThresh = find(tempData < OffThreshold); % find index where data of iTrial below onset threshold for MEP Latency
                        logThresh = (tempData < OffThreshold);

                    end

                    if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                        msgbox ('no trial found')
                        return;
                    end
                    if app.AlgorithmCheckBox.Value == 0
                        tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold

                        if length(tempIndAboveThresh) == length(tempData(onsetInd+1:end))  %  in case the threshold include every data point
                            offsetInd = length(tempData);
                            break;
                        end

                        if tempIndAboveThresh(1) == onsetInd + 1         % 1. the rising edge between onset and offset threshold is included and only this period meet the requirement
                            % 2. in case the threshold include every data point
                            tempIndAboveThresh(1:tempBreakPointInd(1)) = [];      % delete this period
                            tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);
                        end

                        if isempty(tempBreakPointInd) == 1
                            tempBreakPointInd = 1;                           % only one peck detected, then the first point
                            (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                            if ans>minDurPointsOff
                                offsetInd = tempIndAboveThresh(1);
                            else
                                masgbox ('no trial found')
                            end
                        else            % more than one session found
                            tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above/below threshold
                            tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)]; % end index
                            tempEndInd-tempStartInd+1;          % save the duration of every session above/below threshold
                            tempIndMeetReq = find(ans>minDurPointsOff);     % the duration of every session above/below threshold.

                            if isempty(tempIndMeetReq) == 1
                                msgbox ('no trial found' )
                                return;
                            else
                                offsetInd = tempStartInd(tempIndMeetReq(1));
                            end
                        end
                        break;
                    elseif app.AlgorithmCheckBox.Value == 1


                        window_sum = movsum(logThresh,[0 minDurPointsOff]);
                        offsetInd = find(logThresh & (window_sum >= minDurPointsOff/2),1,'first');

                        if isempty(offsetInd) == 1
                            %                             msgbox ('please reset onset thereshold')
                            app.WastedTrials = app.WastedTrials + 1;
                            onsetInd = 1+app.WastedTrials;
                            offsetInd = 20+app.WastedTrials;
                        end

                        break;


                        %                         for iWindow = 1:length(tempIndAboveThresh)
                        %                             if tempIndAboveThresh(iWindow)>= length(tempData) - minDurPointsOff
                        %                                 break;
                        %                             end
                        %
                        %                             find(tempIndAboveThresh(iWindow)<=tempIndAboveThresh<= tempIndAboveThresh(iWindow)+minDurPointsOff);
                        %
                        %                             if length(ans)>=minDurPointsOff/2
                        %                                 offsetInd = tempIndAboveThresh(iWindow);
                        %                                 break;
                        %                             else
                        %                                 iWindow = iWindow+1;
                        %                             end
                        %
                        %                         end
                        %                         break;
                    end
                end

                % other analysis, for single trial
                SPDur = abs((SPTime(onsetInd)-SPTime(offsetInd)))*1000;    % Silent Period duration (ms)
                inverseAreaSP = trapz(SPData(onsetInd:offsetInd));      % must be SPData, because lowPassEnv is only for onset and offset determination
                if strcmp(app.ListBox.Value, 'Silent Period')
                    spPercentDecrease = 100-(mean(SPData(onsetInd:offsetInd))/preStimMean*100); % percent of silent period mean value decreased compared with pre stimulation period
                    areaMeanSP = abs(preStimMean*length(onsetInd:offsetInd));        % mean preStimulation times length of silent period duration equals the area
                    areaSP = (1-inverseAreaSP/areaMeanSP)*100;      % area of slient period
                elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    areaSP = inverseAreaSP;      % area of MEP
                end

                if strcmp(app.ListBox.Value, 'Silent Period')
                    spPercentDecrease = 100-(mean(SPData(onsetInd:offsetInd))/preStimMean*100); % percent of silent period mean value decreased compared with pre stimulation period
                elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    %mepAmp = (max(NoRecData(onsetInd:offsetInd))/preStimMean)*100;
                    mepAmp = max(NoRecData(onsetInd:offsetInd)) - min(NoRecData(onsetInd:offsetInd));
                end

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else  % for multiple trials. Including Multiple trials analyzed individually and analyze the average
                app.WastedTrials = 0;
                for iTrial = 1:size(SPData,1)

                    % if low pass envelop Check box is checked
                    if app.LowPassEnvelopeCheckBox.Value == 1
                        tempData = SPDataEnv(iTrial,:);
                    else
                        tempData = SPData(iTrial,:);
                    end


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if strcmp(app.ListBox.Value, 'Silent Period')                    % for silent period, threshold of non-rectified data would be below prestimulation mean
                        if app.ProcessInd == 1 && app.RectifyCheckBox.Value == true   % rectified data
                            lowEndLimit = preStimMean(iTrial) - preStimStd(iTrial)*app.SDonsetEditField.Value;
                            highEndLimit = preStimMean(iTrial) - preStimStd(iTrial)*app.SDoffsetEditField.Value;

                        else % non-rectified data
                            lowEndLimit = preStimMean(iTrial) + preStimStd(iTrial)*app.SDonsetEditField.Value;
                            highEndLimit = preStimMean(iTrial) + preStimStd(iTrial)*app.SDoffsetEditField.Value;
                        end
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        lowEndLimit = preStimMean(iTrial) + preStimStd(iTrial)*app.SDonsetEditField.Value;
                        highEndLimit = preStimMean(iTrial) + preStimStd(iTrial)*app.SDoffsetEditField.Value;

                    end

                    % MCD Algorithm (except mulitiple trials analyzed individually)
                    if app.MCDCheckBox.Value == 1
                        if strcmp(app.ListBox.Value, 'Silent Period')
                            MCDconstant = app.MCDConstantEditField.Value;
                        elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                            MCDconstant = -app.MCDConstantEditField.Value;
                        end
                        if  app.LowPassEnvelopeCheckBox.Value == true
                            [lowEndLimit, highEndLimit]=MCD(app.TimeToPlot,SPDataEnv,preStimDurInd,MCDconstant);
                        else
                            [lowEndLimit, highEndLimit]=MCD(app.TimeToPlot,tempData,preStimDurInd,MCDconstant);
                        end
                    end

                    if size(SPData,1) == 1
                        % plot the low/highEndLimit
                        hold(app.UIAxes,'on')
                        app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');
                        app.OffsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(highEndLimit,1,length(app.TimeToPlot)),':','Color','b','DisplayName', 'offsetThreshold');

                        hold(app.UIAxes,'off');


                    end
                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end            % change the unit
                    ButtonGroupUnitsSelectionChanged(app, event)

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    if strcmp(app.ListBox.Value, 'Silent Period')
                        OnThreshold = lowEndLimit; % onset threshold
                        StartTimeInd = find(SPTime <= app.StartTimemsEditField.Value/1000);
                        tempData(1:StartTimeInd(end)-1) = OnThreshold;
                        tempIndAboveThresh = find(tempData < OnThreshold); % find index where data of iTrial below onset threshold for SP
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        OnThreshold = lowEndLimit; % onset threshold
                        StartTimeInd = find(SPTime <= app.StartTimemsEditField.Value/1000);
                        tempData(1:StartTimeInd(end)-1) = OnThreshold;
                        tempIndAboveThresh = find(tempData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency
                    end

                    while 1 % used for onset and offset index determination
                        if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                            %                         msgbox ('please reset onset thereshold')
                            app.WastedTrials = app.WastedTrials + 1;
                            onsetInd(1,iTrial) = 1+app.WastedTrials;
                            offsetInd(1,iTrial) = 20+app.WastedTrials;
                            break;
                        end

                        tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find unconsecutive points

                        if length(tempIndAboveThresh) == length(tempData(StartTimeInd(end)-1:end))-1
                            onsetInd(1,iTrial) = StartTimeInd(end)+1;

                        elseif isempty(tempBreakPointInd) == 1
                            tempBreakPointInd = 1;                           % only one peck detected, then the first point
                            tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd)+1;       % duration of the single session
                            if ans>minDurPointsOn
                                onsetInd(1,iTrial) = tempIndAboveThresh(1);
                            else
                                %                             msgbox ('the duration of signal above threshold is too short')
                                app.WastedTrials = app.WastedTrials + 1;
                                onsetInd(1,iTrial) = 1+app.WastedTrials;
                                offsetInd(1,iTrial) = 20+app.WastedTrials;
                                break;
                            end
                        else
                            tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above/below threshold
                            tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];

                            tempEndInd-tempStartInd+1;          % save the duration of every session above/below threshold
                            tempIndMeetReq = find(ans>minDurPointsOn);     % The difference of two consecutive tempBreakPointInd points refers to
                            % the duration of the points above/below threshold.
                            if isempty(tempIndMeetReq) == 1
                                %                             msgbox ('please reset onset thereshold')
                                app.WastedTrials = app.WastedTrials + 1;
                                onsetInd(1,iTrial) = 1+app.WastedTrials;
                                offsetInd(1,iTrial) = 20+app.WastedTrials;
                                break;
                            else
                                onsetInd(1,iTrial) = tempStartInd(tempIndMeetReq(1));
                            end
                        end


                        % find the offset
                        if strcmp(app.ListBox.Value, 'Silent Period')
                            OffThreshold = lowEndLimit; % offset threshold
                            tempData(1:onsetInd(1,iTrial)) = OffThreshold;
                            tempIndAboveThresh = find(tempData > OffThreshold); % find index where data of iTrial above offset threshold for SP
                            logThresh = (tempData > OffThreshold);
                        elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                            OffThreshold = lowEndLimit; % onset threshold
                            tempData(1:onsetInd(1,iTrial)) = OffThreshold;
                            tempIndAboveThresh = find(tempData < OffThreshold); % find index where data of iTrial below onset threshold for MEP Latency
                            logThresh = (tempData < OffThreshold);
                        end


                        if isempty(tempIndAboveThresh) == 1               % if no point found
                            %                         msgbox ('Please reset offset threshold')
                            app.WastedTrials = app.WastedTrials + 1;
                            onsetInd(1,iTrial) = 1+app.WastedTrials;
                            offsetInd(1,iTrial) = 20+app.WastedTrials;
                            break;
                        end

                        if app.AlgorithmCheckBox.Value == 0

                            tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of 1:end-1 session above/below threshold

                            if length(tempIndAboveThresh) == length(tempData(onsetInd(1,iTrial)+1:end))  %  in case the threshold include every data point
                                offsetInd(1,iTrial) = length(tempData);
                                break;
                            end

                            if tempIndAboveThresh(1) == onsetInd(1,iTrial) + 1          % the rising edge between onset and offset threshold is included and only this period meet the requirement
                                tempIndAboveThresh(1:tempBreakPointInd(1)) = [];      % delete this period
                                tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of 1:end-1 session above/below threshold
                            end


                            if isempty(tempBreakPointInd) == 1
                                tempBreakPointInd = 1;                           % only one peck detected, then the first point
                                tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd);       % duration of the single session
                                if ans>minDurPointsOff
                                    offsetInd(1,iTrial) = tempIndAboveThresh(1);
                                else
                                    %                                 msgbox ('the duration of signal meet requirement is too short')
                                    app.WastedTrials = app.WastedTrials + 1;
                                    onsetInd(1,iTrial) = 1+app.WastedTrials;
                                    offsetInd(1,iTrial) = 20+app.WastedTrials;
                                    break;
                                end
                            else            % more than one session found
                                tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start index of every session above/below threshold
                                tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];
                                tempEndInd-tempStartInd+1;          % save the duration of every session above/below threshold
                                tempIndMeetReq = find(ans>minDurPointsOff);     % The difference of two consecutive tempBreakPointInd points refers to
                                % the duration of the points above/below threshold.
                                if isempty(tempIndMeetReq) == 1
                                    %                             msgbox ('please reset onset thereshold')
                                    app.WastedTrials = app.WastedTrials + 1;
                                    onsetInd(1,iTrial) = 1+app.WastedTrials;
                                    offsetInd(1,iTrial) = 20+app.WastedTrials;
                                    break;
                                else
                                    offsetInd(1,iTrial) = tempStartInd(tempIndMeetReq(1));
                                end
                            end
                            break;
                        elseif app.AlgorithmCheckBox.Value == 1

                            window_sum = movsum(logThresh,[0 minDurPointsOff]);
                            tempOffset = find(logThresh & (window_sum >= minDurPointsOff/2),1,'first');

                            if isempty(tempOffset) == 1
                                %                             msgbox ('please reset onset thereshold')
                                app.WastedTrials = app.WastedTrials + 1;
                                onsetInd(1,iTrial) = 1+app.WastedTrials;
                                offsetInd(1, iTrial) = 20+app.WastedTrials;
                            else
                                offsetInd(1,iTrial) = find(logThresh & (window_sum >= minDurPointsOff/2),1,'first');
                            end

                            break;

                            %                             for iWindow = 1:length(tempIndAboveThresh)
                            %                                 if tempIndAboveThresh(iWindow)>= length(tempData) - minDurPointsOff
                            %                                     break;
                            %                                 end
                            %
                            %                                 find(tempIndAboveThresh(iWindow)<=tempIndAboveThresh<= tempIndAboveThresh(iWindow)+minDurPointsOff);
                            %
                            %                                 if length(ans)>=minDurPointsOff/2
                            %                                     offsetInd(1,iTrial) = tempIndAboveThresh(iWindow);
                            %                                     break;
                            %                                 else
                            %                                     iWindow = iWindow+1;
                            %                                 end
                            %
                            %                             end
                            %                             break;
                        end
                    end

                    % other analysis, for every single trial
                    SPDur(1,iTrial) = abs(SPTime(onsetInd(1,iTrial))-SPTime(offsetInd(1,iTrial)))*1000;    % Silent Period duration (ms)
                    inverseAreaSP = trapz(SPData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)));

                    if strcmp(app.ListBox.Value, 'Silent Period')
                        spPercentDecrease(1,iTrial) = 100-(mean(SPData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)))/preStimMean(iTrial)*100); % percent of silent period mean value decreased compared with pre stimulation period
                        areaMeanSP = abs(preStimMean(iTrial)*length(onsetInd(1,iTrial):offsetInd(1,iTrial)));        % mean preStimulation times length of silent period duration equals the area
                        areaSP(1,iTrial) = (1-inverseAreaSP/areaMeanSP)*100;      % area of slient period unit
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        %mepAmp(1,iTrial) = (max(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)))/preStimMean(iTrial))*100;
                        mepAmp(1,iTrial) = max(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial))) - min(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)));
                        areaSP(1,iTrial) = inverseAreaSP;      % area of MEP
                    end

                end
            end

            % show wasted trials number if exist
            if app.WastedTrials >0
                app.TrialsnotfoundEditField.Value = app.WastedTrials;
                app.TrialsnotfoundEditField.Visible = 1;
                app.TrialsnotfoundEditFieldLabel.Visible = 1;
            else
                app.TrialsnotfoundEditField.Value = 0;
                app.TrialsnotfoundEditField.Visible = 0;
                app.TrialsnotfoundEditFieldLabel.Visible = 0;
            end

            %Set Output Mean and STD Display

            if app.uVButton.Value == true
                app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value*1000;
                app.PreStimStdEditField.Value = app.PreStimStdEditField.Value*1000;
            end
            if app.mVButton.Value == true
                app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value;
                app.PreStimStdEditField.Value = app.PreStimStdEditField.Value;
            end
            if app.VButton.Value == true
                app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value/1000;
                app.PreStimStdEditField.Value = app.PreStimStdEditField.Value/1000;
            end

            app.PreStimMean = preStimMean;       % for patch use


            if size(SPData,1) == 1
                app.NALabel.Text = num2str(SPTime(onsetInd)*1000);         % the unit for onset time is ms
                app.NALabel_2.Text = num2str((SPTime(offsetInd)-SPTime(onsetInd))*1000);
                if strcmp(app.ListBox.Value, 'Silent Period')
                    app.NALabel_3.Text = num2str(spPercentDecrease);
                elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    if app.uVButton.Value == true
                        mepAmp = mepAmp*1000;
                        app.DecreaseLabel.Text = 'Amplitude (uV)';
                    end
                    if app.mVButton.Value == true
                        mepAmp = mepAmp;
                        app.DecreaseLabel.Text = 'Amplitude (mV)';
                    end
                    if app.VButton.Value == true
                        mepAmp = mepAmp/1000;
                        app.DecreaseLabel.Text = 'Amplitude (V)';
                    end
                    app.NALabel_3.Text = num2str(mepAmp);
                end
                app.NALabel_4.Text = num2str(areaSP);
            else
                app.NALabel.Text = 'N/A';
                app.NALabel_2.Text = 'N/A';
                app.NALabel_3.Text = 'N/A';
                app.NALabel_4.Text = 'N/A';
            end

            app.onsetLine = zeros(1,length(onsetInd));      % save the onset verticle line
            app.offsetLine = zeros(1,length(onsetInd));
            %             tempYInd1 = zeros(1,length(onsetInd));      % size of onsetInd equals offsetInd
            %             tempYInd2 = max(max(SPData))*ones(1,length(onsetInd)); % in case multiple trials, two max()


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % plot onset and offset
            hold(app.UIAxes,'on')
            for iTrial = 1:length(onsetInd)
                if iTrial == 1
                    legend(app.UIAxes,'AutoUpdate','on');
                else
                    legend(app.UIAxes,'AutoUpdate','off');
                end
                app.onsetLine(iTrial) = xline(app.UIAxes,SPTime(onsetInd(iTrial)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'onset');
                %Deriv
                %app.onsetLine(iTrial) = xline(app.UIAxes,mep_start,'g','LineStyle','--',"LineWidth",2,'DisplayName', 'onset');
                %%%%%%%%%%%
                app.offsetLine(iTrial) = xline(app.UIAxes,SPTime(offsetInd(iTrial)),'b','LineStyle','--',"LineWidth",2,'DisplayName', 'offset');
            end
            hold(app.UIAxes,'off')
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % save the variables
            app.dataOut = [];
            app.dataOut.fileName = app.FileName.Value;
            app.dataOut.AnalyzeOption = app.ListBox.Value;
            app.dataOut.mPreStim = preStimMean;
            app.dataOut.SPOnset=SPTime(onsetInd)*1000;
            app.dataOut.SPDur=SPDur;
            app.dataOut.AreaSP=areaSP;
            if strcmp(app.ListBox.Value, 'Silent Period')
                app.dataOut.spPercentDecrease = spPercentDecrease;
            elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                app.dataOut.mepAmp = mepAmp;
            end
            app.dataOut.ChNum=app.ChannelSpinner.Value;
            if ~isempty(app.MultiChanListBox.Value)
                if app.RemoveCheckBox.Value == 0
                    app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                else
                    app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                end
            else
                app.dataOut.TrialNum=app.TrialSpinner.Value;
            end

            app.dataOut.sdOn=app.SDonsetEditField.Value;
            app.dataOut.sdOff=app.SDoffsetEditField.Value;
            app.dataOut.minDurSP=app.MinDurmsEditField.Value;   %ms
            app.dataOut.maxDurSP=app.MinDurmsEditField_2.Value;   %ms
            app.dataOut.startTimeSP=app.StartTimemsEditField.Value;
            app.dataOut.preStimDur=app.EditField_3.Value - app.PreStimPeriodmsEditField.Value;   %ms
            app.dataOut.preStimStartTime = app.PreStimPeriodmsEditField.Value;        %ms
            app.dataOut.LPEnvelopHz = app.HzEditField_4.Value;
            app.dataOut.OR=app.overRideMark;                % if override, then OR = 1;
            app.dataOut.MCD = app.MCDCheckBox.Value;
            app.dataOut.MCDconstant = app.MCDConstantEditField.Value;
            t = [datetime('now')];
            app.dataOut.Date = datestr(t);
            app.dataOut.Unit = app.unit;
            app.dataOut.SampleRate = sampleRate;

            if app.RemoveCheckBox.Value == 1
                app.dataOut.RemovedCount = removed;
                app.dataOut.LowThresh = app.LowThreshEditField.Value;
                app.dataOut.HighThresh = app.HighThreshEditField.Value;
            else
                app.dataOut.RemovedCount = 'NA';
                app.dataOut.LowThresh = 'NA';
                app.dataOut.HighThresh = 'NA';
            end


            app.UIAxes.Toolbar.Visible = 'off '; %Resets toolbar
            app.UIAxes.Toolbar.Visible = 'on ';  %Resets toolbar
            fprintf('Processing finished');
            app.Processing.Text = 'Processing finished';
            app.Processing.BackgroundColor = 'green';
            toc;

        end

        % Size changed function: AnalysisOptionPanel
        function AnalysisOptionPanelSizeChanged(app, event)
            position = app.AnalysisOptionPanel.Position;

        end

        % Value changed function: ListBox
        function ListBoxValueChanged3(app, event)
            value = app.ListBox.Value;

            % if select Silent Period
            if strcmp(app.ListBox.Value, 'Silent Period')
                app.Panel_4.Visible = 1;
                app.RestingMEPPanel.Visible = 0;
                app.EMGAnalysisPanel.Visible = 0;
                app.SilentPeriodPanel.Visible = 1;
                app.MCDCheckBox.Visible = 1;


                app.SilentPeriodPanel.Title = 'Silent Period';
                app.DecreaseLabel.Text = '% Decrease';
                if size(app.DataToPlot,1) == 1
                    app.AnalyzetheAverageCheckBox.Enable = 0;
                    app.AnalyzetheAverageCheckBox.Value = 0;

                else
                    app.AnalyzetheAverageCheckBox.Enable = 1;

                end

                % if select Motor Evoke Potential
            elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                app.Panel_4.Visible = 1;
                app.RestingMEPPanel.Visible = 0;
                app.EMGAnalysisPanel.Visible = 0;
                app.SilentPeriodPanel.Visible = 1;
                app.MCDCheckBox.Visible = 1;

                app.SilentPeriodPanel.Title = 'Active MEP';
                app.DecreaseLabel.Text = 'Amplitude (mV)';
                if size(app.DataToPlot,1) == 1
                    app.AnalyzetheAverageCheckBox.Enable = 0;
                    app.AnalyzetheAverageCheckBox.Value = 0;

                else
                    app.AnalyzetheAverageCheckBox.Enable = 1;
                end

            elseif strcmp(app.ListBox.Value, 'Resting MEP')
                app.Panel_4.Visible = 0;
                app.RestingMEPPanel.Visible = 1;
                app.EMGAnalysisPanel.Visible = 0;
                app.SilentPeriodPanel.Visible = 0;
                app.MCDCheckBox.Visible = 1;

                if size(app.DataToPlot,1) == 1
                    app.AnalyzetheAverageCheckBox_2.Enable = 0;
                    app.AnalyzetheAverageCheckBox_2.Value = 0;

                else
                    app.AnalyzetheAverageCheckBox_2.Enable = 1;
                end

            elseif strcmp(app.ListBox.Value, 'EMG Burst Onset')
                app.Panel_4.Visible = 0;
                app.RestingMEPPanel.Visible = 0;
                app.EMGAnalysisPanel.Visible = 1;
                app.SilentPeriodPanel.Visible = 0;
            else
                app.Panel_4.Visible = 0;
                app.SilentPeriodPanel.Visible = 0;
            end

            if app.AnalyzetheAverageCheckBox.Value == 1
                app.ClearBackgroundLinesCheckBox.Visible = 1;
            else
                app.ClearBackgroundLinesCheckBox.Visible = 0;
                app.ClearBackgroundLinesCheckBox.Value = 0;
            end

            if app.MCDCheckBox.Value == 1
                app.MCDConstantEditField.Visible = 1;
                app.MCDConstantEditFieldLabel.Visible = 1;
                app.SDonsetEditField.Enable = 0;
                app.SDoffsetEditField.Enable = 0;
            else
                app.MCDConstantEditField.Visible = 0;
                app.MCDConstantEditField.Value = 2.66;   %2.66 is the default value
                app.MCDConstantEditFieldLabel.Visible = 0;
                app.SDonsetEditField.Enable = 1;
                app.SDoffsetEditField.Enable = 1;
            end

            if app.LowPassEnvelopeCheckBox.Value == 0
                app.HzEditField_4.Enable = 0;
            else
                app.HzEditField_4.Enable = 1;
            end
        end

        % Value changed function: AnalyzetheAverageCheckBox
        function AnalyzetheAverageCheckBoxValueChanged(app, event)
            value = app.AnalyzetheAverageCheckBox.Value;
            if app.AnalyzetheAverageCheckBox.Value == 1
                app.ClearBackgroundLinesCheckBox.Visible = 1;
            else
                app.ClearBackgroundLinesCheckBox.Visible = 0;
                app.ClearBackgroundLinesCheckBox.Value = 0;
            end
        end

        % Value changed function: LowPassEnvelopeCheckBox
        function LowPassEnvelopeCheckBoxValueChanged(app, event)
            value = app.LowPassEnvelopeCheckBox.Value;

            if app.LowPassEnvelopeCheckBox.Value == 0
                app.HzEditField_4.Enable = 0;
                app.HzEditField_4.Value = 0;
            else
                app.HzEditField_4.Enable = 1;
            end
        end

        % Value changed function: PreStimPeriodmsEditField
        function PreStimPeriodmsEditFieldValueChanged(app, event)
            value = app.PreStimPeriodmsEditField.Value;

        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;

        end

        % Button pushed function: OverrideButton
        function OverrideButtonPushed(app, event)
            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;

            if app.RemoveCheckBox.Value == 1 || app.RemoveCheckBox.Value == 0
                AnalyzeButtonPushed(app, event) %Analyze and plot - allows for plotting the retained trials prior to override
            else
            end

            if ishghandle(app.onsetLine)~=0
                delete(app.onsetLine);
            end
            if ishghandle(app.offsetLine)~=0
                delete(app.offsetLine);
            end

            if ishghandle(app.OnsetThrePlot)~=0
                delete(app.OnsetThrePlot);  % Contains threshold onset plot
            end
            if ishghandle(app.OffsetThrePlot)~=0
                delete(app.OffsetThrePlot);  % Contains threshold onset plot
            end
            if ishghandle(app.PreMeanPlot)~=0
                delete(app.PreMeanPlot);  % Contains threshold onset plot
            end
            app.overRideMark = 1;           % if override button is clicked
            app.overRideInd = [];           % save the override onset and offset index
            app.MCDCheckBox.Value = 0;
            app.UIFigure.Pointer = 'crosshair';
            hold(app.UIAxes,'on');
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)

            %
            %             set(app.UIAxes,'WindowButtonDownFcn',  @mouseDown);
            %              set(app.UIAxes,'WindowButtonMotionFcn',@mouseMove);
            %              set(app.UIAxes,'WindowButtonUpFcn',    @mouseUp);

            %              hLineToDrag = [];
            %
            %
            %             function mouseDown(hObject,~)
            %
            %                 % is the mouse down event within the axes?
            %                 if IsCursorInControl(hObject, app.UIAxes)
            %
            %                     currentPoint   = get(hAxes,'CurrentPoint');
            %                     xCurrentPoint  = currentPoint(2,1);
            %
            %                     for k=1:length(app.onsetLine)
            %
            %                         xVertLineCoord = get(app.onsetLine(k),'XData');
            %
            %                         if abs(xCurrentPoint - xVertLineCoord(1)) < 0.1
            %                             hLineToDrag = app.onsetLine(k);
            %                             break;
            %                         end
            %                     end
            %                 end
            %             end


            %%%%%% Override in MEP and SP detection
            if app.overRideMark == 1  % if hit override
                % call the mouse

                currPt = app.UIAxes.CurrentPoint;

                app.overRideInd = [app.overRideInd,currPt(1,1)];
                overRideOnset = app.overRideInd(1);           % overRideOnset and overRideOffset here are time not indext, need to convert to index
                if length(app.overRideInd) == 2
                    overRideOffset = app.overRideInd(2);
                    app.UIFigure.Pointer = 'arrow';
                end
                if length(app.overRideInd) > 2  % in case two more than two clicks occur
                    msgbox('please re-override')
                end
                % plot
                %hold(app.UIAxes,'on');
                if length(app.overRideInd) == 1  % the first line is the onset
                    app.onsetLine = xline(app.UIAxes,overRideOnset,'r','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset');
                end
                if length(app.overRideInd)>=2 % the second line is the offset
                    app.offsetLine = xline(app.UIAxes,overRideOffset,'b','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOffset');
                end
                hold(app.UIAxes,'off');
                legend(app.UIAxes)
                % calculate onset and offset after override click
                if length(app.overRideInd)>=2
                    % define parameters
                    SPData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
                    tempRemovedDataToPlot = app.DataToPlot;            %retains individual trials after trials are removed
                    tempRemovedMeanDataToPlot = app.meanData;          %retains average signal after trials are removed


                    if app.ProcessInd == 1
                        NoRecData =  app.LPData;                           % Non rectify data saved
                    else
                        NoRecData =  SPData;
                    end
                    SPTime = app.TimeToPlot;
                    if app.ProcessInd == 1
                        sampleRate = app.dwnSampleRate;
                    else
                        sampleRate = app.origSampleRate;
                    end
                    preStimDurInd = find(SPTime >= (app.PreStimPeriodmsEditField.Value/1000) & SPTime<= app.EditField_3.Value/1000);
                    %                   minDurPoints = round(app.MinDurmsEditField.Value/1000*sampleRate);
                    %                   maxDurPoints = round(app.MinDurmsEditField_2.Value/1000*sampleRate);

                    % define pre-stimulation data in case multiple trials selected
                    tempData = SPData;
                    tempData = num2cell(tempData,2);
                    preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
                    preStimData = cell2mat(preStimData);

                    if app.RemoveCheckBox.Value == 1 %removes trials based on pre-difined thresholds
                        % find average and std of the Pre-stimulation Period
                        preStimMean = sum(preStimData')/(size(preStimData,2));
                        preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                        retainIDX = (find(preStimMean > app.LowThresh.Value & preStimMean < app.HighThresh.Value)); %Find IDX inbetween Low and HighThresh
                        removeIDX = (find(preStimMean < app.LowThresh.Value | preStimMean > app.HighThresh.Value)); %Find IDX outside Low and HighThresh


                        if isempty(app.conditionInd) ~= 1
                            app.conditionIndRmIDX = app.conditionInd;               %!Changes conditionInd to remove trials for group trial analysis
                            for i = 1:length(app.conditionIndRmIDX)                 %|
                                a = cell2num(app.conditionIndRmIDX(i));             %|
                                app.conditionIndRmIDX(i) = {setdiff(a, removeIDX)}; %|
                            end                                                     %|
                            %|


                            N = cellfun(@numel,app.conditionIndRmIDX);             %|
                            app.conditionIndRmIDX = mat2cell(1:sum(N),1,N);        %! Changes conditionInd to remove trials for group trial analysis                                                    %!
                        end

                        removed = length(preStimMean)-length(retainIDX); %Counts the number of removed trials
                        removed = num2str(removed);
                        app.RemovedCount.Text = removed; % Displays number of removed trials
                        SPData = SPData(retainIDX,:); %Remove Rect IDX that are not within threshold
                        NoRecData = NoRecData(retainIDX,:);  %Remove NonRect IDX that are not within treshold
                        tempMultiChan = app.MultiChan(retainIDX); %retains trial numbers after removal
                        tempRemovedDataToPlot = SPData; %retains individual trials after trials are removed
                        tempRemovedMeanDataToPlot = sum(SPData)/size(SPData,1); %retains average signal after trials are removed
                        tempData = SPData;
                        tempData = num2cell(tempData,2);
                        preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false); %Redifine prestimData based on removed trials
                        preStimData = cell2mat(preStimData);

                        if str2num(removed) == length(preStimMean)
                            msgbox('No Trials Retianed')
                            return;
                        end
                    end

                    % analyze the average
                    if app.AnalyzetheAverageCheckBox.Value == 1
                        SPData = sum(SPData)/size(SPData,1);
                        preStimData = sum(preStimData)/size(preStimData,1);
                        NoRecData = sum(NoRecData)/size(NoRecData,1);
                    end

                    preStimMean = sum(preStimData')/(size(preStimData,2));
                    preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                    app.PreStimMeanEditField.Value = double(mean(preStimMean)); %Displays 1) Mean of all Single Trial 2) Mean of average
                    app.PreStimStdEditField.Value = double(mean(preStimStd));   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD

                    %Set Output Mean and STD Display
                    if app.uVButton.Value == true
                        app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value*1000;
                        app.PreStimStdEditField.Value = app.PreStimStdEditField.Value*1000;
                    end
                    if app.mVButton.Value == true
                        app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value;
                        app.PreStimStdEditField.Value = app.PreStimStdEditField.Value;
                    end
                    if app.VButton.Value == true
                        app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value/1000;
                        app.PreStimStdEditField.Value = app.PreStimStdEditField.Value/1000;
                    end

                    % find onset and offset
                    find(SPTime>=overRideOnset);
                    onsetInd(1,1:size(SPData,1)) = ans(1);
                    find(SPTime>=overRideOffset);
                    offsetInd(1,1:size(SPData,1)) = ans(1);

                    % other analysis
                    % for active MEP and SP
                    for iTrial = 1:size(SPData,1)
                        SPDur(1,iTrial) = abs((SPTime(onsetInd(1,iTrial))-SPTime(offsetInd(1,iTrial))))*1000;    % Silent Period duration (ms)
                        inverseAreaSP = trapz(SPData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)));

                        if strcmp(app.ListBox.Value, 'Silent Period')
                            spPercentDecrease(1,iTrial) = 100-(mean(SPData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)))/preStimMean(iTrial)*100); % percent of silent period mean value decreased compared with pre stimulation period
                            areaMeanSP = abs(preStimMean(iTrial)*length(onsetInd(1,iTrial):offsetInd(1,iTrial)));        % mean preStimulation times length of silent period duration equals the area
                            areaSP(1,iTrial) = (1-inverseAreaSP/areaMeanSP)*100;      % area of slient period
                        elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                            %mepAmp(1,iTrial) = (max(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)))/preStimMean(iTrial))*100;
                            mepAmp(1,iTrial) = max(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial))) - min(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)));
                            areaSP(1,iTrial) = inverseAreaSP;      % area of MEP
                        end
                    end

                    %set output
                    if size(SPData,1) == 1
                        app.NALabel.Text = num2str(SPTime(onsetInd)*1000);         % the unit for onset time is ms
                        app.NALabel_2.Text = num2str((SPTime(offsetInd)-SPTime(onsetInd))*1000);
                        if strcmp(app.ListBox.Value, 'Silent Period')
                            app.NALabel_3.Text = num2str(spPercentDecrease);
                        elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')

                            if app.uVButton.Value == true
                                mepAmp = mepAmp*1000;
                                app.DecreaseLabel.Text = 'Amplitude (uV)';
                            end
                            if app.mVButton.Value == true
                                mepAmp = mepAmp;
                                app.DecreaseLabel.Text = 'Amplitude (mV)';
                            end
                            if app.VButton.Value == true
                                mepAmp = mepAmp/1000;
                                app.DecreaseLabel.Text = 'Amplitude (V)';
                            end
                            app.NALabel_3.Text = num2str(mepAmp);

                        end
                        app.NALabel_4.Text = num2str(areaSP);
                    else
                        app.NALabel.Text = 'N/A';
                        app.NALabel_2.Text = 'N/A';
                        app.NALabel_3.Text = 'N/A';
                        app.NALabel_4.Text = 'N/A';
                    end


                    % save the variables
                    app.dataOut = [];
                    app.dataOut.fileName = app.FileName.Value;
                    app.dataOut.AnalyzeOption = app.ListBox.Value;
                    app.dataOut.mPreStim = preStimMean;
                    app.dataOut.SPOnset=SPTime(onsetInd)*1000;
                    app.dataOut.SPDur=SPDur;
                    app.dataOut.AreaSP=areaSP;
                    if strcmp(app.ListBox.Value, 'Silent Period')
                        app.dataOut.spPercentDecrease = spPercentDecrease;
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        app.dataOut.mepAmp = mepAmp;
                    end
                    app.dataOut.ChNum=app.ChannelSpinner.Value;
                    if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                        if app.RemoveCheckBox.Value == 0
                            app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                        else
                            app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                        end
                    else
                        app.dataOut.TrialNum=app.TrialSpinner.Value;
                    end

                    app.dataOut.sdOn=app.SDonsetEditField.Value;
                    app.dataOut.sdOff=app.SDoffsetEditField.Value;
                    app.dataOut.minDurSP=app.MinDurmsEditField.Value;
                    app.dataOut.maxDurSP=app.MinDurmsEditField_2.Value;
                    app.dataOut.startTimeSP=app.StartTimemsEditField.Value;
                    app.dataOut.preStimDur=app.EditField_3.Value - app.PreStimPeriodmsEditField.Value;
                    app.dataOut.preStimStartTime = app.PreStimPeriodmsEditField.Value;
                    app.dataOut.LPEnvelopHz = app.HzEditField_4.Value;
                    app.dataOut.OR=app.overRideMark;                % if override, then OR = 1;
                    app.dataOut.MCD = app.MCDCheckBox.Value;
                    app.dataOut.MCDconstant = app.MCDConstantEditField.Value;

                    t = [datetime('now')];
                    app.dataOut.Date = datestr(t);
                    app.dataOut.Unit = app.unit;
                    app.dataOut.SampleRate = sampleRate;

                    if app.RemoveCheckBox.Value == 1
                        app.dataOut.RemovedCount = removed;
                        app.dataOut.LowThresh = app.LowThreshEditField.Value;
                        app.dataOut.HighThresh = app.HighThreshEditField.Value;
                    else
                        app.dataOut.RemovedCount = 'NA';
                        app.dataOut.LowThresh = 'NA';
                        app.dataOut.HighThresh = 'NA';
                    end
                    app.overRideMark = 0;
                end
            end

            %%%%%% Override in EMG burst detection
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.EMGBurstoverRideMark == 1   % if hit override
                data = app.RectifyData;                    % app.RectifyData saves the processed data
                time = app.DelayTime;
                sampleRate = app.dwnSampleRate;

                cla(app.UIAxes);
                % plot in mV first. Then change the unit accordingly
                legend(app.UIAxes,'AutoUpdate','off');
                if app.ClearBackgroundLinesCheckBox_3.Value == true
                else
                    plot(app.UIAxes,time,data,'Color','k');
                end


                if app.AnalyzetheAverageCheckBox_3.Value == 1
                    if size(data,1)>1
                        hold(app.UIAxes,'on');
                        data = sum(data)/size(data,1);
                        AveragePlot = plot(app.UIAxes,time,data,'Color','k',"LineWidth",2);
                    end
                    app.ClearBackgroundLinesCheckBox_3.Visible = 1;
                else %  clear background lines can only be checked when take average is checked
                    app.ClearBackgroundLinesCheckBox_3.Visible = 0;
                    app.ClearBackgroundLinesCheckBox_3.Value = 0;
                end
                if size(data,1)>1
                    msgbox('Only for single trial or average data')
                    return;
                end

                currPt = app.UIAxes.CurrentPoint;

                app.overRideInd = [app.overRideInd,currPt(1,1)];
                overRideOnset = app.overRideInd(1);           % overRideOnset and overRideOffset here are time not indext, need to convert to index
                if length(app.overRideInd) == 1
                    overRideOffset = app.overRideInd(1);
                    app.UIFigure.Pointer = 'arrow';
                end
                if length(app.overRideInd) > 1  % in case two more than two clicks occur
                    msgbox('please re-override')
                end
                % plot
                hold(app.UIAxes,'on');
                app.onsetLine = xline(app.UIAxes,overRideOnset,'k','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset');
                app.OnsetmsEditField.Value = overRideOnset*1000;
                hold(app.UIAxes,'off');
                legend(app.UIAxes)


                % save variables
                app.dataOut = [];
                app.dataOut.fileName = app.FileName.Value;
                app.dataOut.ChNum=app.ChannelSpinner.Value;
                if ~isempty(app.MultiChanListBox.Value)
                    app.dataOut.TrialNum=app.MultiChan;
                else
                    app.dataOut.TrialNum=app.TrialSpinner.Value;
                end
                app.dataOut.AnalyzeOption = app.ListBox.Value;
                app.dataOut.OR=app.EMGBurstoverRideMark;                % if override, then OR = 1;
                app.dataOut.lowPassFreqZ = 'N/A';
                app.dataOut.Zsd = 'N/A';
                app.dataOut.EMGburstOn = overRideOnset*1000;
                app.dataOut.BurstMethod = 'N/A';
                app.dataOut.SampleRate = sampleRate;
                app.dataOut.StartTime = 'N/A';
                t = [datetime('now')];
                app.dataOut.Date = datestr(t);
                app.dataOut.modulationRatio = 'N/A';
                app.dataOut.peakEnv = 'N/A';
                app.dataOut.emgOnset = 'N/A';
                app.dataOut.timeToPeak = 'N/A';

                app.EMGBurstoverRideMark = 0;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % EMG Envelope
            if app.EMGAnalysisOR == 1


                %Define Data
                if app.LowPassEnvelopeCheckBox_3.Value == 0
                    EMGData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
                    EMGTime = app.TimeToPlot;
                else
                    EMGData = app.ENVDataToPlot;
                    EMGTime = app.TimeToPlot;
                end

                %determine sample Rate
                if app.ProcessInd == 1
                    sampleRate = app.dwnSampleRate;
                else
                    sampleRate = app.origSampleRate;
                end

                % determine if it is multiple trials
                if size(EMGData,1) == 1
                    singleTrial = 1;
                else
                    singleTrial = 0;
                end

                preStimDurInd = find(EMGTime >= (app.PreStimPeriodmsEditField.Value/1000) & EMGTime<= app.EditField_3.Value/1000);
                minDurPointsOn = round(app.MinDurmsEditField_3.Value/1000*sampleRate);    % min duration to determine onset


                tempData = EMGData;       % EMGData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
                tempData = num2cell(tempData,2);
                preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
                preStimData = cell2mat(preStimData);

                % calculate average
                if app.AnalyzetheAverageCheckBox_3.Value == 1
                    EMGData = sum(EMGData)/size(EMGData,1);
                    preStimData = sum(preStimData)/size(preStimData,1);
                    app.ClearBackgroundLinesCheckBox.Visible = 1;
                else %  clear background lines can only be checked when take average is checked
                    app.ClearBackgroundLinesCheckBox.Visible = 0;
                    app.ClearBackgroundLinesCheckBox.Value = 0;
                end


                % find average and std of the Pre-stimulation Period
                preStimMean = mean(preStimData);
                preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                app.PreStimMeanEditField.Value = double(mean(preStimMean)); %Displays 1) Mean of all Single Trial 2) Mean of average
                app.PreStimStdEditField.Value = double(mean(preStimStd));   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % figure plot
                cla(app.UIAxes);
                % plot in mV first. Then change the unit accordingly
                legend(app.UIAxes,'AutoUpdate','off');
                plot(app.UIAxes,app.TimeToPlot,EMGData,'Color','k',"LineWidth",2);
                if app.AnalyzetheAverageCheckBox.Value == true     % here SP data is the mean of all trials
                    AveragePlot = plot(app.UIAxes,app.TimeToPlot,EMGData,'Color','k',"LineWidth",2);
                end
                hold(app.UIAxes,'on')

                % draw prestimulation mean
                if size(EMGData,1) == 1 % exclude the situation that multiple trials selected but dont analyze average
                    app.PreMeanPlot = plot(app.UIAxes,app.TimeToPlot,repmat(preStimMean,1,length(app.TimeToPlot)),'Color','k','DisplayName', 'PreStimMean');
                end

                StartTimeInd = find(app.TimeToPlot <= app.StartTimemsEditField_4.Value/1000);

                app.On = xline(app.UIAxes,EMGTime(StartTimeInd(end)),'k','LineStyle','--',"LineWidth",1,'DisplayName', 'Start Time');
                hold(app.UIAxes,'off');

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % find onset, if single trial
                if singleTrial == 1
                    lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField_2.Value;
                    %
                    hold(app.UIAxes,'on')
                    app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');
                    %
                    hold(app.UIAxes,'off');
                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end            % change the unit
                    ButtonGroupUnitsSelectionChanged(app, event)
                end
                %
                %                 OnThreshold = lowEndLimit; % onset threshold
                %                 StartTimeInd = find(EMGTime <= app.StartTimemsEditField_4.Value/1000);
                %                 EMGData(1:StartTimeInd(end)-1) = OnThreshold;
                %                 tempIndAboveThresh = find(EMGData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency
                %
                %
                %                 if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                %                     msgbox ('no trial found')
                %                     return;
                %                 end
                %
                %                 tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold
                %
                %                 if length(tempIndAboveThresh) == length(EMGData(StartTimeInd(end)-1:end))-1
                %                     onsetInd = StartTimeInd(end)+1;
                %                 elseif isempty(tempBreakPointInd) == 1
                %                     tempBreakPointInd = 1;                           % only one pick detected, then the first point
                %                     (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                %                     if ans>minDurPointsOn
                %                         onsetInd = tempIndAboveThresh(1);
                %                     else
                %                         msgbox ('no trial found')
                %                     end
                %                 else            % more than one session found
                %                     tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                %                     tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                %                     tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                %                     tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.
                %
                %                     if isempty(tempIndMeetReq) == 1
                %                         msgbox ('no trial found')
                %                         return;
                %                     else
                %                         onsetInd = tempStartInd(tempIndMeetReq(1));
                %                     end
                %                 end
                %             else
                %                 %%%%%%%%%%%%%%%%%%%%%%%%PUT IN MULTITRIALDATA
                %                 modulationRatio = zeros(1,size(EMGData,1));          % if choose multiple trials, onsetUbd, offsetInd, SPDur, areaSP and mepAmp saved but not displayed
                %                 peakEnv = zeros(1,size(EMGData,1));
                %                 emgOnset = zeros(1,size(EMGData,1));
                %
                %
                %                 for iTrial = 1:size(EMGData,1)
                %                     tempData = EMGData(iTrial,:);
                %
                %
                %                      lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField_2.Value;
                %
                %                 %hold(app.UIAxes,'on')
                %                 %app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');
                %
                %                 %hold(app.UIAxes,'off');
                %                 %app.tempH = [];
                %                 %h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                %                 %for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                %                 %    app.tempH(iLine,:) = h(iLine).YData(1,:);
                %                 %end            % change the unit
                %                 %ButtonGroupUnitsSelectionChanged(app, event)
                %
                %                 OnThreshold = lowEndLimit; % onset threshold
                %                 StartTimeInd = find(EMGTime <= app.StartTimemsEditField_4.Value/1000);
                %                 tempData(1:StartTimeInd(end)-1) = OnThreshold;
                %                 tempIndAboveThresh = find(tempData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency
                %
                %
                %                 if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                %                     msgbox ('no trial found')
                %                     return;
                %                 end
                %
                %                 tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold
                %
                %                 if length(tempIndAboveThresh) == length(tempData(StartTimeInd(end)-1:end))-1
                %                     onsetInd = StartTimeInd(end)+1;
                %                 elseif isempty(tempBreakPointInd) == 1
                %                     tempBreakPointInd = 1;                           % only one pick detected, then the first point
                %                     (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                %                     if ans>minDurPointsOn
                %                         onsetInd = tempIndAboveThresh(1);
                %                     else
                %                         msgbox ('no trial found')
                %                     end
                %                 else            % more than one session found
                %                     tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                %                     tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                %                     tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                %                     tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.
                %
                %                     if isempty(tempIndMeetReq) == 1
                %                         msgbox ('no trial found')
                %                         return;
                %                     else
                %                         onsetInd = tempStartInd(tempIndMeetReq(1));
                %                     end
                %                end
                %                end
                %            end

                currPt = app.UIAxes.CurrentPoint;
                app.overRideInd = [app.overRideInd,currPt(1,1)];
                onsetInd = app.overRideInd(1);
                onsetInd = find(EMGTime == round(onsetInd,3));
                app.UIFigure.Pointer = 'arrow';
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % plot onset and offset
                hold(app.UIAxes,'on')
                for iTrial = 1:length(onsetInd)
                    if iTrial == 1
                        legend(app.UIAxes,'AutoUpdate','on');
                    else
                        legend(app.UIAxes,'AutoUpdate','off');
                    end
                    app.onsetLine(iTrial) = xline(app.UIAxes,EMGTime(onsetInd(iTrial)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'onset');
                end


                ButtonGroupUnitsSelectionChanged(app, event)
                hold(app.UIAxes,'on')
                h = findobj(app.UIAxes,'Type','line');
                modRatioOn = EMGTime(onsetInd(iTrial));
                modRatioOff = EMGTime(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate));
                Lim = get(app.UIAxes,'Ylim');
                maxLim = max(Lim);
                minLim = min(Lim);
                v2 = [modRatioOn minLim;modRatioOff minLim;modRatioOff maxLim; modRatioOn maxLim];
                f2 = [1 2 3 4];
                app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','green','FaceAlpha',.1,'Parent',app.UIAxes,'DisplayName', 'ModRatio');   %denote the prestimulation period with patch                % patch the pre stimulation durations

                hold(app.UIAxes,'off')
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                if singleTrial == 1
                    modulationRatio = trapz(EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))))/(preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
                    [tempPeakEnv,idx] = max((EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate)))));
                    peakEnv = mean(EMGData((onsetInd+idx)-(round(10/1000*sampleRate)):(onsetInd+idx)+(round(10/1000*sampleRate))));
                    emgOnset = (EMGTime(onsetInd(iTrial)))*1000;
                    timeToPeak = idx/sampleRate*1000;

                    window = (EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))));

                    [rowsize colsize]=size(window);
                    for i=1:rowsize
                        [angfreq(i),power(i)]=medfreq(window);
                        % Convert normalized angular frequency to Hz
                        freq(i)=angfreq(i)/pi*(sampleRate/2);
                    end
                    medianPowerFreq=[freq'];
                    emgMean = mean(window)
                    emgRMS = rms(window)
                    app.MDFText.Text = num2str(medianPowerFreq,'%.1f');

                    %                 Y = fft(EMGData);
                    %                 P2 = abs(Y/colsize);
                    %                 P1 = P2(1:colsize/2+1);
                    %                 P1(2:end-1) = 2*P1(2:end-1);
                    %                 f = sampleRate*(0:(colsize/2))/colsize;
                    %
                    %                 figure;plot(f,P1)
                    %                 title('Single-Sided Amplitude Spectrum of X(t)')
                    %                 xlabel('f (Hz)')
                    %                 ylabel('|P1(f)|')
                    %                 title('Single-Sided Amplitude Spectrum of X(t)')


                    maxRatioOn = (EMGTime((onsetInd+idx)-(round(10/1000*sampleRate))));
                    maxRatioOff = (EMGTime((onsetInd+idx)+(round(10/1000*sampleRate))));
                    Lim = get(app.UIAxes,'Ylim');
                    maxLim = max(Lim);
                    minLim = min(Lim);
                    v2 = [maxRatioOn minLim;maxRatioOff minLim;maxRatioOff maxLim; maxRatioOn maxLim];
                    f2 = [1 2 3 4];
                    app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','red','FaceAlpha',.3,'Parent',app.UIAxes,'DisplayName', 'MaxPoint');   %denote the prestimulation period with patch                % patch the pre stimulation durations

                    app.NALabel_15.Text = num2str(modulationRatio,'%.2f');
                    app.NALabel_14.Text = num2str(emgOnset,'%.1f');
                    app.NALabel_16.Text = num2str(peakEnv,'%.2e');
                    app.NALabel_17.Text = num2str(timeToPeak);
                    app.dataOut.timeToPeak = timeToPeak;

                    app.dataOut.modulationRatio = modulationRatio;
                    app.dataOut.emgOnset = emgOnset;
                    app.dataOut.peakEnv = peakEnv;


                else
                    %medianPowerFreq = zeros(1,size(EMGData,1));
                    for i = 1:size(tempData,1)
                        window = (EMGData(i,(onsetInd(1)):(onsetInd(1)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                        [rowsize colsize]=size(window);
                        for a=1:rowsize
                            [angfreq(a),power(a)]=medfreq(window);
                            % Convert normalized angular frequency to Hz
                            freq(i)=angfreq(a)/pi*(sampleRate/2);
                        end
                        %medianPowerFreq=[freq'];


                        app.dataOut.modulationRatio = 'NA';
                        app.dataOut.emgOnset = 'NA';
                        app.dataOut.peakEnv = 'NA';
                        app.dataOut.timeToPeak = 'NA';

                    end


                end


                medianPowerFreq = [freq']

                hold(app.UIAxes,'on')
                h = findobj(app.UIAxes,'Type','line');


                app.dataOut.emgMean = emgMean;
                app.dataOut.emgRMS = emgRMS;
                app.dataOut.medianPowerFreq = medianPowerFreq;
                app.dataOut.lowPassFreqZ = app.HzEditField_6.Value;
                app.dataOut.SampleRate = sampleRate;
                app.dataOut.StartTime = app.StartTimemsEditField_4.Value;
                t = [datetime('now')];
                app.dataOut.Date = datestr(t);
                app.dataOut.OR= app.EMGAnalysisOR;                % if override, then OR = 1;
                app.dataOut.AnalyzeOption = app.ListBox.Value;
                app.dataOut.SampleRate = sampleRate;
                % app.dataOut.TrialNum=app.MultiChanListBox.Value;
                app.dataOut.fileName = app.FileName.Value;
                %app.dataOut.ChNum=app.ChannelSpinner.Value;


                app.dataOut.ChNum=app.ChannelSpinner.Value;
                if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                    if app.RemoveCheckBox.Value == 0
                        app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                    else
                        app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                    end
                else
                    app.dataOut.TrialNum=app.TrialSpinner.Value;
                end


                %%%%OutPut from Other Buttons

                app.dataOut.Zsd = 'N';
                app.dataOut.EMGburstOn = 'N';
                app.dataOut.BurstMethod = 'Envelope';

                %%%%%
                app.EMGAnalysisOR= 0;

            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % rMEP
            if app.rMEPoverrideMark == 1   % if hit override
                currPt = app.UIAxes.CurrentPoint;
                app.overRideInd = [app.overRideInd,currPt(1,1)];
                overRideOnset = app.overRideInd(1);           % overRideOnset and overRideOffset here are time not indext, need to convert to index
                if length(app.overRideInd) == 2
                    overRideOffset = app.overRideInd(2);
                    app.UIFigure.Pointer = 'arrow';
                end
                if length(app.overRideInd) > 2  % in case two more than two clicks occur
                    msgbox('please re-override')
                end

                % plot
                hold(app.UIAxes,'on');
                if length(app.overRideInd) == 1  % the first line is the onset
                    app.onsetLine = xline(app.UIAxes,overRideOnset,'r','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset');
                end
                if length(app.overRideInd)>=2 % the second line is the offset
                    app.offsetLine = xline(app.UIAxes,overRideOffset,'b','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOffset');
                end
                hold(app.UIAxes,'off');
                legend(app.UIAxes)

                % calculate onset and offset after override click
                if length(app.overRideInd)>=2
                    % define parameters
                    SPTime = app.TimeToPlot;
                    SPData = app.DataToPlot;
                    tempRemovedDataToPlot = app.DataToPlot;            %retains individual trials after trials are removed
                    tempRemovedMeanDataToPlot = app.meanData;          %retains average signal after trials are removed


                    if app.ProcessInd == 1
                        NoRecData =  app.LPData;                           % Non rectify data saved
                    else
                        NoRecData =  SPData;
                    end
                    if app.ProcessInd == 1
                        sampleRate = app.dwnSampleRate;
                    else
                        sampleRate = app.origSampleRate;
                    end


                    preStimDurInd = find(SPTime >= (app.PreStimPeriodmsEditField.Value/1000) & SPTime<= app.EditField_3.Value/1000);
                    minDurPointsOn = round(app.MinDurmsEditField.Value/1000*sampleRate);    % min duration to determine onset
                    minDurPointsOff = round(app.MinDurmsEditField_2.Value/1000*sampleRate);    % min duration to determine offset

                    % define pre-stimulation data in case multiple trials selected
                    tempData = SPData;       % SPData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
                    tempData = num2cell(tempData,2);
                    preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
                    preStimData = cell2mat(preStimData);

                    preStimMean = sum(preStimData')/(size(preStimData,2));
                    app.PreStimMean = preStimMean; %Nabila Fix
                    preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                    app.PreStimMeanEditField.Value = mean(preStimMean); %Displays 1) Mean of all Single Trial 2) Mean of average
                    app.PreStimStdEditField.Value = mean(preStimStd);   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD

                    if app.RemoveCheckBox_2.Value == 1 %removes trials based on pre-difined thresholds
                        % find average and std of the Pre-stimulation Period
                        preStimMean = sum(preStimData')/(size(preStimData,2));
                        preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                        retainIDX = (find(preStimMean > app.LowThreshRest.Value & preStimMean < app.HighThreshRest.Value)); %Find IDX inbetween Low and HighThresh
                        removed = length(preStimMean)-length(retainIDX); %Counts the number of removed trials
                        removed = num2str(removed);
                        app.RemovedCount_2.Text = removed; % Displays number of removed trials
                        SPData = SPData(retainIDX,:); %Remove Rect IDX that are not within threshold
                        NoRecData = NoRecData(retainIDX,:);  %Remove NonRect IDX that are not within treshold
                        tempMultiChan = app.MultiChan(retainIDX); %retains trial numbers after removal
                        tempRemovedDataToPlot = SPData; %retains individual trials after trials are removed
                        tempRemovedMeanDataToPlot = sum(SPData)/size(SPData,1); %retains average signal after trials are removed
                        tempData = SPData;
                        tempData = num2cell(tempData,2);
                        preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false); %Redifine prestimData based on removed trials
                        preStimData = cell2mat(preStimData);

                        if str2num(removed) == length(preStimMean)
                            msgbox('No Trials Retained')
                            return;
                        end
                    end

                    % calculate average
                    if app.AnalyzetheAverageCheckBox_2.Value == 1
                        SPData = sum(SPData)/size(SPData,1);
                        NoRecData = sum(NoRecData)/size(NoRecData,1);
                        app.ClearBackgroundLinesCheckBox_2.Visible = 1;
                    else %  clear background lines can only be checked when take average is checked
                        app.ClearBackgroundLinesCheckBox_2.Visible = 0;
                        app.ClearBackgroundLinesCheckBox_2.Value = 0;
                    end

                    % find onset and offset

                    find(SPTime>=overRideOnset);
                    emgon(1,1:size(SPData,1)) = ans(1);
                    find(SPTime>=overRideOffset);
                    emgoff(1,1:size(SPData,1)) = ans(1);

                    % set output
                    if size(SPData,1) == 1      % single trial or averaged
                        rMEPonsetTime = SPTime(emgon(1))*1000;      % onset time, only saves the first onset if multiple onsets detected
                        rMEPoffsetTime = SPTime(emgoff(1))*1000;
                        mepAmp = max(NoRecData(emgon(1):emgoff(1))) - min(NoRecData(emgon(1):emgoff(1)));
                        rMEPduration = rMEPoffsetTime - rMEPonsetTime;
                        inverseAreaSP = trapz(SPData(emgon(1):emgoff(1)));      % must be SPData, because lowPassEnv is only for onset and offset determination

                        app.NALabel_5.Text = num2str(rMEPonsetTime);      % onset time, only saves the first onset if multiple onsets detected
                        app.NALabel_6.Text = num2str(rMEPduration);  % duration
                        app.NALabel_7.Text = num2str(mepAmp);
                        app.NALabel_8.Text = num2str(inverseAreaSP);
                    elseif size(SPData,1)>1
                        for iTrial = 1:size(SPData,1)
                            rMEPonsetTime(1,iTrial) = SPTime(emgon(iTrial))*1000;      % onset time, only saves the first onset if multiple onsets detected
                            rMEPoffsetTime(1,iTrial) = SPTime(emgoff(iTrial))*1000;
                            mepAmp(1,iTrial) = max(NoRecData(iTrial,(emgon(iTrial):emgoff(iTrial)))) - min(NoRecData(iTrial,(emgon(iTrial):emgoff(iTrial))));
                            rMEPduration(1,iTrial) = rMEPoffsetTime(1,iTrial) - rMEPonsetTime(1,iTrial);
                            inverseAreaSP(1,iTrial) = trapz(SPData(iTrial,(emgon(iTrial):emgoff(iTrial))));      % must be SPData, because lowPassEnv is only for onset and offset determination
                        end

                        app.NALabel_5.Text = 'N/A';      % onset time, only saves the first onset if multiple onsets detected
                        app.NALabel_6.Text = 'N/A';  % duration
                        app.NALabel_7.Text = 'N/A';
                        app.NALabel_8.Text = 'N/A';

                    end

                    % save the variables
                    app.dataOut = [];
                    app.dataOut.fileName = app.FileName.Value;
                    app.dataOut.AnalyzeOption = app.ListBox.Value;
                    app.dataOut.rMEPonset= rMEPonsetTime;
                    app.dataOut.rMEPoffset = rMEPoffsetTime;
                    app.dataOut.rMEPdur=rMEPduration;
                    app.dataOut.Area=inverseAreaSP;
                    app.dataOut.mepAmp = mepAmp;
                    app.dataOut.lowPassFreqZ = 'N/A';
                    app.dataOut.Zsd = 'N/A';
                    app.dataOut.StartTime = 'N/A';
                    app.dataOut.mPreStim = preStimMean;
                    app.dataOut.ChNum=app.ChannelSpinner.Value;
                    if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                        if app.RemoveCheckBox_2.Value == 0
                            app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                        else
                            app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                        end
                    else
                        app.dataOut.TrialNum=app.TrialSpinner.Value;
                    end

                    app.dataOut.LPEnvelopHz = app.HzEditField_5.Value;
                    app.dataOut.OR=app.rMEPoverrideMark;                % if override, then OR = 1;
                    t = [datetime('now')];
                    app.dataOut.Date = datestr(t);
                    app.dataOut.Unit = app.unit;
                    app.dataOut.SampleRate = sampleRate;

                    if app.RemoveCheckBox_2.Value == 1
                        app.dataOut.RemovedCount = removed;
                        app.dataOut.LowThresh = app.LowThreshEditField_2.Value;
                        app.dataOut.HighThresh = app.HighThreshEditField_2.Value;
                    else
                        app.dataOut.RemovedCount = 'NA';
                        app.dataOut.LowThresh = 'NA';
                        app.dataOut.HighThresh = 'NA';
                    end


                    app.rMEPoverrideMark = 0;
                end

            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.Ztrans = 0;
            app.Prolific = 0;

            % define chosen data
            if app.ConditionsListBox.Visible == 1       % when condition applied
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';

                % find the order of the selected trial numbers
                tempOrder = cellfun(@(x) find(strcmp(app.MultiChanListBox.Items,x)), ...
                    app.MultiChanListBox.Value,'UniformOutput', false);
                tempOrder = cell2mat(tempOrder);
                if app.ConditionMeanCheckBox.Value == 1
                    app.chosenData = app.conditionMean(tempOrder,:);  % the chosen data is defined here, try to keep app.conditionData unchanged in case user click 'condition mean' back
                else
                    app.chosenData = app.conditionData(tempOrder,:);
                end
            else

                if ~isempty(app.MultiChanListBox.Value)          % multiple channel selected
                    app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';                              % convert selected channels from cell to num; str2double is not applicable here
                    app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,app.MultiChan)');
                    app.meanData = sum(app.chosenData)/size(app.chosenData,1);
                    if length(app.MultiChanListBox.Value) == 1
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.MultiChan};
                    end
                else
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                end
            end
            legend(app.UIAxes,'off');

            app.DataToPlot = app.chosenData;
            app.TimeToPlot = app.origTime;
            app.UnprocessedData = app.chosenData;

            % if data been pre-processed, plot by pre-process pannel
            if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                ProcessButtonPushed(app, event);
            else
                % change the scale accordingly
                % plot in mV first, the change the unit if need
                cla(app.UIAxes);
                app.AnalyzeInd = 0;
                app.Patch = [];

                if app.StackPlotCheckBox.Value == 1
                    %app.TimeToPlot=repmat(app.TimeToPlot,size(app.DataToPlot,1),1);
                    %multiwaveplot(app.DataToPlot)
                    %stackedplot(app.UIAxes,app.DataToPlot)
                else
                    plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
                end

                if ~isempty(app.MultiChanListBox.Value) && length(app.MultiChanListBox.Value) ~= 1

                    if app.ConditionMeanCheckBox.Value ~= 1
                        hold(app.UIAxes,'on')
                        %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                        hold(app.UIAxes,'off')
                    end


                end
                app.UIAxes.YLabel.String = app.mVButton.Text;
                app.tempH = [];
                h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                    app.tempH(iLine,:) = h(iLine).YData(1,:);
                end                    % change the unit

                ButtonGroupUnitsSelectionChanged(app, event)
            end

            if app.LowPassEnvelopeCheckBox_3.Value == 1
                LowPassEnvelopeCheckBox_3ValueChanged(app, event)
            end

        end

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
            app.reloadInd = 0;
            app.ProcessInd = 0;
            app.allData = [];
            app.time = [];
            %             app.TrialSelectPanel.Visible = 'off';
            app.ConditionsListBox.Visible = 0;  % condition list box visibility is an important sign of if conditions is applied

            %             app.ChannelSpinner.Value = 1;
            app.TrialSpinner.Value = 1;
            app.dataOut = [];
            app.overRideMark = [];
            app.EMGBurstoverRideMark = [];
            app.EMGAnalysisOR = [];
            app.tempH = 0;
            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.AnalyzeInd = 0;
            app.Patch = [];
            app.SampleRateHzEditField.Value = 0;
            app.Ztrans = 0;
            app.Prolific = 0;

            %             app.clickCount = 0;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;

        end

        % Button pushed function: SavecsvButton
        function SavecsvButtonPushed(app, event)
            % get the file name
            textFileName = app.EditField_5.Value;
            app.dataOut.Unit = app.unit;
            if isempty(app.EditField_5.Value) == 1
                msgbox('File Enter Data File Name')
                return;
            end
            if isempty(app.dataOut) == 1
                msgbox('No data to save')
                return;
            end

            % check if the data saving folder exists, if no creat one
            resultsDir = strcat(app.toolboxDir,'/Results');
            if exist(resultsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(resultsDir);
            end
            OldFolder = pwd;
            cd (resultsDir)


            %%Define Conditions and Trial number
            if ~isempty(app.ConditionsListBox.Value) == 1

                plotConditions = (app.ConditionsListBox.Value(:));
                %app.dataOut.conditionCate = plotConditions;
                conditionCate = [];
                for icondition = 1:size(plotConditions,1)
                    tempCate = plotConditions(icondition,:);
                    %tempCate = repmat(cellstr(tempCate),length(app.conditionInd{icondition}),1);
                    tempCate = repmat(cellstr(tempCate),size(app.conditionInd{icondition},2),1);
                    conditionCate = [conditionCate;tempCate];

                end
                app.dataOut.conditionCate = conditionCate;

                if app.ConditionMeanCheckBox.Value == 1
                    %                     conditionNum = num2cell(1:length(plotOrders));
                    %                     tempPlotOrder = cellfun(@(x) find(plotOrders ==x), conditionNum);
                    %                     conditionCate = cellstr(plotConditions);
                    %                     conditionCate = conditionCate(tempPlotOrder);
                    %                     conditionData = dataToAnalyze(tempPlotOrder);
                    %                 else
                    conditionCate = [];
                    conditionData = [];
                    conditionMean = [];
                    for icondition = 1:size(plotConditions,1)
                        tempCate = plotConditions(icondition,:);
                        %tempCate = repmat(cellstr(tempCate),length(app.conditionInd{icondition}),1);
                        tempCate = repmat(cellstr(tempCate),size(app.conditionInd{icondition},1),1);
                        conditionCate = [conditionCate;tempCate];
                        app.dataOut.conditionCate = conditionCate;
                    end
                end
            end


            % creat csv files if no existing one
            % Silent Period CSV file create
            spResults = strcat(resultsDir,'/TempSPResults.csv');
            if exist(spResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'textFileName';
                tempHeaderOut{2} = 'fileName';
                tempHeaderOut{3} = 'ChannelNum';    %
                tempHeaderOut{4} = 'TrialNum';    %
                tempHeaderOut{5} = 'Condition';
                tempHeaderOut{6} = 'AnalyzeOption';   %
                tempHeaderOut{7} = 'OverRide';   %
                tempHeaderOut{8} = 'meanPreStim';
                tempHeaderOut{9} = 'preStimDur(ms)';   %
                tempHeaderOut{10} = 'preStimStartTime(ms)';     %
                tempHeaderOut{11} = 'SilentPeriodOnset(ms)';
                tempHeaderOut{12} = 'SPDur(ms)';
                tempHeaderOut{13} = 'AreaSP';
                tempHeaderOut{14} = 'spPercentDecrease';
                tempHeaderOut{15} = 'sdOn';     %
                tempHeaderOut{16} = 'sdOff';    %
                tempHeaderOut{17} = 'minDurSP(ms)'; %
                tempHeaderOut{18} = 'maxDurSP(ms)';  %
                tempHeaderOut{19} = 'startTimeSP(ms)'; %
                tempHeaderOut{20} = 'SampleRate(Hz)'; %
                tempHeaderOut{21} = 'LPEnvelopHz'; %
                tempHeaderOut{22} = 'MCD';  %
                tempHeaderOut{23} = 'MCD Constant';  %
                tempHeaderOut{24} = 'Removed Count';
                tempHeaderOut{25} = 'Low Thresh';
                tempHeaderOut{26} = 'High Thresh';
                tempHeaderOut{27} = 'Unit';    %
                tempHeaderOut{28} = 'Date';    %
                fid = fopen(spResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,spResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end

            % MEP Latency CSV file create
            activeMEPResults = strcat(resultsDir,'/TempActiveMEPLatencyResults.csv');
            if exist(activeMEPResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'textFileName';
                tempHeaderOut{2} = 'fileName';
                tempHeaderOut{3} = 'ChannelNum';    %
                tempHeaderOut{4} = 'TrialNum';    %
                tempHeaderOut{5} = 'Condition';
                tempHeaderOut{6} = 'AnalyzeOption';   %
                tempHeaderOut{7} = 'OverRide';   %
                tempHeaderOut{8} = 'meanPreStim';
                tempHeaderOut{9} = 'preStimDur(ms)';   %
                tempHeaderOut{10} = 'preStimStartTime(ms)';     %
                tempHeaderOut{11} = 'MEPOnset(ms)';
                tempHeaderOut{12} = 'MEPDur(ms)';
                tempHeaderOut{13} = 'AreaMEP';
                tempHeaderOut{14} = 'mep Amplitude';
                tempHeaderOut{15} = 'sdOn';     %
                tempHeaderOut{16} = 'sdOff';    %
                tempHeaderOut{17} = 'minDurMEP(ms)'; %
                tempHeaderOut{18} = 'maxDurMEP(ms)';  %
                tempHeaderOut{19} = 'startTimeMEP(ms)'; %
                tempHeaderOut{20} = 'SampleRate(Hz)'; %
                tempHeaderOut{21} = 'LPEnvelopHz'; %
                tempHeaderOut{22} = 'MCD';  %
                tempHeaderOut{23} = 'MCD Constant';  %
                tempHeaderOut{24} = 'Removed Count';
                tempHeaderOut{25} = 'Low Thresh';
                tempHeaderOut{26} = 'High Thresh';
                tempHeaderOut{27} = 'Unit';    %
                tempHeaderOut{28} = 'Date';    %
                fid = fopen(activeMEPResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,activeMEPResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end

            % Resting MEP CSV file create
            restingMEPResults = strcat(resultsDir,'/TempRestingMEPResults.csv');
            if exist(restingMEPResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'textFileName';
                tempHeaderOut{2} = 'fileName';
                tempHeaderOut{3} = 'ChannelNum';    %
                tempHeaderOut{4} = 'TrialNum';    %
                tempHeaderOut{5} = 'Condition';
                tempHeaderOut{6} = 'AnalyzeOption';   %
                tempHeaderOut{7} = 'OverRide';   %
                tempHeaderOut{8} = 'Low Pass Freq (Hz)';
                tempHeaderOut{9} = 'Z SD';   %
                tempHeaderOut{10} = 'Search Start Time';
                tempHeaderOut{11} = 'resting MEP onset(ms)';     %
                tempHeaderOut{12} = 'resting MEP offset(ms)';
                tempHeaderOut{13} = 'resting MEP Duration(ms)';
                tempHeaderOut{14} = 'resting MEP Area';
                tempHeaderOut{15} = 'resting MEP Amplitude (mV)';
                tempHeaderOut{16} = 'LPEnvelopHz'; %
                tempHeaderOut{17} = 'SampleRate(Hz)'; %
                tempHeaderOut{18} = 'Removed Count';
                tempHeaderOut{19} = 'Low Thresh';
                tempHeaderOut{20} = 'High Thresh';
                tempHeaderOut{21} = 'Unit';    %
                tempHeaderOut{22} = 'Date';    %
                fid = fopen(restingMEPResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,restingMEPResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end


            % EMG Burst Onset CSV file create
            emgburstResults = strcat(resultsDir,'/TempEMGburstResults.csv');
            if exist(emgburstResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'textFileName';
                tempHeaderOut{2} = 'fileName';
                tempHeaderOut{3} = 'ChannelNum';    %
                tempHeaderOut{4} = 'TrialNum';    %
                tempHeaderOut{5} = 'Condition';
                tempHeaderOut{6} = 'AnalyzeOption';   %
                tempHeaderOut{7} = 'OverRide';   %
                tempHeaderOut{8} = 'Low Pass Freq (Hz)';
                tempHeaderOut{9} = 'Z SD';   %
                tempHeaderOut{10} = 'Search Start Time';
                tempHeaderOut{11} = 'SD Onset';
                tempHeaderOut{12} = 'Min Dur';
                tempHeaderOut{13} = 'Mod Ratio Time';
                tempHeaderOut{14} = 'Force Level';
                tempHeaderOut{15} = 'EMG Burst Onset(ms)';     %
                tempHeaderOut{16} = 'EMG Onset(ms)';     %
                tempHeaderOut{17} = 'Modulation Ratio';     %
                tempHeaderOut{18} = 'Peak EMG';     %
                tempHeaderOut{19} = 'Time to Peak EMG (ms)';     %
                tempHeaderOut{20} = 'Median Power Frequency';
                tempHeaderOut{21} = 'RMS';
                tempHeaderOut{22} = 'Mean';
                tempHeaderOut{23} = 'Onset Detect Method';
                tempHeaderOut{24} = 'SampleRate(Hz)'; %
                tempHeaderOut{25} = 'Date';    %
                fid = fopen(emgburstResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,emgburstResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end


            % RC CSV file create
            recruitmentCurveResults = strcat(resultsDir,'/TempRecruitmentCurveResults.csv');
            if exist(recruitmentCurveResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                grouptempHeaderOut{1} = 'textFileName';
                grouptempHeaderOut{2} = 'fileName';
                grouptempHeaderOut{3} = 'ChannelNum';    %
                grouptempHeaderOut{4} = 'Centrality';    %
                grouptempHeaderOut{5} = 'base';   %
                grouptempHeaderOut{6} = 'pleateau';   %
                grouptempHeaderOut{7} = 'inflection';
                grouptempHeaderOut{8} = 'k slope';   %
                grouptempHeaderOut{9} = 'AUC';
                grouptempHeaderOut{10} = 'rcMean';
                grouptempHeaderOut{11} = 'rcPerMT';
                grouptempHeaderOut{12} = 'minMSOAUC';     %
                grouptempHeaderOut{13} = 'maxMSOAUC';
                grouptempHeaderOut{14} = 'SampleRate(Hz)'; %
                grouptempHeaderOut{15} = 'Date';    %
                fid = fopen(recruitmentCurveResults,'a');
                fclose(fid);
                T = table(grouptempHeaderOut(:)');
                writetable(T,recruitmentCurveResults,'WriteMode','Append','WriteVariableNames',0);
                grouptempHeaderOut = {};
            end

            % RC CSV file create


            cd(OldFolder);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % save to all variables into a table
            % silent period or active motor evoke potential
            if strcmp(app.ListBox.Value, 'Silent Period') || strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                tempTrial = length(app.dataOut.mPreStim);        % when select multiple trials but analyze individualy ( length(app.dataOut.TrialNum) does not distinguish process the average")

                if tempTrial == 1 && length(app.dataOut.TrialNum)>=2 % if take average of multiple trials
                    tempDataOut{4} = num2str(reshape(app.dataOut.TrialNum', 1, []));
                else
                    tempDataOut{4} = app.dataOut.TrialNum';
                end

                tempDataOut{8} = app.dataOut.mPreStim';
                tempDataOut{11} = app.dataOut.SPOnset';
                tempDataOut{12} = app.dataOut.SPDur';
                tempDataOut{13} = app.dataOut.AreaSP';

                if strcmp(app.ListBox.Value, 'Silent Period')
                    tempDataOut{14} = app.dataOut.spPercentDecrease';
                elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    tempDataOut{14} = app.dataOut.mepAmp';
                end


                %%%Allows definition of condition without doing group analysis

                if app.MultiConditionAnalysisCheckBox.Value == 0 && tempTrial>=2 && isfield(app.dataOut, 'conditionCate') == 1

                    if  size(app.conditionInd,2) == 1
                        strjoin(app.MultiChanListBox.Items);
                        str2num(ans);
                        A = {ans};
                    else
                        A = app.conditionInd;
                    end


                    B = app.dataOut.TrialNum;
                    R = cell(size(A));
                    for iA = 1:numel(A)
                        v     = cat(2, A{iA});   % A{iA} as vector
                        ex    = ismember(v, B);
                        R{iA} = A{iA}(ex);
                    end
                    R = R(~cellfun('isempty', R));

                    conditionInd = R;
                    groupNames = app.ConditionsListBox.Value';
                    plotOrders = num2cell((1:size(groupNames,1))');
                    Data = [groupNames, plotOrders];


                    plotOrders = cell2mat(Data(:,2));
                    plotConditions = char(Data(:,1));
                    conditionCate = [];
                    for icondition = 1:length(plotOrders)
                        tempCate = plotConditions(plotOrders == icondition,:);
                        tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);
                        conditionCate = [conditionCate;tempCate];
                    end
                    %app.dataOut.conditionCate = conditionCate;
                end


                if app.MultiConditionAnalysisCheckBox.Value == 0 && tempTrial<2
                    groupNames = app.ConditionsListBox.Value';

                    if  size(app.conditionInd,2) == 1
                        strjoin(app.MultiChanListBox.Items);
                        str2num(ans);
                        A = {ans};
                    else
                        A = app.conditionInd;
                    end

                    B = app.dataOut.TrialNum;
                    R = cell(size(A));
                    for iA = 1:numel(A)
                        v     = cat(2, A{iA});   % A{iA} as vector
                        ex    = ismember(v, B);
                        R{iA} = A{iA}(ex);
                    end
                    R = R(~cellfun('isempty', R));
                    tempCondIDX = find(~cellfun(@isempty,R));
                  %  app.dataOut.conditionCate = groupNames(tempCondIDX);

                end
                %%%Allows definition of condition without doing group analysis

                if isfield(app.dataOut, 'conditionCate') == 0 || isempty(app.dataOut.conditionCate) == 1
                    app.dataOut.conditionCate = repmat('NA',tempTrial,1);
                    app.dataOut.TrialNum =  app.TrialSpinner.Value;
                end

                if tempTrial>=2             % make sure the table is of the same length
                    tempDataOut{1} = repmat(textFileName,tempTrial,1);
                    tempDataOut{2} = repmat(app.dataOut.fileName,tempTrial,1);
                    tempDataOut{3} = repmat(app.dataOut.ChNum',tempTrial,1);
                    %tempDataOut{5} = app.dataOut.conditionCate(app.dataOut.TrialNum');
                    tempDataOut{5} = app.dataOut.conditionCate(app.dataOut.TrialNum');
                    tempDataOut{6} = repmat(app.dataOut.AnalyzeOption,tempTrial,1);
                    tempDataOut{7} = repmat(app.dataOut.OR,tempTrial,1);  % Over Ride
                    tempDataOut{9} = repmat(app.dataOut.preStimDur,tempTrial,1);
                    tempDataOut{10} = repmat(app.dataOut.preStimStartTime,tempTrial,1);
                    tempDataOut{15} = repmat(app.dataOut.sdOn,tempTrial,1);
                    tempDataOut{16} = repmat(app.dataOut.sdOff,tempTrial,1);
                    tempDataOut{17} = repmat(app.dataOut.minDurSP,tempTrial,1);
                    tempDataOut{18} = repmat(app.dataOut.maxDurSP,tempTrial,1);
                    tempDataOut{19} = repmat(app.dataOut.startTimeSP,tempTrial,1);
                    tempDataOut{20} = repmat(app.dataOut.SampleRate,tempTrial,1);
                    if app.LowPassEnvelopeCheckBox.Value == 1
                        tempDataOut{21} = repmat(app.dataOut.LPEnvelopHz,tempTrial,1);
                    else
                        tempDataOut{21} =repmat('N/A',tempTrial,1);%
                    end
                    tempDataOut{21} = repmat(app.dataOut.LPEnvelopHz,tempTrial,1);
                    tempDataOut{22} = repmat(app.MCDCheckBox.Value,tempTrial,1);
                    if app.MCDCheckBox.Value == 1
                        tempDataOut{23} = repmat(app.MCDConstantEditField.Value,tempTrial,1);
                    else
                        tempDataOut{23} = repmat('N/A',tempTrial,1);
                    end
                    tempDataOut{23} = repmat(app.MCDConstantEditField.Value,tempTrial,1);
                    tempDataOut{24} = repmat(app.dataOut.RemovedCount,tempTrial,1);
                    tempDataOut{25} = repmat(app.dataOut.LowThresh,tempTrial,1);
                    tempDataOut{26} = repmat(app.dataOut.HighThresh,tempTrial,1);
                    tempDataOut{27} = repmat(app.dataOut.Unit,tempTrial,1);
                    tempDataOut{28} = repmat(app.dataOut.Date,tempTrial,1);
                else
                    tempDataOut{1} = textFileName;
                    tempDataOut{2} = app.dataOut.fileName;
                    tempDataOut{3} = app.dataOut.ChNum';    %
                    tempDataOut{5} = (app.dataOut.conditionCate(tempTrial));   %
                    tempDataOut{6} = app.dataOut.AnalyzeOption;   %
                    tempDataOut{7} = app.dataOut.OR;   %
                    tempDataOut{9} = app.dataOut.preStimDur';   %
                    tempDataOut{10} = app.dataOut.preStimStartTime';     %
                    tempDataOut{15} = app.dataOut.sdOn';     %
                    tempDataOut{16} = app.dataOut.sdOff';    %
                    tempDataOut{17} = app.dataOut.minDurSP'; %
                    tempDataOut{18} = app.dataOut.maxDurSP';  %
                    tempDataOut{19} = app.dataOut.startTimeSP'; %
                    tempDataOut{20} = app.dataOut.SampleRate'; %
                    if app.LowPassEnvelopeCheckBox.Value == 1
                        tempDataOut{21} = app.dataOut.LPEnvelopHz';
                    else
                        tempDataOut{21} = 'N/A';%
                    end

                    tempDataOut{22} = app.MCDCheckBox.Value';  %
                    if app.MCDCheckBox.Value == 1
                        tempDataOut{23} = app.MCDConstantEditField.Value;
                    else
                        tempDataOut{23} = 'N/A';
                    end
                    tempDataOut{24} = app.dataOut.RemovedCount;
                    tempDataOut{25} = app.dataOut.LowThresh;
                    tempDataOut{26} = app.dataOut.HighThresh;
                    tempDataOut{27} = app.dataOut.Unit;    %
                    tempDataOut{28} = app.dataOut.Date;    %
                end


                if tempTrial>=2
                    T = table(tempDataOut{:});               % compose into a table to better import to csv file,
                else
                    T = table(tempDataOut(:)');
                end

            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if strcmp(app.ListBox.Value, 'Resting MEP')
                tempTrial = length(app.dataOut.rMEPonset);        % when select multiple trials but analyze individualy ( length(app.dataOut.TrialNum) does not distinguish process the average")

                if tempTrial == 1 && length(app.dataOut.TrialNum)>=2 % if take average of multiple trials
                    tempDataOut{4} = num2str(reshape(app.dataOut.TrialNum', 1, []));
                else
                    tempDataOut{4} = app.dataOut.TrialNum';
                end


                %%%Allows definition of condition without doing group analysis
                if app.MultiConditionAnalysisCheckBox.Value == 0 && tempTrial>=2 && isfield(app.dataOut, 'conditionCate') == 1

                    if  size(app.conditionInd,2) == 1
                        strjoin(app.MultiChanListBox.Items);
                        str2num(ans);
                        A = {ans};
                    else
                        A = app.conditionInd;
                    end

                    B = app.dataOut.TrialNum;
                    R = cell(size(A));
                    for iA = 1:numel(A)
                        v     = cat(2, A{iA});   % A{iA} as vector
                        ex    = ismember(v, B);
                        R{iA} = A{iA}(ex);
                    end
                    R = R(~cellfun('isempty', R));

                    conditionInd = R;
                    groupNames = app.ConditionsListBox.Value';
                    plotOrders = num2cell((1:size(groupNames,1))');
                    Data = [groupNames, plotOrders];
                    plotOrders = cell2mat(Data(:,2));
                    plotConditions = char(Data(:,1));
                    conditionCate = [];
                    for icondition = 1:length(plotOrders)
                        tempCate = plotConditions(plotOrders == icondition,:);
                        tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);
                        conditionCate = [conditionCate;tempCate];
                    end
                    app.dataOut.conditionCate = conditionCate;
                end

                if app.MultiConditionAnalysisCheckBox.Value == 0 && tempTrial<2
                    groupNames = app.ConditionsListBox.Value';

                    if  size(app.conditionInd,2) == 1
                        strjoin(app.MultiChanListBox.Items);
                        str2num(ans);
                        A = {ans};
                    else
                        A = app.conditionInd;
                    end

                    B = app.dataOut.TrialNum;
                    R = cell(size(A));
                    for iA = 1:numel(A)
                        v     = cat(2, A{iA});   % A{iA} as vector
                        ex    = ismember(v, B);
                        R{iA} = A{iA}(ex);
                    end
                    R = R(~cellfun('isempty', R));
                    tempCondIDX = find(~cellfun(@isempty,R));
                    %app.dataOut.conditionCate = groupNames(tempCondIDX);

                end
                %%%Allows definition of condition without doing group analysis

                if isfield(app.dataOut, 'conditionCate') == 0 || isempty(app.dataOut.conditionCate) == 1
                    app.dataOut.conditionCate = repmat('NA',tempTrial,1);
                    app.dataOut.TrialNum =  app.TrialSpinner.Value;
                end


                if tempTrial>=2             % make sure the table is of the same length
                    tempDataOut{1} = repmat(textFileName,tempTrial,1);
                    tempDataOut{2} = repmat(app.dataOut.fileName,tempTrial,1);
                    tempDataOut{3} = repmat(app.dataOut.ChNum',tempTrial,1);
                    %tempDataOut{4} = repmat(app.dataOut.TrialNum',tempTrial,1);
                    tempDataOut{5} = (app.dataOut.conditionCate);
                    tempDataOut{6} = repmat(app.dataOut.AnalyzeOption,tempTrial,1);
                    tempDataOut{7} = repmat(app.dataOut.OR,tempTrial,1);  % Over Ride
                    tempDataOut{8} = repmat(app.dataOut.lowPassFreqZ,tempTrial,1);  % low pass frequency in Z-trans
                    tempDataOut{9} = repmat(app.dataOut.Zsd,tempTrial,1);  % Z standard deviation in Z-trans
                    tempDataOut{10} = repmat(app.dataOut.StartTime,tempTrial,1);  % Z standard deviation in Z-trans
                    tempDataOut{11} = app.dataOut.rMEPonset';
                    tempDataOut{12} = app.dataOut.rMEPoffset';
                    tempDataOut{13} = app.dataOut.rMEPdur';
                    tempDataOut{14} = app.dataOut.Area';
                    tempDataOut{15} = app.dataOut.mepAmp';
                    tempDataOut{16} = repmat(app.dataOut.LPEnvelopHz,tempTrial,1);
                    tempDataOut{17} = repmat(app.dataOut.SampleRate,tempTrial,1);
                    tempDataOut{18} = repmat(app.dataOut.RemovedCount,tempTrial,1);
                    tempDataOut{19} = repmat(app.dataOut.LowThresh,tempTrial,1);
                    tempDataOut{20} = repmat(app.dataOut.HighThresh,tempTrial,1);
                    tempDataOut{21} = repmat(app.dataOut.Unit,tempTrial,1);
                    tempDataOut{22} = repmat(app.dataOut.Date,tempTrial,1);

                else
                    tempDataOut{1} = textFileName;
                    tempDataOut{2} = app.dataOut.fileName;
                    tempDataOut{3} = app.dataOut.ChNum';
                    tempDataOut{5} = (app.dataOut.conditionCate(tempTrial));
                    tempDataOut{6} = app.dataOut.AnalyzeOption;
                    tempDataOut{7} = app.dataOut.OR;  % Over Ride
                    tempDataOut{8} = app.dataOut.lowPassFreqZ;  % low pass frequency in Z-trans
                    tempDataOut{9} = app.dataOut.Zsd;  % Z standard deviation in Z-trans
                    tempDataOut{10} = app.dataOut.Zsd;  % Z standard deviation in Z-trans
                    tempDataOut{11} = app.dataOut.rMEPonset;
                    tempDataOut{12} = app.dataOut.rMEPoffset;
                    tempDataOut{13} = app.dataOut.rMEPdur;
                    tempDataOut{14} = app.dataOut.Area;
                    tempDataOut{15} = app.dataOut.mepAmp;
                    tempDataOut{16} = app.dataOut.LPEnvelopHz;
                    tempDataOut{17} = app.dataOut.SampleRate;
                    tempDataOut{18} = app.dataOut.RemovedCount;
                    tempDataOut{19} = app.dataOut.LowThresh;
                    tempDataOut{20} = app.dataOut.HighThresh;
                    tempDataOut{21} = app.dataOut.Unit;
                    tempDataOut{22} = app.dataOut.Date;
                end

                if tempTrial>=2
                    T = table(tempDataOut{:});               % compose into a table to better import to csv file,
                else
                    T = table(tempDataOut(:)');
                end
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % EMG Burst Onset

            if strcmp(app.ListBox.Value, 'EMG Burst Onset')


                tempTrial = length(app.dataOut.TrialNum);        % when select multiple trials but analyze individualy ( length(app.dataOut.TrialNum) does not distinguish process the average")
                if tempTrial == 1 && length(app.dataOut.TrialNum)>=2 % if take average of multiple trials
                    tempDataOut{4} = num2str(reshape(app.dataOut.TrialNum', 1, []));
                else
                    tempDataOut{4} = app.dataOut.TrialNum';
                    %trialNum = cell2num(app.dataOut.TrialNum');
                    trialNum = (app.dataOut.TrialNum');

                    %if isempty(%app.force) == 0
                    %    tempDataOut{14} = num2str(%app.force{1}{trialNum});
                    %else
                    tempDataOut{14} = 'NA';
                    %end

                end


                %%%Allows definition of condition without doing group analysis
                if app.MultiConditionAnalysisCheckBox.Value == 0 && tempTrial>=2 && isfield(app.dataOut, 'conditionCate') == 1

                    if  size(app.conditionInd,2) == 1
                        strjoin(app.MultiChanListBox.Items);
                        str2num(ans);
                        A = {ans};
                    else
                        A = app.conditionInd;
                    end

                    B = app.dataOut.TrialNum;
                    R = cell(size(A));
                    for iA = 1:numel(A)
                        v     = cat(2, A{iA});   % A{iA} as vector
                        ex    = ismember(v, B);
                        R{iA} = A{iA}(ex);
                    end
                    R = R(~cellfun('isempty', R));

                    conditionInd = R;
                    groupNames = app.ConditionsListBox.Value';
                    plotOrders = num2cell((1:size(groupNames,1))');
                    Data = [groupNames, plotOrders];
                    plotOrders = cell2mat(Data(:,2));
                    plotConditions = char(Data(:,1));
                    conditionCate = [];
                    for icondition = 1:length(plotOrders)
                        tempCate = plotConditions(plotOrders == icondition,:);
                        tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);
                        conditionCate = [conditionCate;tempCate];
                    end
                    app.dataOut.conditionCate = conditionCate;
                end

                if app.MultiConditionAnalysisCheckBox.Value == 0 && tempTrial<2
                    groupNames = app.ConditionsListBox.Value';

                    if  size(app.conditionInd,2) == 1
                        strjoin(app.MultiChanListBox.Items);
                        str2num(ans);
                        A = {ans};
                    else
                        A = app.conditionInd;
                    end

                    %B = cell2num(app.dataOut.TrialNum);
                    B = (app.dataOut.TrialNum);
                    R = cell(size(A));
                    for iA = 1:numel(A)
                        v     = cat(2, A{iA});   % A{iA} as vector
                        ex    = ismember(v, B);
                        R{iA} = A{iA}(ex);
                    end
                    R = R(~cellfun('isempty', R));
                    tempCondIDX = find(~cellfun(@isempty,R));
                    %app.dataOut.conditionCate = groupNames(tempCondIDX);
                    %app.dataOut.medianPowerFreq = 'NA';

                end
                %%%Allows definition of condition without doing group analysis

                if isfield(app.dataOut, 'conditionCate') == 0 || isempty(app.dataOut.conditionCate) == 1
                    app.dataOut.conditionCate = repmat('NA',tempTrial,1);
                    app.dataOut.TrialNum =  app.TrialSpinner.Value;
                end


                if tempTrial>=2             % make sure the table is of the same length
                    tempDataOut{1} = repmat(textFileName,tempTrial,1);
                    tempDataOut{2} = repmat(app.dataOut.fileName,tempTrial,1);
                    tempDataOut{3} = repmat(app.dataOut.ChNum',tempTrial,1);
                    tempDataOut{5} = (app.dataOut.conditionCate);
                    tempDataOut{6} = repmat(app.dataOut.AnalyzeOption,tempTrial,1);
                    tempDataOut{7} = repmat(app.dataOut.OR,tempTrial,1);  % Over Ride
                    tempDataOut{8} = repmat(app.dataOut.lowPassFreqZ,tempTrial,1);  % low pass frequency in Z-trans
                    tempDataOut{9} = repmat(app.dataOut.Zsd,tempTrial,1);  % Z standard deviation in Z-trans
                    tempDataOut{10} = repmat(app.dataOut.StartTime,tempTrial,1);
                    tempDataOut{11} = repmat(app.SDonsetEditField_2.Value,tempTrial,1);
                    tempDataOut{12} = repmat(app.MinDurmsEditField_3,tempTrial,1);
                    tempDataOut{13} = repmat(app.modRatioWindow.Value,tempTrial,1);
                    %tempDataOut{14} = repmat(app.modRatioWindow.Value,tempTrial,1);
                    tempDataOut{15} = repmat(app.dataOut.EMGburstOn',tempTrial,1);
                    tempDataOut{16} = repmat(app.dataOut.emgOnset,tempTrial,1);
                    tempDataOut{17} = repmat(app.dataOut.modulationRatio,tempTrial,1);
                    tempDataOut{18} = repmat(app.dataOut.peakEnv,tempTrial,1);
                    tempDataOut{19} = repmat(app.dataOut.timeToPeak,tempTrial,1);
                    tempDataOut{20} = app.dataOut.medianPowerFreq;
                    tempDataOut{21} = repmat(app.dataOut.emgMean, tempTrial,1);
                    tempDataOut{22} = repmat(app.dataOut.emgRMS, tempTrial,1);
                    tempDataOut{23} = repmat(app.dataOut.BurstMethod,tempTrial,1);
                    tempDataOut{24} = repmat(app.dataOut.SampleRate,tempTrial,1);
                    tempDataOut{25} = repmat(app.dataOut.Date,tempTrial,1);

                else
                    tempDataOut{1} = textFileName;
                    tempDataOut{2} = app.dataOut.fileName;
                    tempDataOut{3} = app.dataOut.ChNum';    %
                    tempDataOut{5} = (app.dataOut.conditionCate);
                    %tempDataOut{5} = app.dataOut.conditionCate{find(strcmp(app.MultiChanListBox.Value,app.MultiChanListBox.Items))}; %Likely a better way - convert cell2num trialNum
                    tempDataOut{6} = app.dataOut.AnalyzeOption;   %
                    tempDataOut{7} = app.dataOut.OR;   %
                    tempDataOut{8} = app.dataOut.lowPassFreqZ;  % low pass frequency in Z-trans
                    tempDataOut{9} = app.dataOut.Zsd;
                    tempDataOut{10} = app.dataOut.StartTime;
                    tempDataOut{11} = app.SDonsetEditField_2.Value;
                    tempDataOut{12} = app.MinDurmsEditField_3.Value;
                    tempDataOut{13} = app.modRatioWindow.Value;
                    %tempDataOut{14} = app.modRatioWindow.Value;
                    tempDataOut{15} =  app.dataOut.EMGburstOn;
                    tempDataOut{16} =  app.dataOut.emgOnset;
                    tempDataOut{17} =  app.dataOut.modulationRatio;
                    tempDataOut{18} =  app.dataOut.peakEnv;
                    tempDataOut{19} =  app.dataOut.timeToPeak;
                    tempDataOut{20} = app.dataOut.medianPowerFreq;
                    tempDataOut{21} = app.dataOut.emgRMS;
                    tempDataOut{22} = app.dataOut.emgMean;
                    tempDataOut{23} = app.dataOut.BurstMethod;
                    tempDataOut{24} = app.dataOut.SampleRate'; %
                    tempDataOut{25} = app.dataOut.Date;    %
                end

                if tempTrial>=2
                    T = table(tempDataOut{:});               % compose into a table to better import to csv file,
                else
                    T = table(tempDataOut(:)');
                end
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Recruitment Curve

            if app.RC == 1
                grouptempDataOut{1} = textFileName;
                grouptempDataOut{2} = app.dataOut.fileName;
                grouptempDataOut{3} = app.dataOut.ChNum';    %
                grouptempDataOut{4} = app.dataOut.centrality;    %
                grouptempDataOut{5} = app.dataOut.base;   %
                grouptempDataOut{6} = app.dataOut.plateau;   %
                grouptempDataOut{7} = app.dataOut.inflection;
                grouptempDataOut{8} = app.dataOut.kslope;   %
                grouptempDataOut{9} = app.dataOut.AUC;
                grouptempDataOut{10} = app.dataOut.rcDataMean;
                grouptempDataOut{11} = app.dataOut.rcDataperMT;
                grouptempDataOut{12} = app.dataOut.minMSOAUC;     %
                grouptempDataOut{13} = app.dataOut.maxMSOAUC;
                grouptempDataOut{14} = app.dataOut.SampleRate'; %
                grouptempDataOut{15} = app.dataOut.Date;    %

                gpT = table(grouptempDataOut(:)');

            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % write table to csv file
            if strcmp(app.ListBox.Value, 'Silent Period')
                writetable(T,spResults,'WriteMode','Append')
            end

            if strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                writetable(T,activeMEPResults,'WriteMode','Append')
            end

            if strcmp(app.ListBox.Value, 'EMG Burst Onset')
                writetable(T,emgburstResults,'WriteMode','Append')
            end

            if strcmp(app.ListBox.Value, 'Resting MEP')
                writetable(T,restingMEPResults,'WriteMode','Append')
            end

            if app.RC == 1
                writetable(gpT,recruitmentCurveResults,'WriteMode','Append')
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SavecsvButton.Enable = 0;
            pause(0.5)
            app.SavecsvButton.Enable = 1;

        end

        % Button pushed function: SavematButton
        function SavematButtonPushed(app, event)
            if isempty(app.EditField_4.Value) == 1
                msgbox('File Enter Data File Name')
                return;
            end
            if isempty(app.dataOut) == 1

            end
            textFileName = app.EditField_4.Value;
            if isempty(app.dataOut) == 1
                msgbox('No data to save')
            end
            app.dataOut.textFileName = textFileName;


            app.dataOut.data = app.DataToPlot;

            if isempty(app.dataOut) == 1
                app.dataOut.time = app.time;
            else
                app.dataOut.time = app.DelayTime;
            end


            eval([[textFileName,'Data'],'=','app.dataOut'])


            % save to 'Saved Data' folder
            savedDataDir = strcat(app.toolboxDir,'/SavedData');
            if exist(savedDataDir,'dir') ~=7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(savedDataDir);
            end
            OldFolder = pwd;
            cd (savedDataDir)
            save([textFileName,'.mat'],[textFileName,'Data']);
            cd(OldFolder);

            % in case of double click
            app.SavematButton.Enable = 0;
            pause(0.5)
            app.SavematButton.Enable = 1;


        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;

        end

        % Callback function
        function AnalyzeButton_2Pushed(app, event)

        end

        % Size changed function: TrialSelectPanel
        function TrialSelectPanelSizeChanged(app, event)
            position = app.TrialSelectPanel.Position;

        end

        % Value changed function: MCDCheckBox
        function MCDCheckBoxValueChanged(app, event)
            % show MCD constant edit box
            if app.MCDCheckBox.Value == 1
                app.MCDConstantEditField.Visible = 1;
                app.MCDConstantEditFieldLabel.Visible = 1;
                app.SDonsetEditField.Enable = 0;
                app.SDoffsetEditField.Enable = 0;
            else
                app.MCDConstantEditField.Visible = 0;
                app.MCDConstantEditField.Value = 2.66;   %2.66 is the default value
                app.MCDConstantEditFieldLabel.Visible = 0;
                app.SDonsetEditField.Enable = 1;
                app.SDoffsetEditField.Enable = 1;
            end
        end

        % Value changed function: PreStimMeanEditField
        function PreStimMeanEditFieldValueChanged(app, event)
            value = app.PreStimMeanEditField.Value;

        end

        % Value changed function: TrialsnotfoundEditField
        function TrialsnotfoundEditFieldValueChanged(app, event)
            value = app.TrialsnotfoundEditField.Value;

        end

        % Callback function
        function Button2Pushed(app, event)

        end

        % Button pushed function: ZTransButton
        function ZTransButtonPushed(app, event)
            %% Z-trans
            app.Ztrans = 1;
            app.Prolific = 0;
            app.EMGBurstoverRideMark = 0;
            app.EMGAnalysisOR = 0;
            data = app.RectifyData;                    % app.RectifyData saves the processed data
            time = app.DelayTime;
            sampleRate = app.dwnSampleRate;
            StartTimeInd = find(time <= app.StartTimemsEditField_2.Value/1000);
            StartTimeInd = StartTimeInd(end);

            if app.AnalyzetheAverageCheckBox_3.Value == 1
                if size(data,1)>1
                    data = sum(data)/size(data,1);
                end
                app.ClearBackgroundLinesCheckBox_3.Visible = 1;
            else %  clear background lines can only be checked when take average is checked
                app.ClearBackgroundLinesCheckBox_3.Visible = 0;
                app.ClearBackgroundLinesCheckBox_3.Value = 0;
            end
            if size(data,1)>1
                msgbox('Only for single trial or average data')
                return;
            end
            fc = app.LowPassHzEditField.Value;
            zSD = app.ZSDEditField.Value;

            [b,a] = (butter(6,(fc/(sampleRate/2)),'low'));
            y = filtfilt(b,a,data);

            emgstd      = ft_preproc_standardize(y)';          % z-transform, i.e. mean=0 and stdev=1
            emgtrl1     = emgstd>zSD;                            % detect the muscle activity
            emgtrl      = diff(emgtrl1', [], 2)';

            emgon       = find(emgtrl(:)== 1);
            emgoff      = find(emgtrl(:)==-1);

            emgon = emgon(:);  % as a consequence of the convolution with a one-second boxcar
            emgon(emgon<StartTimeInd) = [];
            emgoff = emgoff(:);  % as a consequence of the convolution with a one-second boxcar

            if isempty(emgon) == 1
                msgbox('no onset found');
                return;
            end


            cla(app.UIAxes);
            % plot in mV first. Then change the unit accordingly
            legend(app.UIAxes,'AutoUpdate','off');

            if app.ClearBackgroundLinesCheckBox_3.Value == true
            else
                dataPlot = plot(app.UIAxes,time,app.RectifyData,'Color','k');
            end

            hold(app.UIAxes,'on')
            if app.AnalyzetheAverageCheckBox_3.Value == true     % here SP data is the mean of all trials
                AveragePlot = plot(app.UIAxes,time,data,'Color','k',"LineWidth",2);
            end


            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end            % change the unit
            ButtonGroupUnitsSelectionChanged(app, event)

            for iTrial=1: numel(emgon)
                if iTrial == 1
                    legend(app.UIAxes,'AutoUpdate','on');
                else
                    legend(app.UIAxes,'AutoUpdate','off');
                end
                xline(app.UIAxes,time(emgon(iTrial)),'b','LineStyle','--',"LineWidth",2,'DisplayName', 'EMG Ztrans');
            end
            hold(app.UIAxes,'off')

            xline(app.UIAxes,time(emgon(1)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'EMG Ztrans Onset');
            app.OnsetmsEditField.Value = time(emgon(1))*1000;

            % save the variables
            app.dataOut = [];
            app.dataOut.fileName = app.FileName.Value;


            app.dataOut.ChNum=app.ChannelSpinner.Value;
            if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                app.dataOut.TrialNum=app.MultiChan;
            else
                app.dataOut.TrialNum=app.TrialSpinner.Value;
            end
            app.dataOut.emgRMS = 'N/A';
            app.dataOut.emgMean = 'N/A';
            app.dataOut.medianPowerFreq = 'N/A';
            app.dataOut.AnalyzeOption = app.ListBox.Value;
            app.dataOut.OR= app.EMGBurstoverRideMark;                % if override, then OR = 1;
            app.dataOut.lowPassFreqZ = app.LowPassHzEditField.Value;
            app.dataOut.Zsd = app.ZSDEditField.Value;
            app.dataOut.EMGburstOn = time(emgon(1))*1000;
            app.dataOut.BurstMethod = 'Z-Trans';
            app.dataOut.SampleRate = sampleRate;
            app.dataOut.StartTime = app.StartTimemsEditField_2.Value;
            t = [datetime('now')];
            app.dataOut.Date = datestr(t);
            app.dataOut.modulationRatio = 'N/A';
            app.dataOut.peakEnv = 'N/A';
            app.dataOut.emgOnset = 'N/A';
            app.dataOut.timeToPeak = 'N/A';

        end

        % Value changed function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanged(app, event)
            value = app.SampleRateHzEditField.Value;

        end

        % Button pushed function: PROLIFICButton
        function PROLIFICButtonPushed(app, event)
            app.Prolific = 1;
            app.Ztrans = 0;
            app.EMGBurstoverRideMark = 0;
            app.EMGAnalysisOR = 0;
            data = app.RectifyData;                    % app.RectifyData saves the processed data
            time = app.DelayTime;
            sampleRate = app.dwnSampleRate;

            if app.AnalyzetheAverageCheckBox_3.Value == 1
                if size(data,1)>1
                    data = sum(data)/size(data,1);
                end
                app.ClearBackgroundLinesCheckBox_3.Visible = 1;
            else %  clear background lines can only be checked when take average is checked
                app.ClearBackgroundLinesCheckBox_3.Visible = 0;
                app.ClearBackgroundLinesCheckBox_3.Value = 0;
            end
            if size(data,1)>1
                msgbox('Only for single trial or average data')
                return;
            end

            distr = choosedistr(data','L',sampleRate);
            emgOnsetIDX = prolific(data',sampleRate,distr);
            emgOnset = time(emgOnsetIDX);

            cla(app.UIAxes);
            % plot in mV first. Then change the unit accordingly
            legend(app.UIAxes,'AutoUpdate','off');
            if app.ClearBackgroundLinesCheckBox_3.Value == true
            else
                dataPlot = plot(app.UIAxes,time,app.RectifyData,'Color','k');
            end
            hold(app.UIAxes,'on')
            if app.AnalyzetheAverageCheckBox_3.Value == true     % here SP data is the mean of all trials
                AveragePlot = plot(app.UIAxes,time,data,'Color','k',"LineWidth",2);
            end


            legend(app.UIAxes,'AutoUpdate','on');
            xline(app.UIAxes,emgOnset,'r','LineStyle','--',"LineWidth",2,'DisplayName', 'EMG Onset prolific');
            hold(app.UIAxes,'off')

            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end            % change the unit
            ButtonGroupUnitsSelectionChanged(app, event)

            app.OnsetmsEditField.Value = emgOnset*1000;


            app.dataOut = [];
            app.dataOut.fileName = app.FileName.Value;


            app.dataOut.ChNum=app.ChannelSpinner.Value;
            if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                app.dataOut.TrialNum=app.MultiChan;
            else
                app.dataOut.TrialNum=app.TrialSpinner.Value;
            end
            app.dataOut.AnalyzeOption = app.ListBox.Value;
            app.dataOut.OR= app.EMGBurstoverRideMark;                % if override, then OR = 1;
            app.dataOut.lowPassFreqZ = 'N/A';
            app.dataOut.Zsd = 'N/A';
            app.dataOut.EMGburstOn = emgOnset*1000;
            app.dataOut.BurstMethod = 'PROLIFIC';
            app.dataOut.SampleRate = sampleRate;
            app.dataOut.StartTime = 'N/A';
            t = [datetime('now')];
            app.dataOut.Date = datestr(t);
            app.dataOut.modulationRatio = 'N/A';
            app.dataOut.peakEnv = 'N/A';
            app.dataOut.emgOnset = 'N/A';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TrialSpinner.Value = app.TrialSpinner.Value + 1;
            TrialSpinnerValueChanged(app, event);
            if app.Ztrans == 1
                ZTransButtonPushed(app, event)
            end

            if app.Prolific == 1
                PROLIFICButtonPushed(app, event)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.TrialSpinner.Value == 1
                msgbox('no trial found')
                return;
            end
            app.TrialSpinner.Value = app.TrialSpinner.Value - 1;
            TrialSpinnerValueChanged(app, event);
            if app.Ztrans == 1
                ZTransButtonPushed(app, event)
            end
            if app.Prolific == 1
                PROLIFICButtonPushed(app, event)
            end
        end

        % Button pushed function: OverrideButton_2
        function OverrideButton_2Pushed(app, event)
            app.Prolific = 0;
            app.Ztrans = 0;
            app.overRideMark = 0;
            app.EMGBurstoverRideMark = 1;           % if override button is clicked, it is used in mouse click capture
            app.EMGAnalysisOR = 0;

            data = app.RectifyData;                    % app.RectifyData saves the processed data
            time = app.DelayTime;

            sampleRate = app.dwnSampleRate;

            cla(app.UIAxes);
            % plot in mV first. Then change the unit accordingly
            legend(app.UIAxes,'AutoUpdate','off');
            if app.ClearBackgroundLinesCheckBox_3.Value == true
            else
                plot(app.UIAxes,time,data,'Color','k');
            end


            if app.AnalyzetheAverageCheckBox_3.Value == 1
                if size(data,1)>1
                    hold(app.UIAxes,'on');
                    data = sum(data)/size(data,1);
                    AveragePlot = plot(app.UIAxes,time,data,'Color','k',"LineWidth",2);
                end
                app.ClearBackgroundLinesCheckBox_3.Visible = 1;
            else %  clear background lines can only be checked when take average is checked
                app.ClearBackgroundLinesCheckBox_3.Visible = 0;
                app.ClearBackgroundLinesCheckBox_3.Value = 0;
            end
            if size(data,1)>1
                msgbox('Only for single trial or average data')
                return;
            end

            app.overRideInd = [];           % save the override onset and offset index
            app.UIFigure.Pointer = 'crosshair';

        end

        % Size changed function: SilentPeriodPanel
        function SilentPeriodPanelSizeChanged(app, event)
            position = app.SilentPeriodPanel.Position;

        end

        % Value changed function: HzEditField_4
        function HzEditField_4ValueChanged(app, event)
            value = app.HzEditField_4.Value;

        end

        % Value changed function: ClearBackgroundLinesCheckBox
        function ClearBackgroundLinesCheckBoxValueChanged(app, event)
            value = app.ClearBackgroundLinesCheckBox.Value;

        end

        % Size changed function: Panel_6
        function Panel_6SizeChanged(app, event)
            position = app.Panel_6.Position;

        end

        % Size changed function: ButtonGroup
        function ButtonGroupSizeChanged(app, event)
            position = app.ButtonGroup.Position;

        end

        % Button pushed function: ZTransButton_2
        function ZTransButton_2Pushed(app, event)

            fprintf('Processing, please wait...');
            pause (.1)
            app.Processing.Visible = "on";
            app.Processing.Text = 'Processing, please wait...';
            app.Processing.BackgroundColor = 'yellow';
            pause (.1)
            tic;


            app.rMEPoverrideMark = 0;
            % define parameters
            SPData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
            SPTime = app.TimeToPlot;
            tempRemovedDataToPlot = app.DataToPlot;            %retains individual trials after trials are removed
            tempRemovedMeanDataToPlot = app.meanData;          %retains average signal after trials are removed

            sampleRate = app.dwnSampleRate;
            StartTimeInd = find(SPTime <= app.StartTimemsEditField_3.Value/1000);
            StartTimeInd = StartTimeInd(end);

            if app.ProcessInd == 1
                NoRecData =  app.LPData;                           % Non rectify data saved
            else
                NoRecData =  SPData;
            end

            preStimDurInd = find(SPTime >= (app.PreStimPeriodmsEditField.Value/1000) & SPTime<= app.EditField_3.Value/1000);
            minDurPointsOn = round(app.MinDurmsEditField.Value/1000*sampleRate);    % min duration to determine onset
            minDurPointsOff = round(app.MinDurmsEditField_2.Value/1000*sampleRate);    % min duration to determine offset

            % define pre-stimulation data in case multiple trials selected
            tempData = SPData;       % SPData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
            tempData = num2cell(tempData,2);
            preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
            preStimData = cell2mat(preStimData);


            if app.RemoveCheckBox_2.Value == 1 %removes trials based on pre-difined thresholds
                % find average and std of the Pre-stimulation Period
                preStimMean = sum(preStimData')/(size(preStimData,2));
                preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                retainIDX = (find(preStimMean > app.LowThreshRest.Value & preStimMean < app.HighThreshRest.Value)); %Find IDX inbetween Low and HighThresh
                removed = length(preStimMean)-length(retainIDX); %Counts the number of removed trials
                removed = num2str(removed);
                app.RemovedCount_2.Text = removed; % Displays number of removed trials
                SPData = SPData(retainIDX,:); %Remove Rect IDX that are not within threshold
                NoRecData = NoRecData(retainIDX,:);  %Remove NonRect IDX that are not within treshold
                tempMultiChan = app.MultiChan(retainIDX); %retains trial numbers after removal
                tempRemovedDataToPlot = SPData; %retains individual trials after trials are removed
                tempRemovedMeanDataToPlot = sum(SPData)/size(SPData,1); %retains average signal after trials are removed
                tempData = SPData;
                tempData = num2cell(tempData,2);
                preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false); %Redifine prestimData based on removed trials
                preStimData = cell2mat(preStimData);


                if str2num(removed) == length(preStimMean)
                    msgbox('No Trials Retianed')
                    return;
                end
            end


            % calculate average
            if app.AnalyzetheAverageCheckBox_2.Value == 1
                SPData = sum(SPData)/size(SPData,1);
                NoRecData = sum(NoRecData)/size(NoRecData,1);
                app.ClearBackgroundLinesCheckBox_2.Visible = 1;
            else %  clear background lines can only be checked when take average is checked
                app.ClearBackgroundLinesCheckBox_2.Visible = 0;
                app.ClearBackgroundLinesCheckBox_2.Value = 0;
            end

            % determine if it is multiple trials
            if size(SPData,1) == 1
                singleTrial = 1;
            else
                singleTrial = 0;
            end

            % show low pass enverlop
            if app.LowPassEnvelopeCheckBox_2.Value == 1
                Fq = app.HzEditField_5.Value;
                [b,a] = (butter(6,(Fq/(sampleRate/2)),'low'));
                LPEverl = num2cell(SPData, 2);                       % low pass everlope
                LPEverl = cellfun(@(x) filtfilt(b,a,x),  LPEverl,'UniformOutput', false);
                LPEverl = cell2mat(LPEverl);
                SPDataEnv = LPEverl;
            end

            % find average and std of the Pre-stimulation Period
            preStimMean = sum(preStimData')/(size(preStimData,2));
            preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
            app.PreStimMeanEditField.Value = mean(preStimMean); %Displays 1) Mean of all Single Trial 2) Mean of average
            app.PreStimStdEditField.Value = mean(preStimStd);   %Displays 1) Mean of all Single Trial STD 2) Average Signal ST

            %Set Output Mean and STD Display
            if app.uVButton.Value == true
                app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value*1000;
                app.PreStimStdEditField.Value = app.PreStimStdEditField.Value*1000;
            end
            if app.mVButton.Value == true
                app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value;
                app.PreStimStdEditField.Value = app.PreStimStdEditField.Value;
            end
            if app.VButton.Value == true
                app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value/1000;
                app.PreStimStdEditField.Value = app.PreStimStdEditField.Value/1000;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % figure plot
            cla(app.UIAxes);
            % plot in mV first. Then change the unit accordingly
            legend(app.UIAxes,'AutoUpdate','off');
            if app.ClearBackgroundLinesCheckBox_2.Value ~= 1
                dataPlot = plot(app.UIAxes,app.TimeToPlot,tempRemovedDataToPlot);
            end
            hold(app.UIAxes,'on')

            if app.AnalyzetheAverageCheckBox_2.Value == true     % here SP data is the mean of all trials
                AveragePlot = plot(app.UIAxes,app.TimeToPlot,SPData,'Color','k',"LineWidth",2);
            end
            if app.LowPassEnvelopeCheckBox_2.Value == true
                if app.AnalyzetheAverageCheckBox_2.Value == true     % here SP data is the mean of all trials
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.TimeToPlot,SPDataEnv,'Color',1/255*[0,104,87],"LineWidth",2);
                else
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.TimeToPlot,SPDataEnv,'Color',1/255*[0,104,87],"LineWidth",2);
                end
            end
            legend(app.UIAxes,'AutoUpdate','on');

            if ~isempty(app.MultiChanListBox.Value) &&length(app.MultiChan)>1 % If multiple trials selected, plot mean value
                if app.AnalyzetheAverageCheckBox_2.Value == true     % here SP data is the mean of all trials
                else
                    MeanDataPlot = plot(app.UIAxes,app.TimeToPlot,tempRemovedMeanDataToPlot,'Color','k',"LineWidth",2,'DisplayName', 'mean');
                end
            end


            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end            % change the unit
            ButtonGroupUnitsSelectionChanged(app, event)

            %             % draw prestimulation mean
            %             if size(SPData,1) == 1 % exclude the situation that multiple trials selected but dont analyze average
            %                 app.PreMeanPlot = plot(app.UIAxes,app.TimeToPlot,repmat(preStimMean,1,length(app.TimeToPlot)),'Color','k','DisplayName', 'PreStimMean');
            %             end
            %
            %             StartTimeInd = find(app.TimeToPlot <= app.StartTimemsEditField.Value/1000);
            %
            %             xline(app.UIAxes,SPTime(StartTimeInd(end)),'k','LineStyle','--',"LineWidth",1,'DisplayName', 'Start Time');
            %             hold(app.UIAxes,'off');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%

            if singleTrial == 1
                % if low pass envelop Check box is checked
                if app.LowPassEnvelopeCheckBox_2.Value == 1
                    tempData = SPDataEnv;
                else
                    tempData = SPData;
                end

                fc = app.LowPassHzEditField_2.Value;
                zSD = app.ZSDEditField_2.Value;

                [b,a] = (butter(6,(fc/(sampleRate/2)),'low'));
                y = filtfilt(b,a,tempData);

                emgstd      = ft_preproc_standardize(y)';          % z-transform, i.e. mean=0 and stdev=1
                emgtrl1     = emgstd>zSD;                            % detect the muscle activity
                emgtrl      = diff(emgtrl1', [], 2)';

                emgon       = find(emgtrl(:)== 1);
                emgoff      = find(emgtrl(:)==-1);

                emgon = emgon(:);  % as a consequence of the convolution with a one-second boxcar
                emgon(emgon<StartTimeInd) = [];

                if isempty(emgon) == 1
                    emgon = 1;
                end


                emgon=emgon(1);
                emgoff = emgoff(:);  % as a consequence of the convolution with a one-second boxcar
                emgoff(emgoff<emgon) = [];%makes sure emgoff is later than emgon

                if emgon == 1
                    emgoff = 1;
                end

                emgoff = emgoff(1);

                if isempty(emgon) == 1
                    msgbox('no onset found');
                    return;
                end


                %                 cla(app.UIAxes);
                % plot in mV first. Then change the unit accordingly
                legend(app.UIAxes,'AutoUpdate','off');
                %                 plot(app.UIAxes,SPTime,data);
                app.onsetLine = [];
                app.offsetLine = [];
                for iTrialZ=1: numel(emgon)
                    if iTrialZ == 1
                        legend(app.UIAxes,'AutoUpdate','on');
                    else
                        legend(app.UIAxes,'AutoUpdate','off');
                    end
                    hold(app.UIAxes,'on')
                    app.onsetLine(iTrialZ) = xline(app.UIAxes,SPTime(emgon(iTrialZ)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'MEP Ztrans Onset');
                    hold(app.UIAxes,'on')
                    app.offsetLine(iTrialZ) = xline(app.UIAxes,SPTime(emgoff(iTrialZ)),'b','LineStyle','--',"LineWidth",2,'DisplayName', 'MEP Ztrans Offset');
                    hold(app.UIAxes,'on')
                end
                hold(app.UIAxes,'off')

                rMEPonsetTime = SPTime(emgon(1))*1000;      % onset time, only saves the first onset if multiple onsets detected
                rMEPoffsetTime = SPTime(emgoff(1))*1000;
                mepAmp = max(NoRecData(emgon(1):emgoff(1))) - min(NoRecData(emgon(1):emgoff(1)));
                rMEPduration = rMEPoffsetTime - rMEPonsetTime;
                inverseAreaSP = trapz(SPData(emgon(1):emgoff(1)));      % must be SPData, because lowPassEnv is only for onset and offset determination
                app.NALabel_5.Text = num2str(rMEPonsetTime);      % onset time, only saves the first onset if multiple onsets detected
                app.NALabel_6.Text = num2str(rMEPduration);  % duration
                app.NALabel_7.Text = num2str(mepAmp);
                app.NALabel_8.Text = num2str(inverseAreaSP);

            elseif singleTrial == 0
                app.WastedTrials = 0;
                onsetInd = zeros(1,size(SPData,1));          % if choose multiple trials, onsetUbd, offsetInd, SPDur, areaSP and mepAmp saved but not displayed
                offsetInd = zeros(1,size(SPData,1));
                SPDur = zeros(1,size(SPData,1));
                areaSP = zeros(1,size(SPData,1));
                mepAmp = zeros(1,size(SPData,1));
                count = 1;
                for iTrial = 1:size(SPData,1)

                    % if low pass envelop Check box is checked
                    if app.LowPassEnvelopeCheckBox.Value == 1
                        tempData = SPDataEnv(iTrial,:);
                    else
                        tempData = SPData(iTrial,:);
                    end
                    fc = app.LowPassHzEditField_2.Value;
                    zSD = app.ZSDEditField_2.Value;

                    [b,a] = (butter(6,(fc/(sampleRate/2)),'low'));
                    y = filtfilt(b,a,tempData);

                    emgstd      = ft_preproc_standardize(y)';          % z-transform, i.e. mean=0 and stdev=1
                    emgtrl1     = emgstd>zSD;                            % detect the muscle activity
                    emgtrl      = diff(emgtrl1', [], 2)';

                    emgon       = find(emgtrl(:)== 1);
                    emgoff      = find(emgtrl(:)==-1);

                    emgon = emgon(:);  % as a consequence of the convolution with a one-second boxcar
                    emgon(emgon<StartTimeInd) = [];

                    if isempty(emgon) == 1
                        emgon = 1;
                    end


                    emgon=emgon(1);
                    emgoff = emgoff(:);  % as a consequence of the convolution with a one-second boxcar
                    emgoff(emgoff<emgon) = [];%makes sure emgoff is later than emgon

                    if emgon == 1
                        emgoff = 1;
                    end

                    emgoff = emgoff(1);

                    if isempty(emgon) == 1
                        msgbox('no onset found');
                        return;
                    end


                    legend(app.UIAxes,'AutoUpdate','off');
                    %                 plot(app.UIAxes,SPTime,data);


                    hold(app.UIAxes,'on')
                    for iTrialZ=1: numel(emgon)
                        if iTrialZ == 1 && iTrial == 1                % first onset in first trial
                            legend(app.UIAxes,'AutoUpdate','on');
                        else
                            legend(app.UIAxes,'AutoUpdate','off');
                        end
                        app.onsetLine(count) = xline(app.UIAxes,SPTime(emgon(iTrialZ)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'MEP Ztrans Onset');
                        app.offsetLine(count) = xline(app.UIAxes,SPTime(emgoff(iTrialZ)),'b','LineStyle','--',"LineWidth",2,'DisplayName', 'MEP Ztrans Offset');
                        count = count + 1;
                    end
                    hold(app.UIAxes,'off')

                    rMEPonsetTime(1,iTrial) = SPTime(emgon(1))*1000;      % onset time, only saves the first onset if multiple onsets detected
                    rMEPoffsetTime(1,iTrial) = SPTime(emgoff(1))*1000;
                    mepAmp(1,iTrial) = max(NoRecData(iTrial,(emgon(1):emgoff(1)))) - min(NoRecData(iTrial,(emgon(1):emgoff(1))));
                    rMEPduration(1,iTrial) = rMEPoffsetTime(1,iTrial) - rMEPonsetTime(1,iTrial);
                    inverseAreaSP(1,iTrial) = trapz(SPData(iTrial,(emgon(1):emgoff(1))));      % must be SPData, because lowPassEnv is only for onset and offset determination
                end
                app.NALabel_5.Text = 'N/A';      % onset time, only saves the first onset if multiple onsets detected
                app.NALabel_6.Text = 'N/A';  % duration
                app.NALabel_7.Text = 'N/A';
                app.NALabel_8.Text = 'N/A';

            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % save the variables
            app.dataOut = [];
            app.dataOut.fileName = app.FileName.Value;
            app.dataOut.AnalyzeOption = app.ListBox.Value;
            app.dataOut.rMEPonset= rMEPonsetTime;
            app.dataOut.rMEPoffset = rMEPoffsetTime;
            app.dataOut.rMEPdur=rMEPduration;
            app.dataOut.Area=inverseAreaSP;
            app.dataOut.mepAmp = mepAmp;
            app.dataOut.lowPassFreqZ = app.LowPassHzEditField_2.Value;
            app.dataOut.Zsd = app.ZSDEditField_2.Value;
            app.dataOut.StartTime = app.StartTimemsEditField_3.Value;

            app.dataOut.ChNum=app.ChannelSpinner.Value;
            if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                if app.RemoveCheckBox_2.Value == 0
                    app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                else
                    app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                end
            else
                app.dataOut.TrialNum=app.TrialSpinner.Value;
            end

            app.dataOut.LPEnvelopHz = app.HzEditField_5.Value;
            app.dataOut.OR= app.rMEPoverrideMark;                % if override, then OR = 1;
            t = [datetime('now')];
            app.dataOut.Date = datestr(t);
            app.dataOut.Unit = app.unit;
            app.dataOut.SampleRate = sampleRate;

            if app.RemoveCheckBox_2.Value == 1
                app.dataOut.RemovedCount = removed;
                app.dataOut.LowThresh = app.LowThreshEditField_2.Value;
                app.dataOut.HighThresh = app.HighThreshEditField_2.Value;
            else
                app.dataOut.RemovedCount = 'NA';
                app.dataOut.LowThresh = 'NA';
                app.dataOut.HighThresh = 'NA';
            end

            fprintf('Processing finished');
            pause (.1)
            app.Processing.Text = 'Processing finished';
            app.Processing.BackgroundColor = 'green';
            pause (.1)
            tic;


        end

        % Value changed function: AnalyzetheAverageCheckBox_2
        function AnalyzetheAverageCheckBox_2ValueChanged(app, event)
            value = app.AnalyzetheAverageCheckBox_2.Value;

            if app.AnalyzetheAverageCheckBox_2.Value == 1
                app.ClearBackgroundLinesCheckBox_2.Visible = 1;
            else
                app.ClearBackgroundLinesCheckBox_2.Visible = 0;
                app.ClearBackgroundLinesCheckBox_2.Value = 0;
            end
        end

        % Value changed function: LowPassEnvelopeCheckBox_2
        function LowPassEnvelopeCheckBox_2ValueChanged(app, event)
            value = app.LowPassEnvelopeCheckBox_2.Value;
            if app.LowPassEnvelopeCheckBox_2.Value == 0
                app.HzEditField_5.Enable = 0;
                app.HzEditField_5.Value = 0;
            else
                app.HzEditField_5.Enable = 1;
            end

        end

        % Button pushed function: OverrideButton_3
        function OverrideButton_3Pushed(app, event)

            if app.RemoveCheckBox_2.Value == 1
                ZTransButton_2Pushed(app, event)         %Will run analysis to remove trials
            else
            end

            if ishghandle(app.onsetLine)~=0
                delete(app.onsetLine);
            end
            if ishghandle(app.offsetLine)~=0
                delete(app.offsetLine);
            end
            app.overRideMark = 0;           % if override button is clicked
            app.rMEPoverrideMark = 1;
            app.overRideInd = [];           % save the override onset and offset index
            app.UIFigure.Pointer = 'crosshair';
        end

        % Size changed function: RestingMEPPanel
        function RestingMEPPanelSizeChanged(app, event)
            position = app.RestingMEPPanel.Position;

        end

        % Value changed function: AnalyzetheAverageCheckBox_3
        function AnalyzetheAverageCheckBox_3ValueChanged(app, event)
            value = app.AnalyzetheAverageCheckBox_3.Value;
            if app.AnalyzetheAverageCheckBox_3.Value == 1
                app.ClearBackgroundLinesCheckBox_3.Visible = 1;
            else
                app.ClearBackgroundLinesCheckBox_3.Visible = 0;
                app.ClearBackgroundLinesCheckBox_3.Value = 0;
            end
        end

        % Button pushed function: DefineConditionButton
        function DefineConditionButtonPushed(app, event)
            app.DefineConditionsPanel.Visible = 1;

            % when apply conditions, can not be processed, in case of been
            % processed two times
            app.EnableCheckBox.Value = 0;
            EnableCheckBoxValueChanged(app, event)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)


            app.TableRow(end+1) = event.Indices(1);
            TableRow = app.TableRow;
            index = event.Indices(1);
            TableRowLast = app.TableRow(end);

            rmIndex = find(TableRow(1:end-1) == index);

            if isempty(rmIndex) == 1
                s = uistyle('BackgroundColor','yellow');
                addStyle(app.UITable,s,'row',TableRow(end));
            else
                app.TableRow = app.TableRow(find(app.TableRow~=index));
                s = uistyle('BackgroundColor','white');
                addStyle(app.UITable,s,'row',index);
            end


        end

        % Button pushed function: TrialSelectButton
        function TrialSelectButtonPushed(app, event)
            app.TrialSelectPanel_3.Visible = 1;
            tempEvents = 1 : app.totEvent;
            app.MultiChanListBox_3.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
        end

        % Button pushed function: OKButton_3
        function OKButton_3Pushed(app, event)
            TrialofThisGroup = str2num(char(app.MultiChanListBox_3.Value'))';                              % convert selected trials from cell to num; str2double is not applicable here
            TrialofThisGroup = num2str(TrialofThisGroup);

            prompt = {'Enter group name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {''};
            groupName = inputdlg(prompt,dlgtitle,dims,definput);
            channel  = num2str(app.ChannelSpinner.Value);
            groupInfo = {TrialofThisGroup,char(groupName),channel,[],[],[]};


            app.TrialSelectPanel_3.Visible = 0;

            % if choose cancel
            if isempty(groupName) == 1
                return;
            else
                app.UITable.Data = [app.UITable.Data;groupInfo];
            end
            % find data to the condition and save it in allConditionData


            % initialization
            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.Ztrans = 0;
            app.Prolific = 0;
            %             app.ConditionsListBox.Visible = 1;
            %             app.ConditionsListBoxLabel.Visible  = 1;

            % extract condition trials
            length(app.UITable.Data(:,2));       % find condition name
            ConditionTrials = app.UITable.Data(ans,1);          % find condition trials to chosen condition name
            ConditionTrials = str2num(cell2num(ConditionTrials));

            % define chosen data
            legend(app.UIAxes,'off');
            app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,ConditionTrials)');
            app.conditionData = app.chosenData;                                     % save the condition data
            app.conditionTotalEvent = size(app.conditionData,1);
            app.meanData = sum(app.chosenData)/size(app.chosenData,1);

            app.DataToPlot = app.chosenData;
            app.TimeToPlot = app.time;

            % if data been pre-processed, plot by pre-process pannel
            if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                ProcessButtonPushed(app, event);
            else
                % change the scale accordingly
                % plot in mV first, the change the unit if need
                cla(app.UIAxes);
                app.AnalyzeInd = 0;
                app.Patch = [];
                plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
                if size(ConditionTrials,2)
                    if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                    else
                        hold(app.UIAxes,'on')
                        %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                        hold(app.UIAxes,'off')
                    end
                end
                app.UIAxes.YLabel.String = app.mVButton.Text;
                app.tempH = [];
                h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                    app.tempH(iLine,:) = h(iLine).YData(1,:);
                end                    % change the unit
                ButtonGroupUnitsSelectionChanged(app, event)
            end

            % save the data to the condition
            if length(app.UITable.Data(:,2)) == 1
                app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
                if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
                    app.allConditionEvents = {app.EventName};
                end
            else
                app.allConditionData = [app.allConditionData; {{app.chosenData}}];
                if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
                    app.allConditionEvents = [app.allConditionEvents;app.EventName];
                end
            end

            % display trials of this condition in trial select list box
            tempEvents = 1 : app.conditionTotalEvent;
            app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
            ListBoxValueChanged3(app, event)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            prompt = {'Enter plan name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {app.FileName.Value};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            ConditionInfo = app.UITable.Data;
            EventInfo = app.EventName;
            ConditionData = app.allConditionData;
            %ForceData = %app.allForce; %Rochester Specific
            FileName = app.FileName;
            EventsToConditions = app.allConditionEvents;
            time = app.origTime; %Was app.time - made values = 0(?);
            if app.ProcessInd == 1
                sampleRate = app.dwnSampleRate;
            else
                sampleRate = app.origSampleRate;
            end

            % check if the data saving folder exists, if no creat one
            conditionsDir = strcat(app.toolboxDir,'/Conditions');
            if exist(conditionsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(conditionsDir);
            end
            OldFolder = pwd;
            cd (conditionsDir)
            save([char(answer),'.mat'], 'ConditionInfo','EventInfo','ConditionData', 'sampleRate','FileName','EventsToConditions','time');
            cd(OldFolder);

            %% in case of double click
            app.SaveButton.Enable = 0;      % save condition button can only be avaliable when apply button clicked
            pause(0.5)
            app.SaveButton.Enable = 1;      % save condition button can only be avaliable when apply button clicked
        end

        % Callback function: LoadConditionButton, 
        % OpenConditionFileMenu
        function LoadConditionButtonPushed(app, event)
            app.TableRow = [];
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'table','');


            app.DefineConditionsPanel.Visible = 1;
            conditionsDir = app.toolboxDir;
            [fname, path] = uigetfile((conditionsDir),"multiselect","on");

            if iscell(fname) == 0
                fname = {fname};
            end

            app.FileOrderListBox.Items = fname;
            for iFile = 1:length(fname)

                loadData = load(fullfile(path, fname{iFile}));                                        % to identify the data type within current workspace
                drawnow;
                figure(app.UIFigure)
                tempName = fieldnames(loadData);
                app.FileName.Value = fname{iFile};
                % concatenate the condition table
                if size(loadData.(tempName{1}),2)<5
                    loadData.(tempName{1})= [loadData.(tempName{1}),cell(2,4)];

                end


                if isempty(app.UITable.Data) == 1
                    app.UITable.Data = loadData.(tempName{1});             % condition pages
                    if isempty(app.EventName) ~=1
                        app.allConditionEvents = loadData.(tempName{5});       % event names
                    end
                else
                    app.UITable.Data = [app.UITable.Data;loadData.(tempName{1})];
                    if isempty(app.EventName) ~=1
                        app.allConditionEvents =  [app.allConditionEvents;loadData.(tempName{5})];
                    end
                end
                % concatenate the data to conditions
                if isempty(app.allConditionData) == 1
                    app.allConditionData = loadData.(tempName{3});         % data to the conditions
                    %%app.allForce = loadData.(tempName{4});
                else
                    app.allConditionData = [app.allConditionData;loadData.(tempName{3})];
                    %%app.allForce = [%app.allForce;loadData.(tempName{4})];
                end
                if isempty(app.origSampleRate) == 1
                    app.origSampleRate = loadData.(tempName{7});
                end
                if isempty(app.time) == 1
                    app.time = loadData.(tempName{6});
                    app.origTime = app.time;
                    app.totEvent = 1;     % event does not matter here. just to make sure can hit 'Select Multiple Trials'
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)


            app.TableRow = [];
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'table','');

            app.UITable.Data = [];
            app.allConditionData = [];
            app.conditionData = [];
            app.conditionInd = [];

            app.GroupAnalysisPanel.Visible = 0;
            app.RecruitmentCurvePanel.Visible = 0;

            app.ConditionsListBox.Visible = 0;
            app.ConditionsListBoxLabel.Visible  = 0;
            app.TrialSelectPanel.Visible = 0;
            app.MultiConditionAnalysisCheckBox.Visible = 0;
            app.ConditionMeanCheckBox.Visible = 0;

            cla(app.UIAxes);

            if app.dataType ~= 6
                FindallEventsButtonPushed(app, event)
            end


        end

        % Value changed function: EventNameListBox_2
        function EventNameListBox_2ValueChanged(app, event)
            value = app.EventNameListBox_2.Value;
            InitializationButtonPushed(app, event);     % whenever choose another event name, find event again instead of call the saved data matrix

            % if conditions are applied, automaticly find events when switch event name
            if app.DefineConditionsPanel.Visible == 1
                FindallEventsButtonPushed(app, event);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            pause(0.5);
            app.importEvent == 0;
            app.TableRow = [];
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'table','');


            %app.TableRow = [];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            answer = questdlg('Apply to current file or Load?','Apply','Apply', 'Load', 'Load');
            switch answer
                case 'Apply'
                    for iCondition = 1:length(app.UITable.Data(:,2))       % find condition name

                        ConditionTrials = app.UITable.Data(iCondition,1);          % find condition trials to chosen condition name
                        ConditionTrials = str2num(cell2num(ConditionTrials));
                        Channel = str2num(cell2num(app.UITable.Data(iCondition,3)));
                        % define chosen data if a different channel
                        % selected

                        % define chosen data if a different event name
                        % selected
                        if isempty(app.EventName) ~= 1
                            cellstr(app.allConditionEvents);
                            EventName = ans(iCondition);
                            if app.EventNameListBox.Visible == 1
                                app.EventNameListBox.Value = EventName;
                                EventNameListBoxValueChanged(app, event)
                            elseif app.EventNameListBox_2.Visible == 1
                                app.EventNameListBox_2.Value = EventName;
                                EventNameListBox_2ValueChanged(app, event)
                            end
                        end

                        legend(app.UIAxes,'off');
                        app.chosenData = cell2mat(app.allData(Channel,ConditionTrials)');
                        app.conditionData = app.chosenData;                                     % save the condition data
                        app.conditionTotalEvent = size(app.conditionData,1);
                        app.meanData = sum(app.chosenData)/size(app.chosenData,1);

                        app.DataToPlot = app.chosenData;
                        app.TimeToPlot = app.origTime;

                        % if data been pre-processed, plot by pre-process pannel

                        % change the scale accordingly
                        % plot in mV first, the change the unit if need
                        cla(app.UIAxes);
                        app.AnalyzeInd = 0;
                        app.Patch = [];
                        plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
                        if size(ConditionTrials,2)
                            if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                            else
                                hold(app.UIAxes,'on')
                                %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                                hold(app.UIAxes,'off')
                            end
                        end
                        app.UIAxes.YLabel.String = app.mVButton.Text;
                        app.tempH = [];
                        h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                        for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                            app.tempH(iLine,:) = h(iLine).YData(1,:);
                        end                    % change the unit
                        ButtonGroupUnitsSelectionChanged(app, event)


                        % save the data to the condition
                        if iCondition == 1
                            app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
                            %app.allForce = {{%app.force}};
                        else
                            app.allConditionData = [app.allConditionData; {{app.chosenData}}];
                            %app.allForce = [%app.allForce; {{%app.force}}];
                        end
                    end

                case 'Load'

            end

            app.ConditionsListBox.Visible = 1;
            app.ConditionsListBoxLabel.Visible  = 1;
            app.ConditionsListBox.Items = app.UITable.Data(:,2)';    % display condition names in 'condition' list box
            app.TrialSelectPanel.Visible = 1;
            app.MultiConditionAnalysisCheckBox.Visible = 1;
            app.ConditionMeanCheckBox.Visible = 1;%app.force


            % display the trails to the first condition in the condition list
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.ConditionsListBox.Value = app.ConditionsListBox.Items(1);
            ConditionsListBoxValueChanged(app, event)
        end

        % Value changed function: ConditionsListBox
        function ConditionsListBoxValueChanged(app, event)
            value = app.ConditionsListBox.Value;

            % initialization
            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.Ztrans = 0;
            app.Prolific = 0;
            app.ConditionsListBox.Visible = 1;
            app.ConditionsListBoxLabel.Visible  = 1;

            if size(value,2) == 1
                % extract condition trials
                find(strcmp(value, app.UITable.Data(:,2)));       % find condition name
                % define chosen data
                app.chosenData = cell2mat(app.allConditionData{ans});
                %app.force = (%app.allForce{ans});
                app.conditionData = app.chosenData;                                     % save the condition data
                app.conditionTotalEvent = size(app.conditionData,1);
                % display trials of this condition in trial select list box

                %     if app.ConditionMeanCheckBox.Value == 1
                %         app.MultiChanListBox.Items = cellstr('1');     % put available trials into list box
                %         app.MultiChan = cellfun(@str2num, app.MultiChanListBox.Items);
                %         app.chosenData = sum(app.chosenData)/size(app.chosenData,1);
                %     else
                ConditionName = app.ConditionsListBox.Value;
                conditionInd = {1:size(app.chosenData,1)};
                app.conditionInd =  conditionInd;
                find(strcmp(ConditionName, app.UITable.Data(:,2)));       % find condition name))
                app.UITable.Data(ans,1);                                    % find trial number to the condition
                tempEvents = str2num(char(ans));
                app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
                app.MultiChan = cellfun(@str2num, app.MultiChanListBox.Items);

                %     end
                ListBoxValueChanged3(app, event);
            elseif size(value,2) >1
                for iCondition = 1: size(value,2)
                    tempOrder = find(strcmp(value(iCondition), app.UITable.Data(:,2)));       % find order of the condition name
                    if iCondition == 1
                        app.chosenData = cell2mat(app.allConditionData{tempOrder});
                        %app.force = (%app.allForce{tempOrder});
                        %             if app.ConditionMeanCheckBox.Value == 1
                        %                 app.chosenData = sum(app.chosenData)/size(app.chosenData,1);
                        %             end
                        conditionInd = {1:size(app.chosenData,1)};
                    else
                        %             if app.ConditionMeanCheckBox.Value == 1
                        %                 tempData = cell2mat(app.allConditionData{tempOrder});
                        %                 tempData = sum(tempData)/size(tempData,1);
                        %                 app.chosenData = [app.chosenData;tempData];
                        %             else
                        tempData = cell2mat(app.allConditionData{tempOrder});
                        %tempForce = (%app.allForce{tempOrder});
                        app.chosenData = [app.chosenData;tempData];
                        %app.force = [%app.force;tempForce];
                        tempSize = size(tempData,1);
                        EndInd = conditionInd{end}(end);
                        conditionInd = [conditionInd, {(EndInd+1):((EndInd+1)+tempSize)-1}];
                        %             end
                    end
                end

                % save the data of this condition, so the original data of this
                % condition can always be tracked. The data of this 'condition'
                % actually includes data of all the condions the user slected.
                app.conditionData = app.chosenData;                                     % save the condition data
                app.conditionInd =  conditionInd;
                app.conditionTotalEvent = size(app.conditionData,1);

                % display trials of this condition in trial select list box
                tempTrials = 1:size(app.chosenData,1);
                tempTrials = tempTrials';
                tempTrials = cellstr(num2str(tempTrials));
                tempTrials = tempTrials';
                app.MultiChanListBox.Items = tempTrials;
                app.MultiChan = cellfun(@str2num, app.MultiChanListBox.Items);
            end

            if app.ConditionMeanCheckBox.Value == 1
                tempConditionMatrix =  arrayfun(@(x) app.chosenData(cell2mat(x),:), conditionInd, 'UniformOutput', false);


                if app.BandPassCheckBox.Value == true % Allows bandpass before taking mean of rectified - will skp in process function
                    aFq = app.EditField_2.Value;
                    bFq =  app.HzEditField.Value;
                    [b,a] = butter(4,[(aFq/(app.dwnSampleRate/2)) (bFq/(app.dwnSampleRate/2))],'bandpass');
                    tempConditionMatrix = cellfun(@(x) filtfilt(b,a,x'), tempConditionMatrix,'UniformOutput', false);
                    tempConditionMatrix = cellfun(@(x) (x'), tempConditionMatrix,'UniformOutput', false);
                end


                if app.RectifyCheckBox.Value == 1 % Allows for rectify prior to taking the mean
                    tempConditionMatrix = cellfun(@abs,tempConditionMatrix,'UniformOutput',false);  %A temp fix for why rectify is not included in the condition mean
                    %tempConditionNonRectMatric = cellfun(@abs,app.chosenData,'UniformOutput',false);
                end

                % calculate mean
                tempAllConditionData = [];
                %tempAllConditionDataNonRect = [];
                for iCondition = 1:size(value,2)
                    tempAllConditionData(iCondition,:) =  mean(tempConditionMatrix{iCondition});
                    %tempAllConditionDataNonRect(iCondition,:) = mean(app.LPData{iCondition});
                end


                app.chosenData = tempAllConditionData;
                app.conditionMean = tempAllConditionData;
                %app.LPData = tempAllConditionDataNonRect;


                % display the trial numbers in the trial select list
                tempTrials = 1:size(app.chosenData,1);
                tempTrials = tempTrials';
                tempTrials = cellstr(num2str(tempTrials));
                tempTrials = tempTrials';
                app.MultiChanListBox.Items = tempTrials;
                app.MultiChan = cellfun(@str2num, app.MultiChanListBox.Items);
            end


            app.UnprocessedData = app.chosenData;
            if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                ProcessButtonPushed(app, event);
            end

            legend(app.UIAxes,'off');
            app.meanData = sum(app.chosenData)/size(app.chosenData,1);

            app.DataToPlot = app.chosenData;
            
            if isempty(app.DelayTime) == 1
                app.TimeToPlot = app.origTime;
            else
                app.TimeToPlot = app.DelayTime; %Was app.time - made values = 0(?)

            end
            
            app.UnprocessedData = app.chosenData;


            % change the scale accordingly
            % plot in mV first, the change the unit if need
            cla(app.UIAxes);
            app.AnalyzeInd = 0;
            app.Patch = [];

            % plot data
            if app.ConditionMeanCheckBox.Value == 1
                plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
            else
                plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
            end

            % plot mean
            if app.ConditionMeanCheckBox.Value == 1 && size(value,2)>1
                hold(app.UIAxes,'on')
                %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                hold(app.UIAxes,'off')
            elseif app.ConditionMeanCheckBox.Value == 0
                hold(app.UIAxes,'on')
                %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                hold(app.UIAxes,'off')
            end
            app.UIAxes.YLabel.String = app.mVButton.Text;
            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end                    % change the unit
            app.dataOut.TrialNum=app.MultiChan; %Loads in Trial Select Numbers if not selected
            % app.MultiChanListBox.Value =  strsplit(num2str(app.MultiChan));
            ButtonGroupUnitsSelectionChanged(app, event);


        end

        % Value changed function: DelayCheckBox
        function DelayCheckBoxValueChanged(app, event)
            value = app.DelayCheckBox.Value;
            if app.DelayCheckBox.Value == 1
                app.msEditField.Enable = 1;
            else
                app.msEditField.Enable = 0;
            end
        end

        % Size changed function: Panel_17
        function Panel_17SizeChanged(app, event)
            position = app.Panel_17.Position;

        end

        % Value changed function: PreTriggerTime
        function PreTriggerTimeValueChanged(app, event)
            value = app.PreTriggerTime.Value;
            InitializationButtonPushed(app, event);
        end

        % Callback function
        function Button2Pushed2(app, event)
            prePointsNum = str2double(app.PreDurs.Value)*app.origSampleRate;        % pre and post duration in points
            postPointsNum = str2double(app.PostDur.Value)*app.origSampleRate;

            app.TrialsnotfoundEditField.Visible = 0;
            app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.ConditionsListBox.Visible = 0;
            app.ConditionsListBoxLabel.Visible  = 0;


            switch app.dataType
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 1                                                      % data type is labChart
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if the event type is comment based
                    % find data to every event name
                    for i = 1: length(app.EventNameListBox.Items)

                        app.time = [];

                        % Event type is 'Comment'
                        if app.CommentsButton.Value == true
                            % if choose comment based
                            % put all trials data of all channels into a cell
                            % type variable "allData", the following operations just call the varible

                            % determine if the comment name is matched
                            app.EventName = app.EventNameListBox.Items(i);
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.labChartData.comtext)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end
                            PEvents = app.eventInfo(app.eventInfo(:,5) == eventNameInd, :);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total event number change with data size under different event name

                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                 % convert start and endpoint to index matrix. The output is a cell matrix
                            indMatrix = cell2mat(indMatrix);

                            % save the data of all trials into one matirx
                            app.allData = cell(app.totChannel,app.totEvent);            % initialize to pre-distribute the space to accelerate the loading speed

                            for iChannel = 1 :app.totChannel
                                %                                 oneChannelData = zeros(app.totEvent,prePointsNum+postPointsNum+1);                                          % initialize the matrix

                                % For a specified channel, save the data of all trails into a
                                % matrix.
                                for nTrial = 1 : app.totEvent
                                    tempTriggerInd = PEvents(nTrial, 3);                                                              % temporally save the comment index relative to the start point of this block
                                    temBlockNum = PEvents(nTrial, 2);                                                                 % temporally save the block number

                                    tempStartPoint = app.labChartData.datastart(iChannel, temBlockNum);                                     % locate the start point of this trail
                                    tempPrePoint = tempTriggerInd -prePointsNum;                                                            % pre duration start point in respect to the trigger index
                                    % Input of editFiled (Text) is string. Need to trans to double
                                    tempPostPoint = tempTriggerInd + postPointsNum;                                                         % post duration start point in respect to the trigger index

                                    oneTrialData = app.labChartData.data(tempStartPoint+tempPrePoint:tempStartPoint+tempPostPoint);         % data of the trial in the channel and the block
                                    app.allData{iChannel,nTrial} = oneTrialData;                                                                % save the data of all trails into one matrix
                                end
                                %                                 app.allData{iChannel} = oneChannelData;                                                                     % save the data of every channel into a cell type variable
                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.allConditionData{i} = app.allData;
                        end
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if the event type is event(e.g. TTL) based

                    if app.EventsegTTLButton.Value == true
                        % save the event location with respect to the
                        % choose channel.
                        app.totEvent = length(app.eventInfo);
                        app.totBlock = size(app.labChartData.datastart,2);                                                              % save the block numbers
                        ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                        delay = app.Delay.Value * app.origSampleRate/1000;
                        % find index of every block and every channel
                        indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                            app.labChartData.dataend, 'UniformOutput', false);                 % convert start and endpoint to index matrix. The output is a cell matrix
                        indMatrix = cell2mat(indMatrix);
                        eventChanData = app.labChartData.data(indMatrix(app.EventChannel.Value,:));

                        % find all events index in event Channel
                        %                             aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold
                        aboveThreshNorm = eventChanData > app.ThresholdmV.Value;
                        aboveThreshNorm      = diff(aboveThreshNorm, [], 2)';
                        aboveThreshOnset       = find(aboveThreshNorm(:)== 1);                % 1 means 0 jump to 1. The first point >.5 （rising edge of a pulse）
                        aboveThreshOnset(aboveThreshOnset<delay) = [];

                        if isempty(aboveThreshOnset) == 1                                                                                % when no points above the threshold skip to next block
                            app.totEvent = 0;                                                                                       % event number in this block is zero
                        end

                        % search interval
                        aboveThreshOnset(find(diff(aboveThreshOnset)<ISI)+1) = [];
                        eventInd = aboveThreshOnset;                             % save index of the event channel
                        app.totEvent = length(eventInd);                    % save the number of events in the event channel
                        if isempty(eventInd) == 1
                            msgbox('No Trials Found')
                            return
                        end

                        % according to the saved event location
                        % of the choosen channel, save the data of
                        % every event in each channel.
                        app.allData = cell(app.totChannel,app.totEvent);               % initialize to pre-distribute the space to accelerate the loading speed
                        if eventInd(1)-prePointsNum < 0
                            msgbox('Pre Duration is too large');
                            return
                        end

                        if eventInd(end)+postPointsNum>length(indMatrix(end,:))
                            msgbox('Post Duration is too large');
                            return
                        end

                        for iChannel = 1:app.totChannel                     % for each channel
                            oneChanInd = arrayfun(@(x) indMatrix(iChannel,x-prePointsNum:x+postPointsNum),...
                                eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                            % Must be transposed in column, or all events would be in one row.
                            oneChanData = arrayfun(@(x) app.labChartData.data(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                oneChanInd, 'UniformOutput', false);

                            app.allData(iChannel,:) = oneChanData';
                        end
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if the event type is block based

                    if app.BlocksFramesButton.Value == true
                        app.totEvent = length(app.labChartData.datastart);                                      % Here the trials refer to the blocks

                        app.allData = cell(app.totChannel,app.totEvent);                                                   % initialize to pre-distribute the space to accelerate the loading speed

                        indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                            app.labChartData.dataend, 'UniformOutput', false);                                  % convert start and endpoint to index matrix. The output is a cell matrix

                        app.allData = arrayfun(@(x) app.labChartData.data(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                            indMatrix, 'UniformOutput', false);

                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 2                                                      % data type is Acqknowledge

                    app.totEvent = [];
                    % save data from original data in channels
                    app.allData = cell(app.totChannel,1);
                    for iChannel = 1 : app.totChannel
                        app.allData{iChannel} = app.AcqknowledgeData.data(:,iChannel)';                                  % transpose to uniform the format
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if event type is comment
                    if app.BlocksFramesButton.Value == true
                        app.chosenData = app.allData{app.ChannelSpinner.Value};
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if event type is event(TTL) based

                    if app.EventsegTTLButton.Value == true

                        ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                        eventChanData = app.allData{app.EventChannel.Value};

                        % find all events index in event Channel
                        aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                        if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                            app.totEvent = 0;                                                                                       % event number in this block is zero
                        end

                        %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                        %}

                        % search interval
                        for i = 1: length(aboveThresh)
                            if i>length(aboveThresh)
                                continue;
                            end
                            aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                        end

                        eventInd = aboveThresh;                             % save index of the event channel
                        app.totEvent = length(eventInd);                    % save the number of events in the event channel
                        if isempty(eventInd) == 1
                            mh = msgbox('No Trials Found');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return
                        end

                        % according to the saved event location
                        % of the choosen channel, save the data of
                        % every event in each channel.

                        if eventInd(1)-prePointsNum < 0
                            mh =msgbox('Pre Duration is too large');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return
                        end

                        if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                            mh =msgbox('Post Duration is too large');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return
                        end

                        app.allData = cell(app.totChannel, app.totEvent);
                        for iChannel = 1:app.totChannel                     % for each channel
                            oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                            % Must be transposed in column, or all events would be in one row.
                            tempData = app.AcqknowledgeData.data(:, iChannel);
                            oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                oneChanInd, 'UniformOutput', false);

                            app.allData(iChannel,:) = oneChanData';
                        end
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 3                                                           % data type is Signal


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % event type is comment
                    if app.CommentsButton.Value == true

                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % event type is event(e.g., TTL)
                    if app.EventsegTTLButton.Value == true
                        tempData = app.allData;                                                                                     % app.allData will be redefined
                        ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points

                        eventChanData = tempData(app.EventChannel.Value,:);
                        eventChanData = cell2mat(eventChanData);
                        % find all events index in event Channel
                        aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                        if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                            app.totEvent = 0;                                                                                       % event number in this block is zero
                        end

                        %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                        %}

                        % search interval
                        for i = 1: length(aboveThresh)
                            if i>length(aboveThresh)
                                continue;
                            end
                            aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                        end

                        eventInd = aboveThresh;                             % save index of the event channel
                        app.totEvent = length(eventInd);                    % save the number of events in the event channel
                        if isempty(eventInd) == 1
                            mh = msgbox('No Trials Found');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return
                        end

                        % according to the saved event location
                        % of the choosen channel, save the data of
                        % every event in each channel.

                        if eventInd(1)-prePointsNum < 0
                            mh =msgbox('Pre Duration is too large');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return
                        end

                        if eventInd(end)+postPointsNum>length(eventChanData)
                            mh =msgbox('Post Duration is too large');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return
                        end

                        app.allData = cell(app.totChannel, app.totEvent);
                        for iChannel = 1:app.totChannel                     % for each channel
                            oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                            % Must be transposed in column, or all events would be in one row.


                            oneChanData = cell2mat(tempData(iChannel, :));
                            oneChanData = arrayfun(@(x) oneChanData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                oneChanInd, 'UniformOutput', false);

                            app.allData(iChannel,:) = oneChanData';
                        end

                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        for i = 1: length(app.EventNameListBox_2.Items)
                            CommentNames = extractfield(app.SignalData.frameinfo,'label');
                            app.EventName = app.EventNameListBox_2.Items(i);
                            eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));     % find event name in the comtext, figure out its sequence number

                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end

                            % in case preduration beyond default pretrigger
                            % time
                            DefaultPreTriggerTime = double(app.SignalData.start);
                            if prePointsNum + DefaultPreTriggerTime*app.origSampleRate>0        % the input pre-duration must be within the default pre-trigger time
                                msgbox('Please re-enter pre-duration')
                                return;
                            end


                            PEvents = app.SignalData.frameinfo(eventNameInd, :);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total events change with data size under different event name
                            app.allData = cell(app.totChannel,app.totEvent);
                            for iChannel = 1 : app.totChannel
                                for nTrial = 1 : app.totEvent
                                    tempData(:,:) = app.SignalData.values(:,iChannel,eventNameInd(nTrial)); % 3 dimensions to 1
                                    app.allData{iChannel,nTrial} = tempData';
                                    app.allData{iChannel,nTrial} = app.allData{iChannel,nTrial}((1+prePointsNum):(abs(DefaultPreTriggerTime)*app.origSampleRate+postPointsNum));
                                end
                            end
                            app.time = (1:length(app.allData{1,1}))/app.origSampleRate+app.SignalData.start;                           % time according to different data length. Points in signal is saved as int32
                            app.time = app.time-(app.PreTriggerTime.Value/1000);

                            app.allConditionData{i} = app.allData;
                        end
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 4                                                      % data type is BrainVision

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if app.CommentsButton.Value == true
                        app.EventName = app.EventNameListBox.Value;
                        idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));

                        empty = isempty(idx);
                        if empty == 1
                            msgbox('No Trials Found')
                            return
                        end
                        %% One Column Array for all Event IDX
                        PEvents = app.eventInfo(idx);
                        app.allData = cell(app.totChannel,length(PEvents));
                        for iChannel = 1:app.totChannel
                            for nTrial = 1:length(PEvents)
                                app.allData{iChannel,nTrial} = app.BrainVisionData.data(iChannel,PEvents(nTrial)-prePointsNum:PEvents(nTrial)+postPointsNum);
                            end
                        end
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if block based
                    if app.BlocksFramesButton.Value == true
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if event type is event(TTL) based

                    if app.EventsegTTLButton.Value == true
                        tempData = app.allData;                                                                                     % app.allData will be redefined
                        ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points

                        eventChanData = tempData(app.EventChannel.Value,:);
                        eventChanData = cell2mat(eventChanData);
                        % find all events index in event Channel
                        aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                        if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                            app.totEvent = 0;                                                                                       % event number in this block is zero
                        end

                        %{
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                        %}

                        % search interval
                        for i = 1: length(aboveThresh)
                            if i>length(aboveThresh)
                                continue;
                            end
                            aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                        end

                        eventInd = aboveThresh;                             % save index of the event channel
                        app.totEvent = length(eventInd);                    % save the number of events in the event channel
                        if isempty(eventInd) == 1
                            mh = msgbox('No Trials Found');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return;
                        end

                        % according to the saved event location
                        % of the choosen channel, save the data of
                        % every event in each channel.

                        if eventInd(1)-prePointsNum < 0
                            mh =msgbox('Pre Duration is too large');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return;
                        end

                        if eventInd(end)+postPointsNum > length(eventChanData)
                            mh =msgbox('Post Duration is too large');
                            th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                            th.FontSize = 14;                                   % Change the font size
                            return;
                        end

                        app.allData = cell(app.totChannel, app.totEvent);
                        for iChannel = 1:app.totChannel                     % for each channel
                            oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                            % Must be transposed in column, or all events would be in one row.

                            oneChanData = cell2mat(tempData(iChannel, :));
                            oneChanData = arrayfun(@(x) oneChanData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                oneChanInd, 'UniformOutput', false);

                            app.allData(iChannel,:) = oneChanData';
                        end

                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                    end

                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                    end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot the figure
            cla(app.UIAxes);
            app.Patch = [];
            app.AnalyzeInd = 0;   % reset Analyze button in case reselection
            app.DataToPlot = app.chosenData;
            app.TimeToPlot = app.time;
            legend(app.UIAxes,'off');
            plot(app.UIAxes, app.TimeToPlot, app.DataToPlot,'Color','k');                                                        % Plot data of the one seleted trail, but only the first time.
            % The following click would call the Trial/Channel spinner to
            % directly plot the figure.
            app.UIAxes.XLabel.String = app.timeUnit;
            app.UIAxes.YLabel.String = app.unit;
            % plot in mV first, then change the unit accordingly
            app.UIAxes.YLabel.String = app.mVButton.Text;
            app.SampleRateHzEditField.Value = app.origSampleRate;

            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            app.tempH = zeros(size(h,1),length(h(1).YData));
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end
            % change the unit
            ButtonGroupUnitsSelectionChanged(app, event)
            % if pre-process toolbox is enabled, then directly plot
            % processed data
            if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                ProcessButtonPushed(app, event);
            end
            app.uVButton.Enable = 'on';
            app.mVButton.Enable = 'on';
            app.VButton.Enable = 'on';

        end

        % Size changed function: TrialSelectPanel_3
        function TrialSelectPanel_3SizeChanged(app, event)
            position = app.TrialSelectPanel_3.Position;

        end

        % Menu selected function: OpenSavedConcatFileMenu
        function OpenSavedConcatFileMenuSelected(app, event)
            [fname, path] = uigetfile(app.DataDir,'*mat');
            load(fullfile(path, fname));
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            app.FileName.Value = fname;


            app.labChartData         = labChartData;% data package of the TMS data exported from LabChart
            app.AcqknowledgeData     = AcqknowledgeData;% data package of the TMS data exported from Acqknowledge
            app.SignalData           = SignalData;% data package of the TMS data exported from Signal
            app.BrainVisionData      = BrainVisionData;% data package of the TMS data exported from BrainVision
            app.origSampleRate       = origSampleRate;% define the sample rate variable
            app.eventInfo            = eventInfo;% define the event/trial information
            app.totChannel           = totChannel;% define the channel number variable
            app.totEvent             = totEvent;% define the event/trial number variable
            app.totBlock             = totBlock;% define the block number variable
            app.unit                 = unit;% define the unit of the TMS data
            app.EventType            = EventType;% define the type of event 1/2/3 refers to comment/event/block ???
            app.allData              = allData;% define the matrix which saves data of all channels and all trials
            app.reloadInd            = reloadInd;% define a mark to reveal if it is the first time to load the data. If the data is saved, next time just call
            app.fileName             = fileName;% name of the data
            app.dataType             = dataType;% data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
            app.time                 = time;% time points of the data
            app.timeUnit             = timeUnit;% unit of the time %%%%%% Save Up to Here %%%%%%

        end

        % Value changed function: LowThreshEditField, 
        % LowThreshEditField_2
        function LowThreshEditFieldValueChanged(app, event)
            valueActive = str2num(app.LowThreshEditField.Value);  %Active MEP and SP
            valueRest = str2num(app.LowThreshEditField_2.Value);  %Rest MEP
            if app.unit == 'uV'
                app.LowThresh.Value = valueActive/1000;
                app.LowThreshRest.Value = valueRest/1000;
            end

            if app.unit == 'V'
                app.LowThresh.Value = valueActive*1000;
                app.LowThreshRest.Value = valueRest*1000;
            end

            if app.unit == 'mV'
                app.LowThresh.Value = valueActive;
                app.LowThreshRest.Value = valueRest;
            end
        end

        % Value changed function: HighThreshEditField, 
        % HighThreshEditField_2
        function HighThreshEditFieldValueChanged(app, event)
            valueActive = str2num(app.HighThreshEditField.Value); %Active MEP and SP
            valueRest = str2num(app.HighThreshEditField_2.Value);  %Rest MEP
            if app.unit == 'uV'
                app.HighThresh.Value = valueActive/1000;
                app.HighThreshRest.Value = valueRest/1000;
            end

            if app.unit == 'V'
                app.HighThresh.Value = valueActive*1000;
                app.HighThreshRest.Value = valueRest*1000;
            end

            if app.unit == 'mV'
                app.HighThresh.Value = valueActive;
                app.HighThreshRest.Value = valueRest;
            end
        end

        % Value changed function: RemoveCheckBox, RemoveCheckBox_2
        function RemoveCheckBoxValueChanged(app, event)

            if app.RemoveCheckBox.Value == 1
                app.HighThreshEditField.Enable = 1; %Active MEP and SP
                app.LowThreshEditField.Enable = 1; %Active MEP and SP

            else
                app.RemovedCount.Text = 'NA';
                app.HighThreshEditField.Enable = 0; %Active MEP and SP
                app.LowThreshEditField.Enable = 0; %Active MEP and SP
            end

            if  app.RemoveCheckBox_2.Value == 1
                app.HighThreshEditField_2.Enable = 1; %Resting MEP
                app.LowThreshEditField_2.Enable = 1;  %Resting MEP
            else
                app.RemovedCount_2.Text = 'NA';       %Resting MEP
                app.HighThreshEditField_2.Enable = 0; %Resting MEP
                app.LowThreshEditField_2.Enable = 0; %Resting MEP
            end
        end

        % Button pushed function: DefineDataDirectoryButton
        function DefineDataDirectoryButtonPushed(app, event)
            app.DataDir = uigetdir; %Defines where open file directs you to
        end

        % Value changed function: ConditionMeanCheckBox
        function ConditionMeanCheckBoxValueChanged(app, event)
            value = app.ConditionMeanCheckBox.Value;

            % change the condition list value to the first condtion so that
            % the condition mean can be calculated (tempConditionMatrx).
            if app.ConditionMeanCheckBox.Value == 1
                app.ConditionsListBox.Value = app.ConditionsListBox.Items(1);
            else
                app.EnableCheckBox.Value = 0;
                EnableCheckBoxValueChanged(app, event)
            end
            ConditionsListBoxValueChanged(app, event);
        end

        % Cell edit callback: UITable_CondOder
        function UITable_CondOderCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed2(app, event)

            check = str2double(app.UITable_CondOder.Data(:,1)'); %Group names have to be values to calculate AUC
            if sum(isnan(check)) >= 1
                msgbox('Rename Conditions to values (MSO or %MT): Conditions cannot be characters for RC calculations')
                return;
            else
                %app.FromEditField.Value = min(str2num(char(app.UITable_CondOder.Data(:,1)'))); %Group names have to be values to calculate AUC
            end


            if app.RemoveCheckBox.Value == 1
                conditionInd = app.conditionIndRmIDX;

                for i = 1:length(conditionInd)
                    isempty(conditionInd{i});

                    if ans == 1
                        msgbox('Condition is empty')
                        return;
                    end


                end


            else
                conditionInd = app.conditionInd;
            end


            plotOrders = cell2mat(app.UITable_CondOder.Data(:,2));
            plotConditions = char(app.UITable_CondOder.Data(:,1));

            % define the data to analyze


            if strcmp(app.ListBox.Value, 'Resting MEP')
                if strcmp(app.MetricstoAnalyzeListBox.Value, 'Onset')
                    dataToAnalyze = app.dataOut.rMEPonset;
                elseif strcmp(app.MetricstoAnalyzeListBox.Value, 'Duration')
                    dataToAnalyze = app.dataOut.rMEPdur;
                elseif strcmp(app.MetricstoAnalyzeListBox.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                elseif strcmp(app.MetricstoAnalyzeListBox.Value, 'Area')
                    dataToAnalyze = app.dataOut.Area;
                end
            end

            if strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                if strcmp(app.MetricstoAnalyzeListBox.Value, 'Onset')
                    dataToAnalyze = app.dataOut.SPOnset;
                elseif strcmp(app.MetricstoAnalyzeListBox.Value, 'Duration')
                    dataToAnalyze = app.dataOut.SPDur;
                elseif strcmp(app.MetricstoAnalyzeListBox.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                elseif strcmp(app.MetricstoAnalyzeListBox.Value, 'Area')
                    dataToAnalyze = app.dataOut.AreaSP;
                end
            end
            % adapt plot order
            %             trialOrders = app.conditionInd(plotOrders);
            %             dataToAnalyze = dataToAnalyze(cell2mat(trialOrders));

            % concatenate data
            if app.ConditionMeanCheckBox.Value == 1
                conditionNum = num2cell(1:length(plotOrders));
                tempPlotOrder = cellfun(@(x) find(plotOrders ==x), conditionNum);
                conditionCate = cellstr(plotConditions);
                conditionCate = conditionCate(tempPlotOrder);
                conditionData = dataToAnalyze(tempPlotOrder);
            else
                conditionCate = [];
                conditionData = [];
                conditionMean = [];
                for icondition = 1:length(plotOrders)
                    tempCate = plotConditions(plotOrders == icondition,:);
                    tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);
                    tempData = dataToAnalyze(conditionInd{plotOrders == icondition});
                    tempMean = mean(tempData);
                    conditionCate = [conditionCate;tempCate];
                    conditionData = [conditionData,tempData];
                    conditionMean = [conditionMean,tempMean];
                end
            end

            % plot the figure
            figure;
            app.dataOut.conditionCate = conditionCate;
            percentMT = str2num(char(conditionCate));
            plot(percentMT',conditionData,'o','MarkerSize',7,'Color','b');
            title('Recruitment Curve');


            % centrilization
            Cate = sort(str2num(plotConditions));   % category
            if app.CentralityCheckBox.Value == 1
                percentile = app.EditField_6.Value;
                dataInMatrix = arrayfun(@(x) conditionData(find(percentMT==x)), Cate,'UniformOutput', false);
                nameInMatrix = arrayfun(@(x) percentMT(percentMT == x), Cate,'UniformOutput', false);
                [centralizedData,centralizedName] = Centralization(dataInMatrix,nameInMatrix,percentile);
                conditionData = cell2mat(centralizedData');     % cell2mat and cell2num reshape the matrix from different dimension
                conditionCate = cell2num(centralizedName);      % conditionCate should be the same format as it is before centralization, so that Boltzman fit can function properly

                % plot centralized data
                percentMT = conditionCate;
                plot(percentMT',conditionData,'o','MarkerSize',7,'Color','k');
                title('Centralized Recruitment Curve');
            end


            % plot mean

            metricsMean = arrayfun(@(x) mean(conditionData(find(percentMT==x))), Cate,'UniformOutput', false);
            metricsMean = cell2mat(metricsMean');
            if app.MeanCheckBox.Value == 1
                hold on;
                plot(Cate,metricsMean,'o','Color','r','MarkerSize',10);
            end

            %Boltzman fitting
            x = sort(str2num(plotConditions));
            fun = @(beta,x)beta(1)+(beta(2))./(1+exp((beta(3)-x)/beta(4)));

            emgBase = min(metricsMean);
            mepPlat = max(metricsMean);
            %
            %             metricsMean = num2cell(metricsMean');
            diffs = metricsMean-mepPlat/2;
            diffs = diffs(diffs>=0);
            sFifty = find(min(diffs) == metricsMean-mepPlat/2);
            sFifty = x(sFifty);
            k = 10;
            %
            guesses = [emgBase mepPlat sFifty k];
            %
            opts = statset('MaxIter', 10000);
            [beta,~,~,~,~,~] = nlinfit(x',metricsMean,fun,guesses,opts);
            %
            new_x = min(x):max(x);
            new_y = fun(beta, new_x);
            %             ActInflection = round(beta(:,3),-1);

            hold on

            slin = plot(new_x, new_y, 'k-','LineWidth', 2);
            %actlin = plot(x, RCmean, 'r-','LineWidth', .5);
            vertline = plot(beta(:,3),ylim);
            conline = line([beta(:,3) beta(:,3)],ylim,'Color','k','LineStyle','--','DisplayName','Bolzmann Fit');


            % calculate features
            base = beta(:,1);
            plateau = beta(:,2);
            inflection = beta(:,3);
            kslope = beta(:,4);

            minMSO = app.FromEditField.Value;
            maxMSO = app.ToEditField.Value;
            AUCtrials = find(Cate == minMSO) : find(Cate == maxMSO);
            centAUC = trapz(Cate(AUCtrials),...
                metricsMean(AUCtrials)');

            % display the features
            app.NALabel_13.Text = num2str(centAUC,'%.2f');
            app.NALabel_10.Text = num2str(base,'%.2f');
            app.NALabel_11.Text = num2str(plateau,'%.2f');
            app.NALabel_12.Text = num2str(inflection,'%.2f');
            app.NALabel_9.Text = num2str(kslope,'%.2f');

            app.dataOut.boltzY  = new_y;               % RC Boltzman Fit
            app.dataOut.boltzX  = new_x;              % RC Boltzman Fit
            app.dataOut.rcData  = conditionData;       % RC Trial x Trial MEP
            app.dataOut.percentMT = percentMT';            % RC X axis
            app.dataOut.rcDataMean = num2str(metricsMean);
            app.dataOut.rcDataperMT = num2str(unique(percentMT'));
            app.dataOut.base = base;
            app.dataOut.plateau = plateau;
            app.dataOut.inflection = inflection;
            app.dataOut.kslope = kslope;
            app.dataOut.AUC = centAUC;
            app.dataOut.minMSOAUC = minMSO;
            app.dataOut.maxMSOAUC = maxMSO;
            app.dataOut.centrality = app.CentralityCheckBox.Value;

        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            if strcmp(value, 'Recruitment Curve')
                % display condition list so that user can edit the order to
                % plot the metrics to the conditons
                app.RecruitmentCurvePanel.Visible = 1;
                app.Visualize.Visible = 0;
                app.PairedPulse.Visible = 0;
                app.Mapping.Visible = 0;
                app.RC = 1;
                groupNames = app.ConditionsListBox.Value';
                plotOrders = num2cell((1:size(groupNames,1))');
                app.UITable_CondOder.Data = [groupNames, plotOrders];

                % display the metric list
                app.MetricstoAnalyzeListBox.Items = {'Onset','Duration', 'Amplitude', 'Area'};
                %app.FromEditField.Value = min(str2num(char(groupNames)));
                %app.ToEditField.Value = max(str2num(char(groupNames)));
            end

            if strcmp(value, 'Paired Pulse')
                % display condition list so that user can edit the order to
                % plot the metrics to the conditons


                app.RecruitmentCurvePanel.Visible = 0;
                app.Visualize.Visible = 0;
                app.PairedPulse.Visible = 1;
                app.Mapping.Visible = 0;
                app.RC = 0;

                groupNames = app.ConditionsListBox.Value';
                plotOrders = num2cell((1:size(groupNames,1))');
                app.UITable_CondOder_3.Data = [groupNames, plotOrders];

                % display the metric list
                app.MetricstoAnalyzeListBox_3.Items = {'Onset','Duration', 'Amplitude', 'Area'};
            end


            if strcmp(value, 'Visualize')
                % display condition list so that user can edit the order to
                % plot the metrics to the conditons

                app.RecruitmentCurvePanel.Visible = 0;
                app.Visualize.Visible = 1;
                app.PairedPulse.Visible = 0;
                app.Mapping.Visible = 0;
                app.RC = 0;

                groupNames = app.ConditionsListBox.Value';
                groupNames =  regexprep(groupNames, '\t', '_');

                plotOrders = num2cell((1:size(groupNames,1))');
                app.UITable_CondOder_2.Data = [groupNames, plotOrders];


                if strcmp(app.ListBox.Value, 'Resting MEP') || strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    % display the metric list
                    app.MetricstoAnalyzeListBox_2.Items = {'Onset','Duration', 'Amplitude', 'Area'};
                elseif strcmp(app.ListBox.Value, 'Silent Period')
                    app.MetricstoAnalyzeListBox_2.Items = {'Onset','Duration', '% Decrease', 'Area'};
                end


            end


            if strcmp(value, 'Mapping')
                % display condition list so that user can edit the order to
                % plot the metrics to the conditons

                app.RecruitmentCurvePanel.Visible = 0;
                app.Visualize.Visible = 0;
                app.PairedPulse.Visible = 0;
                app.Mapping.Visible = 1;
                app.RC = 0;
                app.UITable.Data


                groupNames = app.ConditionsListBox.Value';

                curCon = find(ismember(app.UITable.Data(:,2), groupNames)); %Finds what conditions including in mapping analysis
                groupNames =  regexprep(groupNames, '\t', '_');


                GridX = {};
                GridY = {};
                B = regexp(app.UITable.Data(curCon,2),'\d*','Match');

%                                 for i = 1:length(B)
%                                     t = cell2num(B(i));
%                 
%                                     tempX = (t(2));
%                                     tempY = cell2num(t(3));
%                 
%                                     tempX = str2double(tempX);
%                                     tempY = str2double(tempY);
%                 
%                                     GridX = [GridX;tempX];
%                                     GridY = [GridY;tempY];
%                 
%                                 end


                coord = [app.UITable.Data(curCon,4),app.UITable.Data(curCon,5),app.UITable.Data(curCon,6),GridX,GridY]; %%Only includes relavent conditions and not all conditions in mapping analysis
                %coord = [app.UITable.Data(curCon,4),app.UITable.Data(curCon,5),app.UITable.Data(curCon,6)];
                plotOrders = num2cell((1:size(groupNames,1))');
                app.UITable_CondOder_4.Data = [groupNames, plotOrders,coord];

                if strcmp(app.ListBox.Value, 'Resting MEP') || strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                    % display the metric list
                    app.MetricstoAnalyzeListBox_4.Items = {'Onset','Duration', 'Amplitude', 'Area'};
                elseif strcmp(app.ListBox.Value, 'Silent Period')
                    app.MetricstoAnalyzeListBox_4.Items = {'Onset','Duration', '% Decrease', 'Area'};
                end

            end


        end

        % Button pushed function: ReorderButton
        function ReorderButtonPushed(app, event)
            plotOrders = cell2mat(app.UITable_CondOder.Data(:,2));
            plotConditions = char(app.UITable_CondOder.Data(:,1));

            % reorder the conditions
            A = str2num(plotConditions);
            B = sort(A);
            plotOrders = arrayfun(@(x) find(B == x), A);
            app.UITable_CondOder.Data(:,2) = num2cell(plotOrders');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: AddBlankRowButton
        function AddBlankRowButtonPushed(app, event)
            groupInfo = {[],[],[],[],[],[]};
            app.UITable.Data = [app.UITable.Data;groupInfo];
            app.allConditionEvents = [app.allConditionEvents;app.EventName];
        end

        % Button pushed function: AutoFillButton
        function AutoFillButtonPushed(app, event)
            conditionPages = [];
            groupPages = {};
            for i = 1:size(app.UITable.Data,1)
                page=cell2num(app.UITable.Data(i));
                page = str2num(page);
                conditionPage=(page); %Define last value to base fill
                for in = 1:(app.FillFactor.Value-1)
                    conditionPage(end+1)=(page(end)+in);  %consecutive addition based on last value in row. Will fill based a FillFactor
                end
                conditionPages = {num2str(conditionPage)};
                app.UITable.Data(i) = conditionPages; %amends trial numbers and updates table
                app.UITable.Data(i,3) = {num2str(app.ChannelSpinner.Value)};
            end
        end

        % Value changed function: MultiConditionAnalysisCheckBox
        function MultiConditionAnalysisCheckBoxValueChanged(app, event)
            value = app.MultiConditionAnalysisCheckBox.Value;

            if value == 1
                app.GroupAnalysisPanel.Visible = 1;
                app.RecruitmentCurvePanel.Visible = 1;
                app.RC = 1;
            else
                app.GroupAnalysisPanel.Visible = 0;
                app.RecruitmentCurvePanel.Visible = 0;
                app.PairedPulse.Visible = 0;
                app.Visualize.Visible = 0;
                app.RC = 0;
            end

        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            app.UIAxes.YLim = [str2num(app.YMin.Value),str2num(app.YMax.Value)];
            app.UIAxes.XLim = [str2num(app.XMin.Value),str2num(app.XMax.Value)];
        end

        % Callback function
        function ConvertcfsMenuSelected(app, event)
            cfs2mat.Convert()
        end

        % Callback function
        function ConvertcfsMenuSelected2(app, event)
            cfs2mat.Convert()
        end

        % Menu selected function: SpikesmrsmrxMenu
        function SpikesmrsmrxMenuSelected(app, event)
            app.dataType = 5;
            [fname, path] = uigetfile('*smr*');

            cedpath = getenv('CEDS64ML');
            addpath( cedpath );
            CEDS64LoadLib( cedpath );

            strcat(path,fname);
            app.SpikeData.hdr = CEDS64Open(ans);
            if (app.SpikeData.hdr <= 0); unloadlibrary ceds64int; return; end
            maxChan = CEDS64MaxChan(app.SpikeData.hdr);
            dataTypeInf = [];
            for i = 1:maxChan
                dataTypeInf(end+1) = CEDS64ChanType(app.SpikeData.hdr,i);
            end
            event =find(dataTypeInf == 3);
            null=find(dataTypeInf == 0);
            app.wave=find(dataTypeInf == 1);
            marker=find(dataTypeInf == 5);

            [iRead, vi64T]=CEDS64ReadEvents(app.SpikeData.hdr, event, 10000, 1);
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront
            app.SpikeData.ChSample = [];
            for i = 1:length(app.wave)
                app.SpikeData.ChSample(end+1) = 1.0/(CEDS64ChanDiv(app.SpikeData.hdr, app.wave(i))*CEDS64TimeBase(app.SpikeData.hdr));
            end


            app.origSampleRate = app.SpikeData.ChSample(1);          % Sample rate should be the same across all channels and blocks
            app.totChannel = length(app.wave);                % Define Total Number of Channels.
            %app.unit =                                   % defines unit. Unit of the Spike data
            app.reloadInd = 0;                            % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.timeUnit = 's';
            app.eventInfo = double(vi64T');
            app.SignalData.event = 'Event';


            app.SpikeData.data = cell(app.totChannel,1);
            app.tick = cell(app.totChannel,1);
            block = 1;
            for iChannel = 1:app.totChannel

                maxTimeTicks = CEDS64ChanMaxTime(app.SpikeData.hdr, app.wave(iChannel))+1;
                [fRead, fVals, fTime ] = CEDS64ReadWaveF(app.SpikeData.hdr, app.wave(iChannel), maxTimeTicks, 0, maxTimeTicks );
                app.SpikeData.data{iChannel,1} = fVals';
                app.tick{iChannel,1} = single(fTime:CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel)):maxTimeTicks);

                while (length(fVals)*CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel))+fTime) < maxTimeTicks
                    next=length(fVals)*CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel))+fTime;
                    [fRead, fVals, fTime ] = CEDS64ReadWaveF(app.SpikeData.hdr, app.wave(iChannel), maxTimeTicks, next, maxTimeTicks );
                    app.SpikeData.data{iChannel,block+1} = fVals';
                    app.tick{iChannel,block+1} = single(fTime:CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel)):maxTimeTicks);
                    block = block +1;
                end
                block = 1;
            end


            maxArray = max(cellfun(@(x) size(x,2),app.SpikeData.data));

            for iData = 1:size(app.SpikeData.data,1)

                if size(app.SpikeData.data{iData},2) ~= maxArray
                    factor = 1/(size(app.SpikeData.data{iData},2)/maxArray);
                    app.SpikeData.data{iData}= interp(app.SpikeData.data{iData},round(factor));
                end


            end
            app.totEvent = size(app.SpikeData.data,2);


            %             %%Attemp to Concat if BlockData
            %             if block == 1
            %                 app.SpikeData.datacat = cell(app.totChannel,1);
            %                 app.tickcat = cell(app.totChannel,1);
            %                 for iChannel = 1:app.totChannel
            %
            %                     maxTimeTicks = CEDS64ChanMaxTime(app.SpikeData.hdr, app.wave(iChannel))+1;
            %                     [fRead, fVals, fTime ] = CEDS64ReadWaveF(app.SpikeData.hdr, app.wave(iChannel), maxTimeTicks, 0, maxTimeTicks );
            %                     app.SpikeData.datacat{iChannel,1} = fVals';
            %                     app.tickcat{iChannel,1} = single(fTime:CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel)):maxTimeTicks);
            %
            %                     while (length(fVals)*CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel))+fTime) < maxTimeTicks
            %                         next=length(fVals)*CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel))+fTime;
            %                         [fRead, fVals, fTime ] = CEDS64ReadWaveF(app.SpikeData.hdr, app.wave(iChannel), maxTimeTicks, next, maxTimeTicks );
            %                         app.SpikeData.datacat{iChannel} = [app.SpikeData.datacat{iChannel},fVals'];
            %                         app.tickcat{iChannel} = [app.tickcat{iChannel},single(fTime:CEDS64ChanDiv(app.SpikeData.hdr,app.wave(iChannel)):maxTimeTicks)];
            %                         block = block +1;
            %                     end
            %                     block = 1;
            %                 end
            %
            %
            %
            % %                     blockSize = cellfun(@(x) size(x,2),app.SpikeData.data);
            % %                     maxIDX = max(blockSize, [],'all');
            % %                     minIDX = min(blockSize, [],'all');
            % %                     up = find(blockSize <max(blockSize, [],'all'));
            % %                     factor = maxIDX/minIDX;
            % %
            % %                 for iUp = 1:size(up,1)
            % %                     app.SpikeData.data{up(iUp)} = interp(cell2num(app.SpikeData.data(up(iUp))),factor);
            % %                 end
            %
            %             app.totEvent = size(app.SpikeData.data,2);
            %
            %
            %             end
            app.FileName.Value = fname;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.RemoveCheckBox.Value == 1
                conditionInd = app.conditionIndRmIDX;

                for i = 1:length(conditionInd)
                    isempty(conditionInd{i});

                    if ans == 1
                        msgbox('Condition is empty')
                        return;
                    end


                end


            else
                conditionInd = app.conditionInd;
            end


            plotOrders = cell2mat(app.UITable_CondOder_2.Data(:,2));
            plotConditions = char(app.UITable_CondOder_2.Data(:,1));

            % define the data to plot
            if strcmp(app.ListBox.Value, 'Resting MEP')
                if strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Onset')
                    dataToAnalyze = app.dataOut.rMEPonset;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Duration')
                    dataToAnalyze = app.dataOut.rMEPdur;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Area')
                    dataToAnalyze = app.dataOut.Area;
                    preStimtoAnalyze = app.PreStimMean;
                end
            end

            if strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                if strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Onset')
                    dataToAnalyze = app.dataOut.SPOnset;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Duration')
                    dataToAnalyze = app.dataOut.SPDur;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Area')
                    dataToAnalyze = app.dataOut.AreaSP;
                    preStimtoAnalyze = app.PreStimMean;
                end
            end

            if strcmp(app.ListBox.Value, 'Silent Period')
                if strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Onset')
                    dataToAnalyze = app.dataOut.SPOnset;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Duration')
                    dataToAnalyze = app.dataOut.SPDur;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, '% Decrease')
                    dataToAnalyze = app.dataOut.spPercentDecrease;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_2.Value, 'Area')
                    dataToAnalyze = app.dataOut.AreaSP;
                    preStimtoAnalyze = app.PreStimMean;
                end
            end


            % concatenate data
            if app.ConditionMeanCheckBox.Value == 1
                conditionNum = num2cell(1:length(plotOrders));
                tempPlotOrder = cellfun(@(x) find(plotOrders ==x), conditionNum);
                conditionCate = cellstr(plotConditions);
                conditionCate = conditionCate(tempPlotOrder);
                conditionData = dataToAnalyze(tempPlotOrder);
                conditionPreStimData = preStimtoAnalyze(tempPlotOrder);
            else
                conditionCate = [];
                conditionData = [];
                conditionMean = [];
                conditionPreStimData = [];
                conditionPreStimMean = [];
                for icondition = 1:length(plotOrders)
                    tempCate = plotConditions(plotOrders == icondition,:);
                    tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);

                    tempData = dataToAnalyze(conditionInd{plotOrders == icondition});
                    tempMean = mean(tempData);

                    tempPreStimData = preStimtoAnalyze(conditionInd{plotOrders == icondition});
                    tempPreStimMean = mean(tempData);

                    conditionCate = [conditionCate;tempCate];
                    conditionData = [conditionData,tempData];
                    conditionMean = [conditionMean,tempMean];
                    conditionPreStimData = [conditionPreStimData, tempPreStimData];
                    conditionPreStimMean = [conditionPreStimMean, tempPreStimMean];
                end
            end

            % plot the figure
            figure;
            app.dataOut.conditionCate = conditionCate;
            xtickL = unique(conditionCate,'stable');
            xIDX = [1:size(xtickL,1)];
            [C,~,ib] = unique(conditionCate,'stable');

            Thresh = [ib'; conditionData]';
            Keep = Thresh(Thresh(:,2) >= app.EditField_12.Value, :);
            app.dataOut.count = [];

            for i = 1:max(Thresh(:,1))
                tot = length(find(Thresh(:,1) == i));
                mep = length((find(Keep(:,1) == i)));
                app.dataOut.count(end+1) = (mep/tot)*100;
            end


            Thresh = Thresh(Thresh(:,2) >= app.EditField_12.Value, :);
            plot(Thresh(:,1),Thresh(:,2),'o','MarkerSize',7,'Color','b');

            app.dataOut.condtionLabel = xtickL;

            boxchart(Thresh(:,1),Thresh(:,2));
            xticks(xIDX)
            xticklabels(xtickL)
            title('Visualize');
            hold on
            plot(Thresh(:,1),Thresh(:,2),'o','MarkerSize',4,'Color','b');

            conditionData = Thresh(:,2)';
            ib = Thresh(:,1);

            % centrilization
            Cate = sort(xIDX);   % category
            if app.CentralityCheckBox_2.Value == 1
                percentile = app.EditField_7.Value;
                dataInMatrix = arrayfun(@(x) conditionData(find(ib'==x)), Cate,'UniformOutput', false)';
                nameInMatrix = arrayfun(@(x) ib(ib == x), Cate,'UniformOutput', false)';

                [centralizedData,centralizedName] = Centralization(dataInMatrix,nameInMatrix,percentile);
                conditionData = cell2mat(centralizedData')';
                conditionCate = cell2num(centralizedName)';      % conditionCate should be the same format as it is before centralization, so that Boltzman fit can function properly

                % plot centralized data
                ib = conditionCate';
                boxchart(ib,conditionData');
                xticks(xIDX)
                xticklabels(xtickL)
                hold on
                plot(ib,conditionData,'o','MarkerSize',4,'Color','r');
                title('Centralized');
            end


            app.condition = ib;
            app.conditionOutcome = conditionData;

            % plot mean

            metricsMean = arrayfun(@(x) mean(conditionData(find(ib==x))), Cate,'UniformOutput', false);
            app.dataOut.metricsMean = cell2mat(metricsMean');
            metricsPreStimMean =  arrayfun(@(x) mean(conditionPreStimData(find(ib==x))), Cate,'UniformOutput', false);
            app.dataOut.metricsPreStimMean = cell2mat(metricsPreStimMean');
            if app.MeanCheckBox_2.Value == 1 || app.CentralityCheckBox_2.Value == 1
                hold on;
                plot(Cate,app.dataOut.metricsMean,'o','Color','k','MarkerSize',10);
            end
        end

        % Cell edit callback: UITable_CondOder_2
        function UITable_CondOder_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: ReorderButton_2
        function ReorderButton_2Pushed(app, event)
            plotOrders = cell2mat(app.UITable_CondOder_2.Data(:,2));
            plotConditions = char(app.UITable_CondOder_2.Data(:,1));

            % reorder the conditions
            A = str2num(plotConditions);
            B = sort(A);
            plotOrders = arrayfun(@(x) find(B == x), A);
            app.UITable_CondOder_2.Data(:,2) = num2cell(plotOrders');
        end

        % Value changed function: MeanCheckBox
        function MeanCheckBoxValueChanged(app, event)
            value = app.MeanCheckBox.Value;

        end

        % Callback function
        function CustomRochesterMenuSelected(app, event)
            app.dataType = 6;
            trunk = 0; %helps determine if first trial is removed
            %====================================================================================================%
            [fname,path]=uigetfile('*.xdf'); % File Selection
            file=[path,fname];
            app.stream = load_xdf(file);
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %% Name
            for p=1:length(app.stream)

                a=app.stream{1,p}.info.type;
                if isequal(a ,'EMG') && length(app.stream{1,p}.time_series)>100
                    b=p;
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Runner') && length(app.stream{1,p}.time_series)>10
                    %   if isequal(a ,'Slide') && length(app.stream{1,p}.time_series)>10
                    c=p;
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Events')
                    j=p;
                    break
                end
            end
            %             for p=1:length(app.stream)
            %                 a=app.stream{1,p}.info.type;
            %                 if isequal(a ,'Level')
            %                     h=p;
            %                     break
            %                 end
            %             end
            %             level = app.stream{1,h}.time_series(1:2,:);
            %             level_time=app.stream{1,h}.time_stamps;
            events=app.stream{1,j}.time_series;
            events_time=app.stream{1,j}.time_stamps;
            emg=app.stream{1,b}.time_series;
            emg( all(~emg,2), : ) = [];
            app.origSampleRate=str2double(app.stream{1,b}.info.nominal_srate);
            emg_time = app.stream{1,b}.time_stamps;

            position_time=app.stream{1,c}.time_stamps;

            position=app.stream{1,c}.time_series;
            %% Seperation - Multiple trail in one recording seperation
            time = emg_time(1);
            num=1;
            for n=1:length(events)
                if events(n) == "STOP"
                    time=[time,events_time(n)];
                    num=[num,n];
                end
            end
            %disp(["There are "+(length(num)-1)+" seperated recordings. "])

            %% Save seperations
            b=[1];
            for n=2:length(time)-1
                for m=1:length(emg_time)
                    if -(emg_time(m)-time(n))<10e-5
                        b=[b,m];
                        break
                    end
                end
            end
            b=[b,length(emg_time)];

            %app.origSampleRate = str2double(app.stream{1,5}.info.nominal_srate);

            %Find Event Index expected zone starte
            eventIDX = find(strcmp(events,'expected zone start'));
            eventTime = events_time(eventIDX);

            rEMGb=round(emg_time(:),3);
            rEMGa=round(eventTime,3);
            [~,emgCueIDX]=ismember(rEMGa,rEMGb);


            positionActual = position(1,:);

            rPositionb=round(position_time(:),1);
            rPositiona=round(eventTime,1);
            [~,positionTypeIDX]=ismember(rPositiona,rPositionb);

            if positionTypeIDX(1) == 0
                positionTypeIDX = positionTypeIDX(2:end);
                trunk = 1;
                msgbox('Warning: First Trial Removed')
            end
            positionState = positionActual(positionTypeIDX);


            runTemp = emgCueIDX;
            size(b,2)
            r = 1;
            for i = 2:size(b,2)
                runTemp(runTemp <= b(i) & runTemp >= r) = r;
                r = r+1;
            end

            %runTemp = string(runTemp);

            %%%%
            rHand = {};
            for i = 1:(length(num)-1)
                iTemp = string(i);
                groupName = menu('Run'+iTemp+'_Hand?','Left','Right');
                Run = string(i);
                if groupName == 1
                    Hand = "Le";
                else
                    Hand = "Ri";
                end
                rHand{end+1} = append(Run,Hand);
            end


            a = positionState;
            temp = [-1 a];
            idx2 =  temp(2:end) == 0; %R Extend
            idx3 =  temp(2:end) == 2; % R Flex
            b_loc = idx2 & temp(1:end-1) == 1;
            c_loc = idx2 & temp(1:end-1) == 2;
            d_loc = idx2 & temp(1:end-1) == 0;
            e_loc = idx3 & temp(1:end-1) == 1;
            f_loc = idx3 & temp(1:end-1) == 2;
            g_loc = idx3 & temp(1:end-1) == 0;
            new_a = string(a);
            new_a(b_loc) = "R2E";
            new_a(c_loc) = "F2E";
            new_a(d_loc) = "E2E";
            new_a(e_loc) = "R2F";
            new_a(f_loc) = "F2F";
            new_a(g_loc) = "E2F";
            p = positionState;
            positionState = new_a;


            for i = 1:(length(num)-1)
                z = (find(runTemp == i));
                if isempty(z) == 0
                    positionState(z(1)) = p(z(1));
                end
            end


            %%%%%
            app.CustomData.data = double(emg);
            app.CustomData.cueIDX = emgCueIDX;

            app.CustomData.emgTime = emg_time;
            app.CustomData.positionActual = positionActual;
            app.CustomData.positionTime = position_time;
            runTemp = string(runTemp);
            %positionState = string(positionState);


            if trunk == 1
                runTemp = runTemp(2:end);
                trunk = 0;

            end
            comment = strcat(runTemp,positionState);
            ttEmp = strcat(runTemp,positionState);

            for i = 1:(length(num)-1)
                iTemp = string(i);
                z = (find(runTemp == iTemp));
                if isempty(z) == 0
                    if extract(rHand{i},2) == "R"
                        comment(comment == (iTemp+"E2F")) ="Ri"+iTemp+"E2F";
                        comment(comment == (iTemp+"F2E")) ="Ri"+iTemp+"F2E";
                        comment(comment == (iTemp+"R2E")) ="Ri"+iTemp+"R2E";
                        comment(comment == (iTemp+"R2F")) ="Ri"+iTemp+"R2F";
                        comment(comment == (iTemp+"F2F")) ="Ri"+iTemp+"F2F";
                        comment(comment == (iTemp+"E2E")) ="Ri"+iTemp+"E2E";
                    elseif extract(rHand{i},2) == "L"
                        comment(comment == (iTemp+"E2F")) ="Le"+iTemp+"F2E";
                        comment(comment == (iTemp+"F2E")) ="Le"+iTemp+"E2F";
                        comment(comment == (iTemp+"R2E")) ="Le"+iTemp+"R2F";
                        comment(comment == (iTemp+"R2F")) ="Le"+iTemp+"R2E";
                        comment(comment == (iTemp+"F2F")) ="Le"+iTemp+"E2E";
                        comment(comment == (iTemp+"E2E")) ="Le"+iTemp+"F2F";
                    end
                end
            end
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            app.FileName.Value = fname;
            app.totChannel = size(emg,1);
            app.eventInfo = comment;
            app.totEvent = size(comment,2);
            app.unit = 'V';


            switch app.unit
                case 'V'                    % other units?
                    unitsMutiple = 1000;
                    app.CustomData.data = app.CustomData.data .* unitsMutiple;
            end
            app.unit = 'mV';


        end

        % Button pushed function: AnalyzeButton_3
        function AnalyzeButton_3Pushed(app, event)
            if app.RemoveCheckBox.Value == 1
                conditionInd = app.conditionIndRmIDX;

                for i = 1:length(conditionInd)
                    isempty(conditionInd{i});

                    if ans == 1
                        msgbox('Condition is empty')
                        return;
                    end


                end


            else
                conditionInd = app.conditionInd;
            end


            plotOrders = cell2mat(app.UITable_CondOder_3.Data(:,2));
            plotConditions = char(app.UITable_CondOder_3.Data(:,1));

            % define the data to plot
            if strcmp(app.ListBox.Value, 'Resting MEP')
                if strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Onset')
                    dataToAnalyze = app.dataOut.rMEPonset;
                elseif strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Duration')
                    dataToAnalyze = app.dataOut.rMEPdur;
                elseif strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                elseif strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Area')
                    dataToAnalyze = app.dataOut.Area;
                end
            end

            if strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                if strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Onset')
                    dataToAnalyze = app.dataOut.SPOnset;
                elseif strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Duration')
                    dataToAnalyze = app.dataOut.SPDur;
                elseif strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                elseif strcmp(app.MetricstoAnalyzeListBox_3.Value, 'Area')
                    dataToAnalyze = app.dataOut.AreaSP;
                end
            end

            % concatenate data
            if app.ConditionMeanCheckBox.Value == 1
                conditionNum = num2cell(1:length(plotOrders));
                tempPlotOrder = cellfun(@(x) find(plotOrders ==x), conditionNum);
                conditionCate = cellstr(plotConditions);
                conditionCate = conditionCate(tempPlotOrder);
                conditionData = dataToAnalyze(tempPlotOrder);
            else
                conditionCate = [];
                conditionData = [];
                conditionMean = [];
                for icondition = 1:length(plotOrders)
                    tempCate = plotConditions(plotOrders == icondition,:);
                    tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);
                    tempData = dataToAnalyze(conditionInd{plotOrders == icondition});
                    tempMean = mean(tempData);
                    conditionCate = [conditionCate;tempCate];
                    conditionData = [conditionData,tempData];
                    conditionMean = [conditionMean,tempMean];
                end
            end

            % plot the figure
            figure;
            app.dataOut.conditionCate = conditionCate;
            xtickL = unique(conditionCate, 'stable');
            xIDX = [1:size(xtickL,1)];
            [C,~,ib] = unique(conditionCate,'stable');
            boxchart(ib',conditionData);
            xticks(xIDX)
            xticklabels(xtickL)
            title('Visualize');
            hold on
            plot(ib',conditionData,'o','MarkerSize',4,'Color','b');


            % centrilization
            Cate = sort(xIDX);   % category
            if app.CentralityCheckBox_3.Value == 1
                percentile = app.EditField_7.Value;
                dataInMatrix = arrayfun(@(x) conditionData(find(ib'==x)), Cate,'UniformOutput', false)';
                nameInMatrix = arrayfun(@(x) ib(ib == x), Cate,'UniformOutput', false)';
                [centralizedData,centralizedName] = Centralization(dataInMatrix,nameInMatrix,percentile);
                conditionData = cell2mat(centralizedData')';
                conditionCate = cell2num(centralizedName)';      % conditionCate should be the same format as it is before centralization, so that Boltzman fit can function properly

                % plot centralized data
                ib = conditionCate';
                boxchart(ib,conditionData');
                xticks(xIDX)
                xticklabels(xtickL)
                hold on
                plot(ib,conditionData,'o','MarkerSize',4,'Color','r');
                title('Centralized');
            end


            % plot mean
            metricsMean = arrayfun(@(x) mean(conditionData(find(ib==x))), Cate,'UniformOutput', false);
            metricsMean = cell2mat(metricsMean');
            if app.MeanCheckBox_3.Value == 1
                hold on;
                plot(Cate,metricsMean,'o','Color','k','MarkerSize',10);
            end

            app.dataOut.metricMean2 = metricsMean(2);
            app.dataOut.metricMean1 = metricsMean(1);
            app.dataOut.ratio = (metricsMean(2)/metricsMean(1))*100;
            app.Ratio.Text = num2str(app.dataOut.ratio);


        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;

        end

        % Button pushed function: KeepSelectedRowsButton
        function KeepSelectedRowsButtonPushed(app, event)
            keepTableRow = app.TableRow;

            totTableRows = 1:size(app.UITable.Data,1);
            totTableRows(keepTableRow) = [];

            app.UITable.Data(totTableRows,:) = [];

            if iscell(app.allConditionData) == 1
                if cellfun(@isempty,app.allConditionData) ~= 1
                    app.allConditionData(totTableRow) =[];
                    %app.conditionData(app.TableRow) = [];
                    %app.conditionInd(app.TableRow) = [];
                end
            end
            totTableRow = [];
            app.TableRow = [];


            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'table','');
        end

        % Button pushed function: LoadBrainsighttxtButton
        function LoadBrainsighttxtButtonPushed(app, event)
            opts = delimitedTextImportOptions("NumVariables", 27);
            opts.DataLines = [1, Inf];
            opts.Delimiter = "\t";
            strcat(app.toolboxDir,'/*.txt');
            [fname, path] = uigetfile(ans);
            a = strcat(path,fname);
            app.BrainSightData = readmatrix(a, opts);

            sampleIDX = find(contains(app.BrainSightData(:,1),app.EditField_10.Value));

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront


            for iSample = 1:size(sampleIDX,1)
                x = str2num(app.BrainSightData{sampleIDX(iSample),5});
                y = str2num(app.BrainSightData{sampleIDX(iSample),6});
                z = str2num(app.BrainSightData{sampleIDX(iSample),7});
                grid = (app.BrainSightData{sampleIDX(iSample),4});


                groupInfo = {[],app.BrainSightData{sampleIDX(iSample)},[],x,y,z,grid};
                app.UITable.Data = [app.UITable.Data;groupInfo];
                app.allConditionEvents = [app.allConditionEvents;app.EventName];
            end


        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            if app.RemoveCheckBox.Value == 1
                conditionInd = app.conditionIndRmIDX;

                for i = 1:length(conditionInd)
                    isempty(conditionInd{i});

                    if ans == 1
                        msgbox('Condition is empty')
                        return;
                    end


                end


            else
                conditionInd = app.conditionInd;
            end


            plotOrders = cell2mat(app.UITable_CondOder_4.Data(:,2));
            plotConditions = char(app.UITable_CondOder_4.Data(:,1));

            % define the data to plot
            if strcmp(app.ListBox.Value, 'Resting MEP')
                if strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Onset')
                    dataToAnalyze = app.dataOut.rMEPonset;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Duration')
                    dataToAnalyze = app.dataOut.rMEPdur;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Area')
                    dataToAnalyze = app.dataOut.Area;
                    preStimtoAnalyze = app.PreStimMean;
                end
            end

            if strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                if strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Onset')
                    dataToAnalyze = app.dataOut.SPOnset;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Duration')
                    dataToAnalyze = app.dataOut.SPDur;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Amplitude')
                    dataToAnalyze = app.dataOut.mepAmp;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Area')
                    dataToAnalyze = app.dataOut.AreaSP;
                    preStimtoAnalyze = app.PreStimMean;
                end
            end

            if strcmp(app.ListBox.Value, 'Silent Period')
                if strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Onset')
                    dataToAnalyze = app.dataOut.SPOnset;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Duration')
                    dataToAnalyze = app.dataOut.SPDur;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, '% Decrease')
                    dataToAnalyze = app.dataOut.spPercentDecrease;
                    preStimtoAnalyze = app.PreStimMean;
                elseif strcmp(app.MetricstoAnalyzeListBox_4.Value, 'Area')
                    dataToAnalyze = app.dataOut.AreaSP;
                    preStimtoAnalyze = app.PreStimMean;
                end
            end


            % concatenate data
            if app.ConditionMeanCheckBox.Value == 1
                conditionNum = num2cell(1:length(plotOrders));
                tempPlotOrder = cellfun(@(x) find(plotOrders ==x), conditionNum);
                conditionCate = cellstr(plotConditions);
                conditionCate = conditionCate(tempPlotOrder);
                conditionData = dataToAnalyze(tempPlotOrder);
                conditionPreStimData = preStimtoAnalyze(tempPlotOrder);
            else
                conditionCate = [];
                conditionData = [];
                conditionMean = [];
                conditionPreStimData = [];
                conditionPreStimMean = [];
                for icondition = 1:length(plotOrders)
                    tempCate = plotConditions(plotOrders == icondition,:);
                    tempCate = repmat(cellstr(tempCate),length(conditionInd{plotOrders == icondition}),1);

                    tempData = dataToAnalyze(conditionInd{plotOrders == icondition});
                    tempMean = mean(tempData);

                    tempPreStimData = preStimtoAnalyze(conditionInd{plotOrders == icondition});
                    tempPreStimMean = mean(tempData);

                    conditionCate = [conditionCate;tempCate];
                    conditionData = [conditionData,tempData];
                    conditionMean = [conditionMean,tempMean];
                    conditionPreStimData = [conditionPreStimData, tempPreStimData];
                    conditionPreStimMean = [conditionPreStimMean, tempPreStimMean];
                end
            end

            % plot the figure
            figure;
            app.dataOut.conditionCate = conditionCate;
            xtickL = unique(conditionCate,'stable');
            xIDX = [1:size(xtickL,1)];
            [C,~,ib] = unique(conditionCate,'stable');

            Thresh = [ib'; conditionData]';
            Keep = Thresh(Thresh(:,2) >= app.EditField_11.Value, :);
            app.dataOut.count = [];

            for i = 1:max(Thresh(:,1))
                tot = length(find(Thresh(:,1) == i));
                mep = length((find(Keep(:,1) == i)));
                app.dataOut.count(end+1) = (mep/tot)*100;
            end


            Thresh = Thresh(Thresh(:,2) >= app.EditField_11.Value, :);
            plot(Thresh(:,1),Thresh(:,2),'o','MarkerSize',7,'Color','b');


            boxchart(Thresh(:,1),Thresh(:,2));
            xticks(xIDX)
            xticklabels(xtickL)
            title('Mapping');
            hold on
            plot(Thresh(:,1),Thresh(:,2),'o','MarkerSize',4,'Color','b');

            conditionData = Thresh(:,2);
            ib = Thresh(:,1);

            % centrilization
            Cate = sort(xIDX);   % category
            if app.CentralityCheckBox_4.Value == 1
                percentile = app.EditField_7.Value;
                dataInMatrix = arrayfun(@(x) conditionData(find(ib'==x)), Cate,'UniformOutput', false)';
                nameInMatrix = arrayfun(@(x) ib(ib == x), Cate,'UniformOutput', false)';
                [centralizedData,centralizedName] = Centralization(dataInMatrix,nameInMatrix,percentile);
                conditionData = cell2mat(centralizedData')';
                conditionCate = cell2num(centralizedName)';      % conditionCate should be the same format as it is before centralization, so that Boltzman fit can function properly

                % plot centralized data
                ib = conditionCate';
                boxchart(ib,conditionData');
                xticks(xIDX)
                xticklabels(xtickL)
                hold on
                plot(ib,conditionData,'o','MarkerSize',4,'Color','r');
                title('Centralized');
            end


            app.condition = ib;
            app.conditionOutcome = conditionData;

            % plot mean
            metricsMean = arrayfun(@(x) mean(conditionData(find(ib==x))), Cate,'UniformOutput', false);
            app.dataOut.metricsMean = cell2mat(metricsMean');
            metricsPreStimMean =  arrayfun(@(x) mean(conditionPreStimData(find(ib==x))), Cate,'UniformOutput', false);
            app.dataOut.metricsPreStimMean = cell2mat(metricsPreStimMean');
            if app.MeanCheckBox_4.Value == 1
                hold on;
                plot(Cate,app.dataOut.metricsMean,'o','Color','k','MarkerSize',10);
            end


            %%%%%%%%%%%Update Countour Maps


            RENORM = 0;

            threshMapCrit = (app.dataOut.count' > app.EditField_13.Value);
            dataMap = app.dataOut.metricsMean;

            for idx = 1:size(threshMapCrit)
                if threshMapCrit(idx) == 0
                    dataMap(idx) = NaN;
                end
            end


            Map = [cell2num(app.UITable_CondOder_4.Data(:,3)),cell2num(app.UITable_CondOder_4.Data(:,4)),dataMap];
            Map(isnan(Map))=0;


            X= Map(:,1);
            Y= Map(:,2);
            Z= Map(:,3);

            % NORMALIZATION
            maxZ = 100;
            if RENORM == 1
                maxZ = max(.062);
                Z=100*Z/maxZ;
            end

            ft = fittype( 'biharmonicinterp' );
            opts = fitoptions( ft );
            opts.Weights = zeros(1,0);
            [fitresult, gof] = fit( [X, Y], Z, ft, opts );

            % Plot fit with data.
            figure( 'Name', '3D mapping' );
            h = plot( fitresult);
            grid off
            % Label axes
            xlabel( 'X' );
            ylabel( 'Y' );
            zlabel( 'Z' );
            legend( h, '3D version of the motor map', 'Z vs. X, Y', 'Location', 'NorthEast' );
            % Make contour plot.
            figure('name','contour map');
            h = plot( fitresult, [X, Y], Z, 'Style', 'Contour' );
            grid off
            % Label axes
            xlabel( 'X' );
            ylabel( 'Y' );
            legend( h, 'contour of the motor map', 'Z vs. X, Y' );

            a1=min(X);
            a2=max(X);%get the range the value of X
            b1=min(Y);
            b2=max(Y);%get the range of y
            stepa=(a2-a1)/100;
            stepb=(b2-b1)/100;%get the steps for the coming circulation
            for i1=1:1:101
                x(i1)=a1;
                a1=a1+stepa;
            end
            for i1=1:1:101
                y(i1)=b1;
                b1=b1+stepb;
            end
            n=1;
            for i=1:101
                for j=1:101
                    new(n)=fitresult(x(i),y(j));
                    n=n+1;
                end
            end
            %get total rd
            totalrd=0;
            for i=1:(n-1)
                total(i)=new(i)*(stepa*stepb);
                totalrd=totalrd+total(i);
            end
            totalrd=totalrd/(stepa*stepb*n);
            %calculate group rd's
            n1=1;n2=1;n3=1;n4=1;
            for i=1:(n-1)
                if (new(i)>=0)&(new(i)<25)
                    c1(n1)=new(i)*(stepa*stepb);
                    n1=n1+1;
                else
                    if (new(i)>=25)&(new(i)<50)
                        c2(n2)=new(i)*(stepa*stepb);
                        n2=n2+1;
                    else
                        if (new(i)>=50)&(new(i)<75)
                            c3(n3)=new(i)*(stepa*stepb);
                            n3=n3+1;
                        else
                            if (new(i)>=75)&(new(i)<=100)
                                c4(n4)=new(i)*(stepa*stepb);
                                n4=n4+1;
                            end
                        end
                    end
                end
            end

            rd1=0;
            rd2=0;
            rd3=0;
            rd4=0;
            for i=1:(n1-1)
                rd1=rd1+c1(i);
            end
            for i=1:(n2-1)
                rd2=rd2+c2(i);
            end
            for i=1:(n3-1)
                rd3=rd3+c3(i);
            end
            for i=1:(n4-1)
                rd4=rd4+c4(i);
            end
            rd1=rd1/(stepa*stepb*n1);
            rd2=rd2/(stepa*stepb*n2);
            rd3=rd3/(stepa*stepb*n3);
            rd4=rd4/(stepa*stepb*n4);

            fprintf(1,'%6.4f\t',totalrd*maxZ/100);
            fprintf(1,'%6.4f\t',rd1*maxZ/100);
            fprintf(1,'%6.4f\t',rd2*maxZ/100);
            fprintf(1,'%6.4f\t',rd3*maxZ/100);
            fprintf(1,'%6.4f\t',rd4*maxZ/100);

            fprintf(1,'\n')
            Zrenorm = Z*maxZ/100;
            lev1 = sum( Zrenorm(find(Z>=0 & Z<25)));
            lev2 = sum( Zrenorm(find(Z>=25 & Z<50)));
            lev3 = sum( Zrenorm(find(Z>=50 & Z<75)));
            lev4 = sum( Zrenorm(find(Z>=75 & Z<=100)));
            lev = sum(Zrenorm);
            fprintf(1,'%6.4f\t',lev);
            fprintf(1,'%6.4f\t',lev1);
            fprintf(1,'%6.4f\t',lev2);
            fprintf(1,'%6.4f\t',lev3);
            fprintf(1,'%6.4f\t',lev4);

            fprintf(1,'\n')

            mapCount = find(dataMap > 0);
            app.dataOut.mapCount = size(mapCount,1);
            app.dataOut.dataMap = dataMap;
            app.dataOut.dataMapNorm = (dataMap/max(dataMap))*100;


        end

        % Display data changed function: UITable_CondOder_2
        function UITable_CondOder_2DisplayDataChanged(app, event)
            newDisplayData = app.UITable_CondOder_2.DisplayData;

        end

        % Button pushed function: ReorderButton_4
        function ReorderButton_4Pushed(app, event)
            plotOrders = cell2mat(app.UITable_CondOder_4.Data(:,2));
            plotConditions = char(app.UITable_CondOder_4.Data(:,1));

            % reorder the conditions
            A = str2num(plotConditions);
            B = sort(A);
            plotOrders = arrayfun(@(x) find(B == x), A);
            app.UITable_CondOder_4.Data(:,2) = num2cell(plotOrders');
        end

        % Button pushed function: ExportNeuroMeasurecsvButton
        function ExportNeuroMeasurecsvButtonPushed(app, event)
            % creat csv file
            % NeuroMeasure Mapping CSV file create
            % check if the data saving folder exists, if no creat one
            resultsDir = strcat(app.toolboxDir,'/Results');
            if exist(resultsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(resultsDir);
            end
            OldFolder = pwd;


            resultsDir = strcat(app.toolboxDir,'/Results');
            mappingResults = strcat(resultsDir,'/NeuroMeasure.csv');
            if exist(mappingResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'Group Name';
                tempHeaderOut{2} = 'X';
                tempHeaderOut{3} = 'Y';
                tempHeaderOut{4} = 'Z';    %
                tempHeaderOut{5} = 'GridX';
                tempHeaderOut{6} = 'GridY';
                tempHeaderOut{7} = 'Feature';    %
                tempHeaderOut{8} = 'Normalized Feature';
                tempHeaderOut{9} = 'MEPFrequency';
                tempHeaderOut{10} = 'PreStim Mean';
                fid = fopen(mappingResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,mappingResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end

            if app.MeanCheckBox_4.Value == 1 || app.CentralityCheckBox_4.Value == 1
                tempDataOut{1} = repmat(app.UITable_CondOder_4.Data(:,1),1);
                tempDataOut{2} = repmat(app.UITable_CondOder_4.Data(:,3),1);
                tempDataOut{3} = repmat(app.UITable_CondOder_4.Data(:,4),1);
                tempDataOut{4} = repmat(app.UITable_CondOder_4.Data(:,5),1);
                %tempDataOut{5} = repmat(app.UITable_CondOder_4.Data(:,6),1);
                tempDataOut{5} = repmat(app.UITable_CondOder_4.Data(:,1),1);
                %tempDataOut{6} = repmat(app.UITable_CondOder_4.Data(:,7),1); 
                tempDataOut{6} = repmat(app.UITable_CondOder_4.Data(:,1),1);
                tempDataOut{7} = repmat(app.dataOut.dataMap,1);
                tempDataOut{8} = repmat(app.dataOut.dataMapNorm,1);
                tempDataOut{9} = repmat(app.dataOut.count',1);
                tempDataOut{10} = repmat(app.dataOut.metricsPreStimMean,1);


                T = table(tempDataOut{:});               % compose into a table to better import to csv file,
                T = sortrows(T,5);
                writetable(T,mappingResults,'WriteMode','Append')
            else
                tempDataOut{1} = repmat(app.UITable_CondOder_4.Data(app.condition(:),3),1);
                tempDataOut{2} = repmat(app.UITable_CondOder_4.Data(app.condition(:),4),1);
                tempDataOut{3} = repmat(app.UITable_CondOder_4.Data(app.condition(:),5),1);
                tempDataOut{4} = repmat(app.UITable_CondOder_4.Data(app.condition(:),6),1);
                tempDataOut{5}=num2cell(app.conditionOutcome');

                T = table(tempDataOut{:});               % compose into a table to better import to csv file,

                writetable(T,mappingResults,'WriteMode','Append')
            end


            app.ExportNeuroMeasurecsvButton.Enable = 0;
            pause(0.5)
            app.ExportNeuroMeasurecsvButton.Enable = 1;


        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function OKButton_4Pushed(app, event)
            scroll(app.MultiChanListBox,2)
        end

        % Value changed function: OverlayNonRectifiedCheckBox
        function OverlayNonRectifiedCheckBoxValueChanged(app, event)
            value = app.OverlayNonRectifiedCheckBox.Value;
            if app.OverlayNonRectifiedCheckBox.Value ==1
                hold( app.UIAxes, 'on' )
                plot(app.UIAxes,app.TimeToPlot,app.VelocityData)
                hold( app.UIAxes, 'off' )
                %else
                %ProcessButtonPushed(app, event)
            end
        end

        % Button pushed function: LoadGroupNamesxlsButton
        function LoadGroupNamesxlsButtonPushed(app, event)

            opts = spreadsheetImportOptions("NumVariables",50); %Can include upto 50 cells in excel
            strcat(app.toolboxDir,'/*.xlsx');
            [fname, path] = uigetfile(ans);
            strcat(path,fname)
            loadIn = (readmatrix(ans, opts,"UseExcel", false));
            tempRemove =all(cellfun('isempty',loadIn)); %removes empty cells
            loadIn(:,tempRemove)=[];
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront


            for iSample = 1:size(loadIn,1)
                groupName = strjoin(loadIn(iSample,:),'_'); %Combine Cells and delim by '_'
                groupInfo = {[],groupName,[],[],[],[]};
                app.UITable.Data = [app.UITable.Data;groupInfo];
                app.allConditionEvents = [app.allConditionEvents;app.EventName];
            end

        end

        % Menu selected function: BrainSighttxtMenu
        function BrainSighttxtMenuSelected(app, event)

            app.dataType = 7;
            opts = delimitedTextImportOptions("NumVariables", 22);
            opts.DataLines = [1, Inf];
            opts.Delimiter = "\t"
            strcat(app.DataDir,'/*.txt');
            [fname, path] = uigetfile(ans);
            app.FileName.Value = fname;
            strcat(path,fname)
            app.BrainSightDataWave = readmatrix(ans, opts);

            app.origSampleRate = 3000;                                                   % Sample rate should be the same across all channels and blocks

            ch1 = find(strcmp('EMG Data 1',app.BrainSightDataWave));
            ch2 = find(strcmp('EMG Data 2',app.BrainSightDataWave));

            if ~isempty(ch1) && ~isempty(ch2)
                app.totChannel = 2;                                                         % Define Total Number of Channels. totChannel(1) refers to channels. totChannel(2) refers to blocks
            else
                app.totChannel = 1;
            end

            app.unit = 'uV';                                                            % defines unit. Unit of the LabChart data
            app.reloadInd = 0;                                                          % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.FileName.Value = fname;                                              % display the file name
            app.timeUnit = 's';
            [T,P] = find(strcmp('EMG Start',app.BrainSightDataWave));
            EMGStart = app.BrainSightDataWave(T+1,P);
            EMGEnd =  app.BrainSightDataWave(T+1,P+1);
            app.PreTriggerTime.Value = str2num(cell2num(EMGStart));
            app.PreDurs.Value = num2str(abs(str2num(cell2num(EMGStart))/1000));
            app.PostDur.Value = num2str(str2num(cell2num(EMGEnd))/1000);
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %%Search for Comment
            eventColumn = find(contains(app.BrainSightDataWave(:,1),'Sample Name'));

            %dataInfo = find(contains(app.BrainSightDataWave(9,:),'EMG Data'));

            if ~isempty(ch1) && ~isempty(ch2)
                [T1,P1] = find(strcmp('EMG Data 1',app.BrainSightDataWave));
                [T2,P2] = find(strcmp('EMG Data 2',app.BrainSightDataWave));                                                     % Define Total Number of Channels. totChannel(1) refers to channels. totChannel(2) refers to blocks
            elseif ~isempty(ch1)
                [T,P] = find(strcmp('EMG Data 1',app.BrainSightDataWave));
            else
                [T,P] = find(strcmp('EMG Data 2',app.BrainSightDataWave));
            end


            %%REMOVES SAMPLES WITH NO DATA
            nullIDX = cellfun(@(s) strcmp(s,'(null)'),app.BrainSightDataWave(:,P),'UniformOutput',false)
            [a,b]=find([nullIDX{:}] == 1);
            k = strjoin(string(zeros(601,1)'),';');
            app.BrainSightDataWave(b,P) = {convertStringsToChars(k)};
            %%%%%%%%%%%%%%%%%%%%%

            eventInfo = find(contains(app.BrainSightDataWave(:,1),'Sample'));
            app.eventInfo =eventInfo(eventInfo > eventColumn)

            trialInfo=app.BrainSightDataWave(app.eventInfo,1);
            sp = split(trialInfo);
            app.BrainsightTrialNum = str2double(sp(:,2));

            if app.totChannel == 2
                ch1BrainSightDataWave = (app.BrainSightDataWave(app.eventInfo,P1));
                ch2BrainSightDataWave = (app.BrainSightDataWave(app.eventInfo,P2));
                app.totEvent = length(app.eventInfo);
                app.BrainSightDataWave = num2cell(str2double(split(ch1BrainSightDataWave,';',2)),2)';
                app.BrainSightDataWave = [app.BrainSightDataWave;num2cell(str2double(split(ch2BrainSightDataWave,';',2)),2)'];

            else
                app.BrainSightDataWave = (app.BrainSightDataWave(app.eventInfo,P));
                app.totEvent = length(app.eventInfo);
                app.BrainSightDataWave = num2cell(str2double(split( app.BrainSightDataWave ,';',2)),2)';

            end
            % unify the units across different data types to mV
            switch app.unit
                case 'uV'                    % other units?
                    unitsMutiple = .001;
                    app.BrainSightDataWave = cellfun(@(x) (unitsMutiple*x),  app.BrainSightDataWave,'UniformOutput', false);
            end
            app.unit = 'mV';
        end

        % Button pushed function: DefineResultsDirectoryButton
        function DefineResultsDirectoryButtonPushed(app, event)

            app.toolboxDir = uigetdir; %Defines where open file directs you to
            SavedSettings.toolboxpath = app.toolboxDir;
            strcat(userpath,'/SavedSettings');
            save(ans,'SavedSettings');
        end

        % Button pushed function: SaveCSVButton
        function SaveCSVButtonPushed(app, event)
            % creat csv file
            % NeuroMeasure Mapping CSV file create
            % check if the data saving folder exists, if no creat one
            resultsDir = strcat(app.toolboxDir,'/Results');
            if exist(resultsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(resultsDir);
            end
            OldFolder = pwd;


            resultsDir = strcat(app.toolboxDir,'/Results');
            mappingResults = strcat(resultsDir,'/Visualize.csv');
            if exist(mappingResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'Condition';    %
                tempHeaderOut{2} = 'Feature';    %
                tempHeaderOut{3} = 'MEPFrequency';
                tempHeaderOut{4} = 'PreStim Mean';
                fid = fopen(mappingResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,mappingResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end


            tempDataOut{1} = repmat(app.dataOut.condtionLabel,1);
            tempDataOut{2} = repmat(app.dataOut.metricsMean,1);
            tempDataOut{3} = repmat(app.dataOut.count',1);
            tempDataOut{4} = repmat(app.dataOut.metricsPreStimMean,1);

            T = table(tempDataOut{:});               % compose into a table to better import to csv file,

            writetable(T,mappingResults,'WriteMode','Append')


            app.SaveCSVButton.Enable = 0;
            pause(0.5)
            app.SaveCSVButton.Enable = 1;

        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            
            app. importEvent = 1;
            for i = 1: size(app.EventNameListBox_2.Items,2)
                app.EventNameListBox_2.Value = cell2num(app.EventNameListBox_2.Items(i));
                InitializationButtonPushed(app, event);     % whenever choose another event name, find event again instead of call the saved data matrix
                FindallEventsButtonPushed(app, event);
                TrialofThisGroup = 1 : app.totEvent;
                TrialofThisGroup = num2str(TrialofThisGroup);
                channel  = num2str(app.ChannelSpinner.Value);
                groupInfo = {TrialofThisGroup,app.EventNameListBox_2.Value,channel,[],[],[],app.EventNameListBox_2.Value};

                app.UITable.Data = [app.UITable.Data;groupInfo];

                % initialization
                app.TrialsnotfoundEditField.Visible = 0;
                app.TrialsnotfoundEditFieldLabel.Visible = 0;
                app.Ztrans = 0;
                app.Prolific = 0;
                %             app.ConditionsListBox.Visible = 1;
                %             app.ConditionsListBoxLabel.Visible  = 1;

                % extract condition trials
                length(app.UITable.Data(:,4));       % find condition name
                ConditionTrials = app.UITable.Data(ans,1);          % find condition trials to chosen condition name
                %ConditionTrials = str2num(cell2num(ConditionTrials));
                ConditionTrials = str2num(cell2mat(ConditionTrials));

                % define chosen data
                legend(app.UIAxes,'off');
                app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,ConditionTrials)');
                app.conditionData = app.chosenData;                                     % save the condition data
                app.conditionTotalEvent = size(app.conditionData,1);
                app.meanData = sum(app.chosenData)/size(app.chosenData,1);

                app.DataToPlot = app.chosenData;
                app.TimeToPlot = app.time;

                % if data been pre-processed, plot by pre-process pannel
                if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                    ProcessButtonPushed(app, event);
                else
                    % change the scale accordingly
                    % plot in mV first, the change the unit if need
                    cla(app.UIAxes);
                    app.AnalyzeInd = 0;
                    app.Patch = [];
                    plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
                    if size(ConditionTrials,2)
                        if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                        else
                            hold(app.UIAxes,'on')
                            plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                            hold(app.UIAxes,'off')
                        end
                    end
                    app.UIAxes.YLabel.String = app.mVButton.Text;
                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end                    % change the unit
                    ButtonGroupUnitsSelectionChanged(app, event)
                end

                % save the data to the condition
                if length(app.UITable.Data(:,2)) == 1
                    app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
                    if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
                        app.allConditionEvents = {app.EventName};
                    end
                else
                    app.allConditionData = [app.allConditionData; {{app.chosenData}}];
                    if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
                        app.allConditionEvents = [app.allConditionEvents;app.EventName];
                    end
                end

                % display trials of this condition in trial select list box
                tempEvents = 1 : app.conditionTotalEvent;
                app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
                ListBoxValueChanged3(app, event)

            end

            answer = questdlg('Load New Group Name','Load New Group Name','Yes','No','No');
            switch answer
                case 'Yes'
                    ld = 0;
                case 'No'
                    ld = 1;
            end

            if ld == 0
                opts = spreadsheetImportOptions("NumVariables",50); %Can include upto 50 cells in excel
                strcat(app.toolboxDir,'\*.xlsx');
                [fname, path] = uigetfile(ans);
                strcat(path,fname)
                loadIn = (readmatrix(ans, opts,"UseExcel", false));
                tempRemove =all(cellfun('isempty',loadIn)); %removes empty cells
                loadIn(:,tempRemove)=[];
                drawnow; %Puts UI Figure at the forfront
                figure(app.UIFigure) %Puts UI Figure at the forfront
                groupName = [];
                for iSample = 1:size(loadIn,1)
                    groupName = [groupName,{strjoin(loadIn(iSample,:),'_')}]; %Combine Cells and delim by '_'
                end
                app.UITable.Data(:,2) = groupName;

            else
            end


%             app. importEvent = 1;
%             for i = 1: size(app.EventNameListBox.Items,2)
%                 app.EventNameListBox.Value = cell2num(app.EventNameListBox.Items(i));
%                 InitializationButtonPushed(app, event);     % whenever choose another event name, find event again instead of call the saved data matrix
%                 FindallEventsButtonPushed(app, event);
%                 TrialofThisGroup = 1 : app.totEvent;
% 
%                 if size(TrialofThisGroup,2) ~= 1
% 
%                     TrialofThisGroup = num2str(TrialofThisGroup);
%                     channel  = num2str(app.ChannelSpinner.Value);
%                     groupInfo = {TrialofThisGroup,app.EventNameListBox.Value,channel,[],[],[],app.EventNameListBox.Value};
% 
%                     app.UITable.Data = [app.UITable.Data;groupInfo];
% 
%                     % initialization
%                     app.TrialsnotfoundEditField.Visible = 0;
%                     app.TrialsnotfoundEditFieldLabel.Visible = 0;
%                     app.Ztrans = 0;
%                     app.Prolific = 0;
%                     %             app.ConditionsListBox.Visible = 1;
%                     %             app.ConditionsListBoxLabel.Visible  = 1;
% 
%                     % extract condition trials
%                     length(app.UITable.Data(:,4));       % find condition name
%                     ConditionTrials = app.UITable.Data(ans,1);          % find condition trials to chosen condition name
%                     %ConditionTrials = str2num(cell2num(ConditionTrials));
%                     ConditionTrials = str2num(cell2mat(ConditionTrials));
% 
%                     % define chosen data
%                     legend(app.UIAxes,'off');
%                     app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,ConditionTrials)');
%                     app.conditionData = app.chosenData;                                     % save the condition data
%                     app.conditionTotalEvent = size(app.conditionData,1);
%                     app.meanData = sum(app.chosenData)/size(app.chosenData,1);
% 
%                     app.DataToPlot = app.chosenData;
%                     app.TimeToPlot = app.time;
% 
%                     % if data been pre-processed, plot by pre-process pannel
%                     if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
%                         ProcessButtonPushed(app, event);
%                     else
%                         % change the scale accordingly
%                         % plot in mV first, the change the unit if need
%                         cla(app.UIAxes);
%                         app.AnalyzeInd = 0;
%                         app.Patch = [];
%                         plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
%                         if size(ConditionTrials,2)
%                             if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
%                             else
%                                 hold(app.UIAxes,'on')
%                                 %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
%                                 hold(app.UIAxes,'off')
%                             end
%                         end
%                         app.UIAxes.YLabel.String = app.mVButton.Text;
%                         app.tempH = [];
%                         h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
%                         for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
%                             app.tempH(iLine,:) = h(iLine).YData(1,:);
%                         end                    % change the unit
%                         ButtonGroupUnitsSelectionChanged(app, event)
%                     end
% 
%                     % save the data to the condition
%                     if length(app.UITable.Data(:,2)) == 1
%                         app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
%                         if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
%                             app.allConditionEvents = {app.EventName};
%                         end
%                     else
%                         app.allConditionData = [app.allConditionData; {{app.chosenData}}];
%                         if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
%                             app.allConditionEvents = [app.allConditionEvents;app.EventName];
%                         end
%                     end
% 
%                     % display trials of this condition in trial select list box
%                     tempEvents = 1 : app.conditionTotalEvent;
%                     app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
%                     ListBoxValueChanged3(app, event)
% 
% 
% 
%                 else
%                 end
% 
% 
% 
% 
% 
%             end
% 
%             %%%%%ORIGINAL WAY TO CHANGE GROUP NAME
%             %                              answer = questdlg('Load New Group Name','Load New Group Name','Yes','No','No');
%             %                     switch answer
%             %                         case 'Yes'
%             %                             ld = 0;
%             %                         case 'No'
%             %                             ld = 1;
%             %                     end
%             %
%             %                     if ld == 0
%             %                         opts = spreadsheetImportOptions("NumVariables",50); %Can include upto 50 cells in excel
%             %                         strcat(app.toolboxDir,'/*.xlsx');
%             %                         [fname, path] = uigetfile(ans);
%             %                         strcat(path,fname)
%             %                         loadIn = (readmatrix(ans, opts,"UseExcel", false));
%             %                         tempRemove =all(cellfun('isempty',loadIn)); %removes empty cells
%             %                         loadIn(:,tempRemove)=[];
%             %                         drawnow; %Puts UI Figure at the forfront
%             %                         figure(app.UIFigure) %Puts UI Figure at the forfront
%             %                         groupName = [];
%             %                         for iSample = 1:size(loadIn,1)
%             %                             groupName = [groupName,{strjoin(loadIn(iSample,:),'_')}]; %Combine Cells and delim by '_'
%             %                         end
%             %                         app.UITable.Data(:,2) = groupName;
%             %
%             %                     else
%             %                     end


        end

        % Value changed function: LowPassEnvelopeCheckBox_3
        function LowPassEnvelopeCheckBox_3ValueChanged(app, event)
            value = app.LowPassEnvelopeCheckBox_3.Value;
            if app.LowPassEnvelopeCheckBox_3.Value == 0
                app.HzEditField_6.Enable = 1;
                %app.HzEditField_6.Value = 0;
                plot(app.UIAxes,app.TimeToPlot,app.DataToPlot,'Color','k');
            else
                app.HzEditField_6.Enable = 1;

            end

            if app.ProcessInd == 1
                sampleRate = app.dwnSampleRate;
            else
                sampleRate = app.origSampleRate;
            end

            EMGBurst = app.DataToPlot;

            % show low pass enverlop
            if app.LowPassEnvelopeCheckBox_3.Value == 1
                Fq = app.HzEditField_6.Value;
                [b,a] = (butter(6,(Fq/(sampleRate/2)),'low'));
                LPEverl = num2cell(EMGBurst, 2);                       % low pass everlope
                LPEverl = cellfun(@(x) filtfilt(b,a,x),  LPEverl,'UniformOutput', false);
                LPEverl = cell2mat(LPEverl);
                EMGBurstEnv = LPEverl;
                app.ENVDataToPlot = EMGBurstEnv;
                plot(app.UIAxes,app.TimeToPlot,EMGBurstEnv,'Color',1/255*[0,104,87],"LineWidth",1);
            end


        end

        % Button pushed function: AnalyzeButton_4
        function AnalyzeButton_4Pushed(app, event)
            app.AnalyzeInd = 1;   % Analyze button clicked
            app.overRideMark = 0;                              % it is not overrided
            app.EMGBurstoverRideMark = 0;
            app.EMGAnalysisOR = 0;

            %Define Data
            if app.LowPassEnvelopeCheckBox_3.Value == 0
                EMGData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
                EMGTime = app.TimeToPlot;
            else
                EMGData = app.ENVDataToPlot;
                EMGTime = app.TimeToPlot;
            end

            %determine sample Rate
            if app.ProcessInd == 1
                sampleRate = app.dwnSampleRate;
            else
                sampleRate = app.origSampleRate;
            end

            % determine if it is multiple trials
            if size(EMGData,1) == 1
                singleTrial = 1;
            else
                singleTrial = 0;
            end

            preStimDurInd = find(EMGTime >= (app.PreStimPeriodmsEditField.Value/1000) & EMGTime<= app.EditField_3.Value/1000);
            minDurPointsOn = round(app.MinDurmsEditField_3.Value/1000*sampleRate);    % min duration to determine onset


            tempData = EMGData;       % EMGData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
            tempData = num2cell(tempData,2);
            preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
            preStimData = cell2mat(preStimData);

            % calculate average
            if app.AnalyzetheAverageCheckBox_3.Value == 1
                EMGData = sum(EMGData)/size(EMGData,1);
                preStimData = sum(preStimData)/size(preStimData,1);
                app.ClearBackgroundLinesCheckBox.Visible = 1;
            else %  clear background lines can only be checked when take average is checked
                app.ClearBackgroundLinesCheckBox.Visible = 0;
                app.ClearBackgroundLinesCheckBox.Value = 0;
            end


            % find average and std of the Pre-stimulation Period
            preStimMean = mean(preStimData);
            preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
            app.PreStimMeanEditField.Value = double(mean(preStimMean)); %Displays 1) Mean of all Single Trial 2) Mean of average
            app.PreStimStdEditField.Value = double(mean(preStimStd));   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % figure plot
            cla(app.UIAxes);
            % plot in mV first. Then change the unit accordingly
            legend(app.UIAxes,'AutoUpdate','off');
            plot(app.UIAxes,app.TimeToPlot,EMGData,'Color','k',"LineWidth",2);
            if app.AnalyzetheAverageCheckBox.Value == true     % here SP data is the mean of all trials
                AveragePlot = plot(app.UIAxes,app.TimeToPlot,EMGData,'Color','k',"LineWidth",2);
            end
            hold(app.UIAxes,'on')

            % draw prestimulation mean
            if size(EMGData,1) == 1 % exclude the situation that multiple trials selected but dont analyze average
                app.PreMeanPlot = plot(app.UIAxes,app.TimeToPlot,repmat(preStimMean,1,length(app.TimeToPlot)),'Color','k','DisplayName', 'PreStimMean');
            end

            StartTimeInd = find(app.TimeToPlot <= app.StartTimemsEditField_4.Value/1000);

            app.On = xline(app.UIAxes,EMGTime(StartTimeInd(end)),'k','LineStyle','--',"LineWidth",1,'DisplayName', 'Start Time');
            hold(app.UIAxes,'off');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % find onset, if single trial
            if singleTrial == 1
                lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField_2.Value;

                hold(app.UIAxes,'on')
                app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');

                hold(app.UIAxes,'off');
                app.tempH = [];
                h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                    app.tempH(iLine,:) = h(iLine).YData(1,:);
                end            % change the unit
                ButtonGroupUnitsSelectionChanged(app, event)

                OnThreshold = lowEndLimit; % onset threshold
                StartTimeInd = find(EMGTime <= app.StartTimemsEditField_4.Value/1000);
                EMGData(1:StartTimeInd(end)-1) = OnThreshold;
                tempIndAboveThresh = find(EMGData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency


                if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                    msgbox ('no trial found')
                    return;
                end

                tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold

                if length(tempIndAboveThresh) == length(EMGData(StartTimeInd(end)-1:end))-1
                    onsetInd = StartTimeInd(end)+1;
                elseif isempty(tempBreakPointInd) == 1
                    tempBreakPointInd = 1;                           % only one pick detected, then the first point
                    (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                    if ans>minDurPointsOn
                        onsetInd = tempIndAboveThresh(1);
                    else
                        msgbox ('no trial found')
                        return;
                    end
                else            % more than one session found
                    tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                    tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                    tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                    tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.

                    if isempty(tempIndMeetReq) == 1
                        msgbox ('no trial found')
                        return;
                    else
                        onsetInd = tempStartInd(tempIndMeetReq(1));
                    end
                end
            else
                %%%%%%%%%%%%%%%%%%%%%%%%PUT IN MULTITRIALDATA
                modulationRatio = zeros(1,size(EMGData,1));          % if choose multiple trials, onsetUbd, offsetInd, SPDur, areaSP and mepAmp saved but not displayed
                peakEnv = zeros(1,size(EMGData,1));
                emgOnset = zeros(1,size(EMGData,1));


                for iTrial = 1:size(EMGData,1)
                    tempData = EMGData(iTrial,:);


                    lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField_2.Value;

                    %hold(app.UIAxes,'on')
                    %app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');

                    %hold(app.UIAxes,'off');
                    %app.tempH = [];
                    %h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    %for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                    %    app.tempH(iLine,:) = h(iLine).YData(1,:);
                    %end            % change the unit
                    %ButtonGroupUnitsSelectionChanged(app, event)

                    OnThreshold = lowEndLimit; % onset threshold
                    StartTimeInd = find(EMGTime <= app.StartTimemsEditField_4.Value/1000);
                    tempData(1:StartTimeInd(end)-1) = OnThreshold;
                    tempIndAboveThresh = find(tempData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency


                    if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                        msgbox ('no trial found')
                        return;
                    end

                    tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold

                    if length(tempIndAboveThresh) == length(tempData(StartTimeInd(end)-1:end))-1
                        onsetInd = StartTimeInd(end)+1;
                    elseif isempty(tempBreakPointInd) == 1
                        tempBreakPointInd = 1;                           % only one pick detected, then the first point
                        (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                        if ans>minDurPointsOn
                            onsetInd = tempIndAboveThresh(1);
                        else
                            msgbox ('no trial found')
                        end
                    else            % more than one session found
                        tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                        tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                        tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                        tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.

                        if isempty(tempIndMeetReq) == 1
                            msgbox ('no trial found')
                            return;
                        else
                            onsetInd = tempStartInd(tempIndMeetReq(1));
                        end
                    end
                end
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot onset and offset
            hold(app.UIAxes,'on')
            for iTrial = 1:length(onsetInd)
                if iTrial == 1
                    legend(app.UIAxes,'AutoUpdate','on');
                else
                    legend(app.UIAxes,'AutoUpdate','off');
                end
                app.onsetLine(iTrial) = xline(app.UIAxes,EMGTime(onsetInd(iTrial)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'onset');
            end


            ButtonGroupUnitsSelectionChanged(app, event)
            hold(app.UIAxes,'on')
            h = findobj(app.UIAxes,'Type','line');
            modRatioOn = EMGTime(onsetInd(iTrial));
            modRatioOff = EMGTime(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate));
            Lim = get(app.UIAxes,'Ylim');
            maxLim = max(Lim);
            minLim = min(Lim);
            v2 = [modRatioOn minLim;modRatioOff minLim;modRatioOff maxLim; modRatioOn maxLim];
            f2 = [1 2 3 4];
            app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','green','FaceAlpha',.1,'Parent',app.UIAxes,'DisplayName', 'ModRatio');   %denote the prestimulation period with patch                % patch the pre stimulation durations

            hold(app.UIAxes,'off')
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            window = (EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))))/(preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
            modulationRatio = trapz(EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))))/(preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
            [tempPeakEnv,idx] = max((EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate)))));
            peakEnv = mean(EMGData((onsetInd+idx)-(round(10/1000*sampleRate)):(onsetInd+idx)+(round(10/1000*sampleRate))));
            emgOnset = (EMGTime(onsetInd(iTrial)))*1000;
            timeToPeak = idx/sampleRate*1000;


            [rowsize colsize]=size(window);
            for i=1:rowsize
                [angfreq(i),power(i)]=medfreq(window);
                % Convert normalized angular frequency to Hz
                freq(i)=angfreq(i)/pi*(sampleRate/2);
            end
            medianPowerFreq=[freq']
            app.MDFText.Text = num2str(medianPowerFreq,'%.1f');

            emgMean = mean(window);
            emgRMS = rms(window);


            hold(app.UIAxes,'on')
            h = findobj(app.UIAxes,'Type','line');
            maxRatioOn = (EMGTime((onsetInd+idx)-(round(10/1000*sampleRate))));
            maxRatioOff = (EMGTime((onsetInd+idx)+(round(10/1000*sampleRate))));
            Lim = get(app.UIAxes,'Ylim');
            maxLim = max(Lim);
            minLim = min(Lim);
            v2 = [maxRatioOn minLim;maxRatioOff minLim;maxRatioOff maxLim; maxRatioOn maxLim];
            f2 = [1 2 3 4];
            app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','red','FaceAlpha',.3,'Parent',app.UIAxes,'DisplayName', 'MaxPoint');   %denote the prestimulation period with patch                % patch the pre stimulation durations

            app.NALabel_15.Text = num2str(modulationRatio,'%.2f');
            app.NALabel_14.Text = num2str(emgOnset,'%.1f');
            app.NALabel_16.Text = num2str(peakEnv,'%.2e');
            app.NALabel_17.Text = num2str(timeToPeak);

            app.dataOut.emgMean = emgMean;
            app.dataOut.emgRMS = emgRMS;
            app.dataOut.medianPowerFreq = medianPowerFreq;
            app.dataOut.timeToPeak = timeToPeak;
            app.dataOut.modulationRatio = modulationRatio;
            app.dataOut.emgOnset = emgOnset;
            app.dataOut.peakEnv = peakEnv;
            app.dataOut.lowPassFreqZ = app.HzEditField_6.Value;
            app.dataOut.SampleRate = sampleRate;
            app.dataOut.StartTime = app.StartTimemsEditField_4.Value;
            t = [datetime('now')];
            app.dataOut.Date = datestr(t);
            app.dataOut.OR= app.EMGBurstoverRideMark;                % if override, then OR = 1;
            app.dataOut.AnalyzeOption = app.ListBox.Value;
            app.dataOut.SampleRate = sampleRate;
            app.dataOut.TrialNum= str2num(cell2num(app.MultiChanListBox.Value));
            app.dataOut.fileName = app.FileName.Value;
            app.dataOut.ChNum=app.ChannelSpinner.Value;
            %%%%OutPut from Other Buttons

            app.dataOut.Zsd = 'N';
            app.dataOut.EMGburstOn = 'N';
            app.dataOut.BurstMethod = 'Envelope';

            %%%%%
            app.EMGAnalysisOR= 0;

        end

        % Button pushed function: OverrideButton_4
        function OverrideButton_4Pushed(app, event)

            app.overRideInd = [];           % save the override onset and offset index
            app.UIFigure.Pointer = 'crosshair';
            hold(app.UIAxes,'on');

            app.AnalyzeInd = 0;   % Analyze button clicked
            app.overRideMark = 0;                              % it is not overrided
            app.EMGBurstoverRideMark = 0;
            app.EMGAnalysisOR = 1;
            % overRideOnset and overRideOffset here are time not indext, need to convert to index
            %app.onsetLine = xline(app.UIAxes,overRideOnset,'r','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset')

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.ConditionsListBox.Visible == 1
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';
                app.MultiChan = app.MultiChan+1;
                app.MultiChan = num2str(app.MultiChan);
                Index = find(strcmp(app.MultiChanListBox.Items,app.MultiChan));
                app.MultiChanListBox.Value = app.MultiChanListBox.Items(Index);
                OKButtonPushed(app, event)
            else
                app.TrialSpinner.Value = app.TrialSpinner.Value + 1;
                TrialSpinnerValueChanged(app, event);
            end

            AnalyzeButton_4Pushed(app, event)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.ConditionsListBox.Visible == 1
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';
                app.MultiChan = app.MultiChan-1;
                app.MultiChanListBox.Value = num2strcell(app.MultiChan);
                OKButtonPushed(app, event)
            else
                app.TrialSpinner.Value = app.TrialSpinner.Value - 1;
                TrialSpinnerValueChanged(app, event);
            end

            AnalyzeButton_4Pushed(app, event)
        end

        % Callback function
        function CustomCCFMenuSelected(app, event)
            app.dataType = 8;


            [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");

            if iscell(fname) == 0
                fname = {fname};
            end

            app.FileOrderListBox.Items = fname;

            fname = strcat(path,fname);

            for iFile = 1:length(fname)
                app.EDENData = load(fullfile(fname{iFile}));                         % does not register in the main program
                %tempData = app.EDENData.data;
                if iFile == 1
                else
                    app.EDENData.data = [tempData,app.EDENData.data];
                end
                tempData = app.EDENData.data;
            end

            app.origSampleRate = 20;                                                   % Sample rate should be the same across all channels and blocks
            app.totChannel = 4;                                                         % Define Total Number of Channels. totChannel(1) refers to channels. totChannel(2) refers to blocks
            app.unit = 'V';                                                            % defines unit. Unit of the LabChart data
            app.reloadInd = 0;                                                          % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.FileName.Value = fname{1};                                              % display the file name
            app.timeUnit = 's';

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %%Search for Comment

            % unify the units across different data types to mV
            switch app.unit
                case '.01'                    % other units?
                    unitsMutiple = 1000;
                    app.BrainSightDataWave = cellfun(@(x) (unitsMutiple*x),  app.BrainSightDataWave,'UniformOutput', false);
            end
            app.unit = 'mV';
        end

        % Button pushed function: ChangeGroupNamestxttabButton
        function ChangeGroupNamestxttabButtonPushed(app, event)
            opts = delimitedTextImportOptions("NumVariables", 4); %Can include upto 50 cells in excel
            opts.DataLines = [1, Inf];
            opts.Delimiter = "*";
            strcat(app.toolboxDir,'\*.txt');
            [fname, path] = uigetfile(ans);
            strcat(path,fname)
            loadIn = (readtable(ans, opts));

            loadIn = table2cell(loadIn);
            tempRemove =all(cellfun('isempty',loadIn)); %removes empty cells
            loadIn(:,tempRemove)=[];


            if isempty(app.UITable.Data{1,1}) == 1
                for i = 1:size(loadIn,1)
                    %loadIn{i} = str2num(loadIn{1,1});
                    %loadIn{i} = num2str(loadIn{i});
                    %loadIn{i,3} = str2num(loadIn{i,3});

                    %loadIn{i,1} = strjoin(loadIn(i,2),'_');
                end
            end

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront
            groupName = [];
            %
            %             for iSample = 1:size(loadIn,1)
            %                 groupName = [groupName,{strjoin(loadIn(iSample,2),'_')}]; %Combine Cells and delim by '_'
            %             end
            if isempty(app.UITable.Data{1,1}) == 1
                app.UITable.Data(:,1:3) = loadIn;
            else
                app.UITable.Data(:,2:3) = loadIn;
            end
        end

        % Button pushed function: EDENAnalyzeButton
        function EDENAnalyzeButtonPushed(app, event)
            EMGData = app.DataToPlot;
            EMGTime = app.TimeToPlot;

            on = find(EMGTime == app.areaWindowStart.Value);
            start2 = find(EMGTime == app.areaWindowStart2.Value);
            timeWindow = app.areaWindowTime.Value*20;

            onSet = EMGData(:,on:(on+timeWindow));
            offSet = EMGData(:,start2:(start2+timeWindow));


            app.NALabel_20.Text = num2str((trapz(onSet(1,:)) - trapz(onSet(2,:))),'%.2f');
            app.NALabel_19.Text = num2str((trapz(offSet(1,:)) - trapz(offSet(2,:))),'%.2f');

            app.dataOut.contractAUC1 = trapz(onSet(1,:));
            app.dataOut.contractAUC2 = trapz(onSet(2,:));
            app.dataOut.relaxAUC1 = trapz(offSet(1,:));
            app.dataOut.relaxAUC2 = trapz(offSet(2,:));
            app.dataOut.contractDiff = (trapz(onSet(1,:)) - trapz(onSet(2,:)));
            app.dataOut.relaxDiff = (trapz(offSet(1,:)) - trapz(offSet(2,:)));

            app.dataOut.ContractOn = app.areaWindowStart.Value;
            app.dataOut.RelaxOn = app.areaWindowStart2.Value;
            app.dataOut.timeWindow = app.areaWindowTime.Value;

            %             Lim = get(app.UIAxes,'Ylim');
            %             maxLim = max(Lim);
            %             minLim = min(Lim);
            %             v2 = [app.areaWindowStart.Value minLim;app.areaWindowStart.Value+app.areaWindowTime.Value minLim;app.areaWindowStart.Value+app.areaWindowTime.Value maxLim; app.areaWindowStart.Value maxLim];
            %             f2 = [1 2 3 4];
            %
            %
            %             %delete(findobj('type', 'patch'));
            %
            %             patch('Faces',f2,'Vertices',v2,'FaceColor','black','FaceAlpha',.1,'Parent',app.UIAxes,'DisplayName', 'PreStimDur');   %denote the prestimulation period with patch


            %             x2 = [x, fliplr(x)];
            %             inBetween = [curve1, fliplr(curve2)];
            %             fill(x2, inBetween, 'g');


            t = 1


        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            resultsDir = strcat(app.toolboxDir,'/Results');
            if exist(resultsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(resultsDir);
            end
            OldFolder = pwd;
            cd (resultsDir)

            t = [datetime('now')];
            textFileName = app.EditField_5.Value;
            TempEDENResults = strcat(resultsDir,'/TempEDENResults.csv');
            if exist(TempEDENResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                grouptempHeaderOut{1} = 'textFileName';
                grouptempHeaderOut{2} = 'fileName';
                grouptempHeaderOut{3} = 'Start Contract Time';    %
                grouptempHeaderOut{4} = 'Start Relac Time';    %
                grouptempHeaderOut{5} = 'Time After Start';   %
                grouptempHeaderOut{6} = 'AUC1 Contract';   %
                grouptempHeaderOut{7} = 'AUC2 Contract';
                grouptempHeaderOut{8} = 'AUC Contract Diff';   %
                grouptempHeaderOut{9} = 'AUC1 Relax';
                grouptempHeaderOut{10} = 'AUC2 Relax';
                grouptempHeaderOut{11} = 'AUC Relax Diff';
                grouptempHeaderOut{12} = 'Date';    %
                fid = fopen(TempEDENResults,'a');
                fclose(fid);
                T = table(grouptempHeaderOut(:)');
                writetable(T, TempEDENResults,'WriteMode','Append','WriteVariableNames',0);
                grouptempHeaderOut = {};
            end

            cd(OldFolder);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %EDEN Results

            grouptempDataOut{1} = textFileName;
            grouptempDataOut{2} = app.FileName.Value;
            grouptempDataOut{3} = app.dataOut.ContractOn;    %
            grouptempDataOut{4} = app.dataOut.RelaxOn;    %
            grouptempDataOut{5} = app.dataOut.timeWindow;   %
            grouptempDataOut{6} =  app.dataOut.contractAUC1;  %
            grouptempDataOut{7} = app.dataOut.contractAUC2;
            grouptempDataOut{8} = app.dataOut.contractDiff; %
            grouptempDataOut{9} =  app.dataOut.relaxAUC1;
            grouptempDataOut{10} = app.dataOut.relaxAUC2;
            grouptempDataOut{11} = app.dataOut.relaxDiff;
            grouptempDataOut{12} = datestr(t);    %

            gpT = table(grouptempDataOut(:)');


            writetable(gpT,TempEDENResults,'WriteMode','Append')


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.saveButton.Enable = 0;
            pause(0.5)
            app.saveButton.Enable = 1;


        end

        % Menu selected function: GeorgetownMenu
        function GeorgetownMenuSelected(app, event)
            app.dataType = 9;

            %====================================================================================================%
            [fname, path] = uigetfile(app.DataDir,'*mat');
            load(fullfile(path, fname));                                             % to identify the data type within current workspace
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront
            app.CustomData = load(fullfile(path, fname));

            app.FileName.Value = fname;                            % display the file name
            app.totChannel = size(app.CustomData.data,2);
            app.eventInfo = [app.CustomData.time];
            app.totEvent = size(app.eventInfo,1);
            app.timeUnit = 'mV';
            app.time = app.CustomData.t;
            app.origSampleRate = size(app.CustomData.data,1)/app.CustomData.t(end);
            app.reloadInd = 0;                                                   % the first time find the events, save all data into one variable, so
            app.ProcessInd = 0;
        end

        % Menu selected function: RochesterMenu
        function RochesterMenuSelected(app, event)
            app.dataType = 6;
            trunk = 0; %helps determine if first trial is removed
            app.FigureCheckCheckBox.Visible = 'on';
            %====================================================================================================%
            [fname,path]=uigetfile('*.xdf'); % File Selection
            file=[path,fname];
            app.stream = load_xdf(file);
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %% Name
            for p=1:length(app.stream)

                a=app.stream{1,p}.info.type;
                if isequal(a ,'Smooth EMG') && length(app.stream{1,p}.time_series)>100
                    b=p;
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Runner') && length(app.stream{1,p}.time_series)>10
                    %   if isequal(a ,'Slide') && length(app.stream{1,p}.time_series)>10
                    c=p;
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Events')
                    j=p;
                    break
                end
            end
            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Level')
                    h=p;
                    break
                end
            end
            level = app.stream{1,h}.time_series(1:2,:);
            level_time=app.stream{1,h}.time_stamps;

            events=app.stream{1,j}.time_series;
            events_time=app.stream{1,j}.time_stamps;
            emg=app.stream{1,b}.time_series;
            emg( all(~emg,2), : ) = [];
            app.origSampleRate=str2double(app.stream{1,b}.info.nominal_srate);
            emg_time = app.stream{1,b}.time_stamps;
            position_time=app.stream{1,c}.time_stamps;
            position=app.stream{1,c}.time_series;
            %% Seperation - Multiple trail in one recording seperation
            time = emg_time(1);
            num=1;
            for n=1:length(events)
                if events(n) == "STOP"
                    time=[time,events_time(n)];
                    num=[num,n];
                end
            end
            %disp(["There are "+(length(num)-1)+" seperated recordings. "])

            %% Save seperations
            b=[1];
            for n=2:length(time)-1
                for m=1:length(emg_time)
                    if -(emg_time(m)-time(n))<10e-5
                        b=[b,m];
                        break
                    end
                end
            end
            b=[b,length(emg_time)];

            %app.origSampleRate = str2double(app.stream{1,5}.info.nominal_srate);

            %Find Event Index expected zone starte

            startIDX = find(strcmp(events,'game start'));
            stopIDX = find(strcmp(events,'STOP'));
            allEventIDX = find(strcmp(events,'expected zone start'));
            %%Need to get to game start with 2 runs
            if startIDX >=2
                newEventIDX = [];
                for i = 1:length(startIDX)
                    tempEventIDX = allEventIDX(allEventIDX>startIDX(i) & allEventIDX<stopIDX(i));
                    newEventIDX = [newEventIDX, tempEventIDX(4:end)];
                end
                %%%%%%%%%%%%%%%%%%%%
            else
                eventIDX = find(strcmp(events,'expected zone start'));
                eventIDX = eventIDX(eventIDX>startIDX);
                newEventIDX = eventIDX(4:end);
            end
            eventTime = events_time(newEventIDX);
            rEMGb=round(emg_time(:),3);
            rEMGa=round(eventTime,3);
            [~,emgCueIDX]=ismembertol(rEMGa,rEMGb);


            positionActual = position(2,:); %Is it 1 or 2?
            rPositionb=round(position_time(:),1);
            rPositiona=round(eventTime,1);
            [~,positionTypeIDX]=ismembertol(rPositiona,rPositionb);

            %%%LabelEvents
            totEvent = size(eventTime,2);
            r2 = ones(1,totEvent);
            eventTimelabel = [eventTime;r2]';

            totLevel = size(level_time,2);
            r2 = zeros(1,totLevel);
            levelTimelabel = [level_time;r2]';

            allLabels = [eventTimelabel;levelTimelabel];

            allLabelssorted = sortrows(allLabels);

            t = find(allLabelssorted(:,2) == 0);

            allLabelssorted(t+1,2)=3;

            if allLabelssorted(end,1) == 0
                allLabelssorted(end,:) = [];
            end


            %%%%
            %
            %             figure;plot(position_time,positionActual)
            % %
            %             for i = 1:length(eventTime)
            %                 x1 = xline(eventTime(i));
            %                 x1.Color ='red';
            %                 x1.LineWidth = 1;
            %             end
            %
            %             for i = 1:size(level,2)
            %                 x1 = xline(level_time(i));
            %                 x1.Color ='blue';
            %                 x1.LineWidth = 2;
            %             end
            %
            %
            %
            %             x2 = xline(events_time,'-',events);

            %             if positionTypeIDX(1) == 0
            %                 positionTypeIDX = positionTypeIDX(2:end);
            %                 trunk = 1;
            %                 msgbox('Warning: First Trial Removed')
            %             end
            positionState = positionActual((positionTypeIDX+100)); %captures the value without risk of finding index before


            runTemp = emgCueIDX;
            size(b,2)
            r = 1;
            for i = 2:size(b,2)
                runTemp(runTemp <= b(i) & runTemp >= r) = r;
                r = r+1;
            end

            tempAllLabelssorted = allLabelssorted;
            storeRun = [];
            for i = 1:(length(num)-1)

                find(runTemp == i,1,'first')
                storeRun(end+1) = ans;
                allLabelssorted(ans,2)=3;

            end
            %runTemp = string(runTemp);

            %%%%
            rHand = {};
            for i = 1:(length(num)-1)
                iTemp = string(i);
                groupName = menu('Run'+iTemp+'_Hand?','Left','Right');
                Run = string(i);
                if groupName == 1
                    Hand = "Le";
                else
                    Hand = "Ri";
                end
                rHand{end+1} = append(Hand);
            end


            a = positionState;
            nAll = {}
            for i = 1:(length(num)-1)
                r1 = find(runTemp==i);
                a =double(a);
                m = num2cell(a,1);
                m= cellfun(@(x)  num2str(x), m, 'UniformOutput', false);
                if rHand{i} == "Ri"
                    m1 = replace(m(min(r1):max(r1)),'0','F');
                    m1 = replace(m1,'1','R');
                    m1 = replace(m1,'2','E');

                    N1 ={};
                    for idx = 1:(length(m1)-1)
                        N1 =[N1,[m1{idx} m1{idx+1}]];
                    end

                    N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);

                else
                    m1 = replace(m(min(r1):max(r1)),'0','E');
                    m1 = replace(m1,'1','R');
                    m1 = replace(m1,'2','F');

                    N1 ={};
                    for idx = 1:(length(m1)-1)
                        N1 =[N1,[m1{idx} m1{idx+1}]];
                    end

                    N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);


                end
                nAll = [nAll,N1];
            end

            positionState = string(nAll);

            out = allLabelssorted(all(allLabelssorted,2),:);
            first = find(out(:,2) == 3);


            %%%%%
            app.CustomData.data = double(emg);
            %app.CustomData.cueIDX = emgCueIDX;
            app.CustomData.emgTime = emg_time;
            app.CustomData.positionActual = positionActual;
            app.CustomData.positionTime = position_time;
            %app.CustomData.eventTime = eventTime;
            app.CustomData.level = level;
            app.CustomData.levelTime = level_time;
            %app.CustomData.positionTypeIDX = positionTypeIDX;

            %positionState = string(positionState);


            %             if trunk == 1
            %                 runTemp = runTemp(2:end);
            %                 trunk = 0;
            %
            %             end

            for i = 1:(length(num)-1)

                find(runTemp == i,1,'first')
                runTemp(ans)=[];

            end

            tempFirst = first;
            for i = 1:(length(num)-1)

                tempFirst(find(tempFirst ==storeRun(i)))=[];

            end


            runTemp = string(runTemp);

            comment = strcat(runTemp,positionState);
            ttEmp = strcat(runTemp,positionState);

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront


            comment(tempFirst)=[];


            emgCueIDX(first)=[];
            eventTime(first)=[];
            positionTypeIDX(first) = [] ;

            app.FileName.Value = fname;
            app.totChannel = size(emg,1);
            app.eventInfo = comment;
            app.totEvent = size(comment,2);
            app.unit = 'V';
            app.CustomData.cueIDX = emgCueIDX;
            app.CustomData.eventTime = eventTime;
            app.CustomData.positionTypeIDX = positionTypeIDX;

            switch app.unit
                case 'V'                    % other units?
                    unitsMutiple = 1000;
                    app.CustomData.data = app.CustomData.data .* unitsMutiple;
            end
            app.unit = 'mV';

        end

        % Menu selected function: CCFMenu
        function CCFMenuSelected(app, event)
            app.dataType = 8;

            [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");

            if iscell(fname) == 0
                fname = {fname};
            end


            %fname = strcat(path,fname);

            for iFile = 1:length(fname)
                app.EDENData = load(fullfile(fname{iFile}));                         % does not register in the main program
                %tempData = app.EDENData.data;
                if iFile == 1
                else
                    app.EDENData.data = [tempData,app.EDENData.data_eogclean.trial];
                end
                app.EDENData.data = app.EDENData.data_eogclean.trial;
            end

            %findSampleRate =load(fullfile(fname{1}));
            %[val, idx]= max(findSampleRate.data(2,:));

            app.origSampleRate =app.EDENData.data_eogclean.fsample;                                                  % Sample rate should be the same across all channels and blocks
            app.totChannel = 50;                                                         % Define Total Number of Channels. totChannel(1) refers to channels. totChannel(2) refers to blocks
            app.unit = 'V';                                                            % defines unit. Unit of the LabChart data
            app.reloadInd = 0;                                                          % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.FileName.Value = fname{1};                                              % display the file name
            app.timeUnit = 's';

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront


            %             [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");
            %
            %             if iscell(fname) == 0
            %                 fname = {fname};
            %             end
            %
            %             app.FileOrderListBox.Items = fname;
            %
            %             fname = strcat(path,fname);
            %
            %             for iFile = 1:length(fname)
            %                 app.EDENData = load(fullfile(fname{iFile}));                         % does not register in the main program
            %                 %tempData = app.EDENData.data;
            %                 if iFile == 1
            %                 else
            %                     app.EDENData.data = [tempData,app.EDENData.data];
            %                 end
            %                 tempData = app.EDENData.data;
            %             end
            %
            %             app.origSampleRate = 20;                                                   % Sample rate should be the same across all channels and blocks
            %             app.totChannel = 4;                                                         % Define Total Number of Channels. totChannel(1) refers to channels. totChannel(2) refers to blocks
            %             app.unit = 'V';                                                            % defines unit. Unit of the LabChart data
            %             app.reloadInd = 0;                                                          % the first time find the events, save all data into one variable
            %             app.ProcessInd = 0;
            %             app.FileName.Value = fname{1};                                              % display the file name
            %             app.timeUnit = 's';
            %
            %             drawnow; %Puts UI Figure at the forfront
            %             figure(app.UIFigure) %Puts UI Figure at the forfront
            %
            %             %%Search for Comment
            %
%             % unify the units across different data types to mV
%             switch app.unit
%                 case '.01'                    % other units?
%                     unitsMutiple = 1000;
%                     app.BrainSightDataWave = cellfun(@(x) (unitsMutiple*x),  app.BrainSightDataWave,'UniformOutput', false);
%             end
%             app.unit = 'mV';
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            resultsDir = strcat(app.toolboxDir,'/Results');
            if exist(resultsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(resultsDir);
            end
            OldFolder = pwd;


            resultsDir = strcat(app.toolboxDir,'/Results');
            PPResults = strcat(resultsDir,'/PairedPulse.csv');
            if exist(PPResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
                tempHeaderOut{1} = 'SUB ID';
                tempHeaderOut{2} = 'File Name';
                tempHeaderOut{3} = 'Metric Mean 2';
                tempHeaderOut{4} = 'Metric Mean 1';
                tempHeaderOut{5} = 'Ratio (2/1)';    %
                tempHeaderOut{6} = 'Date';    %

                fid = fopen(PPResults,'a');
                fclose(fid);
                T = table(tempHeaderOut(:)');
                writetable(T,PPResults,'WriteMode','Append','WriteVariableNames',0);
                tempHeaderOut = {};
            end

            if app.MeanCheckBox_4.Value == 1 || app.CentralityCheckBox_4.Value == 1
                tempDataOut{1} = app.EditField_5.Value;
                tempDataOut{2} = app.dataOut.fileName;
                tempDataOut{3} = app.dataOut.metricMean2;
                tempDataOut{4} = app.dataOut.metricMean1;
                tempDataOut{5} = app.dataOut.ratio;    %
                tempDataOut{6} = app.dataOut.Date;    %

                T = table(tempDataOut(:)');
                % compose into a table to better import to csv file,

                writetable(T,PPResults,'WriteMode','Append')
            else
                tempDataOut{1} = app.EditField_5.Value;
                tempDataOut{2} = app.dataOut.fileName;
                tempDataOut{3} = app.dataOut.metricMean2;
                tempDataOut{4} = app.dataOut.metricMean1;
                tempDataOut{5} = app.dataOut.ratio;    %
                tempDataOut{6} = app.dataOut.Date;    %

                T = table(tempDataOut(:)');
                % compose into a table to better import to csv file,

                writetable(T,PPResults,'WriteMode','Append')
            end


            app.SaveButton_2.Enable = 0;
            pause(0.5)
            app.SaveButton_2.Enable = 1;

        end

        % Callback function
        function StackPlotCheckBoxValueChanged(app, event)

        end

        % Callback function
        function SwitchValueChanged(app, event)

            if app.Switch.Value == 1
                %app.TimeToPlot=repmat(app.TimeToPlot,size(app.DataToPlot,1),1);
                multichanplot(app.DataToPlot,length(app.DataToPlot))
                %stackedplot(app.UIAxes,app.DataToPlot)


            end
        end

        % Value changed function: StackPlotCheckBox
        function StackPlotCheckBoxValueChanged2(app, event)
            value = app.StackPlotCheckBox.Value;

            if app.StackPlotCheckBox.Value ==1
                tWin = app.TimeToPlot           ;
                multichanplot(app.DataToPlot',size(app.DataToPlot,2),tWin);
            end

        end

        % Size changed function: Panel_4
        function Panel_4SizeChanged(app, event)
            position = app.Panel_4.Position;

        end

        % Menu selected function: ExoForcejsonMenu
        function ExoForcejsonMenuSelected(app, event)
            app.dataType = 10;

            %====================================================================================================%
            strcat(app.toolboxDir,'/*.json');
            [fname, path] = uigetfile(ans,"multiSelect","on");


            fid = fopen(fullfile(path, fname));
            raw = fread(fid,inf); % Reading the contents
            str = char(raw'); % Transformation
            fclose(fid); % Closing the file
            %data = jsondecode(str);

            % to identify the data type within current workspace
            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            if str(end) == '}'
                str = append(str,']');
            end

            data = jsondecode(str);

            ch1 = [];
            ch2 = [];
            ch3 = [];

            for i = 1:length(data)
                ch1 = [ch1;data(i).CH1_data];
            end

            ch = [];

            for i = 1:length(data)
                ch2 = [ch2;data(i).CH2_data];
            end

            ch = [];

            for i = 1:length(data)
                ch3 = [ch3;data(i).CH3_data];
            end

            app.CustomData.exoForce = {ch1';ch2';ch3'};


            app.FileName.Value = fname;                            % display the file name
            app.totChannel = 3;
            app.timeUnit = 'mV';
            app.PreDurs.Value = num2str(0);
            app.PostDur.Value = num2str(length(ch1)/1000);
            app.origSampleRate = 1000;
            app.reloadInd = 0;                                                   % the first time find the events, save all data into one variable, so
            app.ProcessInd = 0;


        end

        % Button pushed function: LoadxlsButton
        function LoadxlsButtonPushed(app, event)


            opts = spreadsheetImportOptions("NumVariables", 3);
            strcat(app.toolboxDir,'/*.xlsx');
            [fname, path] = uigetfile(ans);
            strcat(path,fname)
            opts.VariableNames = ["VarName1", "VarName2", "VarName3"];
            opts.VariableTypes = ["double", "double", "double"];

            AvgMap = readtable(ans, opts, "UseExcel", false);
            AvgMap = table2array(AvgMap);


            X= AvgMap(:,1);
            Y= AvgMap(:,2);
            Z= AvgMap(:,3);

            % NORMALIZATION
            % maxZ = 100;
            % if RENORM == 1
            %     maxZ = max(.062);
            %     Z=100*Z/maxZ;
            % end

            ft = fittype( 'biharmonicinterp' );
            opts = fitoptions( ft );
            opts.Weights = zeros(1,0);
            [fitresult, gof] = fit( [X, Y], Z, ft, opts );

            % Plot fit with data.
            figure( 'Name', '3D mapping' );
            h = plot( fitresult);
            grid off
            % Label axes
            xlabel( 'X' );
            ylabel( 'Y' );
            zlabel( 'Z' );
            legend( h, '3D version of the motor map', 'Z vs. X, Y', 'Location', 'NorthEast' );
            % Make contour plot.
            figure('name','contour map');
            h = plot( fitresult, [X, Y], Z, 'Style', 'Contour' );
            grid off
            % Label axes
            xlabel( 'X' );
            ylabel( 'Y' );
            legend( h, 'contour of the motor map', 'Z vs. X, Y' );

            a1=min(X);
            a2=max(X);%get the range the value of X
            b1=min(Y);
            b2=max(Y);%get the range of y
            stepa=(a2-a1)/100;
            stepb=(b2-b1)/100;%get the steps for the coming circulation
            for i1=1:1:101
                x(i1)=a1;
                a1=a1+stepa;
            end
            for i1=1:1:101
                y(i1)=b1;
                b1=b1+stepb;
            end
            n=1;
            for i=1:101
                for j=1:101
                    new(n)=fitresult(x(i),y(j));
                    n=n+1;
                end
            end
            %get total rd
            totalrd=0;
            for i=1:(n-1)
                total(i)=new(i)*(stepa*stepb);
                totalrd=totalrd+total(i);
            end
            totalrd=totalrd/(stepa*stepb*n);
            %calculate group rd's
            n1=1;n2=1;n3=1;n4=1;
            for i=1:(n-1)
                if (new(i)>=0)&(new(i)<25)
                    c1(n1)=new(i)*(stepa*stepb);
                    n1=n1+1;
                else
                    if (new(i)>=25)&(new(i)<50)
                        c2(n2)=new(i)*(stepa*stepb);
                        n2=n2+1;
                    else
                        if (new(i)>=50)&(new(i)<75)
                            c3(n3)=new(i)*(stepa*stepb);
                            n3=n3+1;
                        else
                            if (new(i)>=75)&(new(i)<=100)
                                c4(n4)=new(i)*(stepa*stepb);
                                n4=n4+1;
                            end
                        end
                    end
                end
            end

            rd1=0;
            rd2=0;
            rd3=0;
            rd4=0;
            for i=1:(n1-1)
                rd1=rd1+c1(i);
            end
            for i=1:(n2-1)
                rd2=rd2+c2(i);
            end
            for i=1:(n3-1)
                rd3=rd3+c3(i);
            end
            for i=1:(n4-1)
                rd4=rd4+c4(i);
            end
            rd1=rd1/(stepa*stepb*n1);
            rd2=rd2/(stepa*stepb*n2);
            rd3=rd3/(stepa*stepb*n3);
            rd4=rd4/(stepa*stepb*n4);

            % fprintf(1,'%6.4f\t',totalrd*maxZ/100);
            % fprintf(1,'%6.4f\t',rd1*maxZ/100);
            % fprintf(1,'%6.4f\t',rd2*maxZ/100);
            % fprintf(1,'%6.4f\t',rd3*maxZ/100);
            % fprintf(1,'%6.4f\t',rd4*maxZ/100);

            % fprintf(1,'\n')
            % Zrenorm = Z*maxZ/100;
            % lev1 = sum( Zrenorm(find(Z>=0 & Z<25)));
            % lev2 = sum( Zrenorm(find(Z>=25 & Z<50)));
            % lev3 = sum( Zrenorm(find(Z>=50 & Z<75)));
            % lev4 = sum( Zrenorm(find(Z>=75 & Z<=100)));
            % lev = sum(Zrenorm);
            % fprintf(1,'%6.4f\t',lev);
            % fprintf(1,'%6.4f\t',lev1);
            % fprintf(1,'%6.4f\t',lev2);
            % fprintf(1,'%6.4f\t',lev3);
            % fprintf(1,'%6.4f\t',lev4);
            %
            % fprintf(1,'\n')


        end

        % Menu selected function: MCRRMenu
        function MCRRMenuSelected(app, event)
            %%  Import Acqknowledge Data
            app.dataType = 11;
            app.CommentsButton.Enable = 'off';
            % Here the data should be .mat file
            %====================================================================================================%

            [fname, path] = uigetfile(app.DataDir,'*mat',"multiSelect","on");

            if iscell(fname) == 0
                fname = {fname};
            end

            app.FileOrderListBox.Items = fname;

            for iFile = 1:length(fname)
                app.Sulzer = load(fullfile(path, fname{iFile}));                         % does not register in the main program
                tempData = app.Sulzer.DATA;
                if iFile == 1
                    SulzerDataAll = tempData;
                else
                    SulzerDataAll = [AcqknowledgeDatadataAll;app.AcqknowledgeData.data];
                end
                %tempData = app.AcqknowledgeData.data;
            end
            if exist('SulzerDataAll','var') == 1
                app.Sulzer.DATA=SulzerDataAll;
            end

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %====================================================================================================%

            % the import data type should be Acqknowledge, if not, display warning
            %             if exist('isi') == 0
            %                 [Visible1,Visible2,Visible3] = wrongType();
            %                 app.EditField.Visible = Visible1;                                    % if wrong type data, display warning
            %                 app.EventTypePanel.Visible = Visible2;
            %                 app.SetEventWindowPanel.Visible = Visible3;
            %             else

            % default unit unifies to mV
            units = cellstr(app.AcqknowledgeData.units);                                              % char to cell. "strcmp" requires cell type data
            unitsMultiple = strcmp('mV', units);                                 % find the channel where the unit is mV
            unitsMultiple = double(unitsMultiple);
            if prod(unitsMultiple)>0                                             % if all units are "mV", IsUnify=0 means no units unification
                IsUnify = 0;
            else
                IsUnify = 1;                                                     % if units not unified
            end

            if IsUnify == 1
                VoltsChannel = find(units == "Volts");                           % find the channel where the unit is Volits
                unitsMultiple(VoltsChannel) = 1000;                              % unify the units to mV
                app.AcqknowledgeData.data = app.AcqknowledgeData.data.*unitsMultiple'; % save the data and other information in app.AcqknowledgeData
                % if need to only save the data into "allData", Acqknowledge
                % type data does not need further calculation
            end

            app.FileName.Value = fname{1};
            app.eventInfo = [];
            app.reloadInd = 0;                                                   % the first time find the events, save all data into one variable
            app.ProcessInd = 0;
            app.unit ='mV';
            app.totChannel = size(app.AcqknowledgeData.labels,1);
            app.time = 1:app.AcqknowledgeData.isi:length(app.AcqknowledgeData.data);                                       % save the time  isi should be divided or multipled?????
            app.origSampleRate = 1/(app.AcqknowledgeData.isi/1000);                                            % ????
            app.timeUnit = app.AcqknowledgeData.isi_units;
            if strcmp(app.timeUnit, 'ms')                                        % unify time unit to second
                app.time = app.time/1000;
                app.timeUnit = 's';
                app.origSampleRate = 2000;
            end


            while sv == 0

            end


        end

        % Button pushed function: SaveCSVButton_2
        function SaveCSVButton_2Pushed(app, event)
%             % creat csv file
%             % NeuroMeasure Mapping CSV file create
%             % check if the data saving folder exists, if no creat one
%             resultsDir = strcat(app.toolboxDir,'/Results');
%             if exist(resultsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
%                 mkdir(resultsDir);
%             end
%             OldFolder = pwd;
% 
% 
%             resultsDir = strcat(app.toolboxDir,'/Results');
%             mappingResults = strcat(resultsDir,'/Mapping.csv');
%             if exist(mappingResults,'file') ~= 2    % 2 refers to file with extension. If not exist, create one
%                 tempHeaderOut{1} = 'Condition';    %
%                 tempHeaderOut{2} = 'Feature';    %
%                 tempHeaderOut{3} = 'MEPFrequency';
%                 tempHeaderOut{4} = 'PreStim Mean';
%                 fid = fopen(mappingResults,'a');
%                 fclose(fid);
%                 T = table(tempHeaderOut(:)');
%                 writetable(T,mappingResults,'WriteMode','Append','WriteVariableNames',0);
%                 tempHeaderOut = {};
%             end
% 
% 
%             tempDataOut{1} = repmat(app.dataOut.condtionLabel,1);
%             tempDataOut{2} = repmat(app.dataOut.metricsMean,1);
%             tempDataOut{3} = repmat(app.dataOut.count',1);
%             tempDataOut{4} = repmat(app.dataOut.metricsPreStimMean,1);
% 
%             T = table(tempDataOut{:});               % compose into a table to better import to csv file,
% 
%             writetable(T,mappingResults,'WriteMode','Append')
% 
% 
% 
% 
%             app.SaveCSVButton.Enable = 0;
%             pause(0.5)
%             app.SaveCSVButton.Enable = 1;

        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.ConditionsListBox.Visible == 1
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';
                app.MultiChan = app.MultiChan-1;
                app.MultiChanListBox.Value = num2strcell(app.MultiChan);
                OKButtonPushed(app, event)
            else
                app.TrialSpinner.Value = app.TrialSpinner.Value - 1;
                TrialSpinnerValueChanged(app, event);
            end

          AnalyzeButtonPushed(app, event)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.ConditionsListBox.Visible == 1
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';
                app.MultiChan = app.MultiChan+1;
                app.MultiChan = num2str(app.MultiChan);
                Index = find(strcmp(app.MultiChanListBox.Items,app.MultiChan));
                app.MultiChanListBox.Value = app.MultiChanListBox.Items(Index);
                OKButtonPushed(app, event)
            else
                app.TrialSpinner.Value = app.TrialSpinner.Value + 1;
                TrialSpinnerValueChanged(app, event);
            end

            AnalyzeButtonPushed(app, event)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.ConditionsListBox.Visible == 1
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';
                app.MultiChan = app.MultiChan-1;
                app.MultiChanListBox.Value = num2strcell(app.MultiChan);
                OKButtonPushed(app, event)
            else
                app.TrialSpinner.Value = app.TrialSpinner.Value - 1;
                TrialSpinnerValueChanged(app, event);
            end

           ZTransButton_2Pushed(app, event)
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.ConditionsListBox.Visible == 1
                app.MultiChan = str2num(char(app.MultiChanListBox.Value'))';
                app.MultiChan = app.MultiChan+1;
                app.MultiChan = num2str(app.MultiChan);
                Index = find(strcmp(app.MultiChanListBox.Items,app.MultiChan));
                app.MultiChanListBox.Value = app.MultiChanListBox.Items(Index);
                OKButtonPushed(app, event)
            else
                app.TrialSpinner.Value = app.TrialSpinner.Value + 1;
                TrialSpinnerValueChanged(app, event);
            end

           ZTransButton_2Pushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.OpenNewFileMenu = uimenu(app.FileMenu);
            app.OpenNewFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenNewFileMenu.Text = 'Open New File';
            app.LabChartmatMenu = uimenu(app.OpenNewFileMenu);
            app.LabChartmatMenu.MenuSelectedFcn = createCallbackFcn(app, @LabChartmatMenuSelected, true);
            app.LabChartmatMenu.Text = 'LabChart (.mat)';
            app.AcqknowledgematMenu = uimenu(app.OpenNewFileMenu);
            app.AcqknowledgematMenu.MenuSelectedFcn = createCallbackFcn(app, @AcqknowledgematMenuSelected, true);
            app.AcqknowledgematMenu.Text = 'Acqknowledge (.mat)';
            app.SignalmatMenu = uimenu(app.OpenNewFileMenu);
            app.SignalmatMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalmatMenuSelected, true);
            app.SignalmatMenu.Text = 'Signal (.mat)';
            app.BrainVisioneegvhdrvmkrMenu = uimenu(app.OpenNewFileMenu);
            app.BrainVisioneegvhdrvmkrMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainVisioneegvhdrvmkrMenuSelected, true);
            app.BrainVisioneegvhdrvmkrMenu.Text = 'BrainVision (.eeg/.vhdr/.vmkr)';
            app.SpikesmrsmrxMenu = uimenu(app.OpenNewFileMenu);
            app.SpikesmrsmrxMenu.MenuSelectedFcn = createCallbackFcn(app, @SpikesmrsmrxMenuSelected, true);
            app.SpikesmrsmrxMenu.Text = 'Spike (.smr/.smrx)';
            app.BrainSighttxtMenu = uimenu(app.OpenNewFileMenu);
            app.BrainSighttxtMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainSighttxtMenuSelected, true);
            app.BrainSighttxtMenu.Text = 'BrainSight (.txt)';
            app.ExoForcejsonMenu = uimenu(app.OpenNewFileMenu);
            app.ExoForcejsonMenu.MenuSelectedFcn = createCallbackFcn(app, @ExoForcejsonMenuSelected, true);
            app.ExoForcejsonMenu.Text = 'ExoForce (.json)';
            app.CustomMenu = uimenu(app.OpenNewFileMenu);
            app.CustomMenu.Text = 'Custom';
            app.GeorgetownMenu = uimenu(app.CustomMenu);
            app.GeorgetownMenu.MenuSelectedFcn = createCallbackFcn(app, @GeorgetownMenuSelected, true);
            app.GeorgetownMenu.Text = 'Georgetown';
            app.RochesterMenu = uimenu(app.CustomMenu);
            app.RochesterMenu.MenuSelectedFcn = createCallbackFcn(app, @RochesterMenuSelected, true);
            app.RochesterMenu.Text = 'Rochester';
            app.CCFMenu = uimenu(app.CustomMenu);
            app.CCFMenu.MenuSelectedFcn = createCallbackFcn(app, @CCFMenuSelected, true);
            app.CCFMenu.Text = 'CCF';
            app.MCRRMenu = uimenu(app.CustomMenu);
            app.MCRRMenu.MenuSelectedFcn = createCallbackFcn(app, @MCRRMenuSelected, true);
            app.MCRRMenu.Text = 'MCRR';
            app.OpenSavedConcatFileMenu = uimenu(app.FileMenu);
            app.OpenSavedConcatFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSavedConcatFileMenuSelected, true);
            app.OpenSavedConcatFileMenu.Text = 'Open Saved Concat File';
            app.OpenConditionFileMenu = uimenu(app.FileMenu);
            app.OpenConditionFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConditionButtonPushed, true);
            app.OpenConditionFileMenu.Text = 'Open Condition File';
            app.RefreshMenu = uimenu(app.UIFigure);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Visible = 'off';
            app.Panel_4.SizeChangedFcn = createCallbackFcn(app, @Panel_4SizeChanged, true);
            app.EventTypePanel = uipanel(app.UIFigure);
            app.EventTypePanel.Title = 'Event Type';
            app.EventTypePanel.SizeChangedFcn = createCallbackFcn(app, @EventTypePanelSizeChanged, true);
            app.ButtonGroup = uibuttongroup(app.EventTypePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSizeChanged, true);
            app.CommentsButton = uiradiobutton(app.ButtonGroup);
            app.CommentsButton.Text = 'Comments';
            app.EventsegTTLButton = uiradiobutton(app.ButtonGroup);
            app.EventsegTTLButton.Text = 'Events (e.g. TTL)';
            app.BlocksFramesButton = uiradiobutton(app.ButtonGroup);
            app.BlocksFramesButton.Text = 'Blocks/Frames';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Visible = 'off';
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.SetEventWindowPanel = uipanel(app.UIFigure);
            app.SetEventWindowPanel.Title = 'Set Event Window';
            app.FindallEventsButton = uibutton(app.SetEventWindowPanel, 'push');
            app.FindallEventsButton.ButtonPushedFcn = createCallbackFcn(app, @FindallEventsButtonPushed, true);
            app.FindallEventsButton.Text = 'Find all Events';
            app.ChannelSpinnerLabel = uilabel(app.SetEventWindowPanel);
            app.ChannelSpinnerLabel.Text = 'Channel #';
            app.ChannelSpinner = uispinner(app.SetEventWindowPanel);
            app.ChannelSpinner.ValueChangingFcn = createCallbackFcn(app, @FindallEventsButtonPushed, true);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Value = 1;
            app.TrialSpinnerLabel = uilabel(app.SetEventWindowPanel);
            app.TrialSpinnerLabel.Text = 'Trial #';
            app.TrialSpinner = uispinner(app.SetEventWindowPanel);
            app.TrialSpinner.ValueChangingFcn = createCallbackFcn(app, @FindallEventsButtonPushed, true);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.TrialSpinner.Value = 1;
            app.PreDursEditFieldLabel = uilabel(app.SetEventWindowPanel);
            app.PreDursEditFieldLabel.Text = 'Pre Dur (s)';
            app.PreDurs = uieditfield(app.SetEventWindowPanel, 'text');
            app.PreDurs.ValueChangedFcn = createCallbackFcn(app, @PreDursValueChanged, true);
            app.PreDurs.Value = '0.15';
            app.PostDurEditFieldLabel = uilabel(app.SetEventWindowPanel);
            app.PostDurEditFieldLabel.Text = 'Post Dur';
            app.PostDur = uieditfield(app.SetEventWindowPanel, 'text');
            app.PostDur.ValueChangedFcn = createCallbackFcn(app, @PostDurValueChanged, true);
            app.PostDur.Value = '0.15';
            app.SelectMultipleTrialsButton = uibutton(app.SetEventWindowPanel, 'push');
            app.SelectMultipleTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMultipleTrialsButtonPushed, true);
            app.SelectMultipleTrialsButton.Text = 'Select Multiple Trials';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name:';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.FileName.ValueChangedFcn = createCallbackFcn(app, @FileNameValueChanged, true);
            app.FileName.Editable = 'off';
            app.FileName.Enable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.EventChannelLabel = uilabel(app.Panel);
            app.EventChannelLabel.Text = 'Event Channel:';
            app.EventChannel = uieditfield(app.Panel, 'numeric');
            app.EventChannel.ValueChangedFcn = createCallbackFcn(app, @EventChannelValueChanged, true);
            app.ThresholdmVEditFieldLabel = uilabel(app.Panel);
            app.ThresholdmVEditFieldLabel.Text = 'Threshold (mV) :';
            app.ThresholdmV = uieditfield(app.Panel, 'numeric');
            app.ThresholdmV.ValueChangedFcn = createCallbackFcn(app, @ThresholdmVValueChanged, true);
            app.SearchISIsLabel = uilabel(app.Panel);
            app.SearchISIsLabel.Text = 'Search ISI (s) :';
            app.SearchISIs = uieditfield(app.Panel, 'numeric');
            app.SearchISIs.ValueChangedFcn = createCallbackFcn(app, @SearchISIsValueChanged, true);
            app.ButtonGroupUnits = uibuttongroup(app.UIFigure);
            app.ButtonGroupUnits.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupUnitsSelectionChanged, true);
            app.ButtonGroupUnits.SizeChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.uVButton = uiradiobutton(app.ButtonGroupUnits);
            app.uVButton.Enable = 'off';
            app.uVButton.Text = 'uV';
            app.mVButton = uiradiobutton(app.ButtonGroupUnits);
            app.mVButton.Enable = 'off';
            app.mVButton.Text = 'mV';
            app.mVButton.Value = true;
            app.VButton = uiradiobutton(app.ButtonGroupUnits);
            app.VButton.Enable = 'off';
            app.VButton.Text = 'V';
            app.ProcessPanel = uipanel(app.UIFigure);
            app.ProcessPanel.Title = 'Process';
            app.ProcessPanel.SizeChangedFcn = createCallbackFcn(app, @ProcessPanelSizeChanged, true);
            app.EnableCheckBox = uicheckbox(app.ProcessPanel);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.Panel_2 = uipanel(app.ProcessPanel);
            app.Panel_2.SizeChangedFcn = createCallbackFcn(app, @Panel_2SizeChanged, true);
            app.DownSampleCheckBox = uicheckbox(app.Panel_2);
            app.DownSampleCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownSampleCheckBoxValueChanged, true);
            app.DownSampleCheckBox.Enable = 'off';
            app.DownSampleCheckBox.Text = 'Down Sample';
            app.FactorEditFieldLabel = uilabel(app.Panel_2);
            app.FactorEditFieldLabel.Enable = 'off';
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.Panel_2, 'numeric');
            app.FactorEditField.ValueChangedFcn = createCallbackFcn(app, @FactorEditFieldValueChanged, true);
            app.FactorEditField.Enable = 'off';
            app.FactorEditField.Value = 1;
            app.BandPassCheckBox = uicheckbox(app.Panel_2);
            app.BandPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassCheckBoxValueChanged, true);
            app.BandPassCheckBox.Enable = 'off';
            app.BandPassCheckBox.Text = 'Band Pass';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.HzEditFieldLabel = uilabel(app.Panel_2);
            app.HzEditFieldLabel.Enable = 'off';
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.Panel_2, 'numeric');
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @HzEditFieldValueChanged, true);
            app.HzEditField.Enable = 'off';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '---';
            app.NotchCheckBox = uicheckbox(app.Panel_2);
            app.NotchCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchCheckBoxValueChanged, true);
            app.NotchCheckBox.Enable = 'off';
            app.NotchCheckBox.Text = 'Notch ';
            app.HzEditField_2Label = uilabel(app.Panel_2);
            app.HzEditField_2Label.Enable = 'off';
            app.HzEditField_2Label.Text = 'Hz';
            app.HzEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.HzEditField_2.ValueChangedFcn = createCallbackFcn(app, @HzEditField_2ValueChanged, true);
            app.HzEditField_2.Enable = 'off';
            app.TKEOCheckBox = uicheckbox(app.Panel_2);
            app.TKEOCheckBox.ValueChangedFcn = createCallbackFcn(app, @TKEOCheckBoxValueChanged, true);
            app.TKEOCheckBox.Enable = 'off';
            app.TKEOCheckBox.Text = 'TKEO';
            app.RectifyCheckBox = uicheckbox(app.Panel_2);
            app.RectifyCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectifyCheckBoxValueChanged, true);
            app.RectifyCheckBox.Enable = 'off';
            app.RectifyCheckBox.Text = 'Rectify';
            app.LowPassCheckBox = uicheckbox(app.Panel_2);
            app.LowPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowPassCheckBoxValueChanged, true);
            app.LowPassCheckBox.Enable = 'off';
            app.LowPassCheckBox.Text = 'Low Pass';
            app.HzEditField_3Label = uilabel(app.Panel_2);
            app.HzEditField_3Label.Enable = 'off';
            app.HzEditField_3Label.Text = 'Hz';
            app.HzEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.HzEditField_3.ValueChangedFcn = createCallbackFcn(app, @HzEditField_3ValueChanged, true);
            app.HzEditField_3.Enable = 'off';
            app.msEditFieldLabel = uilabel(app.Panel_2);
            app.msEditFieldLabel.Enable = 'off';
            app.msEditFieldLabel.Text = 'ms';
            app.msEditField = uieditfield(app.Panel_2, 'numeric');
            app.msEditField.ValueChangedFcn = createCallbackFcn(app, @msEditFieldValueChanged, true);
            app.msEditField.Enable = 'off';
            app.DelayCheckBox = uicheckbox(app.Panel_2);
            app.DelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelayCheckBoxValueChanged, true);
            app.DelayCheckBox.Enable = 'off';
            app.DelayCheckBox.Text = 'Delay';
            app.VelocityCheckBox = uicheckbox(app.Panel_2);
            app.VelocityCheckBox.Enable = 'off';
            app.VelocityCheckBox.Text = 'Velocity';
            app.ProcessButton = uibutton(app.ProcessPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.NonRectifiedLabel = uilabel(app.ProcessPanel);
            app.NonRectifiedLabel.Text = 'Non-Rectified =';
            app.NALabel_21 = uilabel(app.ProcessPanel);
            app.NALabel_21.Text = 'NA';
            app.ResetScaleButton = uibutton(app.UIFigure, 'push');
            app.ResetScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScaleButtonPushed, true);
            app.ResetScaleButton.Text = 'Reset Scale';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SavematButton = uibutton(app.Panel_3, 'push');
            app.SavematButton.ButtonPushedFcn = createCallbackFcn(app, @SavematButtonPushed, true);
            app.SavematButton.Text = 'Save (.mat)';
            app.SavecsvButton = uibutton(app.Panel_3, 'push');
            app.SavecsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavecsvButtonPushed, true);
            app.SavecsvButton.Text = 'Save (.csv)';
            app.EditField_4 = uieditfield(app.Panel_3, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.Panel_3, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.AnalysisOptionPanel = uipanel(app.UIFigure);
            app.AnalysisOptionPanel.Title = 'Analysis Option';
            app.AnalysisOptionPanel.SizeChangedFcn = createCallbackFcn(app, @AnalysisOptionPanelSizeChanged, true);
            app.ListBox = uilistbox(app.AnalysisOptionPanel);
            app.ListBox.Items = {'Silent Period', 'Active Motor Evoke Potential', 'Resting MEP', 'EMG Burst Onset'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged3, true);
            app.ListBox.Value = {};
            app.PreStimPeriodmsEditFieldLabel = uilabel(app.AnalysisOptionPanel);
            app.PreStimPeriodmsEditFieldLabel.Text = 'PreStim Period (ms):';
            app.PreStimPeriodmsEditField = uieditfield(app.AnalysisOptionPanel, 'numeric');
            app.PreStimPeriodmsEditField.ValueChangedFcn = createCallbackFcn(app, @PreStimPeriodmsEditFieldValueChanged, true);
            app.PreStimPeriodmsEditField.Value = -50;
            app.EditField_3Label = uilabel(app.AnalysisOptionPanel);
            app.EditField_3Label.Text = '-----';
            app.EditField_3 = uieditfield(app.AnalysisOptionPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = -5;
            app.msLabel = uilabel(app.AnalysisOptionPanel);
            app.msLabel.Text = 'ms';
            app.PreStimMeanLabel = uilabel(app.AnalysisOptionPanel);
            app.PreStimMeanLabel.Text = 'Pre-Stim Mean:';
            app.PreStimMeanEditField = uieditfield(app.AnalysisOptionPanel, 'numeric');
            app.PreStimMeanEditField.ValueChangedFcn = createCallbackFcn(app, @PreStimMeanEditFieldValueChanged, true);
            app.PreStimMeanEditField.Editable = 'off';
            app.mVLabel = uilabel(app.AnalysisOptionPanel);
            app.mVLabel.Text = 'mV';
            app.PreStimStdEditFieldLabel = uilabel(app.AnalysisOptionPanel);
            app.PreStimStdEditFieldLabel.Text = 'Pre-Stim Std:';
            app.PreStimStdEditField = uieditfield(app.AnalysisOptionPanel, 'numeric');
            app.PreStimStdEditField.Editable = 'off';
            app.mVLabel_2 = uilabel(app.AnalysisOptionPanel);
            app.mVLabel_2.Text = 'mV';
            app.TrialSelectPanel = uipanel(app.UIFigure);
            app.TrialSelectPanel.Title = 'Trial Select';
            app.TrialSelectPanel.Visible = 'off';
            app.TrialSelectPanel.SizeChangedFcn = createCallbackFcn(app, @TrialSelectPanelSizeChanged, true);
            app.MultiChanListBox = uilistbox(app.TrialSelectPanel);
            app.MultiChanListBox.Multiselect = 'on';
            app.MultiChanListBox.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.MultiChanListBox.Value = {};
            app.OKButton = uibutton(app.TrialSelectPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DefineConditionButton = uibutton(app.TrialSelectPanel, 'push');
            app.DefineConditionButton.ButtonPushedFcn = createCallbackFcn(app, @DefineConditionButtonPushed, true);
            app.DefineConditionButton.Text = {'Define'; 'Condition'};
            app.InitializationButton = uibutton(app.UIFigure, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Visible = 'off';
            app.InitializationButton.Text = 'Initialization';
            app.TrialsnotfoundEditFieldLabel = uilabel(app.UIFigure);
            app.TrialsnotfoundEditFieldLabel.Visible = 'off';
            app.TrialsnotfoundEditFieldLabel.Text = 'Trials not found';
            app.TrialsnotfoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialsnotfoundEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsnotfoundEditFieldValueChanged, true);
            app.TrialsnotfoundEditField.Editable = 'off';
            app.TrialsnotfoundEditField.Visible = 'off';
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanged, true);
            app.SampleRateHzEditField.Editable = 'off';
            app.DefineConditionsPanel = uipanel(app.UIFigure);
            app.DefineConditionsPanel.Title = 'Define Conditions';
            app.DefineConditionsPanel.Visible = 'off';
            app.UITable = uitable(app.DefineConditionsPanel);
            app.UITable.ColumnName = {'Pages'; 'Group Name'; 'Channel'; 'x'; 'y'; 'z'; 'Grid'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.TrialSelectPanel_3 = uipanel(app.DefineConditionsPanel);
            app.TrialSelectPanel_3.Title = 'Trial Select';
            app.TrialSelectPanel_3.Visible = 'off';
            app.TrialSelectPanel_3.SizeChangedFcn = createCallbackFcn(app, @TrialSelectPanel_3SizeChanged, true);
            app.MultiChanListBox_3 = uilistbox(app.TrialSelectPanel_3);
            app.MultiChanListBox_3.Multiselect = 'on';
            app.MultiChanListBox_3.Value = {};
            app.OKButton_3 = uibutton(app.TrialSelectPanel_3, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_3Pushed, true);
            app.OKButton_3.Text = 'OK';
            app.TrialSelectButton = uibutton(app.DefineConditionsPanel, 'push');
            app.TrialSelectButton.ButtonPushedFcn = createCallbackFcn(app, @TrialSelectButtonPushed, true);
            app.TrialSelectButton.Text = 'Trial Select';
            app.Panel_16 = uipanel(app.DefineConditionsPanel);
            app.CancelButton = uibutton(app.Panel_16, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.Panel_16, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadConditionButton = uibutton(app.DefineConditionsPanel, 'push');
            app.LoadConditionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConditionButtonPushed, true);
            app.LoadConditionButton.Text = 'Load Condition';
            app.ApplyButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.AddBlankRowButton = uibutton(app.DefineConditionsPanel, 'push');
            app.AddBlankRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddBlankRowButtonPushed, true);
            app.AddBlankRowButton.Text = 'Add Blank Row';
            app.AutoFillButton = uibutton(app.DefineConditionsPanel, 'push');
            app.AutoFillButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFillButtonPushed, true);
            app.AutoFillButton.Text = 'Auto Fill';
            app.FillFactor = uieditfield(app.DefineConditionsPanel, 'numeric');
            app.FillFactor.Value = 2;
            app.KeepSelectedRowsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.KeepSelectedRowsButton.ButtonPushedFcn = createCallbackFcn(app, @KeepSelectedRowsButtonPushed, true);
            app.KeepSelectedRowsButton.Text = 'Keep Selected Row(s)';
            app.LoadBrainsighttxtButton = uibutton(app.DefineConditionsPanel, 'push');
            app.LoadBrainsighttxtButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBrainsighttxtButtonPushed, true);
            app.LoadBrainsighttxtButton.Text = 'Load Brainsight.txt';
            app.EditField_10 = uieditfield(app.DefineConditionsPanel, 'text');
            app.EditField_10.Value = 'Sample';
            app.SearchFileLabel = uilabel(app.DefineConditionsPanel);
            app.SearchFileLabel.Text = 'Search File';
            app.LoadGroupNamesxlsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.LoadGroupNamesxlsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroupNamesxlsButtonPushed, true);
            app.LoadGroupNamesxlsButton.Text = 'Load Group Names (.xls)';
            app.ImportEventsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = {'Import Events'; ''};
            app.ChangeGroupNamestxttabButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ChangeGroupNamestxttabButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeGroupNamestxttabButtonPushed, true);
            app.ChangeGroupNamestxttabButton.Text = 'Change Group Names (.txt - tab)';
            app.Panel_17 = uipanel(app.UIFigure);
            app.Panel_17.Visible = 'off';
            app.Panel_17.SizeChangedFcn = createCallbackFcn(app, @Panel_17SizeChanged, true);
            app.EventNameListBox_2Label = uilabel(app.Panel_17);
            app.EventNameListBox_2Label.Text = 'Event Name:';
            app.EventNameListBox_2 = uilistbox(app.Panel_17);
            app.EventNameListBox_2.ValueChangedFcn = createCallbackFcn(app, @EventNameListBox_2ValueChanged, true);
            app.PrPotriggertimemsLabel = uilabel(app.Panel_17);
            app.PrPotriggertimemsLabel.Text = 'Pr/Po trigger time (ms):';
            app.PreTriggerTime = uieditfield(app.Panel_17, 'numeric');
            app.PreTriggerTime.ValueChangedFcn = createCallbackFcn(app, @PreTriggerTimeValueChanged, true);
            app.PostTriggerTime = uieditfield(app.Panel_17, 'numeric');
            app.ConditionsListBoxLabel = uilabel(app.UIFigure);
            app.ConditionsListBoxLabel.Visible = 'off';
            app.ConditionsListBoxLabel.Text = 'Conditions';
            app.ConditionsListBox = uilistbox(app.UIFigure);
            app.ConditionsListBox.Multiselect = 'on';
            app.ConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @ConditionsListBoxValueChanged, true);
            app.ConditionsListBox.Visible = 'off';
            app.ConditionsListBox.Value = {};
            app.DefineDataDirectoryButton = uibutton(app.UIFigure, 'push');
            app.DefineDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DefineDataDirectoryButtonPushed, true);
            app.DefineDataDirectoryButton.Text = {'Define '; 'Data Directory'; ''};
            app.ConditionMeanCheckBox = uicheckbox(app.UIFigure);
            app.ConditionMeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConditionMeanCheckBoxValueChanged, true);
            app.ConditionMeanCheckBox.Visible = 'off';
            app.ConditionMeanCheckBox.Text = {'Condition '; 'Mean'};
            app.GroupAnalysisPanel = uipanel(app.UIFigure);
            app.GroupAnalysisPanel.Title = 'Group Analysis';
            app.GroupAnalysisPanel.Visible = 'off';
            app.ListBox_2 = uilistbox(app.GroupAnalysisPanel);
            app.ListBox_2.Items = {'Recruitment Curve', 'Paired Pulse', 'Visualize', 'Mapping'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            app.MultiConditionAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.MultiConditionAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiConditionAnalysisCheckBoxValueChanged, true);
            app.MultiConditionAnalysisCheckBox.Visible = 'off';
            app.MultiConditionAnalysisCheckBox.Text = 'Multi Condition Analysis';
            app.YMin = uieditfield(app.UIFigure, 'text');
            app.YMin.Value = 'NA';
            app.YMax = uieditfield(app.UIFigure, 'text');
            app.YMax.Value = 'NA';
            app.XMin = uieditfield(app.UIFigure, 'text');
            app.XMin.Value = 'NA';
            app.XMax = uieditfield(app.UIFigure, 'text');
            app.XMax.Value = 'NA';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.ScaleButton = uibutton(app.UIFigure, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.OverlayNonRectifiedCheckBox = uicheckbox(app.UIFigure);
            app.OverlayNonRectifiedCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayNonRectifiedCheckBoxValueChanged, true);
            app.OverlayNonRectifiedCheckBox.Text = 'Overlay NonRectified';
            app.DefineResultsDirectoryButton = uibutton(app.UIFigure, 'push');
            app.DefineResultsDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DefineResultsDirectoryButtonPushed, true);
            app.DefineResultsDirectoryButton.Text = {'Define '; 'Results Directory'; ''};
            app.StackPlotCheckBox = uicheckbox(app.UIFigure);
            app.StackPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @StackPlotCheckBoxValueChanged2, true);
            app.StackPlotCheckBox.Text = 'Stack Plot';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Visible = 'off';
            app.EditField.Value = 'Wrong data format!!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Single';
            app.RestingMEPPanel = uipanel(app.SingleTab);
            app.RestingMEPPanel.Title = 'Resting MEP';
            app.RestingMEPPanel.Visible = 'off';
            app.RestingMEPPanel.SizeChangedFcn = createCallbackFcn(app, @RestingMEPPanelSizeChanged, true);
            app.Panel_14 = uipanel(app.RestingMEPPanel);
            app.OnsetmsLabel_2 = uilabel(app.Panel_14);
            app.OnsetmsLabel_2.Text = 'Onset (ms)';
            app.DurationmsLabel_2 = uilabel(app.Panel_14);
            app.DurationmsLabel_2.Text = 'Duration (ms)';
            app.AmplitudemVLabel = uilabel(app.Panel_14);
            app.AmplitudemVLabel.Text = 'Amplitude (mV)';
            app.AreaLabel_2 = uilabel(app.Panel_14);
            app.AreaLabel_2.Text = 'Area';
            app.NALabel_5 = uilabel(app.Panel_14);
            app.NALabel_5.Text = 'N/A';
            app.NALabel_6 = uilabel(app.Panel_14);
            app.NALabel_6.Text = 'N/A';
            app.NALabel_7 = uilabel(app.Panel_14);
            app.NALabel_7.Text = 'N/A';
            app.NALabel_8 = uilabel(app.Panel_14);
            app.NALabel_8.Text = 'N/A';
            app.Panel_13 = uipanel(app.Panel_14);
            app.ZTransButton_2 = uibutton(app.Panel_13, 'push');
            app.ZTransButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZTransButton_2Pushed, true);
            app.ZTransButton_2.Text = 'Z-Trans';
            app.ZSDEditField_2Label = uilabel(app.Panel_13);
            app.ZSDEditField_2Label.Text = 'Z SD';
            app.ZSDEditField_2 = uieditfield(app.Panel_13, 'numeric');
            app.ZSDEditField_2.Value = 1;
            app.LowPassHzEditField_2Label = uilabel(app.Panel_13);
            app.LowPassHzEditField_2Label.Text = 'Low Pass (Hz)';
            app.LowPassHzEditField_2 = uieditfield(app.Panel_13, 'numeric');
            app.LowPassHzEditField_2.Value = 150;
            app.StartTimemsEditField_3Label = uilabel(app.Panel_13);
            app.StartTimemsEditField_3Label.Text = 'Start Time  (ms)';
            app.StartTimemsEditField_3 = uieditfield(app.Panel_13, 'numeric');
            app.StartTimemsEditField_3.Value = 1;
            app.OverrideButton_3 = uibutton(app.Panel_14, 'push');
            app.OverrideButton_3.ButtonPushedFcn = createCallbackFcn(app, @OverrideButton_3Pushed, true);
            app.OverrideButton_3.Text = 'Override';
            app.Button_8 = uibutton(app.Panel_14, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '>';
            app.Button_9 = uibutton(app.Panel_14, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '<';
            app.Panel_15 = uipanel(app.RestingMEPPanel);
            app.AnalyzetheAverageCheckBox_2 = uicheckbox(app.Panel_15);
            app.AnalyzetheAverageCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AnalyzetheAverageCheckBox_2ValueChanged, true);
            app.AnalyzetheAverageCheckBox_2.Text = 'Analyze the Average ';
            app.LowPassEnvelopeCheckBox_2 = uicheckbox(app.Panel_15);
            app.LowPassEnvelopeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LowPassEnvelopeCheckBox_2ValueChanged, true);
            app.LowPassEnvelopeCheckBox_2.Text = 'Low Pass Envelope';
            app.HzEditField_5Label = uilabel(app.Panel_15);
            app.HzEditField_5Label.Text = 'Hz';
            app.HzEditField_5 = uieditfield(app.Panel_15, 'numeric');
            app.ClearBackgroundLinesCheckBox_2 = uicheckbox(app.Panel_15);
            app.ClearBackgroundLinesCheckBox_2.Visible = 'off';
            app.ClearBackgroundLinesCheckBox_2.Text = 'Clear Background Lines';
            app.RemoveCheckBox_2 = uicheckbox(app.Panel_15);
            app.RemoveCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @RemoveCheckBoxValueChanged, true);
            app.RemoveCheckBox_2.Text = 'Remove';
            app.RemovedCount_2 = uilabel(app.Panel_15);
            app.RemovedCount_2.Text = 'NA';
            app.LowLabel_2 = uilabel(app.Panel_15);
            app.LowLabel_2.Text = 'Low';
            app.HighLabel_2 = uilabel(app.Panel_15);
            app.HighLabel_2.Text = 'High';
            app.LowThreshEditField_2 = uieditfield(app.Panel_15, 'text');
            app.LowThreshEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowThreshEditFieldValueChanged, true);
            app.LowThreshEditField_2.Enable = 'off';
            app.HighThreshEditField_2 = uieditfield(app.Panel_15, 'text');
            app.HighThreshEditField_2.ValueChangedFcn = createCallbackFcn(app, @HighThreshEditFieldValueChanged, true);
            app.HighThreshEditField_2.Enable = 'off';
            app.EMGAnalysisPanel = uipanel(app.SingleTab);
            app.EMGAnalysisPanel.Title = 'EMG Analysis';
            app.EMGAnalysisPanel.Visible = 'off';
            app.PROLIFICButton = uibutton(app.EMGAnalysisPanel, 'push');
            app.PROLIFICButton.ButtonPushedFcn = createCallbackFcn(app, @PROLIFICButtonPushed, true);
            app.PROLIFICButton.Text = 'PROLIFIC';
            app.Panel_10 = uipanel(app.EMGAnalysisPanel);
            app.ZTransButton = uibutton(app.Panel_10, 'push');
            app.ZTransButton.ButtonPushedFcn = createCallbackFcn(app, @ZTransButtonPushed, true);
            app.ZTransButton.Text = 'Z-Trans';
            app.LowPassHzEditFieldLabel = uilabel(app.Panel_10);
            app.LowPassHzEditFieldLabel.Text = 'Low Pass (Hz)';
            app.LowPassHzEditField = uieditfield(app.Panel_10, 'numeric');
            app.LowPassHzEditField.Value = 150;
            app.ZSDEditFieldLabel = uilabel(app.Panel_10);
            app.ZSDEditFieldLabel.Text = 'Z SD';
            app.ZSDEditField = uieditfield(app.Panel_10, 'numeric');
            app.ZSDEditField.Value = 1;
            app.StartTimemsEditField_2Label = uilabel(app.Panel_10);
            app.StartTimemsEditField_2Label.Text = 'Start Time  (ms)';
            app.StartTimemsEditField_2 = uieditfield(app.Panel_10, 'numeric');
            app.StartTimemsEditField_2.Value = 1;
            app.OverrideButton_2 = uibutton(app.EMGAnalysisPanel, 'push');
            app.OverrideButton_2.ButtonPushedFcn = createCallbackFcn(app, @OverrideButton_2Pushed, true);
            app.OverrideButton_2.Text = 'Override';
            app.AnalyzetheAverageCheckBox_3 = uicheckbox(app.EMGAnalysisPanel);
            app.AnalyzetheAverageCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @AnalyzetheAverageCheckBox_3ValueChanged, true);
            app.AnalyzetheAverageCheckBox_3.Text = 'Analyze the Average ';
            app.ClearBackgroundLinesCheckBox_3 = uicheckbox(app.EMGAnalysisPanel);
            app.ClearBackgroundLinesCheckBox_3.Visible = 'off';
            app.ClearBackgroundLinesCheckBox_3.Text = 'Clear Background Lines';
            app.LowPassEnvelopeCheckBox_3 = uicheckbox(app.EMGAnalysisPanel);
            app.LowPassEnvelopeCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @LowPassEnvelopeCheckBox_3ValueChanged, true);
            app.LowPassEnvelopeCheckBox_3.Text = 'Low Pass Envelope';
            app.HzEditField_4Label_2 = uilabel(app.EMGAnalysisPanel);
            app.HzEditField_4Label_2.Text = 'Hz';
            app.HzEditField_6 = uieditfield(app.EMGAnalysisPanel, 'numeric');
            app.Panel_20 = uipanel(app.EMGAnalysisPanel);
            app.OnsetmsLabel_3 = uilabel(app.Panel_20);
            app.OnsetmsLabel_3.Text = 'Onset (ms)';
            app.ModulationRatioLabel = uilabel(app.Panel_20);
            app.ModulationRatioLabel.Text = 'Modulation Ratio';
            app.PeakLabel = uilabel(app.Panel_20);
            app.PeakLabel.Text = 'Peak';
            app.NALabel_14 = uilabel(app.Panel_20);
            app.NALabel_14.Text = 'N/A';
            app.NALabel_15 = uilabel(app.Panel_20);
            app.NALabel_15.Text = 'N/A';
            app.NALabel_16 = uilabel(app.Panel_20);
            app.NALabel_16.Text = 'N/A';
            app.Panel_21 = uipanel(app.Panel_20);
            app.Panel_22 = uipanel(app.Panel_21);
            app.SDonsetEditField_2Label = uilabel(app.Panel_22);
            app.SDonsetEditField_2Label.Text = 'SD: onset';
            app.SDonsetEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.SDonsetEditField_2.Value = 1;
            app.MinDurmsEditField_3Label = uilabel(app.Panel_22);
            app.MinDurmsEditField_3Label.Text = 'Min Dur  (ms)';
            app.MinDurmsEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.MinDurmsEditField_3.Value = 1;
            app.StartTimemsEditField_4Label = uilabel(app.Panel_20);
            app.StartTimemsEditField_4Label.Text = 'Start Time  (ms)';
            app.StartTimemsEditField_4 = uieditfield(app.Panel_20, 'numeric');
            app.StartTimemsEditField_4.Value = 1;
            app.Button_4 = uibutton(app.Panel_20, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>';
            app.Button_5 = uibutton(app.Panel_20, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '<';
            app.modRatioWindow = uieditfield(app.Panel_20, 'numeric');
            app.modRatioWindow.Value = 1;
            app.modRatioWindowText = uilabel(app.Panel_20);
            app.modRatioWindowText.Text = {'Mod Ratio '; 'time (ms)'};
            app.NALabel_17 = uilabel(app.Panel_20);
            app.NALabel_17.Text = {'N/A'; ''};
            app.MDF = uilabel(app.Panel_20);
            app.MDF.Text = 'MDF';
            app.MDFText = uilabel(app.Panel_20);
            app.MDFText.Text = 'NA';
            app.AnalyzeButton_4 = uibutton(app.EMGAnalysisPanel, 'push');
            app.AnalyzeButton_4.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_4Pushed, true);
            app.AnalyzeButton_4.Text = 'Analyze';
            app.OverrideButton_4 = uibutton(app.EMGAnalysisPanel, 'push');
            app.OverrideButton_4.ButtonPushedFcn = createCallbackFcn(app, @OverrideButton_4Pushed, true);
            app.OverrideButton_4.Text = 'Override';
            app.EDENAnalyzeButton = uibutton(app.EMGAnalysisPanel, 'push');
            app.EDENAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @EDENAnalyzeButtonPushed, true);
            app.EDENAnalyzeButton.Text = 'EDEN Analyze';
            app.areaWindowTime = uieditfield(app.EMGAnalysisPanel, 'numeric');
            app.areaWindowTime.Value = 1;
            app.areaWindowStart2 = uieditfield(app.EMGAnalysisPanel, 'numeric');
            app.areaWindowStart2.Value = 1;
            app.areaWindowStart = uieditfield(app.EMGAnalysisPanel, 'numeric');
            app.areaWindowStart.Value = 1;
            app.NALabel_19 = uilabel(app.EMGAnalysisPanel);
            app.NALabel_19.Text = 'N/A';
            app.StartLabel = uilabel(app.EMGAnalysisPanel);
            app.StartLabel.Text = 'Start';
            app.TimeLabel = uilabel(app.EMGAnalysisPanel);
            app.TimeLabel.Text = 'Time';
            app.ContractLabel = uilabel(app.EMGAnalysisPanel);
            app.ContractLabel.Text = 'Contract';
            app.RelaxLabel = uilabel(app.EMGAnalysisPanel);
            app.RelaxLabel.Text = 'Relax';
            app.NALabel_20 = uilabel(app.EMGAnalysisPanel);
            app.NALabel_20.Text = 'NA';
            app.saveButton = uibutton(app.EMGAnalysisPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.Button_2 = uibutton(app.EMGAnalysisPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.Button_3 = uibutton(app.EMGAnalysisPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '>';
            app.OnsetmsEditFieldLabel = uilabel(app.EMGAnalysisPanel);
            app.OnsetmsEditFieldLabel.Text = 'Onset (ms):';
            app.OnsetmsEditField = uieditfield(app.EMGAnalysisPanel, 'numeric');
            app.OnsetmsEditField.Editable = 'off';
            app.OnsetmsEditField.Enable = 'off';
            app.SilentPeriodPanel = uipanel(app.SingleTab);
            app.SilentPeriodPanel.Title = 'Silent Period';
            app.SilentPeriodPanel.SizeChangedFcn = createCallbackFcn(app, @SilentPeriodPanelSizeChanged, true);
            app.AnalyzeButton = uibutton(app.SilentPeriodPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.OverrideButton = uibutton(app.SilentPeriodPanel, 'push');
            app.OverrideButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideButtonPushed, true);
            app.OverrideButton.Text = 'Override';
            app.Panel_5 = uipanel(app.SilentPeriodPanel);
            app.AnalyzetheAverageCheckBox = uicheckbox(app.Panel_5);
            app.AnalyzetheAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzetheAverageCheckBoxValueChanged, true);
            app.AnalyzetheAverageCheckBox.Text = 'Analyze the Average ';
            app.LowPassEnvelopeCheckBox = uicheckbox(app.Panel_5);
            app.LowPassEnvelopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowPassEnvelopeCheckBoxValueChanged, true);
            app.LowPassEnvelopeCheckBox.Text = 'Low Pass Envelope';
            app.MCDCheckBox = uicheckbox(app.Panel_5);
            app.MCDCheckBox.ValueChangedFcn = createCallbackFcn(app, @MCDCheckBoxValueChanged, true);
            app.MCDCheckBox.Text = 'MCD';
            app.HzEditField_4Label = uilabel(app.Panel_5);
            app.HzEditField_4Label.Text = 'Hz';
            app.HzEditField_4 = uieditfield(app.Panel_5, 'numeric');
            app.HzEditField_4.ValueChangedFcn = createCallbackFcn(app, @HzEditField_4ValueChanged, true);
            app.ClearBackgroundLinesCheckBox = uicheckbox(app.Panel_5);
            app.ClearBackgroundLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClearBackgroundLinesCheckBoxValueChanged, true);
            app.ClearBackgroundLinesCheckBox.Text = 'Clear Background Lines';
            app.MCDConstantEditFieldLabel = uilabel(app.Panel_5);
            app.MCDConstantEditFieldLabel.Text = 'MCD Constant';
            app.MCDConstantEditField = uieditfield(app.Panel_5, 'numeric');
            app.MCDConstantEditField.Value = 2.66;
            app.RemoveCheckBox = uicheckbox(app.Panel_5);
            app.RemoveCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveCheckBoxValueChanged, true);
            app.RemoveCheckBox.Text = 'Remove';
            app.LowThreshEditField = uieditfield(app.Panel_5, 'text');
            app.LowThreshEditField.ValueChangedFcn = createCallbackFcn(app, @LowThreshEditFieldValueChanged, true);
            app.LowThreshEditField.Enable = 'off';
            app.HighThreshEditField = uieditfield(app.Panel_5, 'text');
            app.HighThreshEditField.ValueChangedFcn = createCallbackFcn(app, @HighThreshEditFieldValueChanged, true);
            app.HighThreshEditField.Enable = 'off';
            app.RemovedCount = uilabel(app.Panel_5);
            app.RemovedCount.Text = 'NA';
            app.LowLabel = uilabel(app.Panel_5);
            app.LowLabel.Text = 'Low';
            app.HighLabel = uilabel(app.Panel_5);
            app.HighLabel.Text = 'High';
            app.Panel_6 = uipanel(app.SilentPeriodPanel);
            app.Panel_6.SizeChangedFcn = createCallbackFcn(app, @Panel_6SizeChanged, true);
            app.OnsetmsLabel = uilabel(app.Panel_6);
            app.OnsetmsLabel.Text = 'Onset (ms)';
            app.DurationmsLabel = uilabel(app.Panel_6);
            app.DurationmsLabel.Text = 'Duration (ms)';
            app.DecreaseLabel = uilabel(app.Panel_6);
            app.DecreaseLabel.Text = '% Decrease';
            app.AreaLabel = uilabel(app.Panel_6);
            app.AreaLabel.Text = 'Area';
            app.NALabel = uilabel(app.Panel_6);
            app.NALabel.Text = 'N/A';
            app.NALabel_2 = uilabel(app.Panel_6);
            app.NALabel_2.Text = 'N/A';
            app.NALabel_3 = uilabel(app.Panel_6);
            app.NALabel_3.Text = 'N/A';
            app.NALabel_4 = uilabel(app.Panel_6);
            app.NALabel_4.Text = 'N/A';
            app.Panel_11 = uipanel(app.Panel_6);
            app.Panel_7 = uipanel(app.Panel_11);
            app.SDonsetEditFieldLabel = uilabel(app.Panel_7);
            app.SDonsetEditFieldLabel.Text = 'SD: onset';
            app.SDonsetEditField = uieditfield(app.Panel_7, 'numeric');
            app.SDonsetEditField.Value = 1;
            app.MinDurmsEditFieldLabel = uilabel(app.Panel_7);
            app.MinDurmsEditFieldLabel.Text = 'Min Dur  (ms)';
            app.MinDurmsEditField = uieditfield(app.Panel_7, 'numeric');
            app.MinDurmsEditField.Value = 1;
            app.Panel_8 = uipanel(app.Panel_11);
            app.MinDurmsEditField_2Label = uilabel(app.Panel_8);
            app.MinDurmsEditField_2Label.Text = 'Min Dur  (ms)';
            app.MinDurmsEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.MinDurmsEditField_2.Value = 1;
            app.SDoffsetEditFieldLabel = uilabel(app.Panel_8);
            app.SDoffsetEditFieldLabel.Text = 'SD: offset';
            app.SDoffsetEditField = uieditfield(app.Panel_8, 'numeric');
            app.SDoffsetEditField.Value = 1;
            app.StartTimemsEditFieldLabel = uilabel(app.Panel_6);
            app.StartTimemsEditFieldLabel.Text = 'Start Time  (ms)';
            app.StartTimemsEditField = uieditfield(app.Panel_6, 'numeric');
            app.StartTimemsEditField.Value = 1;
            app.AlgorithmCheckBox = uicheckbox(app.Panel_6);
            app.AlgorithmCheckBox.Text = '50% Algorithm';
            app.Button_6 = uibutton(app.SilentPeriodPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '>';
            app.Button_7 = uibutton(app.SilentPeriodPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '<';
            app.ConditionTab = uitab(app.TabGroup);
            app.ConditionTab.Title = 'Condition';
            app.PairedPulse = uipanel(app.ConditionTab);
            app.PairedPulse.Title = 'Paired Pulse';
            app.PairedPulse.Visible = 'off';
            app.AnalyzeButton_3 = uibutton(app.PairedPulse, 'push');
            app.AnalyzeButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_3Pushed, true);
            app.AnalyzeButton_3.Text = 'Analyze';
            app.CentralityCheckBox_3 = uicheckbox(app.PairedPulse);
            app.CentralityCheckBox_3.Text = 'Centrality';
            app.EditField_8 = uieditfield(app.PairedPulse, 'numeric');
            app.UITable_CondOder_3 = uitable(app.PairedPulse);
            app.UITable_CondOder_3.ColumnName = {'Group Names'; 'Plot Order'};
            app.UITable_CondOder_3.RowName = {};
            app.UITable_CondOder_3.ColumnSortable = true;
            app.UITable_CondOder_3.ColumnEditable = true;
            app.MetricstoAnalyzeListBox_3Label = uilabel(app.PairedPulse);
            app.MetricstoAnalyzeListBox_3Label.Text = 'Metrics to Analyze';
            app.MetricstoAnalyzeListBox_3 = uilistbox(app.PairedPulse);
            app.MeanCheckBox_3 = uicheckbox(app.PairedPulse);
            app.MeanCheckBox_3.Text = 'Mean';
            app.ReorderButton_3 = uibutton(app.PairedPulse, 'push');
            app.ReorderButton_3.Text = 'Reorder';
            app.Ratio = uilabel(app.PairedPulse);
            app.Ratio.Text = 'NA';
            app.Ratio21Label = uilabel(app.PairedPulse);
            app.Ratio21Label.Text = 'Ratio % (2/1)';
            app.SaveButton_2 = uibutton(app.PairedPulse, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.Visualize = uipanel(app.ConditionTab);
            app.Visualize.Title = 'Visualize';
            app.Visualize.Visible = 'off';
            app.CentralityCheckBox_2 = uicheckbox(app.Visualize);
            app.CentralityCheckBox_2.Text = 'Centrality';
            app.EditField_7 = uieditfield(app.Visualize, 'numeric');
            app.UITable_CondOder_2 = uitable(app.Visualize);
            app.UITable_CondOder_2.ColumnName = {'Group Names'; 'Plot Order'};
            app.UITable_CondOder_2.RowName = {};
            app.UITable_CondOder_2.ColumnSortable = true;
            app.UITable_CondOder_2.ColumnEditable = true;
            app.UITable_CondOder_2.CellEditCallback = createCallbackFcn(app, @UITable_CondOder_2CellEdit, true);
            app.UITable_CondOder_2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable_CondOder_2DisplayDataChanged, true);
            app.PlotButton = uibutton(app.Visualize, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MetricstoAnalyzeListBox_2Label = uilabel(app.Visualize);
            app.MetricstoAnalyzeListBox_2Label.Text = 'Metrics to Analyze';
            app.MetricstoAnalyzeListBox_2 = uilistbox(app.Visualize);
            app.MeanCheckBox_2 = uicheckbox(app.Visualize);
            app.MeanCheckBox_2.Text = 'Mean';
            app.ReorderButton_2 = uibutton(app.Visualize, 'push');
            app.ReorderButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReorderButton_2Pushed, true);
            app.ReorderButton_2.Text = 'Reorder';
            app.SaveCSVButton = uibutton(app.Visualize, 'push');
            app.SaveCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVButtonPushed, true);
            app.SaveCSVButton.Text = 'Save (.CSV)';
            app.ThresholdLabel = uilabel(app.Visualize);
            app.ThresholdLabel.Text = 'Threshold';
            app.EditField_12 = uieditfield(app.Visualize, 'numeric');
            app.RecruitmentCurvePanel = uipanel(app.ConditionTab);
            app.RecruitmentCurvePanel.Title = 'Recruitment Curve';
            app.RecruitmentCurvePanel.Visible = 'off';
            app.Panel_18 = uipanel(app.RecruitmentCurvePanel);
            app.BaseLabel = uilabel(app.Panel_18);
            app.BaseLabel.Text = 'Base';
            app.PlateauLabel = uilabel(app.Panel_18);
            app.PlateauLabel.Text = 'Plateau';
            app.InflectionLabel = uilabel(app.Panel_18);
            app.InflectionLabel.Text = 'Inflection';
            app.KSlopeLabel = uilabel(app.Panel_18);
            app.KSlopeLabel.Text = 'K Slope';
            app.NALabel_10 = uilabel(app.Panel_18);
            app.NALabel_10.Text = 'NA';
            app.NALabel_11 = uilabel(app.Panel_18);
            app.NALabel_11.Text = 'NA';
            app.NALabel_12 = uilabel(app.Panel_18);
            app.NALabel_12.Text = 'NA';
            app.NALabel_9 = uilabel(app.Panel_18);
            app.NALabel_9.Text = 'NA';
            app.Panel_19 = uipanel(app.Panel_18);
            app.NALabel_13 = uilabel(app.Panel_19);
            app.NALabel_13.Text = 'NA';
            app.AUCLabel = uilabel(app.Panel_19);
            app.AUCLabel.Text = 'AUC';
            app.ToEditFieldLabel = uilabel(app.Panel_19);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.Panel_19, 'numeric');
            app.FromEditFieldLabel = uilabel(app.Panel_19);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.Panel_19, 'numeric');
            app.CentralityCheckBox = uicheckbox(app.RecruitmentCurvePanel);
            app.CentralityCheckBox.Text = 'Centrality';
            app.EditField_6 = uieditfield(app.RecruitmentCurvePanel, 'numeric');
            app.UITable_CondOder = uitable(app.RecruitmentCurvePanel);
            app.UITable_CondOder.ColumnName = {'Group Names'; 'Plot Order'};
            app.UITable_CondOder.RowName = {};
            app.UITable_CondOder.ColumnSortable = true;
            app.UITable_CondOder.ColumnEditable = true;
            app.UITable_CondOder.CellEditCallback = createCallbackFcn(app, @UITable_CondOderCellEdit, true);
            app.AnalyzeButton_2 = uibutton(app.RecruitmentCurvePanel, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed2, true);
            app.AnalyzeButton_2.Text = 'Analyze';
            app.MetricstoAnalyzeListBoxLabel = uilabel(app.RecruitmentCurvePanel);
            app.MetricstoAnalyzeListBoxLabel.Text = 'Metrics to Analyze';
            app.MetricstoAnalyzeListBox = uilistbox(app.RecruitmentCurvePanel);
            app.MeanCheckBox = uicheckbox(app.RecruitmentCurvePanel);
            app.MeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanCheckBoxValueChanged, true);
            app.MeanCheckBox.Text = 'Mean';
            app.ReorderButton = uibutton(app.RecruitmentCurvePanel, 'push');
            app.ReorderButton.ButtonPushedFcn = createCallbackFcn(app, @ReorderButtonPushed, true);
            app.ReorderButton.Text = 'Reorder';
            app.Mapping = uipanel(app.ConditionTab);
            app.Mapping.Title = 'Mapping';
            app.Mapping.Visible = 'off';
            app.CentralityCheckBox_4 = uicheckbox(app.Mapping);
            app.CentralityCheckBox_4.Text = 'Centrality';
            app.EditField_9 = uieditfield(app.Mapping, 'numeric');
            app.UITable_CondOder_4 = uitable(app.Mapping);
            app.UITable_CondOder_4.ColumnName = {'Group Names'; 'Plot Order'; 'x'; 'y'; 'z'; 'GridX'; 'GridY'};
            app.UITable_CondOder_4.RowName = {};
            app.UITable_CondOder_4.ColumnSortable = true;
            app.UITable_CondOder_4.ColumnEditable = true;
            app.PlotButton_2 = uibutton(app.Mapping, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.MetricstoAnalyzeListBox_4Label = uilabel(app.Mapping);
            app.MetricstoAnalyzeListBox_4Label.Text = 'Metrics to Analyze';
            app.MetricstoAnalyzeListBox_4 = uilistbox(app.Mapping);
            app.MeanCheckBox_4 = uicheckbox(app.Mapping);
            app.MeanCheckBox_4.Text = 'Mean';
            app.ReorderButton_4 = uibutton(app.Mapping, 'push');
            app.ReorderButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReorderButton_4Pushed, true);
            app.ReorderButton_4.Text = 'Reorder';
            app.ExportNeuroMeasurecsvButton = uibutton(app.Mapping, 'push');
            app.ExportNeuroMeasurecsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNeuroMeasurecsvButtonPushed, true);
            app.ExportNeuroMeasurecsvButton.Text = 'Export NeuroMeasure (.csv)';
            app.EditField_11 = uieditfield(app.Mapping, 'numeric');
            app.ThresholdLabel_2 = uilabel(app.Mapping);
            app.ThresholdLabel_2.Text = 'Threshold';
            app.EditField_13 = uieditfield(app.Mapping, 'numeric');
            app.PercentIncLabel = uilabel(app.Mapping);
            app.PercentIncLabel.Text = 'Percent Inc';
            app.LoadxlsButton = uibutton(app.Mapping, 'push');
            app.LoadxlsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadxlsButtonPushed, true);
            app.LoadxlsButton.Text = 'Load xls';
            app.SaveCSVButton_2 = uibutton(app.Mapping, 'push');
            app.SaveCSVButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVButton_2Pushed, true);
            app.SaveCSVButton_2.Text = 'Save (.CSV)';
            app.EventNameListBox = uilistbox(app.UIFigure);
            app.EventNameListBox.ValueChangedFcn = createCallbackFcn(app, @EventNameListBoxValueChanged, true);
            app.EventNameListBox.Visible = 'off';
            app.EventNameListBoxLabel = uilabel(app.UIFigure);
            app.EventNameListBoxLabel.Visible = 'off';
            app.EventNameListBoxLabel.Text = 'Event Name';
            app.MinLabel_2 = uilabel(app.UIFigure);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.UIFigure);
            app.MaxLabel_2.Text = 'Max';
            app.FigureCheckCheckBox = uicheckbox(app.UIFigure);
            app.FigureCheckCheckBox.Visible = 'off';
            app.FigureCheckCheckBox.Text = 'Figure Check';
            app.Processing = uilabel(app.UIFigure);
            app.Processing.Visible = 'off';
            app.Processing.Text = 'Processing...please wait';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'D. Cunningham, B. Zhang and A. Cahn, "Transcranial Magnetic Stimulation (TMS) Analysis Toolbox: '; 'A user friendly open source software for basic and advanced analysis and data sharing of TMS related '; 'outcomes", Brain Stimul., vol. 14, no. 6, pp. 1641-1642, Nov. 2021'};
            app.PleaseCiteLabel = uilabel(app.UIFigure);
            app.PleaseCiteLabel.Text = 'Please Cite:';
            app.FileOrderListBoxLabel = uilabel(app.UIFigure);
            app.FileOrderListBoxLabel.Text = 'File Order';
            app.FileOrderListBox = uilistbox(app.UIFigure);
            app.FileOrderListBox.Items = {};
            app.FileOrderListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMSAnalysisToolBoxV1_Resize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end